classdef ControlSystem_GUI_V3_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        JustExoPIDControllerUIFigure  matlab.ui.Figure
        Panel_2                       matlab.ui.container.Panel
        ExoskeletonSettingPanel       matlab.ui.container.Panel
        ServoControlPanel             matlab.ui.container.Panel
        ExcuteButton                  matlab.ui.control.Button
        ZeroDegreeButton              matlab.ui.control.Button
        ServoRefDegreeEditField       matlab.ui.control.NumericEditField
        ServoRefDegreeLabel           matlab.ui.control.Label
        ServoSpeedSlider              matlab.ui.control.Slider
        ServoSpeedSliderLabel         matlab.ui.control.Label
        ServoPowerSwitch              matlab.ui.control.Switch
        ServoPowerSwitchLabel         matlab.ui.control.Label
        ExoskeletonConnectionPanel    matlab.ui.container.Panel
        ResetButton                   matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        Panel_4                       matlab.ui.container.Panel
        RefreshButton                 matlab.ui.control.Button
        BatteryStatusLabel            matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        ArduinoConnectionLabel        matlab.ui.control.Label
        Switch                        matlab.ui.control.RockerSwitch
        StatusLamp                    matlab.ui.control.Lamp
        StatusLampLabel               matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        TabGroup2                     matlab.ui.container.TabGroup
        TransferFunctionTab           matlab.ui.container.Tab
        PLOTButton                    matlab.ui.control.Button
        TunningPIDPanel               matlab.ui.container.Panel
        WcEditField                   matlab.ui.control.NumericEditField
        cLabel                        matlab.ui.control.Label
        zetaEditField                 matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        BetaEditField                 matlab.ui.control.NumericEditField
        EditField_2Label              matlab.ui.control.Label
        KiEditField                   matlab.ui.control.NumericEditField
        KiEditFieldLabel              matlab.ui.control.Label
        TauEditField                  matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        GsPlantPanel                  matlab.ui.container.Panel
        denEditField                  matlab.ui.control.EditField
        denEditFieldLabel             matlab.ui.control.Label
        numEditField_2                matlab.ui.control.EditField
        numEditField_2Label           matlab.ui.control.Label
        StepResponseInfoTab           matlab.ui.container.Tab
        ZerosLabel                    matlab.ui.control.Label
        PolesLabel                    matlab.ui.control.Label
        TimeConstantLabel             matlab.ui.control.Label
        PeakLabel                     matlab.ui.control.Label
        OverShootLabel                matlab.ui.control.Label
        SettlingTimeLabel_2           matlab.ui.control.Label
        RiseTimeLabel                 matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
         %%Check the battery charge volatage and show in the screen
         function CheckBattery(app)
             global connected_device;
             global Battery_Voltage;
             global charge_status;
             %%%%%% get the Battery Voltage measurment %%%%%%
             configurePin(connected_device,'A6','AnalogInput');
             Battery_Voltage = readVoltage(connected_device,'A6');
             Battery_Voltage = round(Battery_Voltage,2)*3;

             if Battery_Voltage >=7.4
                 app.Lamp.Color = "green";
                 app.ServoPowerSwitch.Enable = "on";
                 charge_status=1;
             elseif Battery_Voltage>=6.4 && Battery_Voltage<7.4
                 app.Lamp.Color = [0.92 0.52 0.03];
                 app.ServoPowerSwitch.Enable = "on";
                 charge_status=1;
             else
                 app.Lamp.Color = "red";
                 report = [ "The Battery is TOO LOW, unfortunately the servo can't be used.";" ";"Sugesstions:";"Please make sure the Exoskeleton's push button Pressed down";"Or Please Charge the Battery"];
                 msgbox(report,"Error","error")
                 app.ServoPowerSwitch.Enable = "off";
                 charge_status=0;
                 Battery_Voltage = 6.4;
             end

             %Convert the volts to Percentage
             Percentagem_min=0;
             Percentagem_max=100;
             Battery_Percentage = (Battery_Voltage-6.4)*(Percentagem_max-Percentagem_min)/(8.4-6.4) + Percentagem_min;
             Battery_Percentage = round(Battery_Percentage,0);
             app.BatteryStatusLabel.Text = "Battery Status: "+string(Battery_Percentage)+"%";
            
         end
         
        
         function RGB_Colour(app,R,G,B)
             global connected_device;

             PIN_BLUE   = 'D3';
             PIN_GREEN = 'D6';
             PIN_RED  = 'D9';

             %%%%%% RGB Switch Colours%%%%%%
             writePWMVoltage(connected_device,PIN_RED,R);
             writePWMVoltage(connected_device,PIN_GREEN,G);
             writePWMVoltage(connected_device,PIN_BLUE,B);
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global is_connected;
            global Stable_Factor;
            global Danger;
            global charge_status;
            global connected_device;
            Stable_Factor=0;
            Danger=0;
            charge_status=0;
            is_connected=0;
            connected_device="";
        end

        % Value changing function: numEditField_2
        function numEditField_2ValueChanging(app, event)
            TotalValue = event.Value;
            TF = isempty(TotalValue);

            if TF == 0
                changingValue = extract(TotalValue,strlength(TotalValue));
                changingValue = string(changingValue);
                changingValue = convertStringsToChars(changingValue);

                if isstrprop(changingValue,'digit')
                    app.numEditField_2.Value = [TotalValue , ' '];
                else
                    app.numEditField_2.Value = TotalValue;
                end
            end
        end

        % Value changing function: denEditField
        function denEditFieldValueChanging(app, event)
            TotalValue = event.Value;
            TF = isempty(TotalValue);

            if TF == 0
                changingValue = extract(TotalValue,strlength(TotalValue));
                changingValue = string(changingValue);
                changingValue = convertStringsToChars(changingValue);

                if isstrprop(changingValue,'digit')
                    app.denEditField.Value = [TotalValue , ' '];
                else
                    app.denEditField.Value = TotalValue;
                end
            end
        end

        % Callback function
        function numEditFieldValueChanging(app, event)
            TotalValue = event.Value;
            TF = isempty(TotalValue);

            if TF == 0
                changingValue = extract(TotalValue,strlength(TotalValue));
                changingValue = string(changingValue);
                changingValue = convertStringsToChars(changingValue);

                if isstrprop(changingValue,'digit')
                    app.numEditField.Value = [TotalValue , ' '];
                else
                    app.numEditField.Value = TotalValue;
                end
            end
        end

        % Callback function
        function denEditField_2ValueChanging(app, event)
            TotalValue = event.Value;
            TF = isempty(TotalValue);

            if TF == 0
                changingValue = extract(TotalValue,strlength(TotalValue));
                changingValue = string(changingValue);
                changingValue = convertStringsToChars(changingValue);

                if isstrprop(changingValue,'digit')
                    app.denEditField_2.Value = [TotalValue , ' '];
                else
                    app.denEditField_2.Value = TotalValue;
                end
            end
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            clc
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes3)
            
            global Stable_Factor;
            global Danger;
            global is_connected;                %% is arduino connected?
            try
                Danger = 0;

                %%%%%%%%  G(s) Plant Transfer Funtion %%%%%%%%%%
                % G(S) Num Values
                G_num = app.numEditField_2.Value;
                a = regexp(G_num,'(-)?\d+(\.\d+)?(e(-|\+)\d+)?','match');
                nstr = string(a);
                G_num=zeros(1,numel(nstr));
                for i=1:numel(nstr)
                    G_num(i)=str2double(nstr(i));
                end

                % G(s) Den Values
                G_den = app.denEditField.Value;
                a2 = regexp(G_den,'(-)?\d+(\.\d+)?(e(-|\+)\d+)?','match');
                nstr2 = string(a2);
                G_den=zeros(1,numel(nstr2));
                for i=1:numel(nstr2)
                    G_den(i)=str2double(nstr2(i));
                end

                G = tf(G_num,G_den); % Plant Transfer Function

                %%%%%%%%  PID Controller Transfer Funtion %%%%%%%%%%
                t = app.TauEditField.Value;
                ki = app.KiEditField.Value;
                B = app.BetaEditField.Value;
                zeta = app.zetaEditField.Value;
                wc = app.WcEditField.Value;

                F_num = sqrt((1-((wc*t)^2))^2 + (2*zeta*wc*t)^2);
                F_den = wc*(sqrt(1+((wc*t)/B)^2));
                F_PID = ki*(F_num)/(F_den);
                
                sys = feedback(G*F_PID,1); % System General Transfer Function

                %%Main Variables' Values
                Plot_Value = step(sys);
                Ref = Plot_Value(end);
                Ref_degree = app.ServoRefDegreeEditField.Value;
                Danger_degree = 130;

                plot(app.UIAxes3,Plot_Value);
                hold(app.UIAxes3,'on');

                %%Transfer function Plot
                PLOT_degree = (Ref_degree*Plot_Value)/Ref; % convert curve value to degrees
                plot(app.UIAxes_2,PLOT_degree,"blue",'LineWidth',1);
                hold(app.UIAxes_2,'on');

                %%Ploting the Red zone
                X_End_point = app.UIAxes_2.XTick(end);
                Y_End_point = app.UIAxes_2.YTick(end);
                X_values = 1:X_End_point;
                Danger_degree_threshold = Danger_degree.* ones(1,X_End_point);
                Max_Value = Y_End_point.* ones(1,X_End_point);
                Ref_point_line = PLOT_degree(end).* ones(1,X_End_point);

                plot(app.UIAxes_2,X_values,Ref_point_line,":",'LineWidth',1,"Color","black"); %[0.8 0.8 0.8]
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,X_values,Danger_degree_threshold,"red",'LineWidth',2);
                hold(app.UIAxes_2,'on');   
                

                %%check a danger degree
                for q = 1:length(PLOT_degree)
                    if PLOT_degree(q)>= Danger_degree
                        Danger = 1;
                    end
                end

                %%Red zone fill
                if Danger == 1
                    x = 1 : X_End_point;
                    curve1 = Danger_degree_threshold;
                    curve2 = Max_Value;

                    x2 = [x, fliplr(x)];
                    inBetween = [curve1, fliplr(curve2)];
                    fill(app.UIAxes_2,x2, inBetween, 'r','FaceAlpha',0.2);
                end
                
                
                %% disturbance rejection of a pid controller Values
                Tf=t/B;
                Ti=2*zeta*t-Tf;
                Td=((t^2)/Ti)-Tf;
                kp=ki*Ti;
                kd=kp*Td;
                C = pid(kp,ki,kd);

                sys_output = feedback(sys,C);
                disturbance_rejection = step(sys_output);
                plot(app.UIAxes,disturbance_rejection);
%                 app.UIAxes.Title.String ="From:u To:y";
                hold(app.UIAxes,'on');

                %graph info
                S = stepinfo(sys);
                Stable_Factor = isstable(sys);  % check the system's stabiliy

                app.RiseTimeLabel.Text = 'RiseTime:' + string(round(S.RiseTime,4));
                app.SettlingTimeLabel_2.Text = 'SettlingTime:' + string(round(S.SettlingTime,4));
                app.OverShootLabel.Text = 'OverShoot:' + string(round(S.Overshoot,4));
                app.PeakLabel.Text = 'Peak:' + string(round(S.Peak,4));
                app.TimeConstantLabel.Text = 'Time Constant:' + string(round((S.SettlingTime/4),4));

                Poles = pole(sys);
                Zeros = zero(sys);
                app.PolesLabel.Text = 'Pole :' + string(round(Poles,3));
                app.ZerosLabel.Text = 'Zero :' + string(round(Zeros,3));
                
                legend(app.UIAxes_2,{'Servo Trejactory','Servo Refrence Angle','Risk threshold'},'Location','southeast')

                %%%%%% RGB Switch Colours%%%%%%
                value = app.ServoPowerSwitch.Value;
                if is_connected == 1 && value == "Off"
                    R = 2.33; G = 0; B = 0;           %% RGB (white)
                    RGB_Colour(app,R,G,B);
                end

            catch ME
                report = ME.message;
                msgbox(report,"Error","error");

                %%%%%% RGB Switch Colours%%%%%%
                value = app.ServoPowerSwitch.Value;
                if is_connected == 1 && value == "Off"
                    R = 0; G = 3.3; B = 3.3;            %% RGB (red)
                    RGB_Colour(app,R,G,B);
                end
            end
        end

        % Callback function
        function AutoTuningButtonPushed(app, event)
           
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
             app.delete
        end

        % Callback function
        function CompareLoopsPlotButtonPushed(app, event)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            global connected_device;
            global is_connected;
            value = app.Switch.Value;
            
            try

                if strcmp(value,'Run')
                    %% Stop Button 
                    
                    app.StatusLampLabel.Text="Loading...";
                    COM = 'COM11';%serialportlist("available");                 %% get COM value 
                    
                    %% connect matlab to arduino
                    connected_device = arduino(COM,'Nano33IoT','BaudRate',115200);   %921600 Connect Matlab to Arduino
                    is_connected =1;                                   %% to enable using the RGB
                    
                    %%%%%% RGB Switch Colours %%%%%%
                    R = 2.33; G = 0; B = 0; %% white
                    RGB_Colour(app,R,G,B);
                    
                    
                    app.StatusLamp.Color= 'g';
                    app.StatusLampLabel.Text="Running";
                    
                    
                    %%%%%% function to check the battery charge %%%%%%
                    CheckBattery(app);
                    
                    %&&&
                else 
                    %% Stop Button 
                    
                    app.StatusLamp.Color= 'r';
                    app.StatusLampLabel.Text="Disconnected";
                    
                    app.ExcuteButton.Enable = "off";
                    app.ZeroDegreeButton.Enable = "off";
                    app.ServoPowerSwitch.Enable = "off";
                    
                    %&&&
                    
                    clear all
                end

            catch ME
                report = ME.message; %getReport(ME);
                msgbox(report,"Error","error")
                app.StatusLamp.Color= 'r';
                app.StatusLampLabel.Text="Disconnected";
                app.Switch.Value = "Stop";
                
                app.ExcuteButton.Enable = "off";
                app.ZeroDegreeButton.Enable = "off";
                app.ServoPowerSwitch.Enable = "off";
                
                clear all
            end
        end

        % Button pushed function: ExcuteButton
        function ExcuteButtonPushed(app, event)
            global connected_device;
            global Angle_END;
            global Stable_Factor;
            global Danger;
            global charge_status;
            global angle_feedback ;
            try
                legend(app.UIAxes_2,'off');
                CheckBattery(app);
                if Stable_Factor == 1  && Danger == 0 && charge_status == 1
                    connected_servo = servo(connected_device,'D5', 'MaxPulseDuration', 0.00205, 'MinPulseDuration',0.00085); % Connect the servo values to Matlab through Arduino
                    writePosition(connected_servo,1);
                    pause(0.1);
                   
                    h = findobj(app.UIAxes_2, 'type', 'line');
                    Plot_Value = h(end).YData;
                    
                    Servo_Speed = app.ServoSpeedSlider.Value;
                    Servo_Speed= 4 - round(Servo_Speed,0);
                    if Servo_Speed>=1
                        Plot_Value = interp(Plot_Value,Servo_Speed);
                    else
                        Plot_Value = h(end).YData;
                    end
                    
                    Max_Degree= 130;
                    angle = Plot_Value/Max_Degree;

                    for i=1:length(angle)
                        writePosition(connected_servo, 1-angle(i));
                        
                        %% get the servo Analog degree feedback from the 4th wire in the servo
                        Analog_voltage = readVoltage(connected_device,'A3');
                        Analog_bit=(Analog_voltage*1023)/5;
                        
                        Analog_degree = (180/(224-800))*(Analog_bit-800);
                        servo_feedback(i) = 200-Analog_degree; % 200 is an offset
                        
                        angle_feedback =plot(app.UIAxes_2,servo_feedback,'c--','LineWidth',1);
                        hold(app.UIAxes_2, 'on')
                    end
                    legend(app.UIAxes_2,{'Servo Trejactory','Servo Refrence Angle','Risk threshold', 'Servo Feedback'},'Location','southeast')
                    
                    app.ZeroDegreeButton.Enable = "on";
                    app.ExcuteButton.Enable = "off";
                    Angle_END = angle(end);
                    clear connected_servo
                    charge_status=0;

                else
                    report = "The used system (TF) is unstable or may corss the Danger zone . Please Try another system or change the Ref. Degree";
                    msgbox(report,"Unstable System","Warn");
                    %%%%%% RGB Error Colours %%%%%%
                    R = 0; G = 3.3; B = 3.3; %% Red
                    RGB_Colour(app,R,G,B);
                    charge_status=0;
                end

            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
                app.ZeroDegreeButton.Enable = "off";
                %%%%%% RGB Error Colours %%%%%%
                R = 0; G = 3.3; B = 3.3; %% Red
                RGB_Colour(app,R,G,B);
                charge_status=0;
            end
        end

        % Callback function
        function SelectMarixSizeDropDownValueChanged(app, event)
            
        end

        % Callback function
        function PLOTButton_2Pushed(app, event)
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            clc
            cla(app.UIAxes_2 ,'reset') 
            cla(app.UIAxes ,'reset') 
            cla(app.UIAxes3 ,'reset')
            grid(app.UIAxes_2,'on')
            grid(app.UIAxes,'on')
            grid(app.UIAxes3,'on')
            
            app.numEditField_2.Value = "";
            app.denEditField.Value= "";
            
            app.zetaEditField.Value = 1;
            app.WcEditField.Value = 0;
            
            app.TauEditField.Value = 0;
            app.BetaEditField.Value = 1;
            app.KiEditField.Value = 0;
            
            app.RiseTimeLabel.Text = "Rise Time          :";
            app.TimeConstantLabel.Text = "Time Constant  :";
            app.SettlingTimeLabel_2.Text = "Settling Time    :";
            app.OverShootLabel.Text = "OverShoot        :";
            app.PeakLabel.Text = "Peak                  :";
            
            app.ZerosLabel.Text = "Zeros          :";
            app.PolesLabel.Text = "Poles          :";
            app.ServoRefDegreeEditField.Value = 90;
            app.ServoSpeedSlider.Value = 4;
        end

        % Button pushed function: ZeroDegreeButton
        function ZeroDegreeButtonPushed(app, event)
            global Angle_END;
            global connected_device;
            try
                connected_servo = servo(connected_device,'D5', 'MaxPulseDuration', 0.00205, 'MinPulseDuration',0.00085); % Connect the servo values to Matlab through Arduino
                for angle_down = 0:0.02:Angle_END
                    writePosition(connected_servo, (1-(Angle_END-angle_down)));
                end
                writePosition(connected_servo, 1);
                pause(0.1)
                
                delete(findobj(app.UIAxes_2, 'Color','c'));
                legend(app.UIAxes_2,{'Servo Trejactory','Servo Refrence Angle','Risk threshold'},'Location','southeast')
                
                app.ExcuteButton.Enable = "on";
                app.ZeroDegreeButton.Enable = "off";
                clear connected_servo;
            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
            end
        end

        % Value changed function: ServoPowerSwitch
        function ServoPowerSwitchValueChanged(app, event)
            global connected_device;
            global charge_status;
            value = app.ServoPowerSwitch.Value;
            CheckBattery(app);
            
            if value == "On" && charge_status == 1
                writeDigitalPin(connected_device,'D2',1);
                app.ExcuteButton.Enable = "on";
                
                %%%%%% RGB Switch Colours%%%%%%
                R = 3.3; G = 0; B = 3.3; %% RGB (green)
                RGB_Colour(app,R,G,B);
            else
                writeDigitalPin(connected_device,'D2',0);
                app.ExcuteButton.Enable = "off";
                app.ZeroDegreeButton.Enable = "off";
                app.ServoPowerSwitch.Value = "Off";
                %%%%%% RGB Switch Colours%%%%%%
                if charge_status == 0
                    R = 0; G = 3.3; B = 3.3; %% RGB (red)
                else
                    R = 2.33; G = 0; B = 0; %% RGB (white)
                end
                RGB_Colour(app,R,G,B);
            end
        end

        % Callback function
        function BlueSliderValueChanging(app, event)
    
        end

        % Callback function
        function GREENSliderValueChanging(app, event)
       
        end

        % Callback function
        function RedSliderValueChanging(app, event)
        
        end

        % Callback function
        function GeneralTabSizeChanged(app, event)
        
        end

        % Callback function
        function StateSpaceTabButtonDown(app, event)
            global is_connected;                %% is arduino connected?
            value = app.ServoPowerSwitch.Value;
            %%%%%% RGB Switch Colours%%%%%%
            if is_connected == 1 && value == "Off"
                R = 3.3; G = 0; B = 2.2; %% RGB =Teal
                RGB_Colour(app,R,G,B); 
            end
        end

        % Button down function: TransferFunctionTab
        function TransferFunctionTabButtonDown(app, event)
            global is_connected;                %% is arduino connected?
            value = app.ServoPowerSwitch.Value;
            %%%%%% RGB Switch Colours%%%%%%
            if is_connected == 1 && value == "Off"
                R = 2.33; G = 0; B = 0; %% RGB (white)
                RGB_Colour(app,R,G,B);
            end
        end

        % Close request function: JustExoPIDControllerUIFigure
        function JustExoPIDControllerUIFigureCloseRequest(app, event)
            delete(app)
            clear all
        end

        % Callback function
        function PasswordEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if changingValue == "1234"
                app.OverDampedButton.Enable = "on";
                app.UnderDampedButton.Enable = "on";
                app.Critically_DampedButton.Enable = "on";
                app.UnDampedButton.Enable = "on";
                app.AutoTuningButton.Enable = "on";
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            global is_connected;
            try
                if is_connected == 1
                    CheckBattery(app);
                end
            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
            end
        end

        % Callback function
        function OverDampedButtonPushed(app, event)
 
        end

        % Callback function
        function UnderDampedButtonPushed(app, event)
            
        end

        % Callback function
        function Critically_DampedButtonPushed(app, event)
            
        end

        % Callback function
        function UnDampedButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.JustExoPIDControllerUIFigure = uifigure('Visible', 'off');
            app.JustExoPIDControllerUIFigure.Name = 'JustExo PID Controller';
            app.JustExoPIDControllerUIFigure.Icon = 'chalmers-university-of-technology-.png';
            app.JustExoPIDControllerUIFigure.CloseRequestFcn = createCallbackFcn(app, @JustExoPIDControllerUIFigureCloseRequest, true);
            app.Panel = uipanel(app.JustExoPIDControllerUIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Step Plot: Input disturbance rejection')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Ampiltude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.Panel);
            title(app.UIAxes3, 'Step Plot: Reference Tracking ')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Amplitude')
            zlabel(app.UIAxes3, 'Z')
            app.Panel_2 = uipanel(app.JustExoPIDControllerUIFigure);
            app.TabGroup2 = uitabgroup(app.Panel_2);
            app.TransferFunctionTab = uitab(app.TabGroup2);
            app.TransferFunctionTab.Title = 'Transfer Function';
            app.TransferFunctionTab.ButtonDownFcn = createCallbackFcn(app, @TransferFunctionTabButtonDown, true);
            app.GsPlantPanel = uipanel(app.TransferFunctionTab);
            app.GsPlantPanel.Title = 'G(s) Plant ';
            app.numEditField_2Label = uilabel(app.GsPlantPanel);
            app.numEditField_2Label.Text = 'num';
            app.numEditField_2 = uieditfield(app.GsPlantPanel, 'text');
            app.numEditField_2.ValueChangingFcn = createCallbackFcn(app, @numEditField_2ValueChanging, true);
            app.denEditFieldLabel = uilabel(app.GsPlantPanel);
            app.denEditFieldLabel.Text = 'den';
            app.denEditField = uieditfield(app.GsPlantPanel, 'text');
            app.denEditField.ValueChangingFcn = createCallbackFcn(app, @denEditFieldValueChanging, true);
            app.TunningPIDPanel = uipanel(app.TransferFunctionTab);
            app.TunningPIDPanel.Title = 'Tunning PID';
            app.EditFieldLabel = uilabel(app.TunningPIDPanel);
            app.EditFieldLabel.Text = 'τ';
            app.TauEditField = uieditfield(app.TunningPIDPanel, 'numeric');
            app.KiEditFieldLabel = uilabel(app.TunningPIDPanel);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.TunningPIDPanel, 'numeric');
            app.EditField_2Label = uilabel(app.TunningPIDPanel);
            app.EditField_2Label.Text = 'β';
            app.BetaEditField = uieditfield(app.TunningPIDPanel, 'numeric');
            app.BetaEditField.Value = 1;
            app.Label_3 = uilabel(app.TunningPIDPanel);
            app.Label_3.Text = 'ζ';
            app.zetaEditField = uieditfield(app.TunningPIDPanel, 'numeric');
            app.zetaEditField.Value = 1;
            app.cLabel = uilabel(app.TunningPIDPanel);
            app.cLabel.Text = 'ωc';
            app.WcEditField = uieditfield(app.TunningPIDPanel, 'numeric');
            app.PLOTButton = uibutton(app.TransferFunctionTab, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.StepResponseInfoTab = uitab(app.TabGroup2);
            app.StepResponseInfoTab.Title = 'Step Response Info';
            app.RiseTimeLabel = uilabel(app.StepResponseInfoTab);
            app.RiseTimeLabel.Text = 'Rise Time          :';
            app.SettlingTimeLabel_2 = uilabel(app.StepResponseInfoTab);
            app.SettlingTimeLabel_2.Text = 'Settling Time    :';
            app.OverShootLabel = uilabel(app.StepResponseInfoTab);
            app.OverShootLabel.Text = 'OverShoot        :';
            app.PeakLabel = uilabel(app.StepResponseInfoTab);
            app.PeakLabel.Text = 'Peak                  :';
            app.TimeConstantLabel = uilabel(app.StepResponseInfoTab);
            app.TimeConstantLabel.Text = 'Time Constant  :';
            app.PolesLabel = uilabel(app.StepResponseInfoTab);
            app.PolesLabel.Text = 'Poles          :';
            app.ZerosLabel = uilabel(app.StepResponseInfoTab);
            app.ZerosLabel.Text = 'Zeros          :';
            app.ExoskeletonSettingPanel = uipanel(app.Panel_2);
            app.ExoskeletonSettingPanel.Title = 'Exoskeleton Setting Panel';
            app.UIAxes_2 = uiaxes(app.ExoskeletonSettingPanel);
            title(app.UIAxes_2, 'Servo''s Trajectory')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.ExoskeletonConnectionPanel = uipanel(app.ExoskeletonSettingPanel);
            app.ExoskeletonConnectionPanel.Title = 'Exoskeleton Connection';
            app.StatusLampLabel = uilabel(app.ExoskeletonConnectionPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ExoskeletonConnectionPanel);
            app.Switch = uiswitch(app.ExoskeletonConnectionPanel, 'rocker');
            app.Switch.Items = {'Stop', 'Run'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Stop';
            app.ArduinoConnectionLabel = uilabel(app.ExoskeletonConnectionPanel);
            app.ArduinoConnectionLabel.Text = 'Arduino Connection';
            app.Panel_4 = uipanel(app.ExoskeletonConnectionPanel);
            app.Lamp = uilamp(app.Panel_4);
            app.BatteryStatusLabel = uilabel(app.Panel_4);
            app.BatteryStatusLabel.Text = 'Battery Status:';
            app.RefreshButton = uibutton(app.Panel_4, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ExitButton = uibutton(app.ExoskeletonConnectionPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ResetButton = uibutton(app.ExoskeletonConnectionPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ServoControlPanel = uipanel(app.ExoskeletonSettingPanel);
            app.ServoControlPanel.Title = 'Servo Control Panel';
            app.ServoPowerSwitchLabel = uilabel(app.ServoControlPanel);
            app.ServoPowerSwitchLabel.Text = 'Servo Power';
            app.ServoPowerSwitch = uiswitch(app.ServoControlPanel, 'slider');
            app.ServoPowerSwitch.ValueChangedFcn = createCallbackFcn(app, @ServoPowerSwitchValueChanged, true);
            app.ServoPowerSwitch.Enable = 'off';
            app.ServoSpeedSliderLabel = uilabel(app.ServoControlPanel);
            app.ServoSpeedSliderLabel.Text = 'Servo Speed';
            app.ServoSpeedSlider = uislider(app.ServoControlPanel);
            app.ServoSpeedSlider.Limits = [0 4];
            app.ServoSpeedSlider.MajorTicks = [0 1 2 3 4];
            app.ServoSpeedSlider.MinorTicks = [0 1 2 3 4];
            app.ServoSpeedSlider.Value = 4;
            app.ServoRefDegreeLabel = uilabel(app.ServoControlPanel);
            app.ServoRefDegreeLabel.Text = 'Servo Ref.Degree';
            app.ServoRefDegreeEditField = uieditfield(app.ServoControlPanel, 'numeric');
            app.ServoRefDegreeEditField.Value = 90;
            app.ZeroDegreeButton = uibutton(app.ServoControlPanel, 'push');
            app.ZeroDegreeButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroDegreeButtonPushed, true);
            app.ZeroDegreeButton.Enable = 'off';
            app.ZeroDegreeButton.Text = 'Zero Degree';
            app.ExcuteButton = uibutton(app.ServoControlPanel, 'push');
            app.ExcuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExcuteButtonPushed, true);
            app.ExcuteButton.Enable = 'off';
            app.ExcuteButton.Text = 'Excute ';
            % Show the figure after all components are created
            app.JustExoPIDControllerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlSystem_GUI_V3_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.JustExoPIDControllerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.JustExoPIDControllerUIFigure)
        end
    end
end