classdef ExoEduPro_Demo_V2_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        JustExoPanelUIFigure         matlab.ui.Figure
        TabGroup2                    matlab.ui.container.TabGroup
        EMGTab_2                     matlab.ui.container.Tab
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        ForceSensorTab_2             matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        TabGroup                     matlab.ui.container.TabGroup
        EMGTab                       matlab.ui.container.Tab
        BatteryStatusPanel           matlab.ui.container.Panel
        Lamp                         matlab.ui.control.Lamp
        BatteryLabel                 matlab.ui.control.Label
        ResetValuesButton            matlab.ui.control.Button
        StatusLamp                   matlab.ui.control.Lamp
        StatusLampLabel              matlab.ui.control.Label
        Switch_2                     matlab.ui.control.RockerSwitch
        TricepDropDown               matlab.ui.control.DropDown
        TricepDropDownLabel          matlab.ui.control.Label
        BicepDropDown                matlab.ui.control.DropDown
        BicepLabel                   matlab.ui.control.Label
        EMGThresholdPanel            matlab.ui.container.Panel
        TricepThresholdSlider        matlab.ui.control.Slider
        TricepThresholdSliderLabel   matlab.ui.control.Label
        EMGThresholdEditField_2      matlab.ui.control.NumericEditField
        BicepThresholdSlider         matlab.ui.control.Slider
        BicepThresholdSliderLabel    matlab.ui.control.Label
        EMGThresholdEditField        matlab.ui.control.NumericEditField
        RealTimeInfoPanel            matlab.ui.container.Panel
        EMGTricepValueLabel          matlab.ui.control.Label
        EMGBicepValueLabel           matlab.ui.control.Label
        ForceSensorTab               matlab.ui.container.Tab
        BatteryStatusPanel_2         matlab.ui.container.Panel
        Lamp_2                       matlab.ui.control.Lamp
        BatteryLabel_2               matlab.ui.control.Label
        ResetValuesButton_2          matlab.ui.control.Button
        StatusLamp_2                 matlab.ui.control.Lamp
        StatusLamp_2Label            matlab.ui.control.Label
        Switch_3                     matlab.ui.control.RockerSwitch
        ForceAlgorithmSettingPanel   matlab.ui.container.Panel
        LowerThresholdSlider         matlab.ui.control.Slider
        LowerThresholdSliderLabel    matlab.ui.control.Label
        UpperThresholdSlider         matlab.ui.control.Slider
        UpperThresholdSlider_2Label  matlab.ui.control.Label
        InfoPanel_2                  matlab.ui.container.Panel
        ForceSensorOffsetEditField   matlab.ui.control.NumericEditField
        ForceSensorOffsetEditFieldLabel  matlab.ui.control.Label
        ForceSensorvalueLabel        matlab.ui.control.Label
        UpperThresholdLabel          matlab.ui.control.Label
        LowerThresholdLabel          matlab.ui.control.Label
    end

    
    methods (Access = private)
        function CheckBattery(app)
            global connected_device;
            global Battery_Voltage;
            global charge_status;
            %%%%%% get the Battery Voltage measurment %%%%%%
            configurePin(connected_device,'A6','AnalogInput');
            Battery_Voltage = readVoltage(connected_device,'A6');
            Battery_Voltage = round(Battery_Voltage,2)*3;

            if Battery_Voltage >=7.4
                app.Lamp.Color = "green";
                app.Lamp_2.Color = "green";
                charge_status=1;
            elseif Battery_Voltage>=6.4 && Battery_Voltage<7.4
                app.Lamp.Color = [0.92 0.52 0.03];
                app.Lamp_2.Color = [0.92 0.52 0.03];
                charge_status=1;
            else
                app.Lamp.Color = "red";
                app.Lamp_2.Color = "red";
                charge_status=0;
                Battery_Voltage = 6.4;
            end

            %Convert the volts to Percentage
            Percentagem_min=0;
            Percentagem_max=100;
            Battery_Percentage = (Battery_Voltage-6.4)*(Percentagem_max-Percentagem_min)/(8.4-6.4) + Percentagem_min;
            Battery_Percentage = round(Battery_Percentage,0);
            app.BatteryLabel.Text = "Battery: "+string(Battery_Percentage)+"%";
            app.BatteryLabel_2.Text = "Battery: "+string(Battery_Percentage)+"%";
        end
        
        function RGB_Colour(app,R,G,B)
             global connected_device;

             PIN_BLUE   = 'D3';
             PIN_GREEN = 'D6';
             PIN_RED  = 'D9';

             %%%%%% RGB Switch Colours%%%%%%
             writePWMVoltage(connected_device,PIN_RED,R);
             writePWMVoltage(connected_device,PIN_GREEN,G);
             writePWMVoltage(connected_device,PIN_BLUE,B);
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SwitchValueChanged(app, event)
            global connected_device;
            value = app.Switch.Value;
            try

                if strcmp(value,'Run')
                    app.StatusLampLabel.Text="Loading...";
                    COM = serialportlist("available");                 %% get COM value 
                    
                    %% connect matlab to arduino
                    connected_device = arduino(COM,'Nano33IoT','BaudRate',115200);   %921600 Connect Matlab to Arduino
                    app.StatusLampLabel.Text="Connected";
                    app.StatusLamp.Color= 'green';
                    set(app.StartButton,"Enable","on");
                    set(app.StopButton,"Enable","off");
                else 
                    %% Stop Button 
                    app.StatusLamp.Color= 'r';
                    app.StatusLampLabel.Text="Disconnected";
                    set(app.StartButton,"Enable","off");
                    set(app.StopButton,"Enable","off");
                    clear all
                end

            catch ME
                report = ME.message;
                msgbox(report,"Error","error")
                app.StatusLamp.Color= 'r';
                app.StatusLampLabel.Text="Disconnected";
                set(app.StartButton,"Enable","off");
                set(app.StopButton,"Enable","off");
                app.Switch.Value = "Stop";
                clear all
            end
        end

        % Callback function
        function CalibrateButtonPushed(app, event)
            global connected_device;
            global i;
            global new_emg_offset;

            i=1;                %% Start the X Axes from zero
            new_emg_offset=0;   %% as a first test we will start with zero offset.

            Port = app.PortSwitch.Value; %% A1 & A2
            cla(app.UIAxes);


            j=1;
            EMGIs_Old = 0;
            loop_repeats=200;   %% Calibration period (we will start with 200 rounds)
            loop_num=0;
            pause(0.5);

            try

                app.Image.ImageSource = "Flex_your_arm.png";

                while loop_num<loop_repeats
                    loop_num=loop_num+1;

                    EMGIs = readVoltage(connected_device,Port);     %%% get values from EMG Sensor
                    EMGIs=(EMGIs*1023)/5-new_emg_offset;            %% maps sensor readings between 0 and the operating voltage (5V or 3.3V) into integer values between 0 and 1023.
                    EMG_Values(i) = EMGIs;                          %% store EMG values in an array

                    if j==1
                        EMGIsFiltered_Old=EMGIs;
                        j=j+1;
                    end

                    %% low pass filter
                    EMGIsFiltered=0.8*EMGIsFiltered_Old + 0.1*EMGIs + 0.1*EMGIs_Old;
                    EMGIs_Old = EMGIs;
                    EMGIsFiltered_Old = EMGIsFiltered;

                    %% Ploting
                    normalvalue(i) = EMGIs;
                    filteredvalue(i) = EMGIsFiltered;

                    plot(app.UIAxes,normalvalue,'c');
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,filteredvalue,'red');
                    
                    i=i+1;
                    pause(0.005);
                end

                %%Calculating the Threshold
                MAX_Values=maxk(EMG_Values,20);
                EMG_Threshold=mean(MAX_Values);         %% the EMG threshold is the mean of the highest 5 values in the emg sensor, above this values the servo moves 90 degree
                EMG_Threshold = EMG_Threshold -100;
                app.EMGThresholdEditField.Value = EMG_Threshold;
                app.BicepThresholdLabel.Text = "EMG Threshold:"+string(EMG_Threshold);

                app.StatusLamp_2.Color = "g";
                app.Image.ImageSource = "Extend_your_arm.png";
            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
                app.StatusLamp_2.Color = "r";
            end
        end

        % Callback function
        function StopButtonPushed(app, event)
            global connected_servo;
            global Run_EMG_control;
            global connected_device;
            
            Run_EMG_control =0;
            
            
            connected_servo = "";
            clear connected_servo;
            writeDigitalPin(connected_device,'D2',0);
            set(app.StartButton,"Enable","on");
            set(app.StopButton,"Enable","off");
        end

        % Callback function
        function EMGThresholdKnobValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue,1);
            app.EMGThresholdEditField.Value = changingValue;
            app.BicepThresholdLabel.Text = "EMG Threshold:"+string(changingValue);
        end

        % Callback function
        function ExitButtonPushed(app, event)
            app.delete
        end

        % Callback function
        function CalibrateButton_2Pushed(app, event)
            global connected_device;
            global Run_forceSensor_control; % force_sensor_display ON/OFF variable
            global FS_Offset;
            cla(app.UIAxes);
            i=1; % reset the Axes array
            FS_Offset=300; % the defualt value of a force sensor offset
            Run_forceSensor_control = 0; % force_sensor_display is OFF;

            try
                connected_servo = servo(connected_device,'D5', 'MaxPulseDuration', 0.00205, 'MinPulseDuration',0.00085); % Connect the servo values to Matlab through Arduino
                writePosition(connected_servo,0);
                
                normalvalue = numel(1,100);
                filteredvalue = numel(1,100);
                FSVal = 0;
                
                %% store the first force Sensor Value
                forceIs = readVoltage(connected_device,'A0');
                forceIs=(forceIs*1023)/5-FS_Offset;
                forceIsFiltered_Old = forceIs;
                
                for loop_num = 1:50 %% 
                    forceIs = readVoltage(connected_device,'A0');
                    forceIs=(forceIs*1023)/5-FS_Offset;

                    forceIsFiltered = alpha_1*forceIsFiltered_Old + alpha*forceIs + alpha*forceIs_Old;
                    forceIs_Old = forceIs;
                    forceIsFiltered_Old = forceIsFiltered;
                    
                    app.ForceSensorvalueLabel.Text = num2str(forceIsFiltered);
                    FSVal = FSVal+forceIsFiltered;                  % to take the average of the force sensor values to find the Offset

                    normalvalue(i)=forceIs;
                    filteredvalue(i)=forceIsFiltered;
                    plot(app.UIAxes,normalvalue,'blue');
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,filteredvalue,'red');

                    i=i+1;
                    pause(0.001);
                end
                
                FS_Offset = FS_Offset + (FSVal /50);
                
                
            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
                clear connected_servo;
            end
        end

        % Value changing function: BicepThresholdSlider
        function BicepThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue);
            app.EMGThresholdEditField.Value = changingValue;
        end

        % Value changing function: TricepThresholdSlider
        function TricepThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue);
            app.EMGThresholdEditField_2.Value = changingValue;
        end

        % Value changed function: BicepDropDown
        function BicepDropDownValueChanged(app, event)
            value = app.BicepDropDown.Value;
            if value == "EMG0"
                app.TricepDropDown.Value = "EMG1";
            else
                app.TricepDropDown.Value = "EMG0";
            end
        end

        % Value changed function: TricepDropDown
        function TricepDropDownValueChanged(app, event)
            value = app.TricepDropDown.Value;
            if value == "EMG0"
                app.BicepDropDown.Value = "EMG1";
            else
                app.BicepDropDown.Value = "EMG0";
            end
        end

        % Button down function: EMGTab
        function EMGTabButtonDown(app, event)
            app.TabGroup2.SelectedTab = app.EMGTab_2; %% open EMG graph Tab
        end

        % Button down function: ForceSensorTab
        function ForceSensorTabButtonDown(app, event)
            app.TabGroup2.SelectedTab = app.ForceSensorTab_2; %% open EMG graph Tab
        end

        % Close request function: JustExoPanelUIFigure
        function JustExoPanelUIFigureCloseRequest(app, event)
            delete(app)
            clear all
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            global i;
            global connected_device;
            global charge_status;

            try
                %% Start the Serial communication with Arduino
                if strcmp(value,'Start')
                    app.StatusLampLabel.Text="Loading...";
                    COM = serialportlist("available");                 %% get COM value
                    
                    app.ResetValuesButton.Enable = "off";

                    %% connect matlab to arduino
                    connected_device = arduino(COM,'Nano33IoT','BaudRate',115200);   %921600 Connect Matlab to Arduino
                    app.StatusLampLabel.Text="Connected";
                    app.StatusLamp.Color= 'green';

                    %% Turn on Servo
                    writeDigitalPin(connected_device,'D2',1);
                    connected_servo = servo(connected_device,'D5', 'MaxPulseDuration', 0.00205, 'MinPulseDuration',0.00085); % Connect the servo values to Matlab through Arduino
                    writePosition(connected_servo,1);
                    Angle =0;
                    i=1;
                    new_emg_offset=0;       %% as a first test we will start with zero offset.
                    
                    %%Clean the Axes and start over again
                    cla(app.UIAxes2);
                    cla(app.UIAxes3);
                    app.UIAxes2.XLim = [0 100];
                    app.UIAxes3.XLim = [0 100];

                    BicepPort = app.BicepDropDown.Value;
                    %TricepPort = app.TricepDropDown.Value;
                    if BicepPort == "EMG0"
                        BicepPort = "A1";
                        TricepPort = "A2";
                    else
                        BicepPort = "A2";
                        TricepPort = "A1";
                    end

                    %%%Bicep
                    EMGB_Old = 0;
                    EMGB = readVoltage(connected_device,BicepPort);
                    EMGB = (EMGB*1023)/5-new_emg_offset;
                    EMGBFiltered_Old = EMGB;
                    %%%Tricep
                    EMGT_Old = 0;
                    EMGT = readVoltage(connected_device,TricepPort);
                    EMGT=(EMGT*1023)/5-new_emg_offset;
                    EMGTFiltered_Old=EMGT;
                    
                    %%%%%% RGB Switch Colours%%%%%%
                    R = 3.3; G = 0; B = 0.3; %% RGB (Yellow)
                    RGB_Colour(app,R,G,B);
                    CheckBattery(app);
                end

                %%%%%%%% Check The battery Percentage
                if charge_status == 1
                    
                    %%%%%%%% Start the Emg Algorithm
                    while strcmp(value,'Start')
                        value = app.Switch_2.Value;
                        if strcmp(value,'Stop')
                            writeDigitalPin(connected_device,'D2',0);
                            app.StatusLamp.Color= 'r';
                            app.StatusLampLabel.Text="Disconnected";
                            app.Lamp.Color ="Black";
                            app.BatteryLabel.Text = "Battery: ";
                            app.ResetValuesButton.Enable = "on";
                            app.Lamp_2.Color ="Black";
                            app.BatteryLabel_2.Text = "Battery: ";
                            clear all;
                            break
                        end
                        Bicep_Threshold = app.BicepThresholdSlider.Value;   %% get the Bicep EMG Threshold
                        Tricep_Threshold = app.TricepThresholdSlider.Value; %% get the Tricep EMG Threshold
                        %%%%%%%%%% Bicep EMG Data
                        EMGB = readVoltage(connected_device,BicepPort);     %%% get values from EMG Sensor
                        EMGB=(EMGB*1023)/5-new_emg_offset;                  %% maps sensor readings between 0 and the operating voltage (5V or 3.3V) into integer values between 0 and 1023.

                        EMGBFiltered=0.8*EMGBFiltered_Old + 0.1*EMGB + 0.1*EMGB_Old;
                        EMGB_Old = EMGB;
                        EMGBFiltered_Old = EMGBFiltered;
                        app.EMGBicepValueLabel.Text = "EMG Bicep Value:"+string(EMGBFiltered);

                        %%%%%%%%%% Tricep EMG Data
                        EMGT = readVoltage(connected_device,TricepPort);    %%% get values from tricep EMG Sensor
                        EMGT=(EMGT*1023)/5-new_emg_offset;                  %% maps sensor readings between 0 and the operating voltage (5V or 3.3V) into integer values between 0 and 1023.

                        EMGTFiltered=0.8*EMGTFiltered_Old + 0.1*EMGT + 0.1*EMGT_Old;
                        EMGT_Old = EMGT;
                        EMGTFiltered_Old = EMGTFiltered;
                        app.EMGTricepValueLabel.Text = "EMG Tricep Value:"+string(EMGTFiltered);

                        %%%%%%%%%%%%%%%%%%%%%%% Servo Movement %%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        if EMGBFiltered >=Bicep_Threshold
                            if Angle < 0.9
                                writePosition(connected_servo,1-Angle);
                                Angle = Angle + 0.1;
                                B = B+0.3;
                                RGB_Colour(app,R,G,B);
                            end
                        end
                        if EMGTFiltered >=Tricep_Threshold
                            if Angle >= 0.1
                                Angle = Angle - 0.1;
                                writePosition(connected_servo,1-Angle);
                                B = B-0.2;
                                RGB_Colour(app,R,G,B);
                            end
                        end
                        normalvalueB(i) = EMGB;
                        filteredvalueB(i) = EMGBFiltered;
                        EMGB_Threshold_graph(i) = Bicep_Threshold;

                        normalvalueT(i) = EMGT;
                        filteredvalueT(i) = EMGTFiltered;
                        EMGT_Threshold_graph(i) = Tricep_Threshold;

                        if i>=100
                            app.UIAxes2.XLim = [i-80 i+20];
                            app.UIAxes3.XLim = [i-80 i+20];
                        end

                        plot(app.UIAxes2,normalvalueB,'c');
                        hold(app.UIAxes2, 'on')
                        plot(app.UIAxes2,filteredvalueB,'red');
                        hold(app.UIAxes2, 'on')
                        plot(app.UIAxes2,EMGB_Threshold_graph,'black','LineWidth',1);
                        hold(app.UIAxes2, 'on')

                        plot(app.UIAxes3,normalvalueT,'c');
                        hold(app.UIAxes3, 'on')
                        plot(app.UIAxes3,filteredvalueT,'red');
                        hold(app.UIAxes3, 'on')
                        plot(app.UIAxes3,EMGT_Threshold_graph,'black','LineWidth',1);
                        hold(app.UIAxes3, 'on')
                        i=i+1;

                    end
                else
                    report = [ "The Battery is TOO LOW, unfortunately the servo can't be used.";" ";"Sugesstions:";"Please make sure the Exoskeleton's push button Pressed down";"Or Please Charge the Battery"];
                    msgbox(report,"Error","error")
                    app.Switch_2.Value = "Stop";
                    writeDigitalPin(connected_device,'D2',0);
                    app.StatusLamp.Color= 'r';
                    app.StatusLampLabel.Text="Disconnected";
                    app.Lamp.Color ="Black";
                    app.BatteryLabel.Text = "Battery: ";
                    app.ResetValuesButton.Enable = "on";
                    clear all
                end

            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
                writeDigitalPin(connected_device,'D2',0);
                app.Switch_2.Value = "Stop";
                app.StatusLamp.Color= 'r';
                app.StatusLampLabel.Text="Disconnected";
                app.Lamp.Color ="Black";
                app.BatteryLabel.Text = "Battery: ";
                app.Lamp_2.Color ="Black";
                app.BatteryLabel_2.Text = "Battery: ";
                app.ResetValuesButton.Enable = "on";
                clear all;
            end
        end

        % Button pushed function: ResetValuesButton
        function ResetValuesButtonPushed(app, event)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            
            app.EMGBicepValueLabel.Text = "EMG Bicep Value:";
            app.EMGTricepValueLabel.Text = "EMG Tricep Value:";
            
            app.BicepThresholdSlider.Value = 400;
            app.TricepThresholdSlider.Value = 400;
            
            app.EMGThresholdEditField.Value = 400;
            app.EMGThresholdEditField_2.Value = 400;
            
            app.Lamp.Color = "Black";
            app.BatteryLabel.Text = "Battery:";
            
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            global connected_device;
            global charge_status;

            value = app.Switch_3.Value;
            forceOffset = app.ForceSensorOffsetEditField.Value;
            i=1;
            Angle =0;

            try
                %% Start the Serial communication to Arduino
                if strcmp(value,'Start')
                    app.StatusLamp_2Label.Text="Loading...";
                    COM = serialportlist("available");                 %% get COM value

                    %% connect matlab to arduino
                    connected_device = arduino(COM,'Nano33IoT','BaudRate',115200);   %921600 Connect Matlab to Arduino
                    app.StatusLamp_2Label.Text="Connected";
                    app.StatusLamp_2.Color= 'green';

                    writeDigitalPin(connected_device,'D2',1);
                    connected_servo = servo(connected_device,'D5', 'MaxPulseDuration', 0.00205, 'MinPulseDuration',0.00085); % Connect the servo values to Matlab through Arduino
                    writePosition(connected_servo,1);
                    cla(app.UIAxes);
                    app.UIAxes.XLim = [0 100];

                    forceIs_Old = 0;
                    forceIs = readVoltage(connected_device,'A0');
                    forceIs = (forceIs*1023)/5-forceOffset;
                    forceIsFiltered_Old = forceIs;

                    %%%%%% RGB Switch Colours%%%%%%
                    R = 0; G = 0; B = 2.2; %% RGB (Yellow)
                    RGB_Colour(app,R,G,B);
                    CheckBattery(app);
                end

                if charge_status == 1

                    %% Start the Force control Algorithem
                    while strcmp(value,'Start')
                        value = app.Switch_3.Value;

                        %% stop the Force control Algorithem and cut the serial communication if the switch is off
                        if strcmp(value,'Stop')
                            app.StatusLamp_2Label.Text="Disconnected";
                            app.StatusLamp_2.Color= 'red';
                            app.Lamp_2.Color ="Black";
                            app.BatteryLabel_2.Text = "Battery: ";
                            app.Lamp.Color ="Black";
                            app.BatteryLabel.Text = "Battery: ";
                            clear all;
                            break
                        end

                        %% get the Thresholds Value
                        Lower_Threshold = app.UpperThresholdSlider.Value;
                        Upper_Threshold = app.LowerThresholdSlider.Value;
                        app.LowerThresholdLabel.Text = "Lower Threshold:"+string(Lower_Threshold);
                        app.UpperThresholdLabel.Text = "Upper Threshold:"+string(Upper_Threshold);

                        %% get the Force sensor Value and low pass filter it
                        forceIs = readVoltage(connected_device,'A0');
                        forceIs=(forceIs*1023)/5-forceOffset;
                        forceIsFiltered=0.8*forceIsFiltered_Old + 0.1*forceIs + 0.1*forceIs_Old;
                        forceIs_Old = forceIs;
                        forceIsFiltered_Old = forceIsFiltered;
                        app.ForceSensorvalueLabel.Text = "Force Sensor value:"+string(forceIsFiltered);

                        %% Move the servo if force sensor value surpass the thresholds
                        if forceIsFiltered >=Upper_Threshold
                            if Angle < 0.9
                                writePosition(connected_servo,1-Angle);
                                Angle = Angle + 0.1;
                                B = B+0.1;
                                RGB_Colour(app,R,G,B);
                            end
                        end
                        if forceIsFiltered <=Lower_Threshold
                            if Angle > 0.1
                                Angle = Angle - 0.1;
                                writePosition(connected_servo,1-Angle);
                                B = B-0.1;
                                RGB_Colour(app,R,G,B);
                            end
                        end

                        %% Plot the values
                        normalvalue(i) = forceIs;
                        filteredvalue(i) = forceIsFiltered;
                        Upper_Threshold_graph(i) = Upper_Threshold;
                        Lower_Threshold_graph(i) = Lower_Threshold;

                        if i>=100
                            app.UIAxes.XLim = [i-80 i+20];
                        end

                        plot(app.UIAxes,normalvalue,'c');
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,filteredvalue,'red');
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,Upper_Threshold_graph,'black','LineWidth',1);
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,Lower_Threshold_graph,'black','LineWidth',1);
                        i=i+1;
                    end
                else
                    report = [ "The Battery is TOO LOW, unfortunately the servo can't be used.";" ";"Sugesstions:";"Please make sure the Exoskeleton's push button Pressed down";"Or Please Charge the Battery"];
                    msgbox(report,"Error","error")
                    app.Switch_3.Value = "Stop";
                    app.StatusLamp_2Label.Text="Disconnected";
                    app.StatusLamp_2.Color= 'red';
                    app.Lamp_2.Color ="Black";
                    app.BatteryLabel_2.Text = "Battery: ";
                    app.Lamp.Color ="Black";
                    app.BatteryLabel.Text = "Battery: ";
                    clear all
                end
            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
                writeDigitalPin(connected_device,'D2',0);
                app.Switch_3.Value = "Stop";
                app.StatusLamp_2Label.Text="Disconnected";
                app.StatusLamp_2.Color= 'red';
                app.Lamp.Color ="Black";
                app.BatteryLabel.Text = "Battery: ";
                app.Lamp_2.Color ="Black";
                app.BatteryLabel_2.Text = "Battery: ";
                clear all;
            end
        end

        % Button pushed function: ResetValuesButton_2
        function ResetValuesButton_2Pushed(app, event)
            cla(app.UIAxes)
            
            app.ForceSensorvalueLabel.Text = "Force Sensor value:";
            app.LowerThresholdLabel.Text = "Lower Threshold:";
            app.UpperThresholdLabel.Text = "Upper Threshold:";
            
            app.ForceSensorOffsetEditField.Value = 260;
            app.UpperThresholdSlider.Value = 20;
            app.LowerThresholdSlider.Value = -20;

            app.Lamp_2.Color = "Black";
            app.BatteryLabel_2.Text = "Battery:";
        end

        % Value changed function: UpperThresholdSlider
        function UpperThresholdSliderValueChanged(app, event)
           
        end

        % Callback function
        function RefreshBatteryButton_2Pushed(app, event)
            try
                CheckBattery(app);
            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
            end
        end

        % Callback function
        function RefreshBatteryButtonPushed(app, event)
            try
                CheckBattery(app);
            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.JustExoPanelUIFigure = uifigure('Visible', 'off');
            app.JustExoPanelUIFigure.Name = 'JustExoPanel';
            app.JustExoPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @JustExoPanelUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.JustExoPanelUIFigure);
            app.EMGTab = uitab(app.TabGroup);
            app.EMGTab.Title = 'EMG';
            app.EMGTab.ButtonDownFcn = createCallbackFcn(app, @EMGTabButtonDown, true);
            app.RealTimeInfoPanel = uipanel(app.EMGTab);
            app.RealTimeInfoPanel.Title = 'Real Time Info:';
            app.EMGBicepValueLabel = uilabel(app.RealTimeInfoPanel);
            app.EMGBicepValueLabel.Text = 'EMG Bicep Value:';
            app.EMGTricepValueLabel = uilabel(app.RealTimeInfoPanel);
            app.EMGTricepValueLabel.Text = 'EMG Tricep Value:';
            app.EMGThresholdPanel = uipanel(app.EMGTab);
            app.EMGThresholdPanel.Title = 'EMG Threshold ';
            app.EMGThresholdEditField = uieditfield(app.EMGThresholdPanel, 'numeric');
            app.EMGThresholdEditField.Value = 400;
            app.BicepThresholdSliderLabel = uilabel(app.EMGThresholdPanel);
            app.BicepThresholdSliderLabel.Text = 'Bicep Threshold';
            app.BicepThresholdSlider = uislider(app.EMGThresholdPanel);
            app.BicepThresholdSlider.Limits = [0 1000];
            app.BicepThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @BicepThresholdSliderValueChanging, true);
            app.BicepThresholdSlider.Value = 400;
            app.EMGThresholdEditField_2 = uieditfield(app.EMGThresholdPanel, 'numeric');
            app.EMGThresholdEditField_2.Value = 400;
            app.TricepThresholdSliderLabel = uilabel(app.EMGThresholdPanel);
            app.TricepThresholdSliderLabel.Text = 'Tricep Threshold';
            app.TricepThresholdSlider = uislider(app.EMGThresholdPanel);
            app.TricepThresholdSlider.Limits = [0 1000];
            app.TricepThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @TricepThresholdSliderValueChanging, true);
            app.TricepThresholdSlider.Value = 400;
            app.BicepLabel = uilabel(app.EMGTab);
            app.BicepLabel.Text = 'Bicep ';
            app.BicepDropDown = uidropdown(app.EMGTab);
            app.BicepDropDown.Items = {'EMG0', 'EMG1'};
            app.BicepDropDown.ValueChangedFcn = createCallbackFcn(app, @BicepDropDownValueChanged, true);
            app.BicepDropDown.Value = 'EMG0';
            app.TricepDropDownLabel = uilabel(app.EMGTab);
            app.TricepDropDownLabel.Text = 'Tricep';
            app.TricepDropDown = uidropdown(app.EMGTab);
            app.TricepDropDown.Items = {'EMG0', 'EMG1'};
            app.TricepDropDown.ValueChangedFcn = createCallbackFcn(app, @TricepDropDownValueChanged, true);
            app.TricepDropDown.Value = 'EMG1';
            app.Switch_2 = uiswitch(app.EMGTab, 'rocker');
            app.Switch_2.Items = {'Stop', 'Start'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Stop';
            app.StatusLampLabel = uilabel(app.EMGTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.EMGTab);
            app.ResetValuesButton = uibutton(app.EMGTab, 'push');
            app.ResetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetValuesButtonPushed, true);
            app.ResetValuesButton.Text = 'Reset Values';
            app.BatteryStatusPanel = uipanel(app.EMGTab);
            app.BatteryStatusPanel.Title = 'Battery Status';
            app.BatteryLabel = uilabel(app.BatteryStatusPanel);
            app.BatteryLabel.Text = 'Battery:';
            app.Lamp = uilamp(app.BatteryStatusPanel);
            app.ForceSensorTab = uitab(app.TabGroup);
            app.ForceSensorTab.Title = 'Force Sensor';
            app.ForceSensorTab.ButtonDownFcn = createCallbackFcn(app, @ForceSensorTabButtonDown, true);
            app.InfoPanel_2 = uipanel(app.ForceSensorTab);
            app.InfoPanel_2.Title = 'Info:';
            app.LowerThresholdLabel = uilabel(app.InfoPanel_2);
            app.LowerThresholdLabel.Text = 'Lower Threshold:';
            app.UpperThresholdLabel = uilabel(app.InfoPanel_2);
            app.UpperThresholdLabel.Text = 'Upper Threshold:';
            app.ForceSensorvalueLabel = uilabel(app.InfoPanel_2);
            app.ForceSensorvalueLabel.Text = 'Force Sensor value:';
            app.ForceSensorOffsetEditFieldLabel = uilabel(app.InfoPanel_2);
            app.ForceSensorOffsetEditFieldLabel.Text = 'Force Sensor Offset';
            app.ForceSensorOffsetEditField = uieditfield(app.InfoPanel_2, 'numeric');
            app.ForceSensorOffsetEditField.Value = 260;
            app.ForceAlgorithmSettingPanel = uipanel(app.ForceSensorTab);
            app.ForceAlgorithmSettingPanel.Title = 'Force Algorithm Setting';
            app.UpperThresholdSlider_2Label = uilabel(app.ForceAlgorithmSettingPanel);
            app.UpperThresholdSlider_2Label.Text = 'Upper Threshold';
            app.UpperThresholdSlider = uislider(app.ForceAlgorithmSettingPanel);
            app.UpperThresholdSlider.Limits = [0 200];
            app.UpperThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @UpperThresholdSliderValueChanged, true);
            app.UpperThresholdSlider.Value = 20;
            app.LowerThresholdSliderLabel = uilabel(app.ForceAlgorithmSettingPanel);
            app.LowerThresholdSliderLabel.Text = 'Lower Threshold';
            app.LowerThresholdSlider = uislider(app.ForceAlgorithmSettingPanel);
            app.LowerThresholdSlider.Limits = [-200 0];
            app.LowerThresholdSlider.Value = -20;
            app.Switch_3 = uiswitch(app.ForceSensorTab, 'rocker');
            app.Switch_3.Items = {'Stop', 'Start'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'Stop';
            app.StatusLamp_2Label = uilabel(app.ForceSensorTab);
            app.StatusLamp_2Label.Text = 'Status';
            app.StatusLamp_2 = uilamp(app.ForceSensorTab);
            app.ResetValuesButton_2 = uibutton(app.ForceSensorTab, 'push');
            app.ResetValuesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetValuesButton_2Pushed, true);
            app.ResetValuesButton_2.Text = 'Reset Values';
            app.BatteryStatusPanel_2 = uipanel(app.ForceSensorTab);
            app.BatteryStatusPanel_2.Title = 'Battery Status';
            app.BatteryLabel_2 = uilabel(app.BatteryStatusPanel_2);
            app.BatteryLabel_2.Text = 'Battery:';
            app.Lamp_2 = uilamp(app.BatteryStatusPanel_2);
            app.TabGroup2 = uitabgroup(app.JustExoPanelUIFigure);
            app.EMGTab_2 = uitab(app.TabGroup2);
            app.EMGTab_2.Title = 'EMG';
            app.UIAxes2 = uiaxes(app.EMGTab_2);
            title(app.UIAxes2, 'EMG Bicep ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.EMGTab_2);
            title(app.UIAxes3, 'EMG Tricep')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.ForceSensorTab_2 = uitab(app.TabGroup2);
            app.ForceSensorTab_2.Title = 'Force Sensor';
            app.UIAxes = uiaxes(app.ForceSensorTab_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.JustExoPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExoEduPro_Demo_V2_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.JustExoPanelUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.JustExoPanelUIFigure)
        end
    end
end