classdef ExoEduPro_Demo_V2_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        JustExoPanelUIFigure          matlab.ui.Figure
        TabGroup2                     matlab.ui.container.TabGroup
        EMGTab_2                      matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        ForceSensorTab_2              matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        EMGControlTab                 matlab.ui.container.Tab
        ExoskeletonConnectionPanel_2  matlab.ui.container.Panel
        SerialPortCOMDropDown_2       matlab.ui.control.DropDown
        SerialPortCOMDropDown_2Label  matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        StatusLampLabel               matlab.ui.control.Label
        ResetValuesButton             matlab.ui.control.Button
        Switch_2                      matlab.ui.control.RockerSwitch
        BatteryStatusPanel            matlab.ui.container.Panel
        Lamp                          matlab.ui.control.Lamp
        BatteryLabel                  matlab.ui.control.Label
        TricepDropDown                matlab.ui.control.DropDown
        TricepDropDownLabel           matlab.ui.control.Label
        BicepDropDown                 matlab.ui.control.DropDown
        BicepLabel                    matlab.ui.control.Label
        EMGSensorThresholdsPanel      matlab.ui.container.Panel
        TricepThresholdSlider         matlab.ui.control.Slider
        TricepThresholdSliderLabel    matlab.ui.control.Label
        EMGThresholdEditField_2       matlab.ui.control.NumericEditField
        BicepThresholdSlider          matlab.ui.control.Slider
        BicepThresholdSliderLabel     matlab.ui.control.Label
        EMGThresholdEditField         matlab.ui.control.NumericEditField
        InfoPanel_2                   matlab.ui.container.Panel
        EMGTricepValueLabel           matlab.ui.control.Label
        EMGBicepValueLabel            matlab.ui.control.Label
        ForceControlTab               matlab.ui.container.Tab
        ExoskeletonConnectionPanel    matlab.ui.container.Panel
        SerialPortCOMDropDown         matlab.ui.control.DropDown
        SerialPortCOMDropDownLabel    matlab.ui.control.Label
        StatusLamp_2                  matlab.ui.control.Lamp
        StatusLamp_2Label             matlab.ui.control.Label
        ResetValuesButton_2           matlab.ui.control.Button
        Switch_3                      matlab.ui.control.RockerSwitch
        ForceSensorThresholdsPanel    matlab.ui.container.Panel
        LowerThresholdSlider          matlab.ui.control.Slider
        LowerThresholdSliderLabel     matlab.ui.control.Label
        UpperThresholdSlider          matlab.ui.control.Slider
        UpperThresholdSlider_2Label   matlab.ui.control.Label
        InfoPanel                     matlab.ui.container.Panel
        BatteryStatusPanel_2          matlab.ui.container.Panel
        Lamp_2                        matlab.ui.control.Lamp
        BatteryLabel_2                matlab.ui.control.Label
        ForceSensorOffsetEditField    matlab.ui.control.NumericEditField
        ForceSensorOffsetEditFieldLabel  matlab.ui.control.Label
        ForceSensorvalueLabel         matlab.ui.control.Label
        UpperThresholdLabel           matlab.ui.control.Label
        LowerThresholdLabel           matlab.ui.control.Label
    end

    
    methods (Access = private)
        function CheckBattery(app)
            global connected_device;
            global Battery_Voltage;
            global charge_status;
            %%%%%% get the Battery Voltage measurment %%%%%%
            configurePin(connected_device,'A6','AnalogInput');
            Battery_Voltage = readVoltage(connected_device,'A6');
            Battery_Voltage = round(Battery_Voltage,2)*3;

            if Battery_Voltage >=7.4
                app.Lamp.Color = "green";
                app.Lamp_2.Color = "green";
                charge_status=1;
            elseif Battery_Voltage>=6.4 && Battery_Voltage<7.4
                app.Lamp.Color = [0.92 0.52 0.03];
                app.Lamp_2.Color = [0.92 0.52 0.03];
                charge_status=1;
            else
                app.Lamp.Color = "red";
                app.Lamp_2.Color = "red";
                charge_status=0;
                Battery_Voltage = 6.4;
            end

            %Convert the volts to Percentage
            Percentagem_min=0;
            Percentagem_max=100;
            Battery_Percentage = (Battery_Voltage-6.4)*(Percentagem_max-Percentagem_min)/(8.4-6.4) + Percentagem_min;
            Battery_Percentage = round(Battery_Percentage,0);
            app.BatteryLabel.Text = "Battery: "+string(Battery_Percentage)+"%";
            app.BatteryLabel_2.Text = "Battery: "+string(Battery_Percentage)+"%";
        end
        
        function RGB_Colour(app,R,G,B)
             global connected_device;

             PIN_BLUE   = 'D3';
             PIN_GREEN = 'D6';
             PIN_RED  = 'D9';

             %%%%%% RGB Switch Colours%%%%%%
             writePWMVoltage(connected_device,PIN_RED,R);
             writePWMVoltage(connected_device,PIN_GREEN,G);
             writePWMVoltage(connected_device,PIN_BLUE,B);
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SwitchValueChanged(app, event)
            global connected_device;
            value = app.Switch.Value;
            try

                if strcmp(value,'Run')
                    app.StatusLampLabel.Text="Loading...";
                    COM = serialportlist("available");                 %% get COM value 
                    
                    %% connect matlab to arduino
                    connected_device = arduino(COM,'Nano33IoT','BaudRate',115200);   %921600 Connect Matlab to Arduino
                    app.StatusLampLabel.Text="Connected";
                    app.StatusLamp.Color= 'green';
                    set(app.StartButton,"Enable","on");
                    set(app.StopButton,"Enable","off");
                else 
                    %% Stop Button 
                    app.StatusLamp.Color= 'r';
                    app.StatusLampLabel.Text="Disconnected";
                    set(app.StartButton,"Enable","off");
                    set(app.StopButton,"Enable","off");
                    clear all
                end

            catch ME
                report = ME.message;
                msgbox(report,"Error","error")
                app.StatusLamp.Color= 'r';
                app.StatusLampLabel.Text="Disconnected";
                set(app.StartButton,"Enable","off");
                set(app.StopButton,"Enable","off");
                app.Switch.Value = "Stop";
                clear all
            end
        end

        % Callback function
        function CalibrateButtonPushed(app, event)
            global connected_device;
            global i;
            global new_emg_offset;

            i=1;                %% Start the X Axes from zero
            new_emg_offset=0;   %% as a first test we will start with zero offset.

            Port = app.PortSwitch.Value; %% A1 & A2
            cla(app.UIAxes);


            j=1;
            EMGIs_Old = 0;
            loop_repeats=200;   %% Calibration period (we will start with 200 rounds)
            loop_num=0;
            pause(0.5);

            try

                app.Image.ImageSource = "Flex_your_arm.png";

                while loop_num<loop_repeats
                    loop_num=loop_num+1;

                    EMGIs = readVoltage(connected_device,Port);     %%% get values from EMG Sensor
                    EMGIs=(EMGIs*1023)/5-new_emg_offset;            %% maps sensor readings between 0 and the operating voltage (5V or 3.3V) into integer values between 0 and 1023.
                    EMG_Values(i) = EMGIs;                          %% store EMG values in an array

                    if j==1
                        EMGIsFiltered_Old=EMGIs;
                        j=j+1;
                    end

                    %% low pass filter
                    EMGIsFiltered=0.8*EMGIsFiltered_Old + 0.1*EMGIs + 0.1*EMGIs_Old;
                    EMGIs_Old = EMGIs;
                    EMGIsFiltered_Old = EMGIsFiltered;

                    %% Ploting
                    normalvalue(i) = EMGIs;
                    filteredvalue(i) = EMGIsFiltered;

                    plot(app.UIAxes,normalvalue,'c');
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,filteredvalue,'red');
                    
                    i=i+1;
                    pause(0.005);
                end

                %%Calculating the Threshold
                MAX_Values=maxk(EMG_Values,20);
                EMG_Threshold=mean(MAX_Values);         %% the EMG threshold is the mean of the highest 5 values in the emg sensor, above this values the servo moves 90 degree
                EMG_Threshold = EMG_Threshold -100;
                app.EMGThresholdEditField.Value = EMG_Threshold;
                app.BicepThresholdLabel.Text = "EMG Threshold:"+string(EMG_Threshold);

                app.StatusLamp_2.Color = "g";
                app.Image.ImageSource = "Extend_your_arm.png";
            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
                app.StatusLamp_2.Color = "r";
            end
        end

        % Callback function
        function StopButtonPushed(app, event)
            global connected_servo;
            global Run_EMG_control;
            global connected_device;
            
            Run_EMG_control =0;
            
            
            connected_servo = "";
            clear connected_servo;
            writeDigitalPin(connected_device,'D2',0);
            set(app.StartButton,"Enable","on");
            set(app.StopButton,"Enable","off");
        end

        % Callback function
        function EMGThresholdKnobValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue,1);
            app.EMGThresholdEditField.Value = changingValue;
            app.BicepThresholdLabel.Text = "EMG Threshold:"+string(changingValue);
        end

        % Callback function
        function ExitButtonPushed(app, event)
            app.delete
        end

        % Callback function
        function CalibrateButton_2Pushed(app, event)
            global connected_device;
            global Run_forceSensor_control; % force_sensor_display ON/OFF variable
            global FS_Offset;
            cla(app.UIAxes);
            i=1; % reset the Axes array
            FS_Offset=300; % the defualt value of a force sensor offset
            Run_forceSensor_control = 0; % force_sensor_display is OFF;

            try
                connected_servo = servo(connected_device,'D5', 'MaxPulseDuration', 0.00205, 'MinPulseDuration',0.00085); % Connect the servo values to Matlab through Arduino
                writePosition(connected_servo,0);
                
                normalvalue = numel(1,100);
                filteredvalue = numel(1,100);
                FSVal = 0;
                
                %% store the first force Sensor Value
                forceIs = readVoltage(connected_device,'A0');
                forceIs=(forceIs*1023)/5-FS_Offset;
                forceIsFiltered_Old = forceIs;
                
                for loop_num = 1:50 %% 
                    forceIs = readVoltage(connected_device,'A0');
                    forceIs=(forceIs*1023)/5-FS_Offset;

                    forceIsFiltered = alpha_1*forceIsFiltered_Old + alpha*forceIs + alpha*forceIs_Old;
                    forceIs_Old = forceIs;
                    forceIsFiltered_Old = forceIsFiltered;
                    
                    app.ForceSensorvalueLabel.Text = num2str(forceIsFiltered);
                    FSVal = FSVal+forceIsFiltered;                  % to take the average of the force sensor values to find the Offset

                    normalvalue(i)=forceIs;
                    filteredvalue(i)=forceIsFiltered;
                    plot(app.UIAxes,normalvalue,'blue');
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,filteredvalue,'red');

                    i=i+1;
                    pause(0.001);
                end
                
                FS_Offset = FS_Offset + (FSVal /50);
                
                
            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
                clear connected_servo;
            end
        end

        % Value changing function: BicepThresholdSlider
        function BicepThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue);
            app.EMGThresholdEditField.Value = changingValue;
        end

        % Value changing function: TricepThresholdSlider
        function TricepThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue);
            app.EMGThresholdEditField_2.Value = changingValue;
        end

        % Value changed function: BicepDropDown
        function BicepDropDownValueChanged(app, event)
            value = app.BicepDropDown.Value;
            if value == "EMG0"
                app.TricepDropDown.Value = "EMG1";
            else
                app.TricepDropDown.Value = "EMG0";
            end
        end

        % Value changed function: TricepDropDown
        function TricepDropDownValueChanged(app, event)
            value = app.TricepDropDown.Value;
            if value == "EMG0"
                app.BicepDropDown.Value = "EMG1";
            else
                app.BicepDropDown.Value = "EMG0";
            end
        end

        % Button down function: EMGControlTab
        function EMGControlTabButtonDown(app, event)
            app.TabGroup2.SelectedTab = app.EMGTab_2; %% open EMG graph Tab
        end

        % Button down function: ForceControlTab
        function ForceControlTabButtonDown(app, event)
            app.TabGroup2.SelectedTab = app.ForceSensorTab_2; %% open EMG graph Tab
        end

        % Close request function: JustExoPanelUIFigure
        function JustExoPanelUIFigureCloseRequest(app, event)
            delete(app)
            clear all
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            global i;
            global connected_device;
            global charge_status;

            try
                %% Start the Serial communication with Arduino
                if strcmp(value,'Start')
                    app.StatusLampLabel.Text="Loading...";
                    %COM = serialportlist("available");                 
                    
                    COM = app.SerialPortCOMDropDown_2.Value;                 %% get COM value
                    app.ResetValuesButton.Enable = "off";

                    %% connect matlab to arduino
                    connected_device = arduino(COM,'Nano33IoT','BaudRate',115200);   %921600 Connect Matlab to Arduino
                    app.StatusLampLabel.Text="Connected";
                    app.StatusLamp.Color= 'green';

                    %% Turn on Servo
                    writeDigitalPin(connected_device,'D2',1);
                    connected_servo = servo(connected_device,'D5', 'MaxPulseDuration', 0.00205, 'MinPulseDuration',0.00085); % Connect the servo values to Matlab through Arduino
                    writePosition(connected_servo,1);
                    Angle =0;
                    i=1;
                    new_emg_offset=0;       %% as a first test we will start with zero offset.
                    
                    %%Clean the Axes and start over again
                    cla(app.UIAxes2);
                    cla(app.UIAxes3);
                    app.UIAxes2.XLim = [0 100];
                    app.UIAxes3.XLim = [0 100];

                    BicepPort = app.BicepDropDown.Value;
                    %TricepPort = app.TricepDropDown.Value;
                    if BicepPort == "EMG0"
                        BicepPort = "A1";
                        TricepPort = "A2";
                    else
                        BicepPort = "A2";
                        TricepPort = "A1";
                    end

                    %%%Bicep
                    EMGB_Old = 0;
                    EMGB = readVoltage(connected_device,BicepPort);
                    EMGB = (EMGB*1023)/5-new_emg_offset;
                    EMGBFiltered_Old = EMGB;
                    %%%Tricep
                    EMGT_Old = 0;
                    EMGT = readVoltage(connected_device,TricepPort);
                    EMGT=(EMGT*1023)/5-new_emg_offset;
                    EMGTFiltered_Old=EMGT;
                    
                    %%%%%% RGB Switch Colours%%%%%%
                    R = 3.3; G = 0; B = 0.3; %% RGB ()
                    RGB_Colour(app,R,G,B);
                    CheckBattery(app);
                end

                %%%%%%%% Check The battery Percentage
                if charge_status == 1
                    
                    %%%%%%%% Start the Emg Algorithm
                    while strcmp(value,'Start')
                        value = app.Switch_2.Value;
                        if strcmp(value,'Stop')
                            writeDigitalPin(connected_device,'D2',0);
                            app.StatusLamp.Color= 'r';
                            app.StatusLampLabel.Text="Disconnected";
                            app.Lamp.Color ="Black";
                            app.BatteryLabel.Text = "Battery: ";
                            app.ResetValuesButton.Enable = "on";
                            app.Lamp_2.Color ="Black";
                            app.BatteryLabel_2.Text = "Battery: ";
                            clear all;
                            break
                        end
                        Bicep_Threshold = app.BicepThresholdSlider.Value;   %% get the Bicep EMG Threshold
                        Tricep_Threshold = app.TricepThresholdSlider.Value; %% get the Tricep EMG Threshold
                        %%%%%%%%%% Bicep EMG Data
                        EMGB = readVoltage(connected_device,BicepPort);     %%% get values from EMG Sensor
                        EMGB=(EMGB*1023)/5-new_emg_offset;                  %% maps sensor readings between 0 and the operating voltage (5V or 3.3V) into integer values between 0 and 1023.

                        EMGBFiltered=0.8*EMGBFiltered_Old + 0.1*EMGB + 0.1*EMGB_Old;
                        EMGB_Old = EMGB;
                        EMGBFiltered_Old = EMGBFiltered;
                        app.EMGBicepValueLabel.Text = "EMG Bicep Value:"+string(EMGBFiltered);

                        %%%%%%%%%% Tricep EMG Data
                        EMGT = readVoltage(connected_device,TricepPort);    %%% get values from tricep EMG Sensor
                        EMGT=(EMGT*1023)/5-new_emg_offset;                  %% maps sensor readings between 0 and the operating voltage (5V or 3.3V) into integer values between 0 and 1023.

                        EMGTFiltered=0.8*EMGTFiltered_Old + 0.1*EMGT + 0.1*EMGT_Old;
                        EMGT_Old = EMGT;
                        EMGTFiltered_Old = EMGTFiltered;
                        app.EMGTricepValueLabel.Text = "EMG Tricep Value:"+string(EMGTFiltered);

                        %%%%%%%%%%%%%%%%%%%%%%% Servo Movement %%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        if EMGBFiltered >=Bicep_Threshold
                            if Angle < 0.9
                                writePosition(connected_servo,1-Angle);
                                Angle = Angle + 0.1;
                                B = B+0.3;
                                RGB_Colour(app,R,G,B);
                            end
                        end
                        if EMGTFiltered >=Tricep_Threshold
                            if Angle >= 0.1
                                Angle = Angle - 0.1;
                                writePosition(connected_servo,1-Angle);
                                B = B-0.2;
                                RGB_Colour(app,R,G,B);
                            end
                        end
                        normalvalueB(i) = EMGB;
                        filteredvalueB(i) = EMGBFiltered;
                        EMGB_Threshold_graph(i) = Bicep_Threshold;

                        normalvalueT(i) = EMGT;
                        filteredvalueT(i) = EMGTFiltered;
                        EMGT_Threshold_graph(i) = Tricep_Threshold;

                        if i>=100
                            app.UIAxes2.XLim = [i-80 i+20];
                            app.UIAxes3.XLim = [i-80 i+20];
                        end

                        plot(app.UIAxes2,normalvalueB,'c');
                        hold(app.UIAxes2, 'on')
                        plot(app.UIAxes2,filteredvalueB,'red');
                        hold(app.UIAxes2, 'on')
                        plot(app.UIAxes2,EMGB_Threshold_graph,'black','LineWidth',1);
                        hold(app.UIAxes2, 'on')

                        plot(app.UIAxes3,normalvalueT,'c');
                        hold(app.UIAxes3, 'on')
                        plot(app.UIAxes3,filteredvalueT,'red');
                        hold(app.UIAxes3, 'on')
                        plot(app.UIAxes3,EMGT_Threshold_graph,'black','LineWidth',1);
                        hold(app.UIAxes3, 'on')
                        i=i+1;

                    end
                else
                    report = [ "The Battery is TOO LOW, unfortunately the servo can't be used.";" ";"Sugesstions:";"Please make sure the Exoskeleton's push button Pressed down";"Or Please Charge the Battery"];
                    msgbox(report,"Error","error")
                    app.Switch_2.Value = "Stop";
                    writeDigitalPin(connected_device,'D2',0);
                    app.StatusLamp.Color= 'r';
                    app.StatusLampLabel.Text="Disconnected";
                    app.Lamp.Color ="Black";
                    app.BatteryLabel.Text = "Battery: ";
                    app.ResetValuesButton.Enable = "on";
                    clear all
                end

            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
                writeDigitalPin(connected_device,'D2',0);
                app.Switch_2.Value = "Stop";
                app.StatusLamp.Color= 'r';
                app.StatusLampLabel.Text="Disconnected";
                app.Lamp.Color ="Black";
                app.BatteryLabel.Text = "Battery: ";
                app.Lamp_2.Color ="Black";
                app.BatteryLabel_2.Text = "Battery: ";
                app.ResetValuesButton.Enable = "on";
                clear all;
            end
        end

        % Button pushed function: ResetValuesButton
        function ResetValuesButtonPushed(app, event)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            
            app.EMGBicepValueLabel.Text = "EMG Bicep Value:";
            app.EMGTricepValueLabel.Text = "EMG Tricep Value:";
            
            app.BicepThresholdSlider.Value = 400;
            app.TricepThresholdSlider.Value = 400;
            
            app.EMGThresholdEditField.Value = 400;
            app.EMGThresholdEditField_2.Value = 400;
            
            app.Lamp.Color = "Black";
            app.BatteryLabel.Text = "Battery:";
            
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            global connected_device;
            global charge_status;

            value = app.Switch_3.Value;
            forceOffset = app.ForceSensorOffsetEditField.Value;
            i=1;
            Angle =0;

            try
                %% Start the Serial communication to Arduino
                if strcmp(value,'Start')
                    app.StatusLamp_2Label.Text="Loading...";
                    %COM = serialportlist("available");                 
                    
                    COM = app.SerialPortCOMDropDown.Value;                 %% get COM value
                    
                    app.ResetValuesButton_2.Enable = "off";
                    
                    %% connect matlab to arduino
                    connected_device = arduino(COM,'Nano33IoT','BaudRate',115200);   %921600 Connect Matlab to Arduino
                    app.StatusLamp_2Label.Text="Connected";
                    app.StatusLamp_2.Color= 'green';

                    writeDigitalPin(connected_device,'D2',1);
                    connected_servo = servo(connected_device,'D5', 'MaxPulseDuration', 0.00205, 'MinPulseDuration',0.00085); % Connect the servo values to Matlab through Arduino
                    writePosition(connected_servo,1);
                    cla(app.UIAxes);
                    app.UIAxes.XLim = [0 100];

                    forceIs_Old = 0;
                    forceIs = readVoltage(connected_device,'A0');
                    forceIs = (forceIs*1023)/5-forceOffset;
                    forceIsFiltered_Old = forceIs;

                    %%%%%% RGB Switch Colours%%%%%%
                    R = 0; G = 0; B = 2.2; %% RGB (Yellow)
                    RGB_Colour(app,R,G,B);
                    CheckBattery(app);
                end

                if charge_status == 1

                    %% Start the Force control Algorithem
                    while strcmp(value,'Start')
                        value = app.Switch_3.Value;

                        %% stop the Force control Algorithem and cut the serial communication if the switch is off
                        if strcmp(value,'Stop')
                            app.StatusLamp_2Label.Text="Disconnected";
                            app.StatusLamp_2.Color= 'red';
                            app.Lamp_2.Color ="Black";
                            app.BatteryLabel_2.Text = "Battery: ";
                            app.Lamp.Color ="Black";
                            app.BatteryLabel.Text = "Battery: ";
                            clear all;
                            break
                        end

                        %% get the Thresholds Value
                        Lower_Threshold = app.UpperThresholdSlider.Value;
                        Upper_Threshold = app.LowerThresholdSlider.Value;
                        app.LowerThresholdLabel.Text = "Lower Threshold:"+string(Lower_Threshold);
                        app.UpperThresholdLabel.Text = "Upper Threshold:"+string(Upper_Threshold);

                        %% get the Force sensor Value and low pass filter it
                        forceIs = readVoltage(connected_device,'A0');
                        forceIs=(forceIs*1023)/5-forceOffset;
                        forceIsFiltered=0.8*forceIsFiltered_Old + 0.1*forceIs + 0.1*forceIs_Old;
                        forceIs_Old = forceIs;
                        forceIsFiltered_Old = forceIsFiltered;
                        app.ForceSensorvalueLabel.Text = "Force Sensor value:"+string(forceIsFiltered);

                        %% Move the servo if force sensor value surpass the thresholds
                        if forceIsFiltered >=Upper_Threshold
                            if Angle < 0.9
                                writePosition(connected_servo,1-Angle);
                                Angle = Angle + 0.1;
                                B = B+0.1;
                                RGB_Colour(app,R,G,B);
                            end
                        end
                        if forceIsFiltered <=Lower_Threshold
                            if Angle > 0.1
                                Angle = Angle - 0.1;
                                writePosition(connected_servo,1-Angle);
                                B = B-0.1;
                                RGB_Colour(app,R,G,B);
                            end
                        end

                        %% Plot the values
                        normalvalue(i) = forceIs;
                        filteredvalue(i) = forceIsFiltered;
                        Upper_Threshold_graph(i) = Upper_Threshold;
                        Lower_Threshold_graph(i) = Lower_Threshold;

                        if i>=100
                            app.UIAxes.XLim = [i-80 i+20];
                        end

                        plot(app.UIAxes,normalvalue,'c');
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,filteredvalue,'red');
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,Upper_Threshold_graph,'black','LineWidth',1);
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,Lower_Threshold_graph,'black','LineWidth',1);
                        i=i+1;
                    end
                else
                    report = [ "The Battery is TOO LOW, unfortunately the servo can't be used.";" ";"Sugesstions:";"Please make sure the Exoskeleton's push button Pressed down";"Or Please Charge the Battery"];
                    msgbox(report,"Error","error")
                    app.Switch_3.Value = "Stop";
                    app.StatusLamp_2Label.Text="Disconnected";
                    app.StatusLamp_2.Color= 'red';
                    app.Lamp_2.Color ="Black";
                    app.BatteryLabel_2.Text = "Battery: ";
                    app.Lamp.Color ="Black";
                    app.BatteryLabel.Text = "Battery: ";
                    clear all
                end
            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
                writeDigitalPin(connected_device,'D2',0);
                app.Switch_3.Value = "Stop";
                app.StatusLamp_2Label.Text="Disconnected";
                app.StatusLamp_2.Color= 'red';
                app.Lamp.Color ="Black";
                app.BatteryLabel.Text = "Battery: ";
                app.Lamp_2.Color ="Black";
                app.BatteryLabel_2.Text = "Battery: ";
                clear all;
            end
        end

        % Button pushed function: ResetValuesButton_2
        function ResetValuesButton_2Pushed(app, event)
            cla(app.UIAxes)
            
            app.ForceSensorvalueLabel.Text = "Force Sensor value:";
            app.LowerThresholdLabel.Text = "Lower Threshold:";
            app.UpperThresholdLabel.Text = "Upper Threshold:";
            
            app.ForceSensorOffsetEditField.Value = 260;
            app.UpperThresholdSlider.Value = 20;
            app.LowerThresholdSlider.Value = -20;

            app.Lamp_2.Color = "Black";
            app.BatteryLabel_2.Text = "Battery:";
        end

        % Value changed function: UpperThresholdSlider
        function UpperThresholdSliderValueChanged(app, event)
           
        end

        % Callback function
        function RefreshBatteryButton_2Pushed(app, event)
            try
                CheckBattery(app);
            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
            end
        end

        % Callback function
        function RefreshBatteryButtonPushed(app, event)
            try
                CheckBattery(app);
            catch ME
                report = ME.message;
                msgbox(report,"Error","error");
            end
        end

        % Callback function
        function EMGButtonPushed(app, event)
            
            C=[210.268753986948, 205.15684074032987, 208.40021764817724, 218.31466473643867, 207.45511127157327, 213.2581985812949, 209.69683189217488, 211.47664752510907, 217.52712769009077, 217.2993149795941, 206.29760682282536, 213.1028549201916, 212.7639057326661, 206.7942306907265, 213.4616798164929, 207.40787409107584, 213.8916661617325, 216.9939099405628, 204.87442331774583, 205.59779082950135, 214.80813545416898, 211.70239097485018, 206.2623484193466, 201.80063788920235, 216.6072233847186, 217.2922976034142, 209.17531311788385, 211.08165611201043, 214.9684845904726, 217.4168539351339, 202.5711984112043, 201.8623318923812, 209.22789698353498, 213.99428678145642, 202.23926278431497, 208.8961952981417, 203.77483398117988, 206.95631989576262, 203.4819117880729, 209.65677833466253, 212.4141032042745, 216.69003464379293, 210.1901289619384, 203.96555732321862, 204.10955657228613, 215.05715492731406, 210.9399032260783, 215.8606966718355, 208.206204282950, 217.01961928133755];
            A=[2.362385719567682, 1.5124687580370452, 5.217436799869753, 4.478868759618091, 2.939541077524221, 0.5629851342832352, 3.3540574811921665, 7.943952682469733, 3.3015798542262616, 7.047499683267129, 0.23821226377530653, 1.1989966470429947, 7.06612345213695, 4.938952717476126, 5.006646954603406, 5.879971086743817, 7.576623207070885, 7.156493193325081, 6.392860492695149, 6.684131345425098, 3.93971674368088, 2.294642531956057, 4.211674235732325, 6.158853829654488, 3.4782489081918273, 2.931723689105096, 1.237127820972542, 7.044208126577093, 4.0654618740748, 4.891773485903741, 7.028228991206108, 4.032419938309941, 0.6023488193623614, 1.7670278735262065, 6.398948861146322, 3.083466738328451, 5.998745141197919, 2.8057779133439176, 7.825652982842691, 5.049000237122385, 5.502446885170346, 3.0794145152612523, 2.6841189484657446, 3.866479815596012, 4.994896059719981, 5.168680641077927, 2.2744189721949273, 0.6914483423121617];
            B=[417.1258011046792, 461.7927299942591, 450.4690384823077, 405.29338973513924, 432.95170894902974, 426.6360017799544, 460.5831602171979, 451.04109091540063, 423.98538876733005, 421.1769660668614, 433.7932388574578, 465.5780000522425, 469.8194185781444, 465.8652554404168, 476.6537768760532, 430.6222511014776, 454.8425376054397, 457.0675950731953, 452.96529149757894, 469.5791955295544, 463.80145042291304, 401.46594866924446, 457.54215119614576, 472.615551174641, 455.6532012963523, 464.577452765199, 420.3618085567711, 435.7233155125389, 402.2993608675551, 469.6034315704486, 405.3006363374505, 436.8080716175952, 457.10460537242394, 474.96864883467604, 472.23372970043415, 412.18413775972585, 437.7688836842315, 460.5306085716643, 413.1684871129222, 463.67110357822665, 458.8977824073877, 474.7682842365056, 442.04761676583516, 414.3227943863621, 440.76948539060806, 451.8397685304863, 439.93013141111095, 476.3557392200538, 413.10245437980014];
            D=[120.53612799469662, 129.17657830654738, 160.2468254318004, 104.9034961578015, 182.4358477197382, 121.22116885071512, 108.73150467411902, 175.38999696806207, 147.8541169984978, 179.90440107226993, 183.1442561561804, 168.93088786127685, 113.57877525304211, 151.8575154897479, 174.19048424483913, 131.4916203654173, 181.28758306293027, 176.53285284047594, 111.34305107174585, 172.85078033056238, 141.34596570607354, 118.56477499259512, 134.24408411504292, 138.1285273776174, 176.45143363303192, 119.15896483696327, 184.76123836329753, 141.88467493552912, 125.10248087310346, 136.31164000152087, 149.7557163931943, 168.617857112247, 151.1564682155667, 123.79554079819073, 143.0889513943785, 131.72536904507298, 110.70066984048715, 184.51672960087372, 164.8578836374265, 102.06513074881591, 183.49331932951512, 126.67000147468728, 152.68839800438077, 116.47978809674163, 127.31702402827386, 109.48590194016412, 114.87695699006771, 158.87078428931155, 172.33225872348223];
            E=[416.783126764328, 403.453668873181, 434.970987145579, 468.072911168395, 452.156899543545, 447.789987934762, 459.268311820949, 465.391184803075, 437.209975558577, 401.845141210392, 415.0798938346, 431.656874963428, 424.729715369682, 466.132312931557, 461.108315440424, 423.789414366092, 455.943781283964, 455.541414157112, 440.32458184674, 403.917585935318, 444.131001130113, 422.798180203946, 465.061439920676, 441.282400855001, 430.327498834106, 424.142581034383, 402.456409605567, 461.265730439804, 425.042045801109, 463.245973193386, 417.557185337189, 453.425444177823, 460.702777124066, 418.052591999918, 436.031974321938, 449.049712829248, 412.251446596825, 438.469041222727, 469.579923541927, 443.085411463499, 423.187857057142, 470.218083431107, 447.725914093004, 441.06231881085, 455.854652671482, 460.849084008765, 428.823006413571, 464.504449579113, 474.63267887266];
            F=[4.348091850862503, 3.116507275540688, 0.4828500804183381, 1.3586203019816176, 2.958530963451196, 4.928511703186084, 0.3454844053600354, 2.2467036647642877, 4.181782083214938, 2.281768126043908, 3.743344349255591, 1.680753865174118, 3.408350168350768, 0.10401501476343233, 2.987441879260143, 4.529643746563925, 4.214977110262419, 1.0172942959830587, 3.8039811261167297, 1.472819145837852];
            G=[2.948456426748095, 1.222665631429701, 0.9110548434207582, 3.657854113225643, 2.737390154670043, 0.725480734484585, 1.575259165235873, 1.312312233908727, 2.554716806405726, 1.2308379855021595, 0.7255743311016761, 2.8492361072709387, 1.1418945879387646, 3.865245978808725, 0.26726753169604845, 3.071518607597559, 0.44319406260670886, 3.8691533370150386, 3.534621409188712, 1.083646749535144, 3.4169568744152925, 2.9148251213004084, 0.1629819990766614, 0.5079295641111832, 2.7595788435944575, 3.734295396421097, 0.13248757926830387, 2.1564638152837366, 2.791237598791284];
            filteredvalueB=[A,B,C,D,E,F,G];
            
            A2=[5.372378319631748, 4.408907718997725, 8.056165563149234, 7.280435059328772, 4.926001320750716, 6.198291131502503, 9.847130622735529, 6.947894880667051, 9.345471722000884, 11.169539376687855, 10.53056438792496, 4.153370812785201, 5.838218502741664, 6.373966387868554, 7.553850546346027, 3.967463052518249, 4.421539928344237, 7.270692205857061, 3.0626614294826475, 10.134868468360798, 10.57137661415508, 6.968586624218451, 4.980102468131183, 7.488763526745155, 8.579758863567292, 5.488273780552062, 10.682534328732095, 5.508270080683404, 8.74315741566954, 6.192320175650194, 11.512917344735077, 11.002864506868863, 4.719870980973214, 9.877164827772135, 4.28010505989076, 11.282253428523836, 8.223184735194645, 5.062179636063422, 9.844708201847004, 4.650020428852571, 7.636498148852666, 10.662800502622614, 4.831478800491123, 7.947741696089422, 4.550720527800027, 10.875194879972927, 11.055117942001474, 5.364874726111542, 8.606177938142117, 5.878468593978971]; 
            B2=[240.714260048769, 215.387855779724, 260.158906423272, 276.74256969082, 233.675756138919, 282.126062507629, 217.457184978756, 287.29080947235, 222.438758903351, 270.854869336107, 236.743774767996, 210.207209776752, 209.261424145185, 244.31961755848, 226.568414476157, 276.66797824143, 286.839749743284, 280.526043453567, 294.002650474167, 238.107771363682, 291.866755551662, 218.667196544984, 203.687732580149, 227.195464035224, 273.758788594803, 292.946919640542, 282.486747225794, 244.130063725394, 267.369024262572, 276.97959522605, 239.074218389551, 244.189135660227, 294.019101903771, 212.215821440835, 203.541332438513, 255.489986247093, 248.695129443628, 265.808155156307, 284.034124971091, 215.874483242124, 261.049622151965, 251.924522268284, 214.441413406741, 234.556273108834, 284.774998381143, 247.67656304545, 211.083291862718, 217.666334221054];
            C2=[476.87192559671824, 452.6827964685743, 407.73325178934746, 416.8360349635184, 471.7523125243474, 415.2846061270119, 429.408887458968, 461.8452848416164, 453.0975470848795, 441.4422852265958, 445.7173998015478, 460.2451718296572, 466.89911625507964, 470.4797584963213, 406.6757651086643, 403.6579821159479, 461.1124169376197, 466.4651419839986, 482.9866743786138, 409.9295110143634, 472.8370850725296, 472.6959194537695, 401.18737857314455, 441.77849618849105, 463.65301282276595, 471.6406213225461, 430.1297183701299, 495.9815797752056, 441.5203436982297, 431.63493942088435, 478.2817565806363, 429.34467536705185, 499.8243878887027, 482.6090413831237, 429.9252126563695, 418.9508401722975, 461.2478492641442, 448.1920345640126, 480.4668535092054, 406.683523177304, 492.8277823196402, 495.7322260650176, 480.7566840979725, 481.05930313959754, 469.63176634704484, 404.69825971549644, 495.4478582351837, 468.04565711157924, 486.9905967686666];
            D2=[356.2747274788776, 380.8767957303716, 383.4585428924701, 310.5956694549912, 383.8780579241765, 362.1739808680025, 394.1228786802996, 381.8379072595633, 312.93231940550104, 309.36412373038294, 350.09534290532534, 398.80620416665163, 358.1765409523102, 345.8420297528599, 315.8490293369934, 343.47674519106896, 333.814116036677, 371.50786799492895, 318.42424290800776, 315.3800930008229, 394.1790466349244, 405.43222759616356, 314.5692735887125, 343.4467788163922, 340.28755214641815, 315.16648137783116, 342.18683422807616, 396.9001021695613, 342.4815395541513, 359.50430878982325, 399.0256095458654, 319.04607722001265, 349.0917871048014, 385.73996632243604, 381.8777948378105, 319.5741351339569, 363.81968850744285, 349.19335239300795, 389.4324306340365, 405.2001676347584, 309.2239161801005, 319.1928122286301, 312.0838852685385, 359.4652651057463, 318.7614603863084, 392.5300846375635, 375.7690275752806, 366.5177246767983, 341.7527979831409, 352.3017386874409];
            E2=[132.5743081883685, 101.42012341353564, 112.36595259076006, 122.63781387433744, 123.54990704310587, 125.78445121601768, 121.62869867838667, 105.0272581628456, 119.69484121278507, 117.64765109050207, 132.78681569696922, 111.78025818585712, 119.21170284255764, 107.897312725157, 116.72166866722868, 108.21893803865382, 129.457594976661, 110.73202895306743, 103.57812867188424, 121.52717337618003, 110.56286599728288, 133.2131784841521, 113.46096319725248, 132.55174818917354, 107.03479354251804, 125.62682649947599, 113.17353293924985, 111.02711616383433, 116.12337010189633, 111.26662582294197, 129.21930667581876, 110.4960467681915, 114.69381825198015, 120.02470329757646, 103.13487234786704, 102.0054973283207, 121.3609135793506, 102.86008082522847, 130.6676089052066, 129.42526067157093, 110.19305586734025, 113.82674385445012, 114.93873679286093, 132.04766923341988, 125.97038711640989, 131.68485313173644, 117.75928732238812, 110.12222873311267, 108.33640603820783, 107.76775470449168];
            F2=[56.23675098234859, 53.71439896227163, 38.45128652991362, 39.45674992737651, 61.79525468722881, 34.90719388342673, 66.09495313229649, 46.35299372674929, 60.83582031492584, 33.63501885461406, 42.53978383579894, 67.78211729731823, 49.18849661568821, 60.2175322187163, 62.6947472011548, 59.60389278367131, 60.776786217746, 32.36105740826308, 65.52798163567144, 64.68880865752134, 60.17884927623688, 31.60242509089422, 29.41121204901995, 31.571785481555556, 51.47268506298584, 67.12421546918417, 28.42515509128423, 44.60816232937579, 64.76798652310468, 61.18010187793318, 66.19364209181658, 33.46972446380662, 29.90063414637557, 57.00257688472445, 51.83830961770656, 63.13445684038221, 40.81020566970201, 54.52533601119757, 32.97757619209696, 36.12580606561689, 59.05184134039879, 63.98753476607524, 50.128318865964196, 59.41352458798461, 67.16119460821994, 52.00572536959542, 43.00350839363208, 62.01912455314651, 27.7368714342599];  
            filteredvalueT=[A2,B2,C2,D2,E2,F2];
            
            thresholdmax = repmat(350,300);
            plot(app.UIAxes2,filteredvalueB,'red');
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2,thresholdmax,'black','LineWidth',1);
            hold(app.UIAxes2, 'on')
            
            plot(app.UIAxes3,filteredvalueT,'red');
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3,thresholdmax,'black','LineWidth',1);
            hold(app.UIAxes3, 'on')
        end

        % Callback function
        function ForceButtonPushed(app, event)
            app.TabGroup2.SelectedTab = app.ForceSensorTab_2;
            A=[6.789134245861855, 4.345278957602831, 8.94672013526812, 2.345897619700367, 9.673541321929875, 3.267913458973562, 5.890324784001948, 7.124678903746172, 1.2367890123456789, 8.567123456789012, 0.9876543211234567, 4.678912345678913, 9.123456789012345, 3.4567890123456787, 5.43210987654321, 2.1234567890123457, 7.876543210987654, 6.123456789012345, 1.2345678901234567, 9.43210987654321];
            B=[16.739582019324968, 17.68253777802887, 19.47850267904926, 15.860514693540336, 27.341578839993542, 20.992614143804473, 18.342459192551327, 14.19276930721143, 24.255789390925357, 11.545350852932966, 21.146434712465693, 13.768932726222408, 26.890300472963033, 22.312379679968666, 12.556805844631378, 10.12600829069456, 26.769840433713763, 25.499352861443387, 28.160408272972215, 13.689178925211316];
            C=[35.24016553066188, 34.84729076422199, 38.29483781655811, 37.418735682691034, 32.763580864672315, 31.152466657598347, 30.535149543215886, 33.97267672020538, 36.451293884744826, 30.775280142123863, 37.01770678629421, 38.20847212215192, 33.41198362504993, 38.84005518001087, 36.62251831253614];
            D=[51.24516473790123, 47.89260942815326, 44.43021672970488, 45.77244328172018, 43.67533923827145, 51.62883632892006, 53.092138558626875, 45.11624944462159, 50.21231578936339, 43.14499303845162, 49.81506776172095, 50.90987725519607, 54.5904897137551, 51.15605123965731, 46.11630572060972, 44.12587801593082, 47.92350389259727, 54.42351796289941, 53.87978691609879, 47.45864358578857, 51.19308155627388, 48.45068737093136, 48.23860993553175, 48.12620210576607, 50.16647471214007, 51.79149949348292, 42.9812958708016, 53.83923222546588, 46.12736289499647, 42.603220290097216];
            E=[28.726271952340194, 35.79131556273496, 25.86213058625005, 30.69744809666697, 36.93699506389241, 26.43103247593607, 23.769761258347696, 21.7024750080677, 37.3151440090199, 24.874110899425883, 38.82999113864389, 34.84234532774252, 27.92952418869299, 38.46942155507745, 32.228837520939446, 22.52473987008378, 29.10134454283297, 33.52255481038079, 31.36507431584185, 23.276429469559325];
            F=[17.67329465560666, 11.729158733192695, 13.458262441373168, 10.29845668235858, 19.841998927664796, 12.527160611176228, 11.391807482963723, 18.835442804631416, 19.48069218648347, 13.901345916076157, 12.114028285712037, 14.927204929081726, 17.680972680261647, 11.43022651113708, 12.561226066104936, 19.563128535566613, 11.10729253565097, 16.727111753176903, 18.669842362050504, 15.897671430893073];
            G=[3.672319066936727, 7.486170518141165, 4.125335615298016, 2.301367850015378, 8.975892458962586, 1.475345253837682, 6.819728376083789, 9.649771379946622, 4.398408463836563, 1.702306648157937, 7.972623721264236, 3.560359428751072, 9.298057493795652, 7.317122762883478, 0.964284647383488, 8.659526122165764, 5.819749244992057, 5.410078390831105, 3.063998983322824, 4.730196202792175];
            H=[-2.553283754346476, -8.768214893791416, -5.911889418769685, -3.7745251247212835, -1.4978298384301807, -7.341968283580677, -1.9287699289078523, -6.027786256043097, -8.987726834594286, -7.590775735455356, -2.8108897696694095, -4.344186848146319, -4.945453690800396, -6.520305522907629, -0.6135361842157857, -8.518371396537006, -8.257073703987706, -5.853414267448999, -7.135229576499611, -7.426451749470949];
            I=[-20.765428821437258, -16.492076848959942, -25.091689947136707, -13.378261477444265, -18.54171311349709, -15.027834214130232, -20.25956874584503, -16.399907086878696, -14.221594192662132, -22.916206931013817, -25.204642314337846, -21.235170543269475, -11.882373884166184, -17.529427419748953, -29.543987875413704, -14.998956861218074, -25.19563413334235, -18.208867919261036, -17.967827274504943, -26.74570929286669, -15.970211928795625, -12.393557141880748, -14.222628451155802, -13.647933487285152, -10.99022746545669, -16.751802798309375, -11.408855909752314, -10.543604015173067, -22.68879424573097, -23.428505882506694];
            J=[-30.57336558218333, -30.80358115530523, -31.729805084931723, -26.96578198005441, -29.062557729864273, -31.974152655579894];
            K=[-41.76841568856445, -44.825431225951396, -37.291382720448, -45.970071997899845, -35.9857455959512, -38.75519645014383, -43.10726542517486, -41.79360322015945, -37.68220822379492, -47.0696486356761, -44.45147207339571, -39.41627655786107, -38.58246294048457, -39.24675321789713, -47.37934435829696, -44.65679971298216, -37.556097852451255, -39.24473229355708, -43.8802874230381, -48.6246971011812, -45.23012484956146, -45.0066721028757, -36.45174378384285, -47.75555605114354, -38.499754764666216, -39.33229858834019, -36.81215368750639, -35.85298908120962, -38.21847490469041, -46.79713340907489];
            L=[-51.31278375482344, -56.89142193714439, -58.6785123719321, -51.27640104753947, -55.90343295618465, -52.09143964623877, -55.40577724118718, -50.91584013985324, -54.73882697452501, -56.356487138352295, -53.61956244342931, -57.99671875015139, -56.18376221800684, -51.82264721817281, -52.80033892596363, -52.79955891109169, -55.27998597803794, -54.22774290588515, -59.19954217440093, -51.30711874243941, -53.93576932213468, -57.373047498667175, -59.596010250873106, -57.40946326113196, -58.235243801916845, -59.99070915501511, -55.29636960739618, -56.42535439317698, -59.33863356588287, -51.839907635055074];
            M=[-57.37964726155643, -55.57839853947571, -61.2265566717889, -57.31663222721682, -56.48206298196432, -60.94561189745389, -56.93522986643495, -59.82919218644314, -58.69604114765033, -62.721054634933696, -56.80335461284871, -60.01355772385409, -62.60992741374285, -56.68017873194584, -55.58790746626865, -61.58184816288484, -59.27209888791987, -62.896103667782245, -56.49834563572656, -60.37857122231295, -60.54215594913075, -59.876781536407394, -60.45883705930745, -59.19493057743869, -57.40843806287485, -59.67440870358369, -58.2748538103256, -57.49126029430165, -55.69040555611316, -59.845133362869836];
            N=[11.872429503924146, 9.381899104387416, -2.321269933127662, 3.518341595332307, 2.860801727695723, 8.19046889179118, 12.660540885654296, 3.669667198149411, 10.328742167991162, 7.149385581660713, 6.226554957380995, 12.368424622360685, 0.42941929038622985, 8.028122695913678, 9.741577665381983, 9.54843769977854, 0.5906731160961944, 4.664498163216923, 8.79078764776973, -0.8181108185463054, 11.848736535742149, 3.1233198380045676, 11.786108066117041, 0.9701363580411597, 10.172502994886346, 1.3137989909279502, 12.918418014126244, 0.8108751192451449, 0.30593208163621745, 2.169394852710747, 10.076841727238417, 11.774029139869542, 4.430526561480586, 1.0007408126287162, 8.53938649378376, 8.595009419808296, 5.839000406282587, -3.134765693941425, -2.210286846842981, 8.101132450620204, 1.2795886761036513, 7.646489507727579, 4.296650512494057, -0.5641327493890092, 11.406696258236482, 14.398774275364702, 3.743197905573246, 0.8052223931174725, 6.32181044247904];
            O=[36.482746651128125, 42.80488997804319, 41.11524777519263, 33.62907257081943, 43.21519995086524, 44.13192822381176, 38.78455413684315, 38.443674744469986, 37.96292852083283, 40.36879806600912, 34.29456488017769, 44.66124528046324, 44.420223800826625, 33.77884819757372, 40.78348368715038, 43.77305928091342, 42.22962570886751, 34.76299889488118, 38.6314281522806, 44.46895114686178, 37.422712282253, 44.37356629244234, 30.78862321888361, 42.26716192450544, 37.5567280423459, 41.00473021876577, 40.31940639860568, 33.97768323914506, 44.20074010689472, 40.23184900545466, 34.71282499842332, 42.01276579536199, 35.26517516756129, 44.80200144792482, 44.24256454846455, 36.66296496360938, 38.37080095070806, 42.42620477746692, 38.5689636076525, 41.53213957586514, 30.718654752964395, 36.731080441053034, 31.74963139800298, 43.1429997921662, 34.33524908122239, 40.74582593741368, 43.99928344373357, 41.49979846461659];
            P=[49.35929694311636, 46.77495966797845, 49.78652377995063, 47.878043867456176, 49.6015756979809, 45.30389395005376, 46.8355638275516, 49.492229106465265, 46.69130883594664, 46.48971927608062, 48.89925915280249, 45.53663544366795, 47.87395373840888, 46.28520222924948, 49.564861988984174];
            filteredvalue=[A,B,C,D,E,H,I,J,K,L,M,N,O,P];
            Upper_Threshold_graph=repmat(20,400);
            Lower_Threshold_graph=repmat(-25,400);

            plot(app.UIAxes,filteredvalue,'red');
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,Upper_Threshold_graph,'black','LineWidth',1);
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,Lower_Threshold_graph,'black','LineWidth',1);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.JustExoPanelUIFigure = uifigure('Visible', 'off');
            app.JustExoPanelUIFigure.Name = 'JustExoPanel';
            app.JustExoPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @JustExoPanelUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.JustExoPanelUIFigure);
            app.EMGControlTab = uitab(app.TabGroup);
            app.EMGControlTab.Title = 'EMG Control';
            app.EMGControlTab.ButtonDownFcn = createCallbackFcn(app, @EMGControlTabButtonDown, true);
            app.InfoPanel_2 = uipanel(app.EMGControlTab);
            app.InfoPanel_2.Title = 'Info';
            app.EMGBicepValueLabel = uilabel(app.InfoPanel_2);
            app.EMGBicepValueLabel.Text = 'EMG Bicep Value:';
            app.EMGTricepValueLabel = uilabel(app.InfoPanel_2);
            app.EMGTricepValueLabel.Text = 'EMG Tricep Value:';
            app.EMGSensorThresholdsPanel = uipanel(app.EMGControlTab);
            app.EMGSensorThresholdsPanel.Title = 'EMG Sensor Thresholds';
            app.EMGThresholdEditField = uieditfield(app.EMGSensorThresholdsPanel, 'numeric');
            app.EMGThresholdEditField.Value = 350;
            app.BicepThresholdSliderLabel = uilabel(app.EMGSensorThresholdsPanel);
            app.BicepThresholdSliderLabel.Text = 'Bicep Threshold';
            app.BicepThresholdSlider = uislider(app.EMGSensorThresholdsPanel);
            app.BicepThresholdSlider.Limits = [0 1000];
            app.BicepThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @BicepThresholdSliderValueChanging, true);
            app.BicepThresholdSlider.Value = 350;
            app.EMGThresholdEditField_2 = uieditfield(app.EMGSensorThresholdsPanel, 'numeric');
            app.EMGThresholdEditField_2.Value = 350;
            app.TricepThresholdSliderLabel = uilabel(app.EMGSensorThresholdsPanel);
            app.TricepThresholdSliderLabel.Text = 'Tricep Threshold';
            app.TricepThresholdSlider = uislider(app.EMGSensorThresholdsPanel);
            app.TricepThresholdSlider.Limits = [0 1000];
            app.TricepThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @TricepThresholdSliderValueChanging, true);
            app.TricepThresholdSlider.Value = 350;
            app.BicepLabel = uilabel(app.EMGControlTab);
            app.BicepLabel.Text = 'Bicep ';
            app.BicepDropDown = uidropdown(app.EMGControlTab);
            app.BicepDropDown.Items = {'EMG0', 'EMG1'};
            app.BicepDropDown.ValueChangedFcn = createCallbackFcn(app, @BicepDropDownValueChanged, true);
            app.BicepDropDown.Value = 'EMG0';
            app.TricepDropDownLabel = uilabel(app.EMGControlTab);
            app.TricepDropDownLabel.Text = 'Tricep';
            app.TricepDropDown = uidropdown(app.EMGControlTab);
            app.TricepDropDown.Items = {'EMG0', 'EMG1'};
            app.TricepDropDown.ValueChangedFcn = createCallbackFcn(app, @TricepDropDownValueChanged, true);
            app.TricepDropDown.Value = 'EMG1';
            app.BatteryStatusPanel = uipanel(app.EMGControlTab);
            app.BatteryStatusPanel.Title = 'Battery Status';
            app.BatteryLabel = uilabel(app.BatteryStatusPanel);
            app.BatteryLabel.Text = 'Battery:';
            app.Lamp = uilamp(app.BatteryStatusPanel);
            app.ExoskeletonConnectionPanel_2 = uipanel(app.EMGControlTab);
            app.ExoskeletonConnectionPanel_2.Title = 'Exoskeleton Connection';
            app.Switch_2 = uiswitch(app.ExoskeletonConnectionPanel_2, 'rocker');
            app.Switch_2.Items = {'Stop', 'Start'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Stop';
            app.ResetValuesButton = uibutton(app.ExoskeletonConnectionPanel_2, 'push');
            app.ResetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetValuesButtonPushed, true);
            app.ResetValuesButton.Text = 'Reset Values';
            app.StatusLampLabel = uilabel(app.ExoskeletonConnectionPanel_2);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ExoskeletonConnectionPanel_2);
            app.SerialPortCOMDropDown_2Label = uilabel(app.ExoskeletonConnectionPanel_2);
            app.SerialPortCOMDropDown_2Label.Text = 'Serial Port= COM ';
            app.SerialPortCOMDropDown_2 = uidropdown(app.ExoskeletonConnectionPanel_2);
            app.SerialPortCOMDropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.SerialPortCOMDropDown_2.Editable = 'on';
            app.SerialPortCOMDropDown_2.Value = '1';
            app.ForceControlTab = uitab(app.TabGroup);
            app.ForceControlTab.Title = 'Force Control';
            app.ForceControlTab.ButtonDownFcn = createCallbackFcn(app, @ForceControlTabButtonDown, true);
            app.InfoPanel = uipanel(app.ForceControlTab);
            app.InfoPanel.Title = 'Info';
            app.LowerThresholdLabel = uilabel(app.InfoPanel);
            app.LowerThresholdLabel.Text = 'Lower Threshold:';
            app.UpperThresholdLabel = uilabel(app.InfoPanel);
            app.UpperThresholdLabel.Text = 'Upper Threshold:';
            app.ForceSensorvalueLabel = uilabel(app.InfoPanel);
            app.ForceSensorvalueLabel.Text = 'Force Sensor value: ';
            app.ForceSensorOffsetEditFieldLabel = uilabel(app.InfoPanel);
            app.ForceSensorOffsetEditFieldLabel.Text = 'Force Sensor Offset';
            app.ForceSensorOffsetEditField = uieditfield(app.InfoPanel, 'numeric');
            app.ForceSensorOffsetEditField.Value = 260;
            app.BatteryStatusPanel_2 = uipanel(app.InfoPanel);
            app.BatteryStatusPanel_2.Title = 'Battery Status';
            app.BatteryLabel_2 = uilabel(app.BatteryStatusPanel_2);
            app.BatteryLabel_2.Text = 'Battery:';
            app.Lamp_2 = uilamp(app.BatteryStatusPanel_2);
            app.ForceSensorThresholdsPanel = uipanel(app.ForceControlTab);
            app.ForceSensorThresholdsPanel.Title = 'Force Sensor Thresholds';
            app.UpperThresholdSlider_2Label = uilabel(app.ForceSensorThresholdsPanel);
            app.UpperThresholdSlider_2Label.Text = 'Upper Threshold';
            app.UpperThresholdSlider = uislider(app.ForceSensorThresholdsPanel);
            app.UpperThresholdSlider.Limits = [0 200];
            app.UpperThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @UpperThresholdSliderValueChanged, true);
            app.UpperThresholdSlider.Value = 20;
            app.LowerThresholdSliderLabel = uilabel(app.ForceSensorThresholdsPanel);
            app.LowerThresholdSliderLabel.Text = 'Lower Threshold';
            app.LowerThresholdSlider = uislider(app.ForceSensorThresholdsPanel);
            app.LowerThresholdSlider.Limits = [-200 0];
            app.LowerThresholdSlider.Value = -20;
            app.ExoskeletonConnectionPanel = uipanel(app.ForceControlTab);
            app.ExoskeletonConnectionPanel.Title = 'Exoskeleton Connection';
            app.Switch_3 = uiswitch(app.ExoskeletonConnectionPanel, 'rocker');
            app.Switch_3.Items = {'Stop', 'Start'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'Stop';
            app.ResetValuesButton_2 = uibutton(app.ExoskeletonConnectionPanel, 'push');
            app.ResetValuesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetValuesButton_2Pushed, true);
            app.ResetValuesButton_2.Text = 'Reset Values';
            app.StatusLamp_2Label = uilabel(app.ExoskeletonConnectionPanel);
            app.StatusLamp_2Label.Text = 'Status';
            app.StatusLamp_2 = uilamp(app.ExoskeletonConnectionPanel);
            app.SerialPortCOMDropDownLabel = uilabel(app.ExoskeletonConnectionPanel);
            app.SerialPortCOMDropDownLabel.Text = 'Serial Port= COM ';
            app.SerialPortCOMDropDown = uidropdown(app.ExoskeletonConnectionPanel);
            app.SerialPortCOMDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.SerialPortCOMDropDown.Editable = 'on';
            app.SerialPortCOMDropDown.Value = '1';
            app.TabGroup2 = uitabgroup(app.JustExoPanelUIFigure);
            app.EMGTab_2 = uitab(app.TabGroup2);
            app.EMGTab_2.Title = 'EMG';
            app.UIAxes2 = uiaxes(app.EMGTab_2);
            title(app.UIAxes2, 'EMG Bicep ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.EMGTab_2);
            title(app.UIAxes3, 'EMG Tricep')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.ForceSensorTab_2 = uitab(app.TabGroup2);
            app.ForceSensorTab_2.Title = 'Force Sensor';
            app.UIAxes = uiaxes(app.ForceSensorTab_2);
            title(app.UIAxes, 'Force Sensor')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.JustExoPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExoEduPro_Demo_V2_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.JustExoPanelUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.JustExoPanelUIFigure)
        end
    end
end