classdef BT_Calibration_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        JUSTEXOUIFigure                 matlab.ui.Figure
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        EmergencyManager                matlab.ui.container.Panel
        EmergnecySTOPButton             matlab.ui.control.StateButton
        ConnectionManager               matlab.ui.container.Panel
        BTStatusImage                   matlab.ui.control.Image
        ConnectButton                   matlab.ui.control.Button
        BTDeviceDropDown                matlab.ui.control.DropDown
        BTDeviceDropDownLabel           matlab.ui.control.Label
        StatusManager                   matlab.ui.container.Panel
        StatusLamp                      matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        BatteryManager                  matlab.ui.container.Panel
        BatteryImage                    matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        EMGCalibrationTab               matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        OneChannelTab                   matlab.ui.container.Tab
        EMGControlParameters_2          matlab.ui.container.Panel
        MVCRepSpinner                   matlab.ui.control.Spinner
        RepSpinnerLabel                 matlab.ui.control.Label
        EMGLowerThreshold               matlab.ui.control.NumericEditField
        EMGUpperThreshold               matlab.ui.control.NumericEditField
        SpeedSetting_EMG                matlab.ui.control.DiscreteKnob
        ServoMotorSpeedLabel            matlab.ui.control.Label
        ApplyThresholdsButton           matlab.ui.control.StateButton
        EMGLowerThresholdSlider         matlab.ui.control.Slider
        LowerLabel                      matlab.ui.control.Label
        EMGUpperThresholdSlider         matlab.ui.control.Slider
        UpperLabel                      matlab.ui.control.Label
        EMGShowThresholdCheckBox        matlab.ui.control.CheckBox
        ModeSwitch_2                    matlab.ui.control.RockerSwitch
        ModeSwitch_2Label               matlab.ui.control.Label
        AppliedFilterButtonGroup_EMG    matlab.ui.container.ButtonGroup
        EMG_EWMVA                       matlab.ui.control.RadioButton
        EMG_CenteredMovingAverageButton  matlab.ui.control.RadioButton
        EMG_LeftSidedMovingAverageButton  matlab.ui.control.RadioButton
        EMGChannelLabel_2               matlab.ui.control.Label
        ThresholdmVLabel                matlab.ui.control.Label
        StartRecordingSwitch_DualThresh_EMG  matlab.ui.control.RockerSwitch
        StartSwitchLabel_4              matlab.ui.control.Label
        CHDropDown                      matlab.ui.control.DropDown
        ElectromyographyControlGraphs_2  matlab.ui.container.Panel
        MVC_Calibration                 matlab.ui.control.UIAxes
        ForceControlTab                 matlab.ui.container.Tab
        ForceParameters                 matlab.ui.container.Panel
        ShowThresholdCheckBox           matlab.ui.control.CheckBox
        SpeedSetting_Force              matlab.ui.control.DiscreteKnob
        AngleStepSizeKnob_2Label        matlab.ui.control.Label
        ApplyThresholdsButton_2         matlab.ui.control.StateButton
        AppliedFilterButtonGroup_Force  matlab.ui.container.ButtonGroup
        EWMVAButton_2                   matlab.ui.control.RadioButton
        CenteredMovingAverageButton_2   matlab.ui.control.RadioButton
        LeftSidedMovingAverageButton_2  matlab.ui.control.RadioButton
        StartRecordingSwitch_Force      matlab.ui.control.RockerSwitch
        StartSwitchLabel_2              matlab.ui.control.Label
        LowerThresholdSlider            matlab.ui.control.Slider
        LowerThresholdSliderLabel       matlab.ui.control.Label
        UpperThresholdSlider            matlab.ui.control.Slider
        UpperThresholdSliderLabel       matlab.ui.control.Label
        ForceControlGraph               matlab.ui.container.Panel
        ForceReading                    matlab.ui.control.UIAxes
    end

    properties (Access = public)
        % initialize datalines for live plotting
        dL_EMG_CH1_raw 
        dL_EMG_CH1_filt
        dL_EMG_CH1_Threshold
        dL_EMG_CH2_raw 
        dL_EMG_CH2_filt
        dL_EMG_CH2_Threshold
        dL_Force_raw 
        dL_Force_filt
        dL_Force_Upper_Threshold
        dL_Force_Lower_Threshold
        dL_ServoAgle_raw 
        dL_ServoAngle_reference_tracking
        dl_EMG_Calibrate
        dl_Force_Calibrate
        dl_Servo_Calibrate
        Offset_Force_filt
        zero_force_offset
        PLOT_degree;
        % Flags and variables that are accesible for all functions
        connectFlag = false;
        pairedFlag = false;
        selectFlag = false;
        chosen_option;
        chosen_option_indx;
        
        pair_status = "";
        avail_status = "";
        connected_device;
        con_options_friendlyName = "";
        con_options_COMPorts = "";
        battery_voltage = 0;
        charge_status = 0;
        batteryFlag = 0;
        servoState = false;
        Servo_Speed = 1;
        ForceisPlotting = false;
        EMGisPlotting = false;
        IMUisPlotting = false;
        PI_design_flag = false;
        PI_control_flag = true;
        PID_design_flag = false;
        PID_control_flag = false;
        P_controller_flag = false;
        PI_controller_flag = false;
        PID_controller_flag = false;
        Compare_changes_flag = false;
        upper_threshold;
        lower_threshold;
        upperHandle = [];
        lowerHandle = [];
        hForce = [];
        h2Force = [];
        EMGHandleCH1 = [];
        EMGHandleCH2 = [];
        emg1_h = [];
        emg1_h2 = [];
        EMGlowerHandle = [];
        EMGupperHandle = [];
        EMG_Upper_Threshold;
        EMG_Lower_Threshold;
        angleStep = 100;

        %IMU stuff
        posePlot_Shoulder
        posePlot_Elbow % pose plot element
        upper_arm % stl
        lower_arm % stl
        torso % stl
        head % stl
        upper_arm_posepatch % trisurf
        lower_arm_posepatch % trisurf
        fuse
        rec_data % pre-rec data
        % axes_IMU % axes elemenmt

        % RGB Colors for LED
        color_green = 0x03;
        color_white = 0x01;
        color_orange = 0x02;
        color_blue = 0x04;
        color_yellow = 0x06;
        color_off = 0x00;
        color_more_green = 0x07
        color_red = 0x05;
        
        Stable_Factor;
        Danger;

        angle_feedback;
        angle_END;
        pathtest = "";

        % defines for communication protocol
        startMarker = 0x3C;
        endMarker = 0x3E;
        header = 0xFF; %binary: 1111 1111, dec: 255
        force_header = 0x8F;
        max_data_length = 200;
        data = [];
        
        message2receive;
        message2send;
        checksum;
        opcode;

        MaxPoints = 50;  % Maximum number of points to display


        %% EMG Data Simulation
        MVCValues = [NaN];        % Array to store measured MVC values
        AcquisitionTime = 15;  % Total acquisition time in seconds
        MiddleDuration = 5;    % Duration for the middle seconds
        SamplingRate = 66;   % sEMG sampling rate in Hz
        sEMGData  =[];

        ForceValues = [NaN];
        AngleValues = [NaN];
    end
    

    methods (Access = private)
    %% Find BT Devices and matching COM Ports in the Windows registry
        function BT_devices = findBT_devices_and_COMPorts(app)
            % FINDBT_DEVICES_AND_COMPORTS Finds Bluetooth devices and their associated COM ports.
            % This function queries the Windows registry to find Bluetooth devices and their COM
            % ports, then matches the devices to a predefined list of device names.

            % Command to query the registry for Bluetooth devices and friendly names
            cmd = 'REG QUERY HKEY_LOCAL_MACHINE\SYSTEM\CurrentControlSet\Enum\ /s /f "FriendlyName" /t "REG_SZ"';
            
            % Execute the command and capture the output in largeRegistryString
            [~, largeRegistryString] = system(cmd);
            
            % Split the large registry string into individual registry entries using '\n\n' as a delimiter
            registryStrings = strsplit(largeRegistryString, '\n\n');
            % Predefined list of Bluetooth device names to search for
            string_list = {'ESP32-BT-Slave', '01_CLASSROOM_EDU_EXO_PRO', '02_CLASSROOM_EDU_EXO_PRO', ...
                           '03_CLASSROOM_EDU_EXO_PRO', '04_CLASSROOM_EDU_EXO_PRO', '05_CLASSROOM_EDU_EXO_PRO', ...
                           '06_CLASSROOM_EDU_EXO_PRO', '07_CLASSROOM_EDU_EXO_PRO', '08_CLASSROOM_EDU_EXO_PRO'};
            j=1;
            for i=1:length(string_list)
                % find the index of the friendly Name in string_list(i) in the registry string 
                friendlyName_indices = find(contains(registryStrings, string_list(i)));
            
                % Extract the Device Number from the registry string
                device_number_pattern = 'Dev_\w+';
                Dev_Number = regexp(registryStrings(friendlyName_indices), device_number_pattern, 'match', 'once');
                Dev_Number = strrep(Dev_Number, 'Dev_', '');  % or use erase function
            
                % find the index of the COM port using the Device Number
                COM_indices = find(contains(registryStrings, Dev_Number));
                % remove duplicate indices 
                COM_indices = COM_indices(~ismember(COM_indices,friendlyName_indices));
            
                % Extract Com Port Number from registry string
                com_port_pattern = '\(COM\d+\)';
                comPort = regexp(registryStrings(COM_indices), com_port_pattern, 'match', 'once');
                comPort = erase(comPort, {'(',')'});
            
        
                % Match Friendly Name to Comport in a struct
                if(~isempty(comPort))
                    BT_devices.COMport(j) = comPort;
                    BT_devices.friendlyName(j) = string_list(i);
                    j =j+1;
                end
            end

        
        end


    %% Check Battery voltage   
        function CheckBattery(app, mode)
            app.pathtest = fileparts(mfilename('fullpath'));
            %%%%%% get the Battery Voltage measurment %%%%%%
            %configurePin(connected_device,'A6','AnalogInput');
            %app.Battery_Voltage = readVoltage(connected_device,'A6'); %%
            %richtig schreiben!!!!
            %app.battery_voltage = round(Battery_Voltage,2)*3;
            [app.batteryFlag, app.battery_voltage] = getBatteryVoltage(app);
            %app.battery_voltage = 7640;
    
            if app.battery_voltage >=7640
                app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos','fa-battery-4.png');
                if mode == 2
                    app.StatusLamp.Color = [0,255,0]./255;  %green
                    app.Label.Text = 'connected';
                end
                app.charge_status=1;
            elseif app.battery_voltage>=7230 && app.battery_voltage<7640
                app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos','fa-battery-3.png');
                %app.Lamp_2.Color = [0.92 0.52 0.03];
                if mode == 2
                    app.StatusLamp.Color = [179,255,0]./255;  %green-yellowish
                    app.Label.Text = 'connected';
                end
                app.charge_status=1;
            elseif app.battery_voltage>=6820 && app.battery_voltage<7230
                app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos','fa-battery-2.png');
                %app.Lamp_2.Color = [0.92 0.52 0.03];
                if mode == 2
                    app.StatusLamp.Color = [255,130,0]./255; %orange
                    app.Label.Text = 'connected';
                end
                app.charge_status=1;
            elseif app.battery_voltage>=5000 && app.battery_voltage<6820
                app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos','fa-battery-1.png');
                %app.Lamp_2.Color = [0.92 0.52 0.03];
                if mode == 2
                    app.StatusLamp.Color = [255,47,0]./255; %red
                    app.Label.Text = 'connected';
                end
                app.charge_status=1;
            else
                app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos','fa-battery-0.png');
                %app.Lamp_2.Color = "red";
                if mode == 2
                    app.StatusLamp.Color = [255,0,0]./255;
                    app.Label.Text = 'connected';
                end
                app.charge_status=0;
                app.battery_voltage = 5000;
                report = ["Opps, seems like the battery is empty."; "Please recharge using the provided charger!"];
                uialert(app.JUSTEXOUIFigure, report, "BATTERY EMPTY", "ICON", "error")
            end
    
        end
    
        function csum = create_checksum(app)
            %checksum = double(message_header) + double(opcode) + double(snddataLength) + double(sum(snddata));
            csum = double(app.message2send.header) + double(app.message2send.opcode) + double(app.message2send.dataLength); %+ double(sum(snddata));
    
        end
    
        % not done yeat...
        function cbool = check_checksum(app)
                % checksum = mod(sum([msg2receive.header, msg2receive.opcode, typecast(uint16(msg2receive.dataLength),'uint8'), msg2receive.data]), 256);
                calc_checksum = mod(sum([app.msg2receive.header, app.msg2receive.opcode, typecast(uint16(app.msg2receive.dataLength),'uint8')]), 256);
                if calc_checksum == csum
                    cbool = true;
                else
                    cbool = false;
                end
            end
    
    %% send Command/Data Package to Arduino via Serial Port Connection 
        function sendCommand(app, opcode, dataLength, data)
            if(dataLength>app.max_data_length)
                error('message too long!')
                return
            end
        
            % create checksum
            checksum = double(app.header) + double(opcode) + double(dataLength);
        
            %create structure of message
            message2send = struct('startMarker', uint8(app.startMarker), 'header',uint8(app.header), 'opcode', uint8(opcode), 'dataLength', uint8(dataLength),'data',uint8(data),'checksum',mod(checksum,256), 'endMarker', uint8(app.endMarker));
            % transmit!
            flush(app.connected_device)
            if message2send.dataLength >0
                write(app.connected_device,[message2send.startMarker message2send.header message2send.opcode message2send.dataLength message2send.data message2send.checksum message2send.endMarker],'uint8');
            
            else
                write(app.connected_device,[message2send.startMarker message2send.header message2send.opcode message2send.dataLength message2send.checksum message2send.endMarker],'uint8');
             
            end
        end
    
    %% receive Answer/Data package from Arduino via Serial Port Connection
        function [reply data message_type] = receiveReply(app)
            % create structure of message
            message2receive = struct('header',0,'opcode',0,'dataLength',0,'data',0,'checksum',0);
            % receive!
            try
                temp = read(app.connected_device,1, 'uint8');
                % tic
                while((temp ~= app.startMarker))
                    temp = read(app.connected_device,1, 'uint8');
                end
                % disp('receiving package');
                % toc
                % tic
                message2receive.header = read(app.connected_device,1, 'uint8');
                % toc
                % tic
                message2receive.opcode = read(app.connected_device,1,'uint8');
                % toc
        
                switch message2receive.opcode
                    case 0x7F
                        % Servo Streaming Message
                        message2receive.dataLength = read(app.connected_device,1,'uint8');
                        % message2receive.dataLength = typecast(flip(tmp),'uint16');
                        message_type = 'Servo';
                        reply = message2receive.opcode;
                    case 0x8F
                        % Force Streaming Message
                        message2receive.dataLength = read(app.connected_device,1,'uint8');
                        % message2receive.dataLength = typecast(flip(tmp),'uint16');
                        message_type = 'Force';
                        reply = message2receive.opcode;
                    case 0x9F
                        % EMG Steaming Message
                        message2receive.dataLength = read(app.connected_device,1,'uint8');
                        % message2receive.dataLength = typecast(flip(tmp),'uint16');
                        message_type = 'EMG';
                        reply = message2receive.opcode;
                    case 0x6F
                        % IMU Steaming Message
                        message2receive.dataLength = read(app.connected_device,1,'uint8');
                        % message2receive.dataLength = typecast(flip(tmp),'uint16');
                        message_type = 'IMU';
                        reply = message2receive.opcode;
                    otherwise
                        % CMD message
                        message2receive.dataLength = read(app.connected_device, 1, 'uint8');
                        message_type = 'CMD';
                        reply = message2receive.opcode;
                end
                % tic
                if message2receive.dataLength > 0
                   message2receive.data = read(app.connected_device,message2receive.dataLength,'uint8');
                % for i= 1:message2receive.dataLength
                %    message2receive.data(i) = read(app.connected_device, 1, 'uint8');
                % end
                end
                % toc
                % tic
                message2receive.checksum = read(app.connected_device,1,'uint8');
                % check message for integrity
                checksum = mod(sum([message2receive.header, message2receive.opcode, typecast(uint16(message2receive.dataLength),'uint8')]), 256);
                % toc
                if message2receive.checksum == checksum
                    reply = message2receive.opcode;
                    data = uint8(message2receive.data);
                    return
                else
                    reply = -1;
                    data = [];
                    %error('wrong checksum of message!')
                
                end
                
            catch e
                if exist('self.connected_device','var')
                   delete(app.connected_device);
                end
        
                
                 error(e.message)
                return
            end
        end
    
    %% Test connection
    %------------------------------------------------------------------
        function flag = testConnection(app)
            flag = false;
            % send command!
            app.sendCommand(0x01, 0, 0);
            % receive answer
            [reply, ~,msg_type] = app.receiveReply();
            if reply == 0x50
                flag = true;
            end
            return
        end
    
    %% Stop connection
        function flag = stopConnection(app)
            flag = false;
            % send command!
            app.sendCommand(0x15, 0, 0);
            % receive answer
            [reply, ~,msg_type] = app.receiveReply();
            if reply == 0x50
                flag = true;
            end
            return
        end
    
    
    %% Get battery voltage
    %------------------------------------------------------------------
        function [flag, voltage] = getBatteryVoltage(app)
            flag = false;
            app.sendCommand(0x03, 0, 0);
            [reply data msg_type] = app.receiveReply();
            if reply == 0xBB
                flag = true;
                voltage = uint16(data(1)) * 256 + uint16(data(2));
                disp(['Battery voltage: ', num2str(voltage,2) ' mV']);
            end
            return
        end
    
    %% Set LED Color
    %-----------------------------------------------------------------
        function [flag, led_state] = RGB_Colour(app, color)
            flag = false;
            app.sendCommand(0x02, 1, color);
            [reply data msg_type] = app.receiveReply();
            if reply == 0xBB
                flag = true;
                if(data == 0x51)
                    led_state = true;
                end
            end
            return
        end
    
    %% Activate Servo Motor
    %------------------------------------------------------------------
        function [flag servo_state] = setServoState(app, set_servo_state)
            flag = false;
            flush(app.connected_device);
            app.sendCommand(0x04, 1, set_servo_state);
            [reply data msg_type] = app.receiveReply();
            if reply == 0xBB
                flag = true;
                if(data == 0x52)
                    servo_state = true;
                elseif(data == 0x53)
                    servo_state = false;
                end
            end
            return
        end
    
    %% Get one emg value 
    %------------------------------------------------------------------
        function raw_data = getOneEMG(app, numsamples)
            %pause(0.2);
            % start sampling
            % startAcquisition(app);
            % tic;
            % flush(app.connected_device);
            samples_counter = 0;
            raw_data = zeros(1,5);
            while samples_counter < numsamples
                [~, new_data, ~] = app.receiveReply();
                if ((isempty(new_data)) | (new_data == 0xBB) | (length(new_data)<2))
                    raw_data = uint16(10);
                end
                raw_data = uint16(new_data(1)) * 256 + uint16(new_data(2));
                %raw_data = typecast(flip(uint8(new_data)), 'uint16');
                %raw_data = [raw_data new_data];
                
                samples_counter = samples_counter + 1;
            end
            % toc;
            % stop acquisition
            % stopAcquisition(app);
            return
        
        end
    
    %% Get one emg, imu and angle set 
    %------------------------------------------------------------------
        function [emg_data, imu_data, angle] = getOneEMG_IMU_Angle(app, numsamples)
                %pause(0.2);
                % start sampling
                % startAcquisition(app);
                % tic;
                % flush(app.connected_device);
                samples_counter = 0;
                raw_data = zeros(1,5);
                while samples_counter < numsamples
                    [~, new_data, ~] = app.receiveReply();
                    if ((isempty(new_data)) | (new_data == 0xBB) | (length(new_data)<2))
                        emg_data = uint16(10);
                    else
                        if samples_counter == 0
                            emg_data = typecast(flip(new_data(1:2)), 'uint16');
                            imu_data(1) = typecast(flip(new_data(3:6)), 'single');
                            imu_data(2) = typecast(flip(new_data(7:10)), 'single');
                            imu_data(3) = typecast(flip(new_data(11:14)), 'single');
                        else
                            imu_data(4) = typecast(flip(new_data(1:4)), 'single');
                            imu_data(5) = typecast(flip(new_data(5:8)), 'single');
                            imu_data(6) = typecast(flip(new_data(9:12)), 'single');
                            angle = typecast(flip(new_data(13:14)), 'uint16');
                        end
                    end
                    samples_counter = samples_counter + 1;
                   
                end
                % toc;
                % stop acquisition
                % stopAcquisition(app);
                return
            
            end
        

    %% Get current servo position 
    %------------------------------------------------------------------
        function [flag voltage] = getServoAngle(app)
            flag = false;
            app.sendCommand(0x06, 0, 0);
            [reply data msg_type] = app.receiveReply();
            if reply == 0xBB
                flag = true;
                voltage = uint16(data(1)) * 256 + uint16(data(2));
            %     Analog_degree = (180/(266-738)).*(voltage-738);
            %     servo_feedback = 180-Analog_degree;
            %     disp(['Servo: ', num2str(servo_feedback,2) ' °']);
            end
            return
        end

        
    %% Set PID package counter
        function [flag] = setPIDpackagecounter(app, uint8_packageCounter)
            flag = false;
            % uint8_packageCounter = typecast(packageCounter, 'uint8'); 
            app.sendCommand(0x16, 1, uint8_packageCounter);
            [reply data msg_type] = app.receiveReply();
            if reply == 0xBB
                flag = true;
            end
        end
    %% Move Servo alonng PID curve
    %------------------------------------------------------------------
        function [flag] = moveServoPID(app, total_length, PID_angles)
            flag = false;
            app.sendCommand(0x12, total_length, PID_angles);
            [reply data msg_type] = app.receiveReply();
            if reply == 0xBB
                flag = true;
                %voltage = uint16(data(1)) * 256 + uint16(data(2));
                %Analog_degree = (180/(266-738)).*(voltage-738);
                %servo_feedback = 180-Analog_degree;
                %disp(['Servo: ', num2str(servo_feedback,2) ' °']);
            elseif reply == 0x84
                flag = false;
            end
            return
        end

    %% Move Servo to zero Position
    %------------------------------------------------------------------
        function [flag zeroPosition] = resetServoPosition(app, new_ref_degree, speed, stepsize)
            flag = false;
            uint8_new_ref_degree = typecast(new_ref_degree, 'uint8'); 
            settings = [uint8_new_ref_degree speed stepsize];
            app.sendCommand(0x13, 4, settings);
            [reply data msg_type] = app.receiveReply();
            if reply == 0xBB
                flag = true;
            end
        end
    
    %% Set Servo Speed
    %------------------------------------------------------------------
        function [flag] = setServoSpeed(app, speed)
            flag = false;
            app.sendCommand(0x11, 1, speed);
            [reply data msg_type] = app.receiveReply();
            if reply == 0xBB
                flag = true;
            end
        end
    
    
    %% get getServoAngleStream
    %------------------------------------------------------------------
        function [flag voltage] = getServoAngleStream(app)
            flag = false;
            
            [reply data msg_type] = app.receiveReply();
            if reply == 0x7F
                flag = true;
                voltage = uint16(data(1)) * 256 + uint16(data(2));
            %     Analog_degree = (180/(266-738)).*(voltage-738);
            %     servo_feedback = 180-Analog_degree;
            %     disp(['Servo: ', num2str(servo_feedback,2) ' °']);
            elseif reply ~= 0x7F
                [reply data msg_type] = app.receiveReply();
                voltage = uint16(data(1)) * 256 + uint16(data(2));
            end
            return
        end
    
    %% Set Threshold Values
    %------------------------------------------------------------------
        function flag = setThresholds(app, mode, thresh1, thresh2, speed, stepSize)
            flag = false;
            if ((mode == 0x41) | (mode == 0x42))
                uint16_thresh1 = typecast(thresh1, 'uint8');
                uint16_thresh2 = typecast(thresh2, 'uint8');
                uint16_stepStize = typecast(stepSize, 'uint8');
                uint8_speed = typecast(speed, 'uint8');
                data = [mode uint16_thresh1(1) uint16_thresh1(2)  uint16_thresh2(1) uint16_thresh2(2) speed stepSize];
                app.sendCommand(0x20, 7, data);
            elseif (mode == 0x40)
                uint16_thresh1 = typecast(thresh1, 'uint8');
                uint16_stepStize = typecast(stepSize, 'uint8');
                uint8_speed = typecast(speed, 'uint8');
                data = [mode uint16_thresh1(1) uint16_thresh1(2) speed stepSize];
                app.sendCommand(0x20, 5, data);
            end
            [reply ~] = app.receiveReply();
            if reply == 0xBB
                flag = true;
            end
            return
        end

    %------------------------------
    % set EMG Threshold in GUI
        function AdjustEMGThresholdsGUI(app)
            EMG_thresh_upper = app.EMGUpperThresholdSlider.Value;
            EMG_thresh_lower = app.EMGLowerThresholdSlider.Value;
            
            
            if EMG_thresh_lower >= EMG_thresh_upper && (EMG_thresh_upper >=1)
                EMG_thresh_lower = EMG_thresh_upper-1;
            elseif (EMG_thresh_lower >= EMG_thresh_upper) && (EMG_thresh_upper == 0)
                EMG_thresh_lower = 0;
                EMG_thresh_upper = 1;
            end
            if EMG_thresh_lower < EMG_thresh_upper
                app.EMGUpperThresholdSlider.Value = EMG_thresh_upper;
                app.EMGLowerThresholdSlider.Value = EMG_thresh_lower;
                app.EMGUpperThreshold.Value = EMG_thresh_upper;
                app.EMGLowerThreshold.Value = EMG_thresh_lower;
            end
        end

    % set Force Threshold in GUI


    % set Servo Thresholds in GUI
    function AdjustServoLimitsGUI(app)
        value_low = app.LowRefDegreeEditField.Value;
        value_high = app.HighRefDegreeEditField.Value;
        
        if value_low >= value_high && (value_high >=1)
            value_low = value_high-1;
        elseif (value_low >= value_high) && (value_high == 0)
            value_low = 0;
            value_high = 1;
        end
        if value_low < value_high
            app.ServoAngleLimitsSlider.Value(1) = value_low; 
            app.ServoAngleLimitsSlider.Value(2) = value_high;
            app.LowRefDegreeEditField.Value = value_low;
            app.HighRefDegreeEditField.Value = value_high;
        end
    end

    
    %% Set Analog Pin on Arduino
        function flag = setAnalogPin(app, mode, ChP);
            flag = false;
            if (mode == 0x42)
                uint8_ChP = typecast(ChP, 'uint8');
                data = [mode uint8_ChP(8)];
                app.sendCommand(0x24, 2, data);
                [reply ~] = app.receiveReply();
            end
            if reply == 0xBB
                flag = true;
            end
            return
        end
    
    %% Trigger Emergency Shutoff /restart of Arduino Board
    %------------------------------------------------------------------
        function emergencyStop(app)
    
            flag = false;
            app.sendCommand(0x23, 0, 0);
            [reply data msg_type] = app.receiveReply();
            if reply == 0xBB
                flag = true;
                voltage = uint16(data(1)) * 256 + uint16(data(2));
                disp(['Battery voltage: ', num2str(voltage,2) ' mV']);
            end
            
            return
        end
    
    
    %% Start acquisition
    %------------------------------------------------------------------
        function flag = startAcquisition(app, cmd)
            flag = false;
            flush(app.connected_device, "input");
            app.sendCommand(cmd, 0, 0);
            [reply, ~ ,msg_type] = app.receiveReply();
            if reply == 0xBB
                flag = true;
            end
            return
        end
    
    %% Stop acquisition
    %------------------------------------------------------------------
        function flag = stopAcquisition(app)
            flag = false;
            app.sendCommand(0x21, 0, 0);
            [reply, ~ ,msg_type] = app.receiveReply();
            if reply == 0xAA
                flag = true;
            end
            return
        end
    
    %% Get raw EMG enveloope 
    %-------------------------------------------------------------------
        function [raw_data_CH1 raw_data_CH2] = getEMG_raw(app, numsamples)     
            %pause(0.2);
            % start sampling
            % startAcquisition(app);
            % tic;
            % flush(app.connected_device);
            samples_counter = 0;
            new_data = [];
            while samples_counter < numsamples
                [~, new_data, ~] = app.receiveReply();
                if ((isempty(new_data)) | (new_data == 0xBB))
                    raw_data_CH1 = uint16(340);
                    raw_data_CH2 = uint16(340);
                end
                raw_data_CH1 = uint16(new_data(1)) * 256 + uint16(new_data(2));
                raw_data_CH2 = uint16(new_data(3)) * 256 + uint16(new_data(4));
                % app.dL_EMG_CH1_raw.XData = [app.dL_EMG_CH1_raw.XData samples_counter+1];
                % app.dL_EMG_CH1_raw.YData = [app.dL_EMG_CH1_raw.YData raw_data(1)];
                samples_counter = samples_counter + 1;
            end
            % toc;
            % stop acquisition
            % stopAcquisition(app);
            return
        end
    
    %% Get Force Values
    %-------------------------------------------------------------------
        function force_data = getForce_raw(app, numsamples)     
            %pause(0.2);
            % start sampling
            %startAcquisition(app);
            
            samples_counter = 0;
            new_data = [];
            % tic;
            while samples_counter < numsamples
                [~, new_data, ~] = app.receiveReply();
                if ((isempty(new_data)) | (new_data == 0xBB))
                    force_data = uint16(340);                
                else
                    force_data = uint16(new_data(1)) * 256 + uint16(new_data(2));
                    
                %force_data = [force_data new_data];
                
                end
                samples_counter = samples_counter +1;
            end
            % toc;
            
            % stop acquisition
            %stopAcquisition(app);
            return
        end
    
    
    %% Get IMU Values
    %-------------------------------------------------------------------
        function [imu_data, servo_angle] = getIMU_raw(app, numsamples)     
                %pause(0.2);
                % start sampling
                %startAcquisition(app);
                
                samples_counter = 0;
                new_data = zeros(1,12);
                % imu_data = zeros(1,6);
                % tic
                while samples_counter < numsamples
                    [~, new_data, ~] = app.receiveReply();
                    if ((isempty(new_data)) | (new_data == 0xBB))
                        imu_data = single(340);                
                    else
                        if samples_counter == 0
                            % check endianness!!
                            imu_data(1) = typecast(flip(new_data(1:4)), 'single');
                            imu_data(2) = typecast(flip(new_data(5:8)), 'single');
                            imu_data(3) = typecast(flip(new_data(9:12)), 'single');
                        else 
                            imu_data(4) = typecast(flip(new_data(1:4)), 'single');
                            imu_data(5) = typecast(flip(new_data(5:8)), 'single');
                            imu_data(6) = typecast(flip(new_data(9:12)), 'single');
                            servo_angle = typecast(flip(new_data(13:14)), 'uint16');
                        end


                    end
                    samples_counter = samples_counter+1;
                end
                % toc
                
                % stop acquisition
                %stopAcquisition(app);
                return
            end
        
    %% FOR DEBUG ONLY
    % Random EMG Values
    %----------------------------------------------------------------
        function sEMGValue = acquireData(app)
            % Simulate sEMG data acquisition
            
            % Replace this with your actual sEMG data acquisition code
            sEMGValue = 2+rand()*5;  % Simulated sEMG value
            app.sEMGData = [app.sEMGData, sEMGValue];                
            
        end
    
    %% Calculate MVC from Calibration
    %-----------------------------------------------------------------
        function calculateMVC(app)
                % Calculate MVC from the middle seconds of the acquired data
                middleStart = floor((app.AcquisitionTime - app.MiddleDuration) / 2) * app.SamplingRate + 1;
                middleEnd = middleStart + app.MiddleDuration * app.SamplingRate - 1;
    
                middleData = app.sEMGData(middleStart:middleEnd);
                maxMVC = max(middleData);
    
                % Store the measured MVC value
                app.MVCValues = [app.MVCValues, maxMVC];
        end
    
    %% Save data to CSV File
    %------------------------------------------------------------------
        function saveCSV(app, timestamp)
            % Save MVC, force, and angle values to a CSV file
            current_path = pwd;
            % Construct the dynamic filename with the current date
            temp_filename = 'CalibrationData' + timestamp + '.csv';
            filename = fullfile(current_path, temp_filename);
            fileheader = {'Timestamp', 'MVC Values', 'Force Values', 'Angles'};
                  
            %writetable(measurementTable, filename, 'WriteMode','replacefile');
            % Open the file for writing
            fileID = fopen(filename, 'w');
        
            % Write the header
            fprintf(fileID, '%s,%s,%s,%s\n', fileheader{:});
        
            % Write the data
            for i = 1:length(timestamp)
                fprintf(fileID, '%s,%.4f,%.4f,%.4f\n', timestamp(i), app.MVCValues(i), app.ForceValues(i), app.AngleValues(i));
            end
    
        
            % Close the file
            fclose(fileID);
        end
    
    %% Decode factorial G(s) Plant input
        function [roots, gain] = extractRoots(app, polyString)
        polyString = erase(polyString, {' ', '*'});
    
        % Expand terms with exponents
        [tk_exp, mt_exp] = regexp(polyString, '\^(\d+)', 'tokens', 'match') % find exponent
        if ~isempty(tk_exp)
            tk_brac = regexprep(polyString, ['\^(\d+)'], '') 
            polyString = regexprep(polyString, '\(s([+-]?(\d+(\.\d*)?|\.\d+))\)\^(\d+)', '')% remove exponent
            tk_brac = erase(tk_brac, polyString)
    
            polyString_exp = repmat(tk_brac, 1, str2double(strrep(tk_exp{1}, '^', '')))
        
            polyString = [polyString, polyString_exp]
        end
    
        % Regular expression to identify and extract the gain
        % gainPattern = '^\(?([+-]?\d+)\)?';
        gainPattern = '^\(?(\d+(\.\d*)?|\.\d+)\)?'
        % factorialPattern = '\(s([+-]\d+)\)';
        factorialPattern = 's([+-]?\d+(\.\d+)?([+-]\d+(\.\d+)?[ij])?)?';
        
        % Find and extract the gain
        gainMatch = regexp(polyString, gainPattern, 'tokens');
        if ~isempty(gainMatch)
            gain = str2double(gainMatch{1});
        else
            gain = 1; % Default gain if not specified
        end
        
        % Remove the gain from the input string
        polyString = regexprep(polyString, gainPattern, '');
        
        terms = regexp(polyString, factorialPattern , 'tokens');
    
        roots = cellfun(@(x) str2double(x{1}), terms);
        
        roots(isnan(roots)) = -1;
        
        roots = roots * -1;
    end 
    %% IMU Plotting functions
        function new_stl=transformStl(app,stl,p,quat)
            T=se3(quat2rotm(quat),p);
            points = stl.Points;
            t_points = transform(T,points);
            clist = stl.ConnectivityList;
            new_stl=triangulation(clist,t_points);
        end
    
        function tP=calcImuPos(app,quat,radius)
            norm_vect=[0 -1 0]'; %y column vector
            rotm = quat2rotm(quat); %get rot matrix from quaternion
            nVec = rotm*norm_vect; %get normal vector of xy-plane (Z axis)
            tP = radius*nVec;   
        end
    
        function [tP, rotm]=calcServoPos(app,theta)
            x = cos(theta);
    y = sin(theta);
    z = 0;
            %rotm = quat2rotm(quat); %get rot matrix from quaternion
            %z, y, x
            rotm = eul2rotm([theta, 0,0]);
            tP = [x; y; z];   
        end


        function plotPose(app,property, p,q)
            set(property,Position = p,Orientation = q);
        end


    %% Force Recording 
  
        function StartRecordingForce(app)
            counter = 1;
            while(app.ForceisPlotting)
                if(counter == 1)
                    
                    % Initialize Filter variables
                    windowSize = 5;
                    dataBuffer = zeros(1, windowSize);
                    currentIndex = 1;
                    i=1;
                    % start Acquissition
                    startAcquisition(app, 0x41);
                    a = tic; % reset timer after updating     
                end

                % if ((app.charge_status == 1) && (app.servoState))
 
                %% Read Force Data
                
                % read and convert new data to mN
                % 
                % calculate one factor for performance: 
                temp_newdata = typecast(getForce_raw(app,1), 'int16');
                newdata = ((temp_newdata)-379)*17;%mN
                
                if newdata > 0
                    newdata = newdata*0.5;
                else 
                    newdata = newdata*1.0;
                end
                
                if counter == 1
                    forceIsFiltered_Old = newdata;
                end
                
                % Calculate Moving Average filter
                if app.CenteredMovingAverageButton_2.Value 
                     newmovingAverage = 0.5*forceIsFiltered_Old + 0.5*newdata;
                     forceIsFiltered_Old = newmovingAverage;

                elseif app.LeftSidedMovingAverageButton_2.Value
                    newmovingAverage=0.8*forceIsFiltered_Old + 0.2*newdata; %+ 0.1*forceIs_Old;
                    forceIsFiltered_Old = newmovingAverage;
                    forceIs_Old = newdata;

                elseif app.EWMVA_2.Value
                    newmovingAverage=0.8*forceIsFiltered_Old + 0.1*newdata; + 0.1*forceIs_Old;
                    forceIsFiltered_Old = newmovingAverage;
                    forceIs_Old = newdata;

                end

                %% now in Arduino implemented
                % if (force_filtered >= upper_threshold) && (angle < 90)
                %    % angle =- 1;
                     % move down
                     %app.message2send.dataLength = 1;
                     %app.message2send.data = angle;
                % 
                % elseif (force_filtered < lower_threshold) && (angle > 10)
                %     %angle = +1;
                      % move up
                      %app.message2send.dataLength = 1;
                      %app.message2send.data = angle;
                % end

                %% plot filtered Force data
                % Add new data to animatedline
                
                % Force Sensor
                hold(app.ForceReading, "on");
                % app.dL_Force_filt.XData = [app.dL_Force_filt.XData counter];
                % app.dL_Force_filt.YData = [app.dL_Force_filt.YData newmovingAverage];
                % app.dL_Force_raw.XData = [app.dL_Force_raw.XData counter];
                % app.dL_Force_raw.YData = [app.dL_Force_raw.YData newdata];
                addpoints(app.hForce, counter, newdata);
                addpoints(app.h2Force, counter, newmovingAverage);
                hold(app.ForceReading, "off");

                   
                if (app.ShowThresholdCheckBox.Value)
                    app.dL_Force_Upper_Threshold = app.upper_threshold;
                    app.dL_Force_Lower_Threshold = app.lower_threshold;
                end
                
                
                % b = toc(a); % check timer
                % if b > (1/3)
                %     drawnow; % update screen every 1/30 seconds
                %     a = tic; % reset timer after updating
                % end
                

                % adjust x-axis limits if necessary
                % adjust x-axis limits if necessary
                if counter > 100
                    xlim(app.ForceReading, [max(0, counter - 100), counter+1]);
                end
                % if counter > 100
                %     new_xlim = [max(0, counter - 100), counter+1];
                %     xlim(app.ForceReading, new_xlim);
                    % Adjust x-ticks
                    % if mod(counter, 10) == 0
                    %     temp = num2cell(counter);
                    %     app.ForceReading.XTick = app.ForceReading.XTick(2:end);
                    %     app.ForceReading.XTick(end+1) = counter;
                    %     app.ForceReading.XTickLabel = app.ForceReading.XTickLabel(2:end, 1);
                    %     app.ForceReading.XTickLabel(end+1, 1) = temp;
                    %     xticks(app.ForceReading);
                    % end
                    
                % end

                % force the plot to be drawn now
                % drawnow;

                % Update the current index for the circular buffer
                currentIndex = mod(currentIndex, windowSize) + 1;
                counter=counter+1;

            end

        end


        function StopRecording(app, mode)
            startAcquisition(app, mode);
                
        end

    %% EMG Recording 
  
        function StartRecordingEMG(app)
            Fs = 20;
            radius = 2.5;
            lims = radius*2;
            goalFPS = 20;
            counter = 1;
            while(app.EMGisPlotting)
                if(counter == 1)
                    
                    % Initialize Filter variables
                    windowSize = 5;
                    dataBuffer = zeros(1, windowSize);
                    currentIndex = 1;
                    i=1;
                    % start Acquissition
                    startAcquisition(app, 0x42);
                    % a = tic; % reset timer after updating     
                end

                % if ((app.charge_status == 1) && (app.servoState))
 
                %% Read EMG Data
                
                % read and convert new data to mV
                % Vmilli = (ADC reading) * (Vref / resolution of ADC) * 1000 )
                % gain of 10000 is not calculated with that!!! -> divide by 10k
                % calculate one factor for performance: ((3.3/1023)*1000) ~ 3,23
                % temp_newdata = getOneEMG(app,1);
                [temp_emg, temp_imu, temp_angle] = getOneEMG_IMU_Angle(app,2); 
                emg_data = double(temp_emg) * 0.00323;
                gyro(1:3) = cast(temp_imu(:,1:3), 'double')*pi/180;
                accel(1:3) = cast(temp_imu(:,4:6), 'double')*9.81;
                angle_rad = (cast(temp_angle, 'double')/1800)*pi;

                % % if length(newdata) < 5
                % %     newdata = EMGIs_Old;
                % % end
                if counter == 1
                    EMGIsFiltered_Old = 0;
                    EMGIs_Old = 0;
                end
                
                % Calculate Moving Average filter
                if app.EMG_CenteredMovingAverageButton.Value 
                     %dataBuffer(currentIndex) = mean(newdata);
                     newmovingAverage=0.3*EMGIsFiltered_Old + 0.4*emg_data + 0.3*EMGIs_Old;
                     EMGIsFiltered_Old = newmovingAverage;
                     EMGIs_Old = emg_data;

                elseif app.EMG_LeftSidedMovingAverageButton.Value
                    newmovingAverage=0.8*EMGIsFiltered_Old + 0.2*emg_data; %+ 0.1*EMGIs_Old;
                    EMGIsFiltered_Old = newmovingAverage;
                    EMGIs_Old = emg_data;

                elseif app.EMG_EWMVA.Value
                    newmovingAverage=0.8*EMGIsFiltered_Old + 0.1*emg_data + 0.1*EMGIs_Old;
                    EMGIsFiltered_Old = newmovingAverage;
                    EMGIs_Old = emg_data;

                end

                
                %% plot filtered EMG data
                % Add new data to animatedline
                
                % EMG Sensor
                hold(app.MVC_Calibration, "on");
                addpoints(app.emg1_h, counter + (0:length(emg_data)-1), emg_data);
                addpoints(app.emg1_h2, counter + (0:length(newmovingAverage)-1), newmovingAverage);
                hold(app.MVC_Calibration, "off");

                   
                if (app.EMGShowThresholdCheckBox.Value)
                    app.dL_EMG_CH1_Threshold = app.EMGUpperThreshold;
                    app.dL_EMG_CH2_Threshold = app.EMGLowerThreshold;
                end
                drawnow; % update screen every 1/30 seconds
                
                % adjust x-axis limits if necessary
                if counter > 100
                    xlim(app.MVC_Calibration, [max(0, counter - 100), counter+1]);
                end

                % force the plot to be drawn now
                % drawnow;

                % Update the current index for the circular buffer
                currentIndex = mod(currentIndex, windowSize) + 1;
                counter=counter+length(emg_data);


                %% plot IMU data
                upper_arm_quat = app.fuse(accel,gyro);
                upper_arm_pos = calcImuPos(app,upper_arm_quat,radius);
                upper_arm_rotm = quat2rotm(upper_arm_quat);
                [lower_arm_pos, lower_arm_rotm] = calcServoPos(app,angle_rad);
                global_rotm = upper_arm_rotm * lower_arm_rotm;
            
             
                %Update plots
                %plotAngles(linesList,n,timevec,q);
                plotPose(app, app.posePlot_Elbow, upper_arm_pos', quaternion(rotm2quat(global_rotm)));
                upper_arm_ = transformStl(app, app.upper_arm, upper_arm_pos', upper_arm_quat);
                lower_arm_ = transformStl(app, app.lower_arm, upper_arm_pos',quaternion(rotm2quat(global_rotm)));
                delete(app.upper_arm_posepatch);
                delete(app.lower_arm_posepatch);
                % update arm position
                app.upper_arm_posepatch = trisurf(upper_arm_,"FaceColor", 'r', "Parent", app.IMUPlot_2);
                app.lower_arm_posepatch = trisurf(lower_arm_,"FaceColor", 'y', "Parent", app.IMUPlot_2);

            end

        end


        function StopRecordingEMG(app)
            startAcquisition(app, 0x42);

        end

    %% IMU Recording


        function StartRecordingIMU(app) 
        Fs = 20;
        radius = 2.5;
        lims = radius*2;
        goalFPS = 20;
        counter = 1;
        
        while(app.IMUisPlotting)
        % for n=1:1:400
            % framestart=tic;
            % dataloop=tic;
            if(counter == 1)
                
                % Initialize Filter variables
                currentIndex = 1;
                i=1;
                % start Acquissition
                startAcquisition(app, 0x44);
                % a = tic; % reset timer after updating     
            end
            % a = tic;
            % if ((app.charge_status == 1) && (app.servoState))

            %% Read IMU Data
            
            [newdata, angle_deg] = getIMU_raw(app,2);
            
            gyro(1:3) = cast(newdata(:,1:3), 'double')*pi/180;
            accel(1:3) = cast(newdata(:,4:6), 'double')*9.81;
            angle_rad = (cast(angle_deg, 'double')/1800)*pi;
            
            upper_arm_quat = app.fuse(accel,gyro);
            upper_arm_pos = calcImuPos(app,upper_arm_quat,radius);
            upper_arm_rotm = quat2rotm(upper_arm_quat);
            [lower_arm_pos, lower_arm_rotm] = calcServoPos(app,angle_rad);
            global_rotm = upper_arm_rotm * lower_arm_rotm;
        
         
            %Update plots
            %plotAngles(linesList,n,timevec,q);
            plotPose(app, app.posePlot_Elbow, upper_arm_pos', quaternion(rotm2quat(global_rotm)));
            upper_arm_ = transformStl(app, app.upper_arm, upper_arm_pos', upper_arm_quat);
            lower_arm_ = transformStl(app, app.lower_arm, upper_arm_pos',quaternion(rotm2quat(global_rotm)));
            delete(app.upper_arm_posepatch);
            delete(app.lower_arm_posepatch);
            % update arm position
            app.upper_arm_posepatch = trisurf(upper_arm_,"FaceColor", 'r', "Parent", app.IMUPlot);
            app.lower_arm_posepatch = trisurf(lower_arm_,"FaceColor", 'y', "Parent", app.IMUPlot);
            counter = counter+1
           
            % toc(a);
            % looptime=toc(dataloop);
            % tm = 1/goalFPS-(looptime);
            % pause(tm);
            % frameEnd=toc(framestart);
            % fprintf("fps: %f\n",1/frameEnd);
            
        end
    end
    
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             
            
            %global con_options;
            app.pathtest = fileparts(mfilename('fullpath'));
            
            app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos', 'fa-battery-0.png');
            app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-searching.png');
            app.StatusLamp.Color = [0,0,0]./255;
            app.Label.Text = 'not connected';

            pause(1);

            report = ["Please be patient, we are searching for available devices..."; "This may take up to 60 seconds."];
            uialert(app.JUSTEXOUIFigure, report, "Alert", "Icon", "info");

            % search for paired Bluetooth devices in the Windows registry
            % and match them to the corresponding COM Ports

            BT_Devices = findBT_devices_and_COMPorts(app);

            if (isempty(BT_Devices.COMport))
                app.BTDeviceDropDown.Items = "No devices";
                report = ["No Bluetooth device found."; "Check to enable Bluetooth connection on your device, make sure that the Classroom Edu-Exo-Pro device is powered on and was paired in the Windows Bluetooth Manager!."];
                uialert(app.JUSTEXOUIFigure,report, "Bluetooth Error", "Icon", "error");

                app.ConnectButton.Enable = "off";
                app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-disabled.png');
                app.StatusLamp.Color = "k";

                pause(0.5);

                app.ConnectButton.Enable = "on";
                app.ConnectButton.Text = "Retry";
            else
                app.con_options_friendlyName = BT_Devices.friendlyName;
                app.con_options_COMPorts = BT_Devices.COMport;
                
                app.BTDeviceDropDown.Items = app.con_options_friendlyName;
                app.BTDeviceDropDown.Enable = 'on';
                app.BTDeviceDropDown.ItemsData=1:numel(app.BTDeviceDropDown.Items);
                app.ConnectButton.Enable = 'on';
            end

            app.chosen_option = app.BTDeviceDropDown.Items{1, app.BTDeviceDropDown.Value};
            app.chosen_option_indx = app.BTDeviceDropDown.Value;

            % devicelist = bluetoothlist("Timeout", 30)
            % devicelist{1,1} = "ESP32-BT-Slave";

            % clear app.connected_device app.con_options app.pair_status;
            % j = 1;
            % 
            % for i = 1:height(devicelist)
            %     %if (endsWith(devicelist{i,1}, "EDU_EXO_PRO"))  ...
            %     % if ((devicelist{i,1} == "02_CLASSROOM_EDU_EXO_PRO"))% | (devicelist{i,1} == "06_CLASSROOM_EDU_EXO_PRO"))
            %     if ((devicelist{i,1} == "ESP32-BT-Slave"))
            %         app.con_options_friendlyName(j) = devicelist{i,1};
            %         app.pair_status(j) = devicelist{i,4};
            %         app.avail_status(j) = devicelist{i,3};
            %         j=+1;
            %     end
            % end


            % initialize communication variables
            app.message2receive = struct('header',0,'opcode',0,'dataLength',0,'data',0,'checksum',0);
            app.message2send = struct('startMarker', uint8(app.startMarker),'header',uint8(app.header),'opcode',uint8(app.opcode),'dataLength',uint8(app.max_data_length),'data',uint8(app.data),'checksum',mod(app.checksum,256), 'endMarker', uint8(app.endMarker));


        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if(app.ConnectButton.Text == "Connect")
                    
                    if (~isempty(app.con_options_friendlyName))
                        app.pairedFlag = true;
                    else
                        app.pairedFlag = false;
                    end


                    if (app.connectFlag == false && app.pairedFlag == true)% && ((app.selectFlag  == true) | (indx == 1)))
                        % connect device
                        try
                            % app.connected_device = bluetooth(chosen_option);
                            COMport = app.con_options_COMPorts{app.chosen_option_indx};
                            BAUDrate = 115200;
                            app.connected_device = serialport(COMport,BAUDrate, "Timeout",10);
                        catch ME
                            report = getReport(ME);
                            uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
                        end
                        
                        connect_answer = testConnection(app);
        
                        if (connect_answer)
                            app.connectFlag = true;
                            app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-connected.png');
                            app.StatusLamp.Color = [0,0,255]./255;
                            app.Label.Text = 'connected';
                        else
                            app.connectFlag = false;
                        end
        
                        % app.pairedFlag = true;
                        % app.selectFlag = true;
                    elseif (app.connectFlag == false && app.selectFlag == false)
                        % please select device
                        report = ["No Bluetooth device selected."; "To connect with a Bluetooth device, please select one from the drop down list."];
                        uialert(app.JUSTEXOUIFigure, report, "Warning!","Icon", "warning");
                    elseif (app.connectFlag == false && app.pairedFlag == false)
                        % please pair device
                        report = ["Bluetooth device not paired."; "To use the Bluetooth device with this application, please pair it in your system settings."];
                        uialert(app.JUSTEXOUIFigure, report, "Warning!", "Icon", "warning");
                    elseif (app.connectFlag == true)
                        % device already connected
                        report = ["A Bluetooth device is already connected."; "If you want to disconnect, please restart the application and re-connect a new device!"];
                        uialert(app.JUSTEXOUIFigure, report, "Attention", "Icon", "info");
                    else
                        report = ["Unknown Error"; "Please Quit, and restart Apllication."];
                        uialert(app.JUSTEXOUIFigure, report, "Error", "Icon", "error")
                    end
        
                    CheckBattery(app, 2);

                    app.ConnectButton.Text = "Disconnect";

            elseif(app.ConnectButton.Text == "Disconnect")
                 % Disconnect from Arduino
                connect_answer = stopConnection(app);
                
                app.BTDeviceDropDown.Enable = "off";
                app.BTDeviceDropDown.Placeholder = "select device";
                app.ConnectButton.Enable = 'off';
                app.ConnectButton.Text = "Connect";
                app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-disabled.png');
                app.StatusLamp.Color = [255,255,255]./255;
                app.Label.Text = 'not connected';
                % reset flags
                app.pairedFlag = false;
                app.connectFlag = false;
                app.selectFlag = false;
                app.connected_device = "";
                clear gobal app.connected_device ;
                clear global app.con_options;
                clear devicelist;
                pause(1);
                
                startupFcn(app);

            elseif(app.ConnectButton.Text == "Retry")
                app.BTDeviceDropDown.Enable = "off";
                app.BTDeviceDropDown.Placeholder = "select device";
                app.ConnectButton.Enable = 'off';
                app.ConnectButton.Text = "Connect";
                app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-disabled.png');
                app.StatusLamp.Color = "k"; 
                % reset flags
                app.pairedFlag = false;
                app.connectFlag = false;
                app.selectFlag = false;
                clear gobal app.connected_device ;
                clear global app.con_options;
                clear devicelist;
              
                pause(1);
                startupFcn(app);
            end


        end

        % Value changed function: BTDeviceDropDown
        function BTDeviceDropDownValueChanged(app, event)
            app.chosen_option = app.BTDeviceDropDown.Items{1, app.BTDeviceDropDown.Value};
            app.chosen_option_indx = app.BTDeviceDropDown.Value;
            
        end

        % Clicked callback: BTDeviceDropDown
        function BTDeviceDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            app.selectFlag = true;
        end

        % Callback function: not associated with a component
        function Button_2Pushed(app, event)
            
            try
                % connected_servo = servo(connected_device,'D5', 'MaxPulseDuration', 0.00205, 'MinPulseDuration',0.00085); % Connect the servo values to Matlab through Arduino
                
                app.Servo_Speed = str2num(app.ServoSpeedKnob.Value);
                speed = 5 - app.Servo_Speed;
                stepsize = 10;
                LOW_Ref_degree = 1800 - (app.ServoAngleLimitsSlider.Value(1)'*10);
                HIGH_Ref_degree = 1800 - (app.ServoAngleLimitsSlider.Value(2)'*10);
                uint16_LOW_Ref_degree = uint16(LOW_Ref_degree);
                resetServoPosition(app, uint16_LOW_Ref_degree, speed, stepsize);
                
                delete(findobj(app.ServoTrajectory, 'Color','c'));
                legend(app.ServoTrajectory,{'Servo Trajectory','Servo Refrence Angle','Risk threshold'},'Location','southeast')
                
                deltaT = (speed * 0.05) * ((HIGH_Ref_degree - LOW_Ref_degree)/stepsize);
                
                pause(deltaT);

                app.ExecuteButton.Enable = "on";
                app.Button_2.Enable = "off";
                
            catch ME
                report = getReport(ME);
                uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end
        end

        % Callback function
        function CH1DropDownValueChanged(app, event)
            value = app.CH1DropDown.Value;
            if value == "EMG0"
                app.CH2DropDown.Value = app.CH2DropDown.Items{1,2};
            else
                app.CH2DropDown.Value = app.CH2DropDown.Items{1,1};
            end
        end

        % Callback function
        function CH2DropDownValueChanged(app, event)
            value = app.CH2DropDown.Value;
            if value == "EMG1"
                app.CH1DropDown.Value = app.CH1DropDown.Items{1,1};
            else
                app.CH1DropDown.Value = app.CH1DropDown.Items{1,2};
            end
        end

        % Value changed function: EmergnecySTOPButton
        function EmergnecySTOPButtonValueChanged(app, event)
            value = app.EmergnecySTOPButton.Value;

            try 
                
                % Set Arduino to emergency Stop Mode -> manually turn off
                % arduino
                emergencyStop(app);

                %h= findobj(app.JUSTEXOUIFigure,'Type','uibutton');
                h= findobj(app.JUSTEXOUIFigure,'-property','Enable');
                
                set(h,'Enable','off')


                report = ["Emergency Stop"; "System disconnected and diasbled. Please restart Program and manually turn off power to the Edu Exo Pro!"]
                uialert(app.JUSTEXOUIFigure, report, "Warning!", "Icon", "warning");

                clear all;


            catch ME
                report = getReport(ME);
                uialert(app.JUSTEXOUIFigure, report, "Error", "Icon", "error");
            end


        end

        % Value changed function: StartRecordingSwitch_Force
        function StartRecordingSwitch_ForceValueChanged(app, event)
            value = app.StartRecordingSwitch_Force.Value;


            try
                if strcmp(value, 'On')
                    % app.AppliedFilterButtonGroup_Force.Enable = 'off';
                    % app.UpperThresholdSlider.Enable = 'off';
                    % app.LowerThresholdSlider.Enable = 'off';
                    % app.ForceNumericUpperThresholdField.Enable = 'off';
                    % app.ForceNumericLowerThresholdField.Enable = 'off';
                    app.StatusLamp.Color = [50,255,255]./255;
                    app.Label.Text = 'Force control';
                    % clear up plots
                    cla(app.ForceReading);
                    app.ForceReading.XLim = [0 100];
                    app.upper_threshold = app.UpperThresholdSlider.Value;
                    app.lower_threshold = app.LowerThresholdSlider.Value;

                    % initialize plots
                    app.ForceReading.NextPlot = 'add';  % move upt to startfcn?
                    
                    time = 1:1:120;
                    
                    hold(app.ForceReading, "on");
                    % app.dL_Force_raw = line(app.ForceReading, nan, nan);
                    % app.dL_Force_filt = line(app.ForceReading, nan, nan, 'Color','red','LineStyle','--');
                    app.hForce = animatedline(app.ForceReading, nan, nan, 'Color', 'red', 'LineStyle','--', 'MaximumNumPoints',110);
                    app.h2Force = animatedline(app.ForceReading, nan, nan, 'MaximumNumPoints',110);
                    legend([app.hForce app.h2Force], {'raw', 'filtered'}, 'Location', 'northeast');

                    if (app.ShowThresholdCheckBox.Value)
                        app.upperHandle = yline(app.ForceReading, app.upper_threshold, 'Color','blue');
                        app.lowerHandle = yline(app.ForceReading, app.lower_threshold, 'Color','blue');
                        legend([app.hForce app.h2Force app.upperHandle app.lowerHandle], {'raw', 'filtered','Upper Thresh', 'Lower Thresh'}, 'Location', 'northeast');
                        
                    end
                    

                    hold(app.ForceReading, "off");


                    % check Thresholds
                    app.upper_threshold = uint16(((app.UpperThresholdSlider.Value/8650)/5)*1023);
                    app.lower_threshold = uint16(abs((app.LowerThresholdSlider.Value/8650)*1023));

                    speed = 5 - app.SpeedSetting_Force.Value;
                    stepsize = 10;
                    
                    setThresholds(app, 0x41, app.upper_threshold, app.lower_threshold, speed, stepsize);
                    pause(0.1);
                    [~, app.servoState] = setServoState(app, 0x52);
                    

                    CheckBattery(app,1);
                    app.ForceisPlotting = true;
                    pause(0.1);
                    StartRecordingForce(app);
                    
                else
                    app.ForceisPlotting = false;
                    StopRecording(app, 0x41);
                    % report = "The Battery Voltage is too low. Servo Motor cannot be operated. Please Charge Battery!";
                    % uialert(app.JUSTEXOUIFigure, report, "Battery Error", "Icon", "error");
                    CheckBattery(app,2)
                end

                catch ME
                    report = getReport(ME);
                    error_line = ME.stack.line;
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
                    
            end

        end

        % Value changed function: StartRecordingSwitch_DualThresh_EMG
        function StartRecordingButtonPushed(app, event)
            
                try

                    % app.MVCNormalizingButtonGroup.Enable = "off";
                    app.MVCRepSpinner.Enable = "off";
                    app.StatusLamp.Color = "c"; % only temporary
    
                    %%Clean the Axes and start over again
                    cla(app.MVC_Calibration);
                    app.MVC_Calibration.XLim = [0 15];

                    % initialize plots
                    app.MVC_Calibration.NextPlot = 'add';  % move upt to startfcn?

                    hold(app.MVC_Calibration, "on");
                    app.dl_EMG_Calibrate = line(app.MVC_Calibration, nan, nan);
                    
                    time = 1:1:120;
                    
                    CheckBattery(app,1);

                    app.servoState = true;
       
                    % if ((app.charge_status == 1) && (app.servoState))

                        % Initialize Filter variables
                        windowSize = 5;
                        dataBuffer.CH1 = zeros(1, windowSize);
                        
                        currentIndex = 1;
                        i=1;
                        cent_counter = 0;


                        % execute code here
                        % normalizing_option = app.MVCNormalizingButtonGroup.SelectedObject;
                        repetitions = app.MVCRepSpinner.Value;

                        %% read EMG Data
                        % Function to be executed when the Start button is pushed
                        app.MVCValues = []; % Clear previous data
                        

                        for rep = 1:repetitions
                            app.sEMGData = [];  % Clear previous data
                            for t = 1:(app.AcquisitionTime * app.SamplingRate)
                                new_data = app.acquireData();   % Start data acquisition
                                app.dl_EMG_Calibrate.YData(t) = new_data;
                                drawnow;
                                pause(0.5);
                            end
                            app.calculateMVC();  % Calculate MVC value
                        end

                        % Calculate average MVC value
                        averageMVC = mean(app.MVCValues);


                        %% plot filtered EMG data
                        % Add new data to animatedline
                        
                        % Channel 1
                        % app.dL_EMG_CH1_raw.YData(i) = app.sEMGData;
                        % app.dL_EMG_CH1_filt.YData(i) = newmovingAverage.CH1;
                        % % Channel 2
                        % app.dL_EMG_CH2_raw.YData(i) = newdata.CH2;
                        % app.dL_EMG_CH2_filt.YData(i) = newmovingAverage.CH2;
                        % 
                        % % adjust x-axis limits if necessary
                        % xlim(app.EMG_CH1, [max(0, i - 20), i + 1]);
                        % 
                        % drawnow;
     
                        % pause(0.015);
                        % 
                        % % Update the current index for the circular buffer
                        % currentIndex = mod(currentIndex, windowSize) + 1;
                        % time(i) = time(i)+1;
                        % %app.EMG_CH1.xticks(time(k))
                        % %app.EMG_CH1.xticklabels(string(time(k)))
                        % %app.EMG_CH1.XTick = cent_counter*100 + time(i);
                        % if(mod(i, 100) == 0) 
                        %     cent_counter =+ 1;
                        % 
                        % end
                        % i=mod(i, 100)+1;

                        app.ConfirmCalibrationButton_3.Enable = 'on';


                catch ME
                    report = getReport(ME);
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
                end


        end

        % Callback function
        function ConfirmCalibrationButton_3Pushed(app, event)
            try
                
                app.CalibrationTabs.SelectedTab = app.ZeroForceTab; %% open Zero Force Calibration Tab

            catch ME
                    report = getReport(ME);
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end

        end

        % Callback function
        function ConfirmCalibrationButton_2Pushed(app, event)
            try
                app.CalibrationTabs.SelectedTab = app.ServoAngleTab; %% open Servo Angle Calibration Tab
            catch ME
                    report = getReport(ME);
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end
        end

        % Callback function
        function ConfirmCalibrationButtonPushed(app, event)
            try
                % Create a table to store measurement data
                timestamp = '';
                timestamp = string(datetime, 'uuuu-MM-dd');
                %measurementTable = table(timestamp, app.MVCValues, app.ForceValues, app.AngleValues,'VariableNames', {'DateTime', 'MVCValues', 'ForceValues', 'Angles'});
                saveCSV(app, timestamp);
                report = ["Hurray";"Calibration Data has been saved!"];
                uialert(app.JUSTEXOUIFigure, report, "Success", "Icon", "success");
            catch ME
                report = getReport(ME);
                uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end
            
        end

        % Callback function
        function StartForceCalibartionButtonPushed(app, event)
            value = app.StartRecordingSwitch_Force.Value;
            try
                
                app.StatusLamp.Color = "m";
                app.ZeroForceOffsetLabel.Text = 'Zero Force Offset: ';

                % clear up plots
                cla(app.ZeroForce);
                app.ZeroForce.XLim = [0 100];
                app.Offset_Force_filt = zeros(1, 100);
                

                % initialize plots
                app.ZeroForce.NextPlot = 'add';  % move upt to startfcn?
                         
                hold(app.ZeroForce, "on");
                % app.dL_Force_raw = line(app.ForceReading, nan, nan);
                % app.dL_Force_filt = line(app.ForceReading, nan, nan, 'Color','red','LineStyle','--');
                h = animatedline(app.ZeroForce, nan, nan, 'Color', 'red', 'LineStyle','--', 'MaximumNumPoints',100);
                h2 = animatedline(app.ZeroForce, nan, nan);
                
                
                legend(app.ZeroForce, {'raw', 'filtered'}, 'Location', 'northeast');

                hold(app.ZeroForce, "off");

                % [~, app.servoState] = setServoState(app, 0x52);
                

                CheckBattery(app,1);
                

                if ((app.charge_status == 1))


                    % Initialize Filter variables
                        windowSize = 5;
                        dataBuffer = zeros(1, windowSize);
                        currentIndex = 1;
                        i=1;

                        % start Acquissition
                        startAcquisition(app, 0x32);
                    a = tic; % reset timer after updating
                    for counter=1:100
                        value = app.StartRecordingSwitch_Force.Value;

                        %% Read Force Data
                        
                        % read and convert new data to mN
                        temp_newdata = typecast(getForce_raw(app,1), 'int16');
                        newdata = (temp_newdata);%-3755;
                        
                        % Calculate Moving Average filter
                        
                         dataBuffer(currentIndex) = newdata;
                         newmovingAverage = mean(dataBuffer);

    
                        %% plot filtered Force data
                        % Add new data to animatedline
                        
                        % Force Sensor
                        hold(app.ForceReading, "on");
                        % app.Offset_Force_filt.XData = [app.Offset_Force_filt.XData counter];
                        app.Offset_Force_filt(counter) = newmovingAverage;
                        
                        addpoints(h, counter, newdata);
                        addpoints(h2, counter, newmovingAverage);
                        
                        hold(app.ForceReading, "off");
    
                        b = toc(a); % check timer
                        if b > (1/3)
                            drawnow % update screen every 1/30 seconds
                            a = tic; % reset timer after updating
                        end

                        % adjust x-axis limits if necessary
                        % if counter > 100
                        %     xlim(app.ForceReading, [max(0, counter - 50), counter + 1]);
                        % end

                        % force the plot to be drawn now
                        % drawnow;

                        % Update the current index for the circular buffer
                        currentIndex = mod(currentIndex, windowSize) + 1;
                        counter=counter+1;
                    end

                    pause(1);
                    app.zero_force_offset = app.Offset_Force_filt/100;
                    app.ZeroForceOffsetLabel.Text = 'Zero Force Offset: ' + string(round(app.zero_force_offset));

                    
                else
                    report = "The Battery Voltage is too low. Servo Motor cannot be operated. Please Charge Battery!";
                    uialert(app.JUSTEXOUIFigure, report, "Battery Error", "Icon", "error");
                end

                catch ME
                    report = getReport(ME);
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end

            
        end

        % Callback function
        function MeasureAngleButtonPushed(app, event)
            try
                %app.message2send.opcode = % servo
                app.message2send.data = [];
                app.message2send.datalength = 0;

                app.AnlgeValues(1) = send_receive_command(app);

                app.MeasuredAngleLabel.Text = 'Measured Angle:' + string(round(app.AngleValues(1)));

                app.MeasureAngleButton_2.Enable = 'on';
            catch ME
                report = getReport(ME);
                uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end
        end

        % Callback function
        function MeasureAngleButton_2Pushed(app, event)
            try
                %app.message2send.opcode = % servo
                app.message2send.data = [];
                app.message2send.datalength = 0;

                app.AnlgeValues(2) = send_receive_command(app);

                app.MeasuredAngleLabel_2.Text = 'Measured Angle:' + string(round(app.AngleValues(2)));

                app.ConfirmCalibrationButton.Enable = 'on';


            catch ME
                report = getReport(ME);
                uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end
        end

        % Value changed function: ShowThresholdCheckBox
        function ShowThresholdCheckBoxValueChanged(app, event)
            if (app.ShowThresholdCheckBox.Value)
                app.upperHandle = yline(app.ForceReading, app.UpperThresholdSlider.Value, 'Color','blue');
                app.lowerHandle = yline(app.ForceReading, app.LowerThresholdSlider.Value, 'Color','blue');
                legend([app.hForce app.h2Force app.upperHandle app.lowerHandle], {'raw', 'filtered','Upper Thresh', 'Lower Thresh'}, 'Location', 'northeast');
            else
               delete(app.upperHandle);
               delete(app.lowerHandle);
            end
        end

        % Callback function
        function ShowThresholdCheckBox_2ValueChanged(app, event)
            if (app.ShowThresholdCheckBox.Value)
                app.EMGHandleCH1 = yline(app.EMG_CH1, app.CH1ThresholdSlider.Value, 'Color','black');
                app.EMGHandleCH2 = yline(app.EMG_CH2, app.CH2ThresholdSlider.Value, 'Color','black');
                
            else
               delete(app.EMGHandleCH1);
               delete(app.EMGHandleCH2);
            end
            
        end

        % Callback function: not associated with a component
        function StartRecordingSwitch_DualThresh_EMGValueChanged(app, event)
            value = app.StartRecordingSwitch_DualThresh_EMG.Value;

            try

                if strcmp(value, 'On')
                    % app.CH1DropDown.Enable = "off";
                    % app.CH2DropDown.Enable = "off";
                    % app.AppliedFilterButtonGroup_EMG.Enable = "off";
                    app.StatusLamp.Color = [175,0,255]./255;
                    app.Label.Text = 'EMG control';

                    %%Clean up plots
                    cla(app.MVC_Calibration);
                    app.MVC_Calibration.XLim = [0 100];
                    app.EMG_Upper_Threshold = app.EMGUpperThresholdSlider.Value;
                    app.EMG_Lower_Threshold = app.EMGLowerThresholdSlider.Value;
                    

                    % set filter type
                    filter_option = app.AppliedFilterButtonGroup_EMG.SelectedObject;
    

                    % initialize plots
                    app.MVC_Calibration.NextPlot = 'add';  % move upt to startfcn?
                    %initialize IMU plot
                    %% initialize IMU plot
                    radius = 2.5;
                    upper_arm_ = stlread('stl/arm.stl');
                    lower_arm_ = stlread('stl/arm.stl');
                    torso_ = stlread('stl/torso.stl');
                    head_ = stlread('stl/head.stl');
                    Fs = 20;
                    % clear up plots
                    cla(app.IMUPlot_2, "reset");


                    % init
                    %[30 0 0] 
                    % [x z y] eul2rotm([z y x])
                    app.torso = transformStl(app, torso_,[0 0 0],eul2quat([-pi/2 0 pi/2]));
                    app.head = transformStl(app, head_,[0 0 0],eul2quat([0 0 0]));
                    app.upper_arm = transformStl(app,upper_arm_,[-0.25 0 0],eul2quat([0 0 pi]));
                    app.lower_arm = transformStl(app,lower_arm_,[0.25 0 0],eul2quat([pi 0 0]));
                    lims = radius*2;
                    
                   
                    app.fuse = imufilter('SampleRate',Fs,'DecimationFactor',1)
                    
                    % initialize plots
                    app.posePlot_Shoulder = poseplot("Parent",app.IMUPlot_2);
                    hold(app.IMUPlot_2);
                    app.posePlot_Elbow = poseplot("Parent", app.IMUPlot_2);

                    % app.IMUPlot_2.XLabel.String = "North-x (m)";
                    % app.IMUPlot_2.YLabel.String = "East-y (m)"; 
                    % app.IMUPlot_2.ZLabel.String = "Down-z (m)"; 
                    app.IMUPlot_2.YLim = [-lims lims];
                    app.IMUPlot_2.XLim = [-lims lims];
                    app.IMUPlot_2.ZLim = [-lims lims];
                    % app.IMUPlot_2.XTickLabel = []
                    app.IMUPlot_2.XGrid = 'off';
                    app.IMUPlot_2.YGrid = 'off';
                    app.IMUPlot_2.ZGrid = 'off';
                    app.IMUPlot_2.XMinorGrid = 'off';
                    app.IMUPlot_2.YMinorGrid = 'off';
                    app.IMUPlot_2.ZMinorGrid = 'off';
                    app.IMUPlot_2.Color = 'none';

                    % place the torso to fixed position
                    % trisurf(app.torso, "FaceColor", 'r', "Parent",app.IMUPlot_2);
                    % trisurf(app.head, "EdgeColor", 'none', "Parent", app.IMUPlot_2);
                    % place the arm in a first fixed position
                    app.upper_arm_posepatch = trisurf(app.upper_arm,"Parent", app.IMUPlot_2); 
                    app.lower_arm_posepatch = trisurf(app.lower_arm,"Parent", app.IMUPlot_2); 
                    % view(app.IMUPlot,-27, 9);
                    
                    
                    % app.EMG_CH1.axh()
                    hold(app.MVC_Calibration, "on");
                    % app.dL_EMG_CH1_raw = line(app.EMG_CH1, nan, nan);
                    % app.dL_EMG_CH1_filt = line(app.EMG_CH1, nan, nan, 'Color','red','LineStyle','--');
                    app.emg1_h = animatedline(app.MVC_Calibration, nan, nan, 'Color', 'red', 'LineStyle','--', 'MaximumNumPoints',200);
                    app.emg1_h2 = animatedline(app.MVC_Calibration, nan, nan, 'MaximumNumPoints',200);
                    % app.EMGupperHandle = yline(app.MVC_Calibration, app.EMG_Upper_Threshold, 'Color','blue');
                    % app.EMGlowerHandle = yline(app.MVC_Calibration, app.EMG_Lower_Threshold, 'Color','blue');
                    legend([app.emg1_h app.emg1_h2], {'raw', 'filtered'}, 'Location', 'northeast');

                    if (app.EMGShowThresholdCheckBox.Value)
                        app.EMGupperHandle = yline(app.MVC_Calibration, app.EMG_Upper_Threshold, 'Color','blue');
                        app.EMGlowerHandle = yline(app.MVC_Calibration, app.EMG_Lower_Threshold, 'Color','blue');
                        legend([app.emg1_h app.emg1_h2 app.EMGupperHandle app.EMGlowerHandle], {'raw', 'filtered','Upper Thresh', 'Lower Thresh'}, 'Location', 'northeast');
                        
                    end


                    hold(app.MVC_Calibration, "off");

                    Channel_Port = app.CHDropDown.Value;
    
                    if strcmp(Channel_Port, 'EMG0')
                        analogPin = 1;
                    else
                        analogPin = 2;
                    end


                    % set EMG threshold values
                    %%TODO: check if bupper is bigger than lower!
                    % if not: make lower 100 point smaller than upper.
                    if app.EMGUpperThresholdSlider.Value < app.EMGLowerThresholdSlider.Value
                        if app.EMGLowerThresholdSlider.Value < 0.2
                            app.EMGLowerThresholdSlider.Value = 0.3;
                        app.EMGLowerThresholdSlider.Value = app.EMGUpperThresholdSlider.Value - 0.2;
                        end
                    end
                    app.EMG_Upper_Threshold = uint16((app.EMGUpperThresholdSlider.Value)*1023/3.3);
                    app.EMG_Lower_Threshold = uint16((app.EMGLowerThresholdSlider.Value)*1023/3.3);

                    speed = 5 - app.SpeedSetting_EMG.Value;
                    stepsize = 10;


                    setAnalogPin(app, 0x42, analogPin);
                    pause(0.1);
                    setThresholds(app, 0x42, app.EMG_Upper_Threshold, app.EMG_Lower_Threshold, speed, stepsize);  
                    pause(0.1);
                    [~, app.servoState] = setServoState(app, 0x52);
                    
                    CheckBattery(app,1);
                    app.EMGisPlotting = true;
                    pause(0.1);
                    StartRecordingEMG(app);

                else
                    app.EMGisPlotting = false;
                    StopRecordingEMG(app);
                    CheckBattery(app,2);
    
                end
       

                catch ME
                    report = getReport(ME);
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end
            
            
        end

        % Value changed function: EMGShowThresholdCheckBox
        function EMGShowThresholdCheckBoxValueChanged(app, event)
            if (app.EMGShowThresholdCheckBox.Value)
                app.EMGupperHandle = yline(app.MVC_Calibration, app.EMGUpperThresholdSlider.Value, 'Color','blue');
                app.EMGlowerHandle = yline(app.MVC_Calibration, app.EMGLowerThresholdSlider.Value, 'Color','blue');
                legend([app.emg1_h app.emg1_h2 app.EMGupperHandle app.EMGlowerHandle], {'raw', 'filtered','Upper Thresh', 'Lower Thresh'}, 'Location', 'northeast');
            else
               delete(app.EMGupperHandle);
               delete(app.EMGlowerHandle);
            end            
        end

        % Value changed function: ApplyThresholdsButton
        function ApplyThresholdsButtonValueChanged(app, event)
            value = app.ApplyThresholdsButton.Value;

            app.EMG_Upper_Threshold = uint16((app.EMGUpperThresholdSlider.Value)*1023/3.3);
            app.EMG_Lower_Threshold = uint16((app.EMGLowerThresholdSlider.Value)*1023/3.3);
            app.angleStep = uint16(app.angleStep);
            
            StopRecordingEMG(app);

            pause(0.1);
            setThresholds(app, 0x42, app.EMG_Upper_Threshold, app.EMG_Lower_Threshold, app.angleStep);  
            pause(0.1);

            
            if (app.EMGShowThresholdCheckBox.Value)
                clearpoints(app.emg1_h);
                clearpoints(app.emg1_h2);
                delete(app.EMGupperHandle);
                delete(app.EMGlowerHandle);
                app.EMGupperHandle = yline(app.MVC_Calibration, app.EMGUpperThresholdSlider.Value, 'Color','blue');
                app.EMGlowerHandle = yline(app.MVC_Calibration, app.EMGLowerThresholdSlider.Value, 'Color','blue');
                legend([app.emg1_h app.emg1_h2 app.EMGupperHandle app.EMGlowerHandle], {'raw', 'filtered','Upper Thresh', 'Lower Thresh'}, 'Location', 'northeast');
            else
               clearpoints(app.emg1_h);
               clearpoints(app.emg1_h2);
               delete(app.EMGupperHandle);
               delete(app.EMGlowerHandle);
            end            

            StartRecordingEMG(app);
        end

        % Value changed function: ApplyThresholdsButton_2
        function ApplyThresholdsButton_2ValueChanged(app, event)
            value = app.ApplyThresholdsButton_2.Value;


            app.upper_threshold = uint16(((app.UpperThresholdSlider.Value/8650)/5)*1023);
            app.lower_threshold = uint16(abs((app.LowerThresholdSlider.Value/8650)*1023));
            app.angleStep = uint16(app.angleStep);
            
            
            StopRecording(app, 0x41);

            pause(0.1);
            setThresholds(app, 0x41, app.upper_threshold, app.lower_threshold, app.angleStep);
            pause(0.1);

            
            if (app.ShowThresholdCheckBox.Value)
                clearpoints(app.hForce);
                clearpoints(app.h2Force);
                delete(app.upperHandle);
                delete(app.lowerHandle);
                app.upperHandle = yline(app.ForceReading, app.UpperThresholdSlider.Value, 'Color','blue');
                app.lowerHandle = yline(app.ForceReading, app.LowerThresholdSlider.Value, 'Color','blue');
                legend([app.hForce app.h2Force app.upperHandle app.lowerHandle], {'raw', 'filtered','Upper Thresh', 'Lower Thresh'}, 'Location', 'northeast');
            else
               clearpoints(app.hForce);
               clearpoints(app.h2Force);
               delete(app.upperHandle);
               delete(app.lowerHandle);
            end            

            StartRecordingForce(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.JUSTEXOUIFigure = uifigure('Visible', 'off');
            app.JUSTEXOUIFigure.Name = 'JUST EXO';
            app.JUSTEXOUIFigure.Icon = fullfile(pathToMLAPP, 'photos', 'lastttt.png');
            app.TabGroup = uitabgroup(app.JUSTEXOUIFigure);
            app.EMGCalibrationTab = uitab(app.TabGroup);
            app.EMGCalibrationTab.Title = 'EMG Calibration';
            app.TabGroup3 = uitabgroup(app.EMGCalibrationTab);
            app.OneChannelTab = uitab(app.TabGroup3);
            app.OneChannelTab.Title = 'One Channel ';
            app.ElectromyographyControlGraphs_2 = uipanel(app.OneChannelTab);
            app.ElectromyographyControlGraphs_2.Title = 'Electromyography MVC Calibration';
            app.MVC_Calibration = uiaxes(app.ElectromyographyControlGraphs_2);
            xlabel(app.MVC_Calibration, 'Time [ms]')
            ylabel(app.MVC_Calibration, 'ENV Amplitude [mV]')
            zlabel(app.MVC_Calibration, 'Z')
            app.MVC_Calibration.XLim = [0 100];
            app.MVC_Calibration.YLim = [0 3.3];
            app.EMGControlParameters_2 = uipanel(app.OneChannelTab);
            app.EMGControlParameters_2.Title = 'Parameters';
            app.CHDropDown = uidropdown(app.EMGControlParameters_2);
            app.CHDropDown.Items = {'EMG0', 'EMG1'};
            app.CHDropDown.Placeholder = 'choose Channel';
            app.CHDropDown.Value = 'EMG0';
            app.StartSwitchLabel_4 = uilabel(app.EMGControlParameters_2);
            app.StartSwitchLabel_4.Text = 'Start';
            app.StartRecordingSwitch_DualThresh_EMG = uiswitch(app.EMGControlParameters_2, 'rocker');
            app.StartRecordingSwitch_DualThresh_EMG.ValueChangedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.ThresholdmVLabel = uilabel(app.EMGControlParameters_2);
            app.ThresholdmVLabel.Enable = 'off';
            app.ThresholdmVLabel.Visible = 'off';
            app.ThresholdmVLabel.Text = 'Threshold [mV]';
            app.EMGChannelLabel_2 = uilabel(app.EMGControlParameters_2);
            app.EMGChannelLabel_2.Text = 'EMG Channel';
            app.AppliedFilterButtonGroup_EMG = uibuttongroup(app.EMGControlParameters_2);
            app.AppliedFilterButtonGroup_EMG.Enable = 'off';
            app.AppliedFilterButtonGroup_EMG.Title = 'Applied Filter';
            app.AppliedFilterButtonGroup_EMG.Visible = 'off';
            app.EMG_LeftSidedMovingAverageButton = uiradiobutton(app.AppliedFilterButtonGroup_EMG);
            app.EMG_LeftSidedMovingAverageButton.Text = {'Left Sided '; 'Moving Average'};
            app.EMG_LeftSidedMovingAverageButton.Value = true;
            app.EMG_CenteredMovingAverageButton = uiradiobutton(app.AppliedFilterButtonGroup_EMG);
            app.EMG_CenteredMovingAverageButton.Text = {'Centered '; 'Moving Average'};
            app.EMG_EWMVA = uiradiobutton(app.AppliedFilterButtonGroup_EMG);
            app.EMG_EWMVA.Text = 'Button3';
            app.ModeSwitch_2Label = uilabel(app.EMGControlParameters_2);
            app.ModeSwitch_2Label.Enable = 'off';
            app.ModeSwitch_2Label.Visible = 'off';
            app.ModeSwitch_2Label.Text = 'Mode';
            app.ModeSwitch_2 = uiswitch(app.EMGControlParameters_2, 'rocker');
            app.ModeSwitch_2.Items = {'Min/Max', 'Increment'};
            app.ModeSwitch_2.Enable = 'off';
            app.ModeSwitch_2.Visible = 'off';
            app.ModeSwitch_2.Value = 'Min/Max';
            app.EMGShowThresholdCheckBox = uicheckbox(app.EMGControlParameters_2);
            app.EMGShowThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @EMGShowThresholdCheckBoxValueChanged, true);
            app.EMGShowThresholdCheckBox.Enable = 'off';
            app.EMGShowThresholdCheckBox.Visible = 'off';
            app.EMGShowThresholdCheckBox.Text = 'Show Threshold';
            app.UpperLabel = uilabel(app.EMGControlParameters_2);
            app.UpperLabel.Enable = 'off';
            app.UpperLabel.Visible = 'off';
            app.UpperLabel.Text = 'Upper';
            app.EMGUpperThresholdSlider = uislider(app.EMGControlParameters_2);
            app.EMGUpperThresholdSlider.Limits = [0 3];
            app.EMGUpperThresholdSlider.MinorTicks = [0 5 10 15 25 30 35 45 50 55 65 70 75 85 90 95];
            app.EMGUpperThresholdSlider.Enable = 'off';
            app.EMGUpperThresholdSlider.Visible = 'off';
            app.EMGUpperThresholdSlider.Value = 2;
            app.LowerLabel = uilabel(app.EMGControlParameters_2);
            app.LowerLabel.Enable = 'off';
            app.LowerLabel.Visible = 'off';
            app.LowerLabel.Text = 'Lower';
            app.EMGLowerThresholdSlider = uislider(app.EMGControlParameters_2);
            app.EMGLowerThresholdSlider.Limits = [0 3];
            app.EMGLowerThresholdSlider.MinorTicks = [0 5 10 15 25 30 35 45 50 55 65 70 75 85 90 95];
            app.EMGLowerThresholdSlider.Enable = 'off';
            app.EMGLowerThresholdSlider.Visible = 'off';
            app.EMGLowerThresholdSlider.Value = 1;
            app.ApplyThresholdsButton = uibutton(app.EMGControlParameters_2, 'state');
            app.ApplyThresholdsButton.ValueChangedFcn = createCallbackFcn(app, @ApplyThresholdsButtonValueChanged, true);
            app.ApplyThresholdsButton.Enable = 'off';
            app.ApplyThresholdsButton.Visible = 'off';
            app.ApplyThresholdsButton.Text = 'Apply Thresholds';
            app.ServoMotorSpeedLabel = uilabel(app.EMGControlParameters_2);
            app.ServoMotorSpeedLabel.Enable = 'off';
            app.ServoMotorSpeedLabel.Visible = 'off';
            app.ServoMotorSpeedLabel.Text = 'Servo Motor Speed';
            app.SpeedSetting_EMG = uiknob(app.EMGControlParameters_2, 'discrete');
            app.SpeedSetting_EMG.Items = {'Low', 'default', 'Medium', 'High'};
            app.SpeedSetting_EMG.ItemsData = [1 2 3 4];
            app.SpeedSetting_EMG.Enable = 'off';
            app.SpeedSetting_EMG.Visible = 'off';
            app.SpeedSetting_EMG.Value = 1;
            app.EMGUpperThreshold = uieditfield(app.EMGControlParameters_2, 'numeric');
            app.EMGUpperThreshold.Limits = [0 3];
            app.EMGUpperThreshold.Editable = 'off';
            app.EMGUpperThreshold.Visible = 'off';
            app.EMGUpperThreshold.Value = 2;
            app.EMGLowerThreshold = uieditfield(app.EMGControlParameters_2, 'numeric');
            app.EMGLowerThreshold.Limits = [0 3];
            app.EMGLowerThreshold.Editable = 'off';
            app.EMGLowerThreshold.Visible = 'off';
            app.EMGLowerThreshold.Value = 1;
            app.RepSpinnerLabel = uilabel(app.EMGControlParameters_2);
            app.RepSpinnerLabel.Text = 'Rep';
            app.MVCRepSpinner = uispinner(app.EMGControlParameters_2);
            app.MVCRepSpinner.Limits = [0 3];
            app.ForceControlTab = uitab(app.TabGroup);
            app.ForceControlTab.Title = 'Force Control';
            app.ForceControlGraph = uipanel(app.ForceControlTab);
            app.ForceControlGraph.Title = 'Force Control';
            app.ForceReading = uiaxes(app.ForceControlGraph);
            title(app.ForceReading, 'Force Sensor')
            xlabel(app.ForceReading, 'Time [s]')
            ylabel(app.ForceReading, 'Force Amplitude [mN]')
            zlabel(app.ForceReading, 'Z')
            app.ForceReading.XLim = [0 100];
            app.ForceReading.YLim = [-1500 1500];
            app.ForceReading.YTick = [-5000 -4000 -3000 -2000 -1000 0 1000 2000 3000 4000 5000];
            app.ForceReading.YTickLabel = {'-5000'; '-4000'; '-3000'; '-2000'; '-1000'; '0'; '1000'; '2000'; '3000'; '4000'; '5000'};
            app.ForceParameters = uipanel(app.ForceControlTab);
            app.ForceParameters.Title = 'Parameters';
            app.UpperThresholdSliderLabel = uilabel(app.ForceParameters);
            app.UpperThresholdSliderLabel.Text = 'Upper Threshold';
            app.UpperThresholdSlider = uislider(app.ForceParameters);
            app.UpperThresholdSlider.Limits = [0 1000];
            app.UpperThresholdSlider.Value = 500;
            app.LowerThresholdSliderLabel = uilabel(app.ForceParameters);
            app.LowerThresholdSliderLabel.Text = 'Lower Threshold';
            app.LowerThresholdSlider = uislider(app.ForceParameters);
            app.LowerThresholdSlider.Limits = [-1000 0];
            app.LowerThresholdSlider.Value = -500;
            app.StartSwitchLabel_2 = uilabel(app.ForceParameters);
            app.StartSwitchLabel_2.Text = 'Start';
            app.StartRecordingSwitch_Force = uiswitch(app.ForceParameters, 'rocker');
            app.StartRecordingSwitch_Force.ValueChangedFcn = createCallbackFcn(app, @StartRecordingSwitch_ForceValueChanged, true);
            app.AppliedFilterButtonGroup_Force = uibuttongroup(app.ForceParameters);
            app.AppliedFilterButtonGroup_Force.Title = 'Applied Filter';
            app.LeftSidedMovingAverageButton_2 = uiradiobutton(app.AppliedFilterButtonGroup_Force);
            app.LeftSidedMovingAverageButton_2.Text = {'Left Sided '; 'Moving Average'};
            app.LeftSidedMovingAverageButton_2.Value = true;
            app.CenteredMovingAverageButton_2 = uiradiobutton(app.AppliedFilterButtonGroup_Force);
            app.CenteredMovingAverageButton_2.Text = {'Centered '; 'Moving Average'};
            app.EWMVAButton_2 = uiradiobutton(app.AppliedFilterButtonGroup_Force);
            app.EWMVAButton_2.Text = 'EWMVA';
            app.ApplyThresholdsButton_2 = uibutton(app.ForceParameters, 'state');
            app.ApplyThresholdsButton_2.ValueChangedFcn = createCallbackFcn(app, @ApplyThresholdsButton_2ValueChanged, true);
            app.ApplyThresholdsButton_2.Text = 'Apply Thresholds';
            app.AngleStepSizeKnob_2Label = uilabel(app.ForceParameters);
            app.AngleStepSizeKnob_2Label.Text = 'Angle Step Size';
            app.SpeedSetting_Force = uiknob(app.ForceParameters, 'discrete');
            app.SpeedSetting_Force.Items = {'Low', 'default', 'Medium', 'High'};
            app.SpeedSetting_Force.ItemsData = {'1', '2', '3', '4'};
            app.SpeedSetting_Force.Value = '1';
            app.ShowThresholdCheckBox = uicheckbox(app.ForceParameters);
            app.ShowThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowThresholdCheckBoxValueChanged, true);
            app.ShowThresholdCheckBox.Text = 'Show Threshold';
            app.ShowThresholdCheckBox.Value = true;
            app.BatteryManager = uipanel(app.JUSTEXOUIFigure);
            app.BatteryImage = uiimage(app.BatteryManager);
            app.BatteryImage.ImageSource = fullfile(pathToMLAPP, 'photos', 'fa-battery-4.png');
            app.StatusManager = uipanel(app.JUSTEXOUIFigure);
            app.Label = uilabel(app.StatusManager);
            app.Label.Text = '';
            app.StatusLamp = uilamp(app.StatusManager);
            app.ConnectionManager = uipanel(app.JUSTEXOUIFigure);
            app.BTDeviceDropDownLabel = uilabel(app.ConnectionManager);
            app.BTDeviceDropDownLabel.Text = 'BT Device';
            app.BTDeviceDropDown = uidropdown(app.ConnectionManager);
            app.BTDeviceDropDown.Items = {};
            app.BTDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @BTDeviceDropDownValueChanged, true);
            app.BTDeviceDropDown.Enable = 'off';
            app.BTDeviceDropDown.Placeholder = 'select device';
            app.BTDeviceDropDown.ClickedFcn = createCallbackFcn(app, @BTDeviceDropDownClicked, true);
            app.BTDeviceDropDown.Value = {};
            app.ConnectButton = uibutton(app.ConnectionManager, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.BTStatusImage = uiimage(app.ConnectionManager);
            app.EmergencyManager = uipanel(app.JUSTEXOUIFigure);
            app.EmergnecySTOPButton = uibutton(app.EmergencyManager, 'state');
            app.EmergnecySTOPButton.ValueChangedFcn = createCallbackFcn(app, @EmergnecySTOPButtonValueChanged, true);
            app.EmergnecySTOPButton.Icon = fullfile(pathToMLAPP, 'photos', 'stop-button.png');
            app.EmergnecySTOPButton.Text = '';
            app.Image = uiimage(app.JUSTEXOUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'photos', 'lastttt.png');
            app.Image_2 = uiimage(app.JUSTEXOUIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'photos', 'chalmers-university-of-technology-.png');
            % Show the figure after all components are created
            app.JUSTEXOUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BT_Calibration_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.JUSTEXOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.JUSTEXOUIFigure)
        end
    end
end