classdef BT_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        JUSTEXOUIFigure                 matlab.ui.Figure
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        EmergencyManager                matlab.ui.container.Panel
        EmergnecySTOPButton             matlab.ui.control.StateButton
        ConnectionManager               matlab.ui.container.Panel
        RefreshButton_Connection_List   matlab.ui.control.Button
        BTDeviceDropDown                matlab.ui.control.DropDown
        BTDeviceDropDownLabel           matlab.ui.control.Label
        BTStatusImage                   matlab.ui.control.Image
        ConnectButton                   matlab.ui.control.Button
        StatusManager                   matlab.ui.container.Panel
        StatusLamp                      matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        BatteryManager                  matlab.ui.container.Panel
        BatteryPercentage               matlab.ui.control.Label
        BatteryImage                    matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        SimulatedControllerTab          matlab.ui.container.Tab
        Transfer                        matlab.ui.container.Panel
        ServoControlPanel               matlab.ui.container.Panel
        ServoSpeedKnob                  matlab.ui.control.DiscreteKnob
        SpeedKnobLabel                  matlab.ui.control.Label
        ServoPowerSwitch                matlab.ui.control.Switch
        ExecuteButton                   matlab.ui.control.Button
        Servo_Plot_Reset                matlab.ui.control.Button
        ServoPowerSwitchLabel           matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        TransferFunctionTab             matlab.ui.container.Tab
        ShowInputSIgnalCheckBox         matlab.ui.control.CheckBox
        OptimizePIDButton               matlab.ui.control.Button
        uLabel                          matlab.ui.control.Label
        vLabel                          matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        y_mLabel                        matlab.ui.control.Label
        yLabel                          matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        rLabel                          matlab.ui.control.Label
        Image9_8                        matlab.ui.control.Image
        Image9_11                       matlab.ui.control.Image
        Image9_10                       matlab.ui.control.Image
        Image9_9                        matlab.ui.control.Image
        Image9_7                        matlab.ui.control.Image
        Image9_6                        matlab.ui.control.Image
        Image9_5                        matlab.ui.control.Image
        Image9_4                        matlab.ui.control.Image
        PIDControllerPanel              matlab.ui.container.Panel
        KdEditFieldPIDController        matlab.ui.control.NumericEditField
        K_iLabel_4                      matlab.ui.control.Label
        KiEditFieldPIDController        matlab.ui.control.NumericEditField
        K_iLabel_3                      matlab.ui.control.Label
        KpEditFieldPIDController        matlab.ui.control.NumericEditField
        K_iEditField_2Label_4           matlab.ui.control.Label
        PControllerPanel                matlab.ui.container.Panel
        KpEditFieldPController          matlab.ui.control.NumericEditField
        K_iEditField_2Label_2           matlab.ui.control.Label
        PIControllerPanel               matlab.ui.container.Panel
        KiEditFieldPIController         matlab.ui.control.NumericEditField
        K_iLabel_2                      matlab.ui.control.Label
        KpEditFieldPIController         matlab.ui.control.NumericEditField
        K_iEditField_2Label_3           matlab.ui.control.Label
        HighRefDegreeEditField          matlab.ui.control.NumericEditField
        LowRefDegreeEditField           matlab.ui.control.NumericEditField
        TuningPIPanel_Control_Params    matlab.ui.container.Panel
        TiEditFieldPIControl            matlab.ui.control.NumericEditField
        T_iEditFieldLabel               matlab.ui.control.Label
        KiEditFieldPIControl            matlab.ui.control.NumericEditField
        K_iEditField_2Label             matlab.ui.control.Label
        Gs_PlantPanel                   matlab.ui.container.Panel
        omega_G150Label                 matlab.ui.control.Label
        DivisionLine                    matlab.ui.control.Label
        GsLabel                         matlab.ui.control.Label
        numEditField                    matlab.ui.control.EditField
        denEditField                    matlab.ui.control.EditField
        Image9                          matlab.ui.control.Image
        Image6_4                        matlab.ui.control.Image
        Image7_2                        matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        InputResponseButtonGroup        matlab.ui.container.ButtonGroup
        NaturalButton                   matlab.ui.control.RadioButton
        RampButton                      matlab.ui.control.RadioButton
        StepButton                      matlab.ui.control.RadioButton
        TuningPIDPanel_Control_Params   matlab.ui.container.Panel
        zetaEditField_3Label_5          matlab.ui.control.Label
        ZetaEditFieldPIDControl         matlab.ui.control.NumericEditField
        BetaEditFieldPIDControl         matlab.ui.control.NumericEditField
        cLabel_11                       matlab.ui.control.Label
        KiEditFieldPIDControl           matlab.ui.control.NumericEditField
        K_iLabel                        matlab.ui.control.Label
        TauEditFieldPIDControl          matlab.ui.control.NumericEditField
        KpLabel_5                       matlab.ui.control.Label
        TuningPIPanel_Design_Params     matlab.ui.container.Panel
        PhiMEditFieldPIDesign           matlab.ui.control.NumericEditField
        cLabel_10                       matlab.ui.control.Label
        OmegaCEditFieldPIDesign         matlab.ui.control.NumericEditField
        cLabel_9                        matlab.ui.control.Label
        TuningPIDPanel_Design_Params    matlab.ui.container.Panel
        zetaEditField_3Label_4          matlab.ui.control.Label
        ZetaEditFieldPIDDesign          matlab.ui.control.NumericEditField
        OmegaCEditFieldPIDDesign        matlab.ui.control.NumericEditField
        cEditFieldLabel                 matlab.ui.control.Label
        BetaEditFieldPIDDesign          matlab.ui.control.NumericEditField
        cLabel_8                        matlab.ui.control.Label
        PhiMEditFieldPIDDesign          matlab.ui.control.NumericEditField
        KpLabel_4                       matlab.ui.control.Label
        OptimizePIButton                matlab.ui.control.Button
        TrackChangesCheckBox            matlab.ui.control.CheckBox
        ServoAngleLimitsSlider          matlab.ui.control.RangeSlider
        RefreshButton                   matlab.ui.control.Button
        PLOTButton                      matlab.ui.control.Button
        ServoAngleLimitsSliderLabel     matlab.ui.control.Label
        ControlDropDown                 matlab.ui.control.DropDown
        Image4                          matlab.ui.control.Image
        Image4_2                        matlab.ui.control.Image
        Image4_3                        matlab.ui.control.Image
        Image4_4                        matlab.ui.control.Image
        Image7_3                        matlab.ui.control.Image
        InputSignalTab                  matlab.ui.container.Tab
        NaturalPeriodSpinner            matlab.ui.control.Spinner
        PeriodSpinner_2Label_2          matlab.ui.control.Label
        NaturalRepetitionsSpinner       matlab.ui.control.Spinner
        RepetitionsSpinner_2Label_2     matlab.ui.control.Label
        RampPeriodSpinner               matlab.ui.control.Spinner
        PeriodSpinner_2Label            matlab.ui.control.Label
        RampRepetitionsSpinner          matlab.ui.control.Spinner
        RepetitionsSpinner_2Label       matlab.ui.control.Label
        StepPeriodSpinner               matlab.ui.control.Spinner
        PeriodSpinnerLabel              matlab.ui.control.Label
        StepRepetitionsSpinner          matlab.ui.control.Spinner
        RepetitionsSpinnerLabel         matlab.ui.control.Label
        NaturalMovementSignalParametersLabel  matlab.ui.control.Label
        RampSignalParametersLabel       matlab.ui.control.Label
        StepSignalParametersLabel       matlab.ui.control.Label
        InputSignalGraph                matlab.ui.control.UIAxes
        ServoTrajectory                 matlab.ui.control.UIAxes
        FunctionsResonseTab             matlab.ui.container.Panel
        StepResponsePanel               matlab.ui.container.Panel
        ZerosLabel                      matlab.ui.control.Label
        PolesLabel                      matlab.ui.control.Label
        TimeConstantLabel               matlab.ui.control.Label
        PeakLabel                       matlab.ui.control.Label
        OverShootLabel                  matlab.ui.control.Label
        SettlingTimeLabel               matlab.ui.control.Label
        TransientTimeLabel              matlab.ui.control.Label
        StepReference                   matlab.ui.control.UIAxes
        StepDisturbance                 matlab.ui.control.UIAxes
        EMGControlTab                   matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        ThresholdControlTab             matlab.ui.container.Tab
        EMGControlParameters_2          matlab.ui.container.Panel
        CH2DropDown                     matlab.ui.control.DropDown
        EMGLowerThreshold               matlab.ui.control.NumericEditField
        EMGUpperThreshold               matlab.ui.control.NumericEditField
        SpeedSetting_EMG                matlab.ui.control.DiscreteKnob
        ServoMotorSpeedLabel            matlab.ui.control.Label
        ApplyThresholdsButton_EMG       matlab.ui.control.StateButton
        EMGLowerThresholdSlider         matlab.ui.control.Slider
        LowerLabel                      matlab.ui.control.Label
        EMGUpperThresholdSlider         matlab.ui.control.Slider
        UpperLabel                      matlab.ui.control.Label
        EMGShowThresholdCheckBox        matlab.ui.control.CheckBox
        ModeSwitch_2                    matlab.ui.control.RockerSwitch
        ModeSwitch_2Label               matlab.ui.control.Label
        AppliedFilterButtonGroup_EMG    matlab.ui.container.ButtonGroup
        EMG_EWMVA                       matlab.ui.control.RadioButton
        EMG_CenteredMovingAverageButton  matlab.ui.control.RadioButton
        EMG_LeftSidedMovingAverageButton  matlab.ui.control.RadioButton
        EMGChannelLabel_2               matlab.ui.control.Label
        ThresholdVLabel                 matlab.ui.control.Label
        StartRecordingSwitch_DualThresh_EMG  matlab.ui.control.RockerSwitch
        StartSwitchLabel_4              matlab.ui.control.Label
        CH1DropDown                     matlab.ui.control.DropDown
        ElectromyographyControlGraphs_2  matlab.ui.container.Panel
        IMUPlot_EMG                     matlab.ui.control.UIAxes
        EMG_OneCH                       matlab.ui.control.UIAxes
        ForceControlTab                 matlab.ui.container.Tab
        TabGroup3_2                     matlab.ui.container.TabGroup
        ThresholdTab                    matlab.ui.container.Tab
        ForceControlGraphs              matlab.ui.container.Panel
        ForceParameters                 matlab.ui.container.Panel
        ThresholdmNLabel                matlab.ui.control.Label
        ShowThresholdCheckBox           matlab.ui.control.CheckBox
        SpeedSetting_Force              matlab.ui.control.DiscreteKnob
        ServoMotorSpeedLabel_4          matlab.ui.control.Label
        ApplyThresholdsButton_Force     matlab.ui.control.StateButton
        AppliedFilterButtonGroup_Force  matlab.ui.container.ButtonGroup
        EWMVAButton                     matlab.ui.control.RadioButton
        CenteredMovingAverageButton     matlab.ui.control.RadioButton
        LeftSidedMovingAverageButton    matlab.ui.control.RadioButton
        StartRecordingSwitch_DualThresh_Force  matlab.ui.control.RockerSwitch
        StartSwitchLabel_7              matlab.ui.control.Label
        ForceLowerThresholdSlider       matlab.ui.control.Slider
        LowerThresholdSliderLabel       matlab.ui.control.Label
        ForceUpperThresholdSlider       matlab.ui.control.Slider
        UpperThresholdSliderLabel       matlab.ui.control.Label
        IMUPlot_Force                   matlab.ui.control.UIAxes
        ForceReading                    matlab.ui.control.UIAxes
        ArmWeightCompensationTab        matlab.ui.container.Tab
        ForceControlGraphs_2            matlab.ui.container.Panel
        ForceParameters_2               matlab.ui.container.Panel
        ServoPowerSwitch_2              matlab.ui.control.Switch
        ServoPowerSwitch_2Label         matlab.ui.control.Label
        UserSpecificParametersLabel     matlab.ui.control.Label
        UserWeightkgSpinner             matlab.ui.control.Spinner
        UserWeightkgSpinnerLabel        matlab.ui.control.Label
        UserHeightmSpinner              matlab.ui.control.Spinner
        UserHeightmSpinnerLabel         matlab.ui.control.Label
        SpeedSetting_Force_2            matlab.ui.control.DiscreteKnob
        ServoMotorSpeedLabel_5          matlab.ui.control.Label
        AppliedFilterButtonGroup_Force_2  matlab.ui.container.ButtonGroup
        EWMVAButton_2                   matlab.ui.control.RadioButton
        CenteredMovingAverageButton_2   matlab.ui.control.RadioButton
        LeftSidedMovingAverageButton_2  matlab.ui.control.RadioButton
        StartRecordingSwitch_Force_2    matlab.ui.control.RockerSwitch
        StartSwitchLabel_8              matlab.ui.control.Label
        IMUPlot_Force_2                 matlab.ui.control.UIAxes
        ForceReading_2                  matlab.ui.control.UIAxes
        IMUPosePlotTab                  matlab.ui.container.Tab
        IMU_Parameters                  matlab.ui.container.Panel
        StartRecordingSwitch_IMU        matlab.ui.control.RockerSwitch
        StartSwitchLabel_5              matlab.ui.control.Label
        IMUPosePlot                     matlab.ui.container.Panel
        IMUPlot                         matlab.ui.control.UIAxes
    end

    properties (Access = public)
        % initialize datalines for live plotting
        dL_EMG_CH1_raw 
        dL_EMG_CH1_filt
        dL_EMG_CH1_Threshold
        dL_EMG_CH2_raw 
        dL_EMG_CH2_filt
        dL_EMG_CH2_Threshold
        dL_Force_raw 
        dL_Force_filt
        dL_Force_Upper_Threshold
        dL_Force_Lower_Threshold
        dL_ServoAgle_raw 
        dL_ServoAngle_reference_tracking
        dl_EMG_Calibrate
        dl_Force_Calibrate
        dl_Servo_Calibrate
        Offset_Force_filt
        zero_force_offset
        PLOT_degree;
        % Flags and variables that are accesible for all functions
        connectFlag = false;
        pairedFlag = false;
        selectFlag = false;
        chosen_option;
        chosen_option_indx;
        
        pair_status = "";
        avail_status = "";
        connected_device;
        con_options_friendlyName = "";
        con_options_COMPorts = "";
        battery_voltage = 0;
        battery_percentage = 0;
        charge_status = 0;
        batteryFlag = 0;
        servoState = false;
        Servo_Speed = 1;
        ForceisPlotting = false;
        EMGisPlotting = false;
        IMUisPlotting = false;
        PI_design_flag = false;
        PI_control_flag = true;
        PID_design_flag = false;
        PID_control_flag = false;
        P_controller_flag = false;
        PI_controller_flag = false;
        PID_controller_flag = false;
        Compare_changes_flag = false;
        ShowInputSignal_flag = true;
        upper_threshold;
        lower_threshold;
        upperHandle = [];
        lowerHandle = [];
        hForce = [];
        h2Force = [];
        EMGHandleCH1 = [];
        EMGHandleCH2 = [];
        emg1_h = [];
        emg1_h2 = [];
        EMGlowerHandle = [];
        EMGupperHandle = [];
        EMG_Upper_Threshold;
        EMG_Lower_Threshold;
        angleStep = 100;
        BiChannel_flag = false;
        analogEMG0Pin = 1;
        analogEMG1Pin = 2;

        %IMU stuff
        posePlot_Shoulder
        posePlot_Elbow % pose plot element
        upper_arm % stl
        lower_arm % stl
        torso % stl
        head % stl
        upper_arm_posepatch % trisurf
        lower_arm_posepatch % trisurf
        fuse
        rec_data % pre-rec data
        % axes_IMU % axes elemenmt

        % RGB Colors for LED
        color_green = 0x03;
        color_white = 0x01;
        color_orange = 0x02;
        color_blue = 0x04;
        color_yellow = 0x06;
        color_off = 0x00;
        color_more_green = 0x07
        color_red = 0x05;
        
        Stable_Factor;
        Danger;

        angle_feedback;
        angle_END;
        pathtest = "";

        % defines for communication protocol
        startMarker = 0x3C;
        endMarker = 0x3E;
        header = 0xFF; %binary: 1111 1111, dec: 255
        force_header = 0x8F;
        max_data_length = 200;
        data = [];
        
        message2receive;
        message2send;
        checksum;
        opcode;

        MaxPoints = 50;  % Maximum number of points to display

        %% PI controller params
        Ki = 0;
        Kp = 0;
        Kd = 0;

        %% Filter params
        filter_type
        window_size
        
        
        %% Input Signals params
        repetitions
        period
        

        %% EMG Data Simulation
        MVCValues = [NaN];        % Array to store measured MVC values
        AcquisitionTime = 15;  % Total acquisition time in seconds
        MiddleDuration = 5;    % Duration for the middle seconds
        SamplingRate = 66;   % sEMG sampling rate in Hz
        sEMGData  =[];

        ForceValues = [NaN];
        AngleValues = [NaN];
        CommManager
    end
    
    properties (Access = private)
        % CommManager %% TODO make this property private and add getter
        % function
    end

    methods (Access = private)
        function initializeCommunication(app)
            config = struct(...
                'max_data_length', app.max_data_length, ...
                'header', app.header, ...
                'startMarker', app.startMarker, ...
                'endMarker', app.endMarker);

            app.CommManager = CommunicationManager(app.connected_device, config);
        end

        function sendComm(app, opcode, dataLength, data)
            disp(class(app));  % This will show you what type app is
            disp(properties(app));  % This will list all properties of app
            app.CommManager.sendCommand(opcode, dataLength, data);    
        end

        function [reply, data, message_type] = receiveComm(app)
            [reply, data, message_type] = app.CommManager.receiveCommand();       
        end


    %% Check Battery voltage   
        function CheckBattery(app, mode)
            app.pathtest = fileparts(mfilename('fullpath'));
            HIGH_BAT_VOLT = 8400;
            LOW_BAT_VOLT = 6200;
            %%%%%% get the Battery Voltage measurment %%%%%%
            %configurePin(connected_device,'A6','AnalogInput');
            %app.Battery_Voltage = readVoltage(connected_device,'A6'); %%
            %richtig schreiben!!!!
            %app.battery_voltage = round(Battery_Voltage,2)*3;
            [app.batteryFlag, app.battery_voltage] = CommunicationFunctions.getBatteryVoltage(app);
            % battery percentage trend line
            app.battery_percentage = (app.battery_voltage-6200)/(8000-6200)*100;
            app.BatteryPercentage.Text = [num2str(app.battery_percentage,2), '%'];
            %app.battery_voltage = 7640;
    
            if app.battery_voltage >=7600
                app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos','fa-battery-4.png');
                if mode == 2
                    app.StatusLamp.Color = [0,255,0]./255;  %green
                    app.Label.Text = 'connected';
                end
                app.charge_status=1;
            elseif app.battery_voltage>=7200 && app.battery_voltage<7600
                app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos','fa-battery-3.png');
                %app.Lamp_2.Color = [0.92 0.52 0.03];
                if mode == 2
                    app.StatusLamp.Color = [255,255,0]./255;  %green-yellowish
                    app.Label.Text = 'connected';
                end
                app.charge_status=1;
            elseif app.battery_voltage>=6800 && app.battery_voltage<7200
                app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos','fa-battery-2.png');
                %app.Lamp_2.Color = [0.92 0.52 0.03];
                if mode == 2
                    app.StatusLamp.Color = [255,130,0]./255; %orange
                    app.Label.Text = 'connected';
                end
                app.charge_status=1;
            elseif app.battery_voltage>=6200 && app.battery_voltage<6800
                app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos','fa-battery-1.png');
                %app.Lamp_2.Color = [0.92 0.52 0.03];
                if mode == 2
                    app.StatusLamp.Color = [255,47,0]./255; %red
                    app.Label.Text = 'connected';
                end
                app.charge_status=1;
            else
                app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos','fa-battery-0.png');
                %app.Lamp_2.Color = "red";
                if mode == 2
                    app.StatusLamp.Color = [255,0,0]./255;
                    app.Label.Text = 'connected';
                end
                app.charge_status=0;
                app.battery_voltage = 6200;
                report = ["Opps, seems like the battery is empty."; "Please recharge using the provided charger!"];
                uialert(app.JUSTEXOUIFigure, report, "BATTERY EMPTY", "ICON", "error")
            end
    
        end


        function csum = create_checksum(app)
            %checksum = double(message_header) + double(opcode) + double(snddataLength) + double(sum(snddata));
            csum = double(app.message2send.header) + double(app.message2send.opcode) + double(app.message2send.dataLength); %+ double(sum(snddata));
    
        end
    
        % not done yeat...
        function cbool = check_checksum(app)
                % checksum = mod(sum([msg2receive.header, msg2receive.opcode, typecast(uint16(msg2receive.dataLength),'uint8'), msg2receive.data]), 256);
                calc_checksum = mod(sum([app.msg2receive.header, app.msg2receive.opcode, typecast(uint16(app.msg2receive.dataLength),'uint8')]), 256);
                if calc_checksum == csum
                    cbool = true;
                else
                    cbool = false;
                end
            end
    
    %% send Command/Data Package to Arduino via Serial Port Connection 
        % function sendCommand(app, opcode, dataLength, data)
        %     if(dataLength>app.max_data_length)
        %         error('message too long!')
        %         return
        %     end
        % 
        %     % create checksum
        %     checksum = double(app.header) + double(opcode) + double(dataLength);
        % 
        %     %create structure of message
        %     message2send = struct('startMarker', uint8(app.startMarker), 'header',uint8(app.header), 'opcode', uint8(opcode), 'dataLength', uint8(dataLength),'data',uint8(data),'checksum',mod(checksum,256), 'endMarker', uint8(app.endMarker));
        %     % transmit!
        %     flush(app.connected_device)
        %     if message2send.dataLength >0
        %         write(app.connected_device,[message2send.startMarker message2send.header message2send.opcode message2send.dataLength message2send.data message2send.checksum message2send.endMarker],'uint8');
        % 
        %     else
        %         write(app.connected_device,[message2send.startMarker message2send.header message2send.opcode message2send.dataLength message2send.checksum message2send.endMarker],'uint8');
        % 
        %     end
        % end
    
    %% receive Answer/Data package from Arduino via Serial Port Connection
    % function [reply data message_type] = receiveCommand(app)
    %         % create structure of message
    %         message2receive = struct('header',0,'opcode',0,'dataLength',0,'data',0,'checksum',0);
    %         % receive!
    %         try
    %             temp = read(app.connected_device,1, 'uint8');
    %             % tic
    %             while((temp ~= app.startMarker))
    %                 temp = read(app.connected_device,1, 'uint8');
    %             end
    %             % disp('receiving package');
    %             % toc
    %             tStart = tic;
    %             message2receive.header = read(app.connected_device,1, 'uint8');
    %             tEnd = toc(tStart);
    %             if tEnd > 1
    %                 message2receive.header = 0;
    %                 message2receive.opcode = 0xBB;
    %                 message2receive.data = 0;
    %                 message2receive.checksum = 0;
    %                 message_type = 'CMD';
    %                 reply = message2receive.opcode;
    %                 data = uint8(message2receive.data);
    %                 return
    %             end
    %             % toc
    %             % toc
    %             % tic
    %             message2receive.opcode = read(app.connected_device,1,'uint8');
    %             % toc
    % 
    %             switch message2receive.opcode
    %                 case 0x7F
    %                     % Servo Streaming Message
    %                     message2receive.dataLength = read(app.connected_device,1,'uint8');
    %                     % message2receive.dataLength = typecast(flip(tmp),'uint16');
    %                     message_type = 'Servo';
    %                     reply = message2receive.opcode;
    %                 case 0x8F
    %                     % Force Streaming Message
    %                     message2receive.dataLength = read(app.connected_device,1,'uint8');
    %                     % message2receive.dataLength = typecast(flip(tmp),'uint16');
    %                     message_type = 'Force';
    %                     reply = message2receive.opcode;
    %                 case 0x9F
    %                     % EMG Steaming Message
    %                     message2receive.dataLength = read(app.connected_device,1,'uint8');
    %                     % message2receive.dataLength = typecast(flip(tmp),'uint16');
    %                     message_type = 'EMG';
    %                     reply = message2receive.opcode;
    %                 case 0x6F
    %                     % IMU Steaming Message
    %                     message2receive.dataLength = read(app.connected_device,1,'uint8');
    %                     % message2receive.dataLength = typecast(flip(tmp),'uint16');
    %                     message_type = 'IMU';
    %                     reply = message2receive.opcode;
    %                 otherwise
    %                     % CMD message
    %                     message2receive.dataLength = read(app.connected_device, 1, 'uint8');
    %                     message_type = 'CMD';
    %                     reply = message2receive.opcode;
    %             end
    %             % tic
    %             if message2receive.dataLength > 0
    %                message2receive.data = read(app.connected_device,message2receive.dataLength,'uint8');
    %             % for i= 1:message2receive.dataLength
    %             %    message2receive.data(i) = read(app.connected_device, 1, 'uint8');
    %             % end
    %             end
    %             % toc
    %             % tic
    %             message2receive.checksum = read(app.connected_device,1,'uint8');
    %             % check message for integrity
    %             checksum = mod(sum([message2receive.header, message2receive.opcode, typecast(uint16(message2receive.dataLength),'uint8')]), 256);
    %             % toc
    %             if message2receive.checksum == checksum
    %                 reply = message2receive.opcode;
    %                 data = uint8(message2receive.data);
    %                 return
    %             else
    %                 reply = -1;
    %                 data = [];
    %                 %error('wrong checksum of message!')
    % 
    %             end
    % 
    %         catch e
    %             if exist('self.connected_device','var')
    %                delete(app.connected_device);
    %             end
    % 
    % 
    %              error(e.message)
    %             return
    %         end
    %     end
    % 


    %% Set PI Controller Params
    %------------------------------------------------------------------
   
        function [flag] = setPIControlParams(app, initialStep, finalStep, totalIterations, Kp, Ki)
            flag = false;
            uint8_initialStep = typecast(initialStep, 'uint8');
            uint8_finalStep = typecast(finalStep, 'uint8');
            uint8_totalIterations = typecast(totalIterations, 'uint8');
            uint8_Kp = typecast(Kp, 'uint8');
            uint8_Ki = typecast(Ki, 'uint8');
            settings = [uint8_initialStep uint8_finalStep [0 uint8_totalIterations] uint8_Kp uint8_Ki];
            app.sendComm(0x18, 14, settings);
            [reply data msg_type] = app.receiveComm();
            if reply == 0xBB
                flag = true;
            end
        end
        

    %% Start PI Controller 
    %------------------------------------------------------------------
   
    function [flag] = startPIController(app)
            flag = false;
            app.sendComm(0x17, 0, 0);
            [reply data msg_type] = app.receiveComm();
            if reply == 0xBB
                flag = true;
            end
        end


    %------------------------------
    %% set EMG Threshold in GUI
        function AdjustEMGThresholdsGUI(app)
            EMG_thresh_upper = app.EMGUpperThresholdSlider.Value;
            EMG_thresh_lower = app.EMGLowerThresholdSlider.Value;
            
            
            if EMG_thresh_lower >= EMG_thresh_upper && (EMG_thresh_upper >=1)
                EMG_thresh_lower = EMG_thresh_upper-1;
            elseif (EMG_thresh_lower >= EMG_thresh_upper) && (EMG_thresh_upper == 0)
                EMG_thresh_lower = 0;
                EMG_thresh_upper = 1;
            end
            if EMG_thresh_lower < EMG_thresh_upper
                app.EMGUpperThresholdSlider.Value = EMG_thresh_upper;
                app.EMGLowerThresholdSlider.Value = EMG_thresh_lower;
                app.EMGUpperThreshold.Value = EMG_thresh_upper;
                app.EMGLowerThreshold.Value = EMG_thresh_lower;
            end
        end

    % set Force Threshold in GUI


    % set Servo Thresholds in GUI
    function AdjustServoLimitsGUI(app)
        value_low = app.LowRefDegreeEditField.Value;
        value_high = app.HighRefDegreeEditField.Value;
        
        if value_low >= value_high && (value_high >=1)
            value_low = value_high-1;
        elseif (value_low >= value_high) && (value_high == 0)
            value_low = 0;
            value_high = 1;
        end
        if value_low < value_high
            app.ServoAngleLimitsSlider.Value(1) = value_low; 
            app.ServoAngleLimitsSlider.Value(2) = value_high;
            app.LowRefDegreeEditField.Value = value_low;
            app.HighRefDegreeEditField.Value = value_high;
        end
    end

    function AdjustServoLimitsSliderGUI(app)
        value_low = app.ServoAngleLimitsSlider.Value(1); 
        value_high = app.ServoAngleLimitsSlider.Value(2);
        
        if value_low >= value_high && (value_high >=1)
            value_low = value_high-1;
        elseif (value_low >= value_high) && (value_high == 0)
            value_low = 0;
            value_high = 1;
        end
        if value_low < value_high
            app.ServoAngleLimitsSlider.Value(1) = value_low; 
            app.ServoAngleLimitsSlider.Value(2) = value_high;
            app.LowRefDegreeEditField.Value = value_low;
            app.HighRefDegreeEditField.Value = value_high;
        end
    end


    %% FOR DEBUG ONLY
    % Random EMG Values
    %----------------------------------------------------------------
        function sEMGValue = acquireData(app)
                % Simulate sEMG data acquisition
                
                    % Replace this with your actual sEMG data acquisition code
                    sEMGValue = 2+rand()*5;  % Simulated sEMG value
                    app.sEMGData = [app.sEMGData, sEMGValue];                
                
        end
    
    %% Calculate MVC from Calibration
    %-----------------------------------------------------------------
        function calculateMVC(app)
                % Calculate MVC from the middle seconds of the acquired data
                middleStart = floor((app.AcquisitionTime - app.MiddleDuration) / 2) * app.SamplingRate + 1;
                middleEnd = middleStart + app.MiddleDuration * app.SamplingRate - 1;
    
                middleData = app.sEMGData(middleStart:middleEnd);
                maxMVC = max(middleData);
    
                % Store the measured MVC value
                app.MVCValues = [app.MVCValues, maxMVC];
        end
    
    %% Save data to CSV File
    %------------------------------------------------------------------
        function saveCSV(app, timestamp)
            % Save MVC, force, and angle values to a CSV file
            current_path = pwd;
            % Construct the dynamic filename with the current date
            temp_filename = 'CalibrationData' + timestamp + '.csv';
            filename = fullfile(current_path, temp_filename);
            fileheader = {'Timestamp', 'MVC Values', 'Force Values', 'Angles'};
                  
            %writetable(measurementTable, filename, 'WriteMode','replacefile');
            % Open the file for writing
            fileID = fopen(filename, 'w');
        
            % Write the header
            fprintf(fileID, '%s,%s,%s,%s\n', fileheader{:});
        
            % Write the data
            for i = 1:length(timestamp)
                fprintf(fileID, '%s,%.4f,%.4f,%.4f\n', timestamp(i), app.MVCValues(i), app.ForceValues(i), app.AngleValues(i));
            end
    
        
            % Close the file
            fclose(fileID);
        end
    
    %% Decode factorial G(s) Plant input
    function G = analyze_transfer_function(app,tf_num, tf_den)
            % Preprocess the string
            num_str = preprocess_tf_string(app, tf_num);
            den_str = preprocess_tf_string(app, tf_den);
                        
            % Convert to symbolic expressions
            syms s
            num = str2sym(num_str);
            den = str2sym(den_str);
            % Convert to MATLAB transfer function
            numerator = double(coeffs(num, s, 'all'));
            denominator = double(coeffs(den, s, 'all'));
            G = tf(numerator, denominator, 0);
            
            % Display the transfer function
            disp('Continuous-time transfer function:');
             
        end

        function processed_str = preprocess_tf_string(app, tf_string)
            % Remove spaces
            tf_string = strrep(tf_string, ' ', '');
            
            % Replace ^ with .^
            tf_string = strrep(tf_string, '^', '.^');
            
            % Add multiplication symbols where needed
            tf_string = regexprep(tf_string, '(\d+)([a-zA-Z])', '$1*$2');
            tf_string = regexprep(tf_string, ')(',  ')*(' );
            % tf_string = regexprep(tf_string, '([a-zA-Z0-9)])([a-zA-Z(])', '$1*$2');
            tf_string = regexprep(tf_string, '([a-zA-Z0-9)])(', '$1*(');
            
            processed_str = tf_string;
        end
    %% Find correct values for PID design parameters
        function [F,Ki,Kinf,tau,err]=pid_design(app,G,wc,phim,beta,zeta)
            
            %  [F,Ki,Kinf,tau,err]=PID_BETA(G,wc,phim,beta,zeta) returns controller 
            %  parameters for a PID controller
            %
            %  F=Ki*(1+2*zeta*s*tau+(s*tau)^2)/(s*(1+s*tau/beta))
            %  Kinf=Ki*tau*beta
            %  Jv=1/Ki
            %
            %  err=1 => desired phase margin not achievable   OK R 020223
            
            err=0;
            [Gwc,phiGwc]=bode(G,[wc]);
            if (phiGwc>90), phiGwc=phiGwc-360; end
            phiL=-180+phim;
            phiF=phiL-phiGwc;
            [wctau,Kinf,phiF0]=pid_design_beta(app,Gwc,phiF,beta,zeta);
            if (abs(phiF-phiF0)>1e-4)
                err=1; 
                warning('Desired stability margin not achievable')
            end
            phiF;
            tau=wctau/wc;
            Ki=Kinf/tau/beta;
            F=tf([Ki*tau^2 Ki*2*zeta*tau Ki],[tau/beta 1 0]);
            if err==0
                err = abs(norm(feedback(G*F,1)))==inf;
                if err
                    warning('Unstable closed loop system')
                end
            end
        end

        function [wctau,Kinf,phiF0]=pid_design_beta(app,Gwc,phiF,beta,zeta)
        %
        %  [wctau,Kinf,phiF0]=pid_design_beta(Gwc,phiF,beta,zeta)   OK R 020223
        %
        
            wctau=logspace(-1,1,200);
            
            for iter=1:2,
            
               r=sqrt((1-wctau.^2).^2+(2*zeta*wctau).^2);
               res = acos((1-wctau.^2)./r) - atan(wctau/beta) - phiF*pi/180;
               wctau=inversefcn1(app,wctau,res,pi/2);
            
               if isempty(wctau)
                   phiF0=phiF+1;
                   Kinf=1;
                   wctau=1;    
                   return
               end
               if iter==1
                   wctau=wctau*[0.9805:0.001:1.02];
               end
            end
            
            r=sqrt((1-wctau^2)^2+(2*zeta*wctau)^2);
            Kinf=beta*wctau*sqrt(1+(wctau/beta)^2)/Gwc/r;
            
            phiF0=(acos((1-wctau^2)/r) - atan(wctau/beta))*180/pi-90;
        end

        function [xval,xival,zval]=inversefcn1(app,x,y,yconst,z)  % OKR o test 020302
        
            if y(1)<=yconst
                ix=find(y>=yconst);
            else
                ix=find(y<=yconst);
            end
            N=length(x);
            
            if isempty(ix) | (N<2)
                xval=[];
                zval=[];
                xival=[];
                return
            else
                i=min(ix(1),N-1);
                xival=i;
            end
            
            if abs(y(i+1)-y(i))>=1e-12
                xval=x(i)+(x(i+1)-x(i))/(y(i+1)-y(i))*(yconst-y(i));
            else
                xval=x(i);
            end
            
            if nargin==4 & nargout==3
                zval=z(i)+(z(i+1)-z(i))/(x(i+1)-x(i))*(xval-x(i));
            end
        end
    
        %% Response Input Functions
        function [signal, t] = symmetric_ramp_input(app,min_angle, max_angle, period, num_repeats)
            T = period + (num_repeats * period);% Total time duration of motion in seconds
            dt = 0.02; % Time step
            t = 0:dt:T; % Time vector
            % Generate a repeating symmetric ramp input signal with plateaus
            signal = zeros(size(t));
            quarter_period = period / 4;
            amplitude = max_angle - min_angle;
            
            for i = 0:num_repeats-1
                start = period/2 + (i * period);
                ramp_up_end = start + period/4;
                plateau_top_end = start + period/2;
                ramp_down_end = start + 3*period/4;
                ending = start + period;
                
                % Ramp up
                segment = t(t >= start & t < ramp_up_end);
                signal(t >= start & t < ramp_up_end) = (amplitude * (segment - start)/quarter_period);
                
                % Top plateau
                signal(t >= ramp_up_end & t < plateau_top_end) = amplitude;
                
                % Ramp down
                segment = t(t >= plateau_top_end & t < ramp_down_end);
                signal(t >= plateau_top_end & t < ramp_down_end) = amplitude - (amplitude * (segment - plateau_top_end)/quarter_period);
                
                % Bottom plateau
                signal(t >= ramp_down_end & t < ending) = 0;
            end
            signal(t>=ending & t<(ending+period/2)) = 0;
        end

        function [signal, t] = step_input(app,min_angle, max_angle, period, num_repeats)
            T = period + (num_repeats * period);% Total time duration of motion in seconds, add half period at start and end of the input signal
            dt = 0.02; % Time step
            t = 0:dt:T; % Time vector
            % Generate a repeating step input signal
            signal = zeros(size(t));
            amplitude = max_angle - min_angle;
            for i = 0:num_repeats-1
                start = period/2 + (i * period);
                ending = start + period/2;
                signal(t >= start & t < ending) = amplitude;
            end
            signal(t>=ending & t<(ending+period/2)) = 0;
        end

        
        function [angle, t] = natural_elbow_movement(app,min_angle, max_angle, period, num_repeats)
            T = period + (num_repeats * period);% Total time duration of motion in seconds
            dt = 0.02; % Time step
            t = 0:dt:T; % Time vector

            frequency = 1/period;
            % Simulate natural elbow flexion/extension
            amplitude = (max_angle - min_angle) / 2;
            offset = (max_angle - min_angle) / 2;
            angle = amplitude * cos(2 * pi * frequency * t) + offset;
            angle(t >= 0 & t < period/2) = 0;
            angle(t >= ((num_repeats*period)+period/2) & t <= ((num_repeats*period)+period)) = 0;
        end

        function [angle, t] = stroke_elbow_movement(app,min_angle, max_angle, period, num_repeats)
            T = num_repeats * period;% Total time duration of motion in seconds
            dt = 0.1; % Time step
            t = 0:dt:T; % Time vector
            frequency = 1/period;
            % Simulate stroke patient elbow movement
            natural_angle = natural_elbow_movement(app, t,min_angle, max_angle, frequency);
            
            % Add realistic tremor
            tremor = realistic_tremor(app,t);
            
            % Add jerkiness and reduced range of motion
            jerkiness = 10 * sin(2 * pi * 0.2 * t);
            reduced_rom_factor = 0.7;
            
            angle = (natural_angle - min_angle) * reduced_rom_factor + min_angle + tremor + jerkiness;
            
            % Clip to ensure angles stay within bounds
            angle = max(min_angle, min(max_angle, angle));
        end

        function tremor = realistic_tremor(app,t)
            % Generate a realistic tremor
            
            % Combine multiple frequencies with random phases
            f1 = 8 + randn(1); % ~8 Hz, varying between individuals
            f2 = 12 + randn(1); % ~12 Hz, varying between individuals
            phase1 = 2*pi*rand(1);
            phase2 = 2*pi*rand(1);
            
            % Amplitude modulation to simulate varying tremor intensity
            amp_mod = 1 + 0.5*sin(2*pi*0.1*t);
            
            % Combine components
            tremor = amp_mod .* (2*sin(2*pi*f1*t + phase1) + sin(2*pi*f2*t + phase2));
            
            % Add some noise
            tremor = tremor + 0.5*randn(size(t));
            
            % Normalize amplitude
            tremor = tremor / max(abs(tremor)) * 3; % Adjust the 3 to change overall tremor magnitude
        end
        
        %% Filter Implemenations
        function obj = RealTimeFilter(app, filter_type, window_size)
            obj.filter_type = filter_type;
            obj.window_size = window_size;
            obj.buffer = zeros(1, window_size);
            if strcmp(filter_type, 'ewma')
                obj.alpha = 2 / (window_size + 1);
            end
        end

        function filtered_value = update(app,obj, new_data)
            % Shift buffer and add new data
            obj.buffer = [obj.buffer(2:end), new_data];
            
            switch obj.filter_type
                case 'centered_mva'
                    filtered_value = mean(obj.buffer);
                case 'left_sided_mva'
                    filtered_value = mean(obj.buffer);
                case 'ewma'
                    if isempty(filtered_value)
                        filtered_value = new_data;
                    else
                        filtered_value = obj.alpha * new_data + (1 - obj.alpha) * filtered_value;
                    end
                otherwise
                    error('Unknown filter type');
            end
        end

    %% En/Disable all Tab buttons
    function changeConnectionTab(app, state)
        switch state
            case 'disable'
                app.BTDeviceDropDown.Enable = "off";
                app.RefreshButton_Connection_List.Enable = "off";
                app.ConnectButton.Enable = "off";
                app.EmergnecySTOPButton.Enable = "off";
            case 'enable'
                app.BTDeviceDropDown.Enable = "on";
                app.RefreshButton_Connection_List.Enable = "on";
                app.ConnectButton.Enable = "on";
                app.EmergnecySTOPButton.Enable = "on";
            case 'connected'
                app.BTDeviceDropDown.Enable = "off";
                app.RefreshButton_Connection_List.Enable = "off";
                app.ConnectButton.Enable = "on";
                app.EmergnecySTOPButton.Enable = "on";
            case 'initial'
                app.BTDeviceDropDown.Enable = "off";
                app.RefreshButton_Connection_List.Enable = "off";
                app.ConnectButton.Enable = "off";
                app.EmergnecySTOPButton.Enable = "off";
        end
    end
    

    function changeTransferFunctionTab(app, state)
        switch state
            case 'disable'
                app.PIDControllerPanel.Enable = "off";
                app.PControllerPanel.Enable = "off";
                app.PIControllerPanel.Enable = "off";
                app.TuningPIDPanel_Control_Params.Enable = "off";
                app.TuningPIPanel_Control_Params.Enable = "off";
                app.TuningPIDPanel_Design_Params.Enable = "off";
                app.TuningPIPanel_Design_Params.Enable = "off";
                app.Gs_PlantPanel.Enable = "off";
                app.PLOTButton.Enable = "off";
                app.OptimizePIButton.Enable = "off";
                app.OptimizePIDButton.Enable = "off";
                app.RefreshButton.Enable = "off";
                app.ServoAngleLimitsSlider.Enable = "off"; 
                app.LowRefDegreeEditField.Enable = "off";
                app.HighRefDegreeEditField.Enable = "off";
                app.InputResponseButtonGroup.Enable = "off";
                app.Gs_PlantPanel.Enable = "off";

            case 'enable'
                app.PIDControllerPanel.Enable = "on";
                app.PControllerPanel.Enable = "on";
                app.PIControllerPanel.Enable = "on";
                app.TuningPIDPanel_Control_Params.Enable = "on";
                app.TuningPIPanel_Control_Params.Enable = "on";
                app.TuningPIDPanel_Design_Params.Enable = "on";
                app.TuningPIPanel_Design_Params.Enable = "on";
                app.Gs_PlantPanel.Enable = "on";
                app.PLOTButton.Enable = "on";
                app.OptimizePIButton.Enable = "on";
                app.OptimizePIDButton.Enable = "on";
                app.RefreshButton.Enable = "on";
                app.ServoAngleLimitsSlider.Enable = "on";
                app.LowRefDegreeEditField.Enable = "on";
                app.HighRefDegreeEditField.Enable = "on";
                app.InputResponseButtonGroup.Enable = "on";
                app.Gs_PlantPanel.Enable = "on";

            case 'initial'
                app.PLOTButton.Enable = "on";
                app.ControlDropDown.Enable = "on";
                app.OptimizePIButton.Enable = "on";
                app.ServoAngleLimitsSlider.Enable = "on";
                app.TuningPIPanel_Control_Params.Enable = "on";
                app.LowRefDegreeEditField.Enable = "on";
                app.HighRefDegreeEditField.Enable = "on";
                app.InputResponseButtonGroup.Enable = "on";
                app.Gs_PlantPanel.Enable = "off";
        end
    end

    function changeServoControlPanel(app, state)
        switch state
            case 'disable'
                app.Servo_Plot_Reset.Enable = "off";
                app.ExecuteButton.Enable = "off";
                app.ServoPowerSwitch.Enable = "off";
                app.ServoSpeedKnob.Enable = "off";
                
            case 'enable'
                app.Servo_Plot_Reset.Enable = "on";
                app.ExecuteButton.Enable = "on";
                app.ServoPowerSwitch.Enable = "on";
                app.ServoSpeedKnob.Enable = "on";
        end
    end

    function changeEMGControlTab(app, state)
        switch state
            case 'disable'
                app.EMGControlParameters_2.Enable = "off";
                app.StartRecordingSwitch_DualThresh_EMG.Enable = "off";
                app.CH1DropDown.Enable = "off";
                app.ApplyThresholdsButton_EMG.Enable = "off";
                app.SpeedSetting_EMG.Enable = "off";
                app.EMGLowerThreshold.Enable = "off";
                app.EMGUpperThreshold.Enable = "off";
                app.EMGLowerThresholdSlider.Enable = "off";
                app.EMGUpperThresholdSlider.Enable = "off";
                app.AppliedFilterButtonGroup_EMG.Enable = "off";

            case 'enable'
                app.EMGControlParameters_2.Enable = "on";   
                app.StartRecordingSwitch_DualThresh_EMG.Enable = "on";
                app.CH1DropDown.Enable = "on";
                app.ApplyThresholdsButton_EMG.Enable = "on";
                app.SpeedSetting_EMG.Enable = "on";
                app.EMGLowerThreshold.Enable = "on";
                app.EMGUpperThreshold.Enable = "on";
                app.EMGLowerThresholdSlider.Enable = "on";
                app.EMGUpperThresholdSlider.Enable = "on";
                app.AppliedFilterButtonGroup_EMG.Enable = "on";

            case 'initial'
                app.EMGControlParameters_2.Enable = "on"; 
                app.StartRecordingSwitch_DualThresh_EMG.Enable = "on";
                app.CH1DropDown.Enable = "on";
                app.ApplyThresholdsButton_EMG.Enable = "off";
                app.SpeedSetting_EMG.Enable = "on";
                app.EMGLowerThreshold.Enable = "on";
                app.EMGUpperThreshold.Enable = "on";
                app.EMGLowerThresholdSlider.Enable = "on";
                app.EMGUpperThresholdSlider.Enable = "on";
                app.AppliedFilterButtonGroup_EMG.Enable = "on";

            case 'running'
                app.EMGControlParameters_2.Enable = "on"; 
                app.StartRecordingSwitch_DualThresh_EMG.Enable = "on";
                app.CH1DropDown.Enable = "off";
                app.ApplyThresholdsButton_EMG.Enable = "on";
                app.SpeedSetting_EMG.Enable = "off";
                app.EMGLowerThreshold.Enable = "on";
                app.EMGUpperThreshold.Enable = "on";
                app.EMGLowerThresholdSlider.Enable = "on";
                app.EMGUpperThresholdSlider.Enable = "on";
                app.AppliedFilterButtonGroup_EMG.Enable = "on";
        end
    end

    function changeForceControlTab(app, state)
        switch state
            case 'disable'
                app.ForceParameters.Enable = "off";
                app.StartRecordingSwitch_DualThresh_Force.Enable = "off";
                app.ApplyThresholdsButton_Force.Enable = "off";
                app.SpeedSetting_Force.Enable = "off";
                app.ForceLowerThresholdSlider.Enable = "off";
                app.ForceUpperThresholdSlider.Enable = "off";
                app.AppliedFilterButtonGroup_EMG.Enable = "off";

            case 'enable'
                app.ForceParameters.Enable = "on";
                app.StartRecordingSwitch_DualThresh_Force.Enable = "on";
                app.ApplyThresholdsButton_Force.Enable = "on";
                app.SpeedSetting_Force.Enable = "on";
                app.ForceLowerThresholdSlider.Enable = "on";
                app.ForceUpperThresholdSlider.Enable = "on";
                app.AppliedFilterButtonGroup_Force.Enable = "on";

            case 'initial'
                app.ForceParameters.Enable = "on";
                app.StartRecordingSwitch_DualThresh_Force.Enable = "on";
                app.ApplyThresholdsButton_Force.Enable = "off";
                app.SpeedSetting_Force.Enable = "on";
                app.ForceLowerThresholdSlider.Enable = "on";
                app.ForceUpperThresholdSlider.Enable = "on";
                app.AppliedFilterButtonGroup_Force.Enable = "on";

            case 'running'
                app.ForceParameters.Enable = "on";
                app.StartRecordingSwitch_DualThresh_Force.Enable = "on";
                app.ApplyThresholdsButton_Force.Enable = "on";
                app.SpeedSetting_Force.Enable = "off";
                app.ForceLowerThresholdSlider.Enable = "on";
                app.ForceUpperThresholdSlider.Enable = "on";
                app.AppliedFilterButtonGroup_Force.Enable = "on";
        end
    end


    %% IMU Plotting functions
        function new_stl=transformStl(app,stl,p,quat)
            T=se3(quat2rotm(quat),p);
            points = stl.Points;
            t_points = transform(T,points);
            clist = stl.ConnectivityList;
            new_stl=triangulation(clist,t_points);
        end
    
        function tP=calcImuPos(app,quat,radius)
            norm_vect=[0 -1 0]'; %y column vector
            rotm = quat2rotm(quat); %get rot matrix from quaternion
            nVec = rotm*norm_vect; %get normal vector of xy-plane (Z axis)
            tP = radius*nVec;   
        end
    
        function [tP, rotm]=calcServoPos(app,theta)
            x = cos(theta);
    y = sin(theta);
    z = 0;
            %rotm = quat2rotm(quat); %get rot matrix from quaternion
            %z, y, x
            rotm = eul2rotm([theta, 0,0]);
            tP = [x; y; z];   
        end


        function plotPose(app,property, p,q)
            set(property,Position = p,Orientation = q);
        end


    %% Force Recording 
  
        function StartRecordingForce(app)
            Fs = 20;
            radius = 2.5;
            lims = radius*2;
            goalFPS = 20;
            counter = 1;
            app.window_size = 5;  
            force_data = zeros(1, app.window_size);
            filtered_force = zeros(1, app.window_size);
            forceIsFiltered_Old = zeros(1, app.window_size);
            forceIs_Old = zeros(1, app.window_size);
            knownFrequency = 10;
            timeStep = 1 / knownFrequency;
            while(app.ForceisPlotting)
                if(counter == 1)
                    
                    % Initialize Filter variables
                    windowSize = 5;
                    dataBuffer = zeros(1, windowSize);
                    currentIndex = 1;
                    i=1;
                    % start Acquissition
                    CommunicationFunctions.startAcquisition(app, 0x41);
                    a = tic; % reset timer after updating     
                end

                % if ((app.charge_status == 1) && (app.servoState))
 
                %% Read Force Data
                
                % read and convert new data to mN
                % 
                % calculate one factor for performance: 
                [temp_force, temp_imu, temp_angle] = CommunicationFunctions.getForce_IMU_Angle(app, 2)

                force_data(currentIndex) = (double(temp_force)) * 17 %mN
                force_data = circshift(force_data, -1);
                gyro(1:3) = cast(temp_imu(:,1:3), 'double')*pi/180;
                accel(1:3) = cast(temp_imu(:,4:6), 'double')*9.81;
                angle_rad = (cast(temp_angle, 'double')/1800)*pi;
                
                % Calculate Moving Average filter
                if app.CenteredMovingAverageButton_2.Value 
                     % newmovingAverage = 0.5*forceIsFiltered_Old + 0.5*force_data;
                     % forceIsFiltered_Old = newmovingAverage;
                    filtered_force = movmean(force_data, app.window_size);
                elseif app.LeftSidedMovingAverageButton_2.Value
                    % newmovingAverage=0.8*forceIsFiltered_Old + 0.2*force_data; %+ 0.1*forceIs_Old;
                    % forceIsFiltered_Old = newmovingAverage;
                    % forceIs_Old = force_data;
                    filtered_force = movmean(force_data, [app.window_size-1 0]);

                elseif app.EWMVA_2.Value
                    filtered_force=0.8*forceIsFiltered_Old + 0.1*force_data + 0.1*forceIs_Old;
                    forceIsFiltered_Old = filtered_force;
                    forceIs_Old = force_data;

                end

                
                %% plot filtered Force data
                % Add new data to animatedline
                % Force Sensor
                hold(app.ForceReading, "on");
                
                addpoints(app.hForce, counter, force_data(currentIndex));
                addpoints(app.h2Force, counter, filtered_force(currentIndex));
                hold(app.ForceReading, "off");

                   
                if (app.ShowThresholdCheckBox.Value)
                    app.dL_Force_Upper_Threshold = app.upper_threshold;
                    app.dL_Force_Lower_Threshold = app.lower_threshold;
                end

               
                % adjust x-axis limits if necessary
                if counter > 100
                    xlim(app.ForceReading, [max(0, counter - 100), counter+1]);
                
                % Calculate appropriate time step for x-ticks
                tickStep = 10;
                tickInterval = 0.5*tickStep;
                % Set x-ticks and labels
                startTick = max(0, counter - 100);
                endTick = counter;
                % ticks = startTick:tickStep:endTick
                ticks = ceil(startTick / tickInterval) * tickInterval : tickInterval : endTick;
   
                app.ForceReading.XTick = ticks;
                app.ForceReading.XTickLabel = arrayfun(@(t) sprintf('%.1f', t), ticks./tickStep, 'UniformOutput', false);
                end
                

                % Update the current index for the circular buffer
                currentIndex = mod(currentIndex, windowSize) + 1;
                counter=counter+1;


                %% plot IMU data
                upper_arm_quat = app.fuse(accel,gyro);
                upper_arm_pos = calcImuPos(app,upper_arm_quat,radius);
                upper_arm_rotm = quat2rotm(upper_arm_quat);
                [lower_arm_pos, lower_arm_rotm] = calcServoPos(app,angle_rad);
                global_rotm = upper_arm_rotm * lower_arm_rotm;
            
             
                %Update plots
                %plotAngles(linesList,n,timevec,q);
                plotPose(app, app.posePlot_Elbow, upper_arm_pos', quaternion(rotm2quat(global_rotm)));
                upper_arm_ = transformStl(app, app.upper_arm, upper_arm_pos', upper_arm_quat);
                lower_arm_ = transformStl(app, app.lower_arm, upper_arm_pos',quaternion(rotm2quat(global_rotm)));
                delete(app.upper_arm_posepatch);
                delete(app.lower_arm_posepatch);
                % update arm position
                app.upper_arm_posepatch = trisurf(upper_arm_,"FaceColor", 'r', "Parent", app.IMUPlot_Force);
                app.lower_arm_posepatch = trisurf(lower_arm_,"FaceColor", 'y', "Parent", app.IMUPlot_Force);

            end

        end


        function StopRecording(app)
            CommunicationFunctions.stopAcquisition(app);
                
        end

    %% EMG Recording 
  
        function StartRecordingEMG(app)
            Fs = 20;
            radius = 2.5;
            lims = radius*2;
            goalFPS = 20;
            counter = 1;


            % Initialize data buffers
            % buffer_size = 100;
            % data_buffer = zeros(1, buffer_size);
            % time_buffer = 1:buffer_size;  % Initialize with sequential numbers
            % % Set up x-axis limits and initial ticks
            % app.EMG_OneCH.XLim = [1, buffer_size];
            % app.EMG_OneCH.XTick = 1:20:buffer_size;
            % app.EMG_OneCH.XTickLabel = arrayfun(@num2str, time_buffer(1:10:end)*0.05, 'UniformOutput', false);

            
            app.window_size = 5;  
            emg_data = zeros(1, app.window_size);
            filtered_emg = zeros(1, app.window_size);
            EMGIsFiltered_Old = zeros(1, app.window_size);
            EMGIs_Old = zeros(1, app.window_size);
            % if app.EMG_CenteredMovingAverageButton.Value
            %     app.filter = RealTimeFilter(app,'centered_mva', app.window_size);
            % elseif app.EMG_LeftSidedMovingAverageButton.Value
            %     app.filter = RealTimeFilter(app,'left_sided_mva', app.window_size);
            % elseif app.EMG_EWMVA.Value
            %     app.filter = RealTimeFilter(app,'ewma', app.window_size);
            % end
            
            while(app.EMGisPlotting)
                if(counter == 1)
                    
                    % Initialize Filter variables
                    windowSize = 5;
                    dataBuffer = zeros(1, windowSize);
                    currentIndex = 1;
                    i=1;
                    % start Acquissition
                    CommunicationFunctions.startAcquisition(app, 0x40);
                    % a = tic; % reset timer after updating     
                end

                % if ((app.charge_status == 1) && (app.servoState))
 
                %% Read EMG Data
                
                % read and convert new data to mV
                % Vmilli = (ADC reading) * (Vref / resolution of ADC) * 1000 )
                % gain of 10000 is not calculated with that!!! -> divide by 10k
                % voltage = analog_reading*5/1023/gain
                % calculate one factor for performance: ((3.3/1023)*1000) ~ 3,23
                % temp_newdata = getOneEMG(app,1);
                              
                [temp_emg, temp_imu, temp_angle] = CommunicationFunctions.getOneEMG_IMU_Angle(app,2);
                
                emg_data(currentIndex) = double(temp_emg) * 0.0003323;
                emg_data = circshift(emg_data, -1);
                gyro(1:3) = cast(temp_imu(:,1:3), 'double')*pi/180;
                accel(1:3) = cast(temp_imu(:,4:6), 'double')*9.81;
                angle_rad = (cast(temp_angle, 'double')/1800)*pi;


                % 
                % 
                % 
                % Calculate Moving Average filter
                if app.EMG_CenteredMovingAverageButton.Value 
                     %dataBuffer(currentIndex) = mean(newdata);
                     % newmovingAverage=0.3*EMGIsFiltered_Old + 0.4*emg_data + 0.3*EMGIs_Old;
                     % EMGIsFiltered_Old = newmovingAverage;
                     % EMGIs_Old = emg_data;
                     filtered_emg = movmean(emg_data, app.window_size);

                elseif app.EMG_LeftSidedMovingAverageButton.Value
                    % newmovingAverage=0.8*EMGIsFiltered_Old + 0.2*emg_data; %+ 0.1*EMGIs_Old;
                    % EMGIsFiltered_Old = newmovingAverage;
                    % EMGIs_Old = emg_data;
                    filtered_emg = movmean(emg_data, [app.window_size-1 0]);

                elseif app.EMG_EWMVA.Value
                    if counter == 1
                        EMGIsFiltered_Old = zeros(1, app.window_size);
                        EMGIs_Old = zeros(1, app.window_size);
                    end
                    filtered_emg=0.8*EMGIsFiltered_Old + 0.1*emg_data + 0.1*EMGIs_Old;
                    EMGIsFiltered_Old = filtered_emg;
                    EMGIs_Old = emg_data;
                    
                end

                
                %% plot filtered EMG data
                % Add new data to animatedline
                
                % EMG Sensor
                hold(app.EMG_OneCH, "on");
                addpoints(app.emg1_h, counter + (0:length(emg_data(currentIndex))-1), emg_data(currentIndex));
                addpoints(app.emg1_h2, counter + (0:length(filtered_emg(currentIndex))-1), filtered_emg(currentIndex));
                hold(app.EMG_OneCH, "off");

                   
                if (app.EMGShowThresholdCheckBox.Value)
                    app.dL_EMG_CH1_Threshold = app.EMGUpperThreshold;
                    app.dL_EMG_CH2_Threshold = app.EMGLowerThreshold;
                end


                % adjust x-axis limits if necessary
                if counter > 100
                    xlim(app.EMG_OneCH, [max(0, counter - 100), counter+1]);
                
                % Calculate appropriate time step for x-ticks
                tickStep = 10;
                tickInterval = 0.5*tickStep;
                % Set x-ticks and labels
                startTick = max(0, counter - 100);
                endTick = counter;
                % ticks = startTick:tickStep:endTick
                ticks = ceil(startTick / tickInterval) * tickInterval : tickInterval : endTick;
   
                app.EMG_OneCH.XTick = ticks;
                app.EMG_OneCH.XTickLabel = arrayfun(@(t) sprintf('%.1f', t), ticks./tickStep, 'UniformOutput', false);
                end
                

                % Update the current index for the circular buffer
                currentIndex = mod(currentIndex, windowSize) + 1;
                counter=counter+length(emg_data);
                % time_buffer = [time_buffer(2:end), counter];


                %% plot IMU data
                upper_arm_quat = app.fuse(accel,gyro);
                upper_arm_pos = calcImuPos(app,upper_arm_quat,radius);
                upper_arm_rotm = quat2rotm(upper_arm_quat);
                [lower_arm_pos, lower_arm_rotm] = calcServoPos(app,angle_rad);
                global_rotm = upper_arm_rotm * lower_arm_rotm;
            
             
                %Update plots
                %plotAngles(linesList,n,timevec,q);
                plotPose(app, app.posePlot_Elbow, upper_arm_pos', quaternion(rotm2quat(global_rotm)));
                upper_arm_ = transformStl(app, app.upper_arm, upper_arm_pos', upper_arm_quat);
                lower_arm_ = transformStl(app, app.lower_arm, upper_arm_pos',quaternion(rotm2quat(global_rotm)));
                delete(app.upper_arm_posepatch);
                delete(app.lower_arm_posepatch);
                % update arm position
                app.upper_arm_posepatch = trisurf(upper_arm_,"FaceColor", 'r', "Parent", app.IMUPlot_EMG);
                app.lower_arm_posepatch = trisurf(lower_arm_,"FaceColor", 'y', "Parent", app.IMUPlot_EMG);

            end

        end


    %% IMU Recording


        function StartRecordingIMU(app) 
        Fs = 20;
        radius = 2.5;
        lims = radius*2;
        goalFPS = 20;
        counter = 1;
        
        while(app.IMUisPlotting)
        % for n=1:1:400
            % framestart=tic;
            % dataloop=tic;
            if(counter == 1)
                
                % Initialize Filter variables
                currentIndex = 1;
                i=1;
                % start Acquissition
                CommunicationFunctions.startAcquisition(app, 0x44);
                % a = tic; % reset timer after updating     
            end
            % a = tic;
            % if ((app.charge_status == 1) && (app.servoState))

            %% Read IMU Data
            
            [newdata, angle_deg] = CommunicationFunctions.getIMU_raw(app,2);
            
            gyro(1:3) = cast(newdata(:,1:3), 'double')*pi/180;
            accel(1:3) = cast(newdata(:,4:6), 'double')*9.81;
            angle_rad = (cast(angle_deg, 'double')/1800)*pi;
            
            upper_arm_quat = app.fuse(accel,gyro);
            upper_arm_pos = calcImuPos(app,upper_arm_quat,radius);
            upper_arm_rotm = quat2rotm(upper_arm_quat);
            [lower_arm_pos, lower_arm_rotm] = calcServoPos(app,angle_rad);
            global_rotm = upper_arm_rotm * lower_arm_rotm;
        
         
            %Update plots
            %plotAngles(linesList,n,timevec,q);
            plotPose(app, app.posePlot_Elbow, upper_arm_pos', quaternion(rotm2quat(global_rotm)));
            upper_arm_ = transformStl(app, app.upper_arm, upper_arm_pos', upper_arm_quat);
            lower_arm_ = transformStl(app, app.lower_arm, upper_arm_pos',quaternion(rotm2quat(global_rotm)));
            delete(app.upper_arm_posepatch);
            delete(app.lower_arm_posepatch);
            % update arm position
            app.upper_arm_posepatch = trisurf(upper_arm_,"FaceColor", 'r', "Parent", app.IMUPlot);
            app.lower_arm_posepatch = trisurf(lower_arm_,"FaceColor", 'y', "Parent", app.IMUPlot);
            counter = counter+1
           
            % toc(a);
            % looptime=toc(dataloop);
            % tm = 1/goalFPS-(looptime);
            % pause(tm);
            % frameEnd=toc(framestart);
            % fprintf("fps: %f\n",1/frameEnd);
            
        end
    end
    
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            %global con_options;
            app.pathtest = fileparts(mfilename('fullpath'));
            
            app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos', 'fa-battery-0.png');
            app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-searching.png');
            app.StatusLamp.Color = [0,0,0]./255;
            app.Label.Text = 'not connected';

            report = ["Please be patient, we are searching for available devices..."; "This may take up to 10 seconds."];
            uialert(app.JUSTEXOUIFigure, report, "Alert", "Icon", "info");

            % search for paired Bluetooth devices in the Windows registry
            % and match them to the corresponding COM Ports
            pause(3);
            BT_Devices = findBT_devices_and_COMPorts();


            if (isempty(BT_Devices.COMport))
                app.BTDeviceDropDown.Items = "No devices";
                report = ["No Bluetooth device found."; "Check to enable Bluetooth connection on your device, make sure that the Classroom Edu-Exo-Pro device is powered on and was paired in the Windows Bluetooth Manager!."];
                uialert(app.JUSTEXOUIFigure,report, "Bluetooth Error", "Icon", "error");

                app.ConnectButton.Enable = "off";
                app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-disabled.png');
                app.StatusLamp.Color = "k";

                pause(0.5);

                app.ConnectButton.Enable = "on";
                app.ConnectButton.Text = "Retry";
            else
                app.con_options_friendlyName = BT_Devices.friendlyName;
                app.con_options_COMPorts = BT_Devices.COMport;
                
                app.BTDeviceDropDown.Items = app.con_options_friendlyName;
                app.BTDeviceDropDown.Enable = 'on';
                app.BTDeviceDropDown.ItemsData=1:numel(app.BTDeviceDropDown.Items);
                app.ConnectButton.Enable = 'on';

                app.chosen_option = app.BTDeviceDropDown.Items{1, app.BTDeviceDropDown.Value};
                app.chosen_option_indx = app.BTDeviceDropDown.Value;
            end

            app.RefreshButton_Connection_List.Enable = "on";
            

            % initialize communication variables
            app.message2receive = struct('header',0,'opcode',0,'dataLength',0,'data',0,'checksum',0);
            app.message2send = struct('startMarker', uint8(app.startMarker),'header',uint8(app.header),'opcode',uint8(app.opcode),'dataLength',uint8(app.max_data_length),'data',uint8(app.data),'checksum',mod(app.checksum,256), 'endMarker', uint8(app.endMarker));


        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if(app.ConnectButton.Text == "Connect")
                    
                    if (~isempty(app.con_options_friendlyName))
                        app.pairedFlag = true;
                    else
                        app.pairedFlag = false;
                    end


                    if (app.connectFlag == false && app.pairedFlag == true)% && ((app.selectFlag  == true) | (indx == 1)))
                        % connect device
                        try
                            % app.connected_device = bluetooth(chosen_option);
                            COMport = app.con_options_COMPorts{app.chosen_option_indx};
                            BAUDrate = 115200;
                            app.connected_device = serialport(COMport,BAUDrate, "Timeout",1);
                            app.initializeCommunication();
                        catch ME
                            report = getReport(ME);
                            uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
                        end
                        
                        connect_answer = CommunicationFunctions.testConnection(app);
        
                        if (connect_answer)
                            app.connectFlag = true;
                            app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-connected.png');
                            app.StatusLamp.Color = [0,0,255]./255;
                            app.Label.Text = 'connected';
                            changeTransferFunctionTab(app, 'initial');
                            changeEMGControlTab(app, 'initial');
                            changeForceControlTab(app, 'initial');
                            changeConnectionTab(app, 'connected');
                        else
                            app.connectFlag = false;
                        end
        
                        % app.pairedFlag = true;
                        % app.selectFlag = true;
                    elseif (app.connectFlag == false && app.selectFlag == false)
                        % please select device
                        report = ["No Bluetooth device selected."; "To connect with a Bluetooth device, please select one from the drop down list."];
                        uialert(app.JUSTEXOUIFigure, report, "Warning!","Icon", "warning");
                    elseif (app.connectFlag == false && app.pairedFlag == false)
                        % please pair device
                        report = ["Bluetooth device not paired."; "To use the Bluetooth device with this application, please pair it in your system settings."];
                        uialert(app.JUSTEXOUIFigure, report, "Warning!", "Icon", "warning");
                    elseif (app.connectFlag == true)
                        % device already connected
                        report = ["A Bluetooth device is already connected."; "If you want to disconnect, please restart the application and re-connect a new device!"];
                        uialert(app.JUSTEXOUIFigure, report, "Attention", "Icon", "info");
                    else
                        report = ["Unknown Error"; "Please Quit, and restart Application."];
                        uialert(app.JUSTEXOUIFigure, report, "Error", "Icon", "error")
                    end
        
                    CheckBattery(app, 2);

                    app.ConnectButton.Text = "Disconnect";

            elseif(app.ConnectButton.Text == "Disconnect")
                 % Disconnect from Arduino
                connect_answer = CommunicationFunctions.stopConnection(app);

                %disable UI Buttons
                changeTransferFunctionTab(app, 'disable');
                app.ControlDropDown.Enable = "off";
                changeServoControlPanel(app, 'disable');
                changeConnectionTab(app, 'initial');

                app.BTDeviceDropDown.Enable = "off";
                app.BTDeviceDropDown.Placeholder = "select device";
                app.ConnectButton.Enable = 'off';
                app.ConnectButton.Text = "Connect";
                app.BatteryPercentage.Text = "";
                app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-disabled.png');
                app.StatusLamp.Color = [0,0,0]./255;
                app.Label.Text = 'not connected';
                % reset flags
                app.pairedFlag = false;
                app.connectFlag = false;
                app.selectFlag = false;
                app.connected_device = "";
                app.CommManager.resetConnection();
                delete(app.connected_device);
                clear global app.con_options;
                clear devicelist;
                pause(1);
                
                startupFcn(app);

            elseif(app.ConnectButton.Text == "Retry")
                app.BTDeviceDropDown.Enable = "off";
                app.BTDeviceDropDown.Placeholder = "select device";
                app.ConnectButton.Enable = 'off';
                app.ConnectButton.Text = "Connect";
                app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-disabled.png');
                app.StatusLamp.Color = [0,0,0]./255; 
                % reset flags
                app.pairedFlag = false;
                app.connectFlag = false;
                app.selectFlag = false;
                clear gobal app.connected_device ;
                clear global app.con_options;
                clear devicelist;
              
                pause(1);
                startupFcn(app);
            end


        end

        % Value changed function: BTDeviceDropDown
        function BTDeviceDropDownValueChanged(app, event)
            app.chosen_option = app.BTDeviceDropDown.Items{1, app.BTDeviceDropDown.Value};
            app.chosen_option_indx = app.BTDeviceDropDown.Value;
            
        end

        % Callback function
        function numEditFieldValueChanging(app, event)
            TotalValue = event.Value;
            TF = isempty(TotalValue);
% TODO Change to check for polynom!!!
            if TF == 0
                changingValue = extract(TotalValue,strlength(TotalValue));
                changingValue = string(changingValue);
                changingValue = convertStringsToChars(changingValue);

                if isstrprop(changingValue,'digit')
                    app.numEditField.Value = [TotalValue , ' '];
                else
                    app.numEditField.Value = TotalValue;
                end
            end
        end

        % Callback function
        function denEditFieldValueChanging(app, event)
            TotalValue = event.Value;
            TF = isempty(TotalValue);

            % TODO Change to check for polynom!!!

            if TF == 0
                changingValue = extract(TotalValue,strlength(TotalValue));
                changingValue = string(changingValue);
                changingValue = convertStringsToChars(changingValue);

                if isstrprop(changingValue,'digit')
                    app.denEditField.Value = [TotalValue , ' '];
                else
                    app.denEditField.Value = TotalValue;
                end
            end
            
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            clc
            % reset lots if changes are not tracked
            if (~app.Compare_changes_flag)
                cla(app.StepDisturbance)
                cla(app.ServoTrajectory)
                cla(app.StepReference)
            end
            
            changeTransferFunctionTab(app, 'disable');

            try
                app.Danger = 0;
                progressbar_controller = uiprogressdlg(app.JUSTEXOUIFigure, 'Title','Please Wait','Message','Initializing Controller...', 'Icon','info');
                %%%%%%%%  G(s) Plant Transfer Funtion %%%%%%%%%%
                % G(S) Num Values
                G_num = convertCharsToStrings(app.numEditField.Value);
                G_den = convertCharsToStrings(app.denEditField.Value);
                                
                G = analyze_transfer_function(app, G_num, G_den) % create the G(s) plant
      
                 
                [G_mag,G_phi, G_wc] = bode(G);
                G_phi = squeeze(G_phi);
                w150=interp1(G_phi,G_wc,-150); %% ERROR!! sample points must be unique! -> anders berechnen

                % display wc to G(s) Plant Tab
                % see "coice of crossover fre" in book!
                % 0.6 for PID and 0.4 for PI
                if app.PID_control_flag | app.PID_design_flag
                    app.omega_G150Label.Text = '0.6\omega_{G150} = ' + string(round(w150*0.6,2));  
                elseif app.PI_design_flag || app.PI_control_flag 
                    app.omega_G150Label.Text = '0.4\omega_{G150} = ' + string(round(w150*0.4,2));
                end

                progressbar_controller.Message = 'Calculating Input Response';
                progressbar_controller.Value = .33;
%---------------%%%%%%%%  PID Controller Transfer Funtion %%%%%%%%%%--------------------------------------------
                if app.PID_control_flag
                    % get values from GUI
                    tau = app.TauEditFieldPIDControl.Value;
                    Ki = app.KiEditFieldPIDControl.Value;
                    beta = app.BetaEditFieldPIDControl.Value;
                    zeta = app.ZetaEditFieldPIDControl.Value;
                    
                    % calculate dimensioning parameters according to eq 8.33
                    Tf=tau/beta;
                    Ti=2*zeta*tau-Tf;
                    Td=((tau^2)/Ti)-Tf;
                    Kp=Ki*Ti;
                    Kd=Kp*Td;

                    % calculate PID controller with Kp, Ki, and Kp parameters
                    F_PID = pid(Kp,Ki,Kd);
                    
                    sys = feedback(G*F_PID,1); % System General Transfer Function with G(s) and F_PID(s)
                    sys_disturbance = feedback(sys,F_PID); % System Disturbance 
                    
                elseif app.PID_design_flag
                    % get values from GUI
                    wc = app.OmegaCEditFieldPIDDesign.Value;
                    phim = app.PhiMEditFieldPIDDesign.Value;
                    beta = app.BetaEditFieldPIDDesign.Value;
                    zeta = app.ZetaEditFieldPIDDesign.Value;

                    % calculate PI controller with routine from Bengt
                    % Lennartson (see Eq. 8.37)
                    
                    [F_PID,app.Ki,Kinf,tau,err] = pid_design(app,G,wc,phim,beta,zeta)  %% determines the correct pid controller for 
                    
                    sys = feedback(G*F_PID,1);  % System General Transfer Function with G(s) and F_PI(s)
                    sys_disturbance = feedback(G,F_PID); % System Disturbance 

                    % --------------------------------------------------------------------
                    % Betrag von Fpid and der Stelle wc!! 
                    % ->>> FALSCHE BERECHNUNG (Gleichung 8.36)
                    % F_num = sqrt((1-((wc*tau)^2))^2 + (2*zeta*wc*tau)^2);
                    % F_den = wc*(sqrt(1+((wc*tau)/beta)^2));
                    % F_PID = ki*(F_num)/(F_den);

                    % sys = feedback(G*F_PID,1); % System General Transfer Function   FAAALSCH!!!!!"!!!!
                    % --------------------------------------------------------------------
                
%---------------%%%%%%%%  PI Controller Transfer Funtion %%%%%%%%%%--------------------------------------------
                elseif app.PI_control_flag
                    % get values from GUI
                    app.Ki = app.KiEditFieldPIControl.Value;
                    Ti = app.TiEditFieldPIControl.Value;

                    app.Kp = app.Ki*Ti;

                    % calculate PI controller with Kp, Ki, and Kp parameters
                    % F_PI=tf([Ki*Ti Ki],[1 0]); is the same as:
                    F_PI = pid(app.Kp, app.Ki);
                    
                    sys = feedback(G*F_PI,1);  % System General Transfer Function with G(s) and F_PI(s)
                    sys_disturbance = feedback(G,F_PI); % System Disturbance 

                elseif app.PI_design_flag
                    % get values from GUI
                    phim = app.PhiMEditFieldPIDesign.Value;
                    wc = app.OmegaCEditFieldPIDesign.Value;
                    
                    % calculate the phase at borderfrequency wc according to eq 8.7
                    % ∠F(jωc) = −180◦ + ϕm − ∠G(jωc) 
                    % with ∠G(jωc) = −90◦ − arctan(ωcT)

                    [gain_G,phase_G]=bode(G,wc);

                    phase_F_PI=-180+phim-phase_G;
                    [gain_G,phase_G]=bode(G,wc);
                    phase_F_PI = -180+phim-phase_G; 

                    Ti = cotd(-phase_F_PI)/wc; % calculate Ti according to eq. 8.11
                    app.Ki = wc/(gain_G*sqrt(1+(Ti*wc)^2)); % calculate Ki according to eq. 8.12
                    
                    app.Kp = app.Ki*Ti;
                    % calculate PI controller with Kp, Ki, and Kp parameters
                    % F_PI=tf([Ki*Ti Ki],[1 0]);
                    F_PI = pid(app.Kp, app.Ki);
                    
                    sys = feedback(G*F_PI,1);  % System General Transfer Function with G(s) and F_PI(s)
                    sys_disturbance = feedback(G,F_PI); % System Disturbance 
                elseif app.P_controller_flag
                    app.Kp = app.KpEditFieldPController.Value;

                    F_P = pid(app.Kp);
                    sys = feedback(G*F_P,1);  % System General Transfer Function with G(s) and F_P(s)
                    sys_disturbance = feedback(G,F_P); % System Disturbance 

                elseif app.PI_controller_flag
                    app.Ki = app.KiEditFieldPIController.Value;
                    app.Kp = app.KpEditFieldPIController.Value;

                    F_PI = pid(app.Kp, app.Ki);
                    sys = feedback(G*F_PI,1);  % System General Transfer Function with G(s) and F_PI(s)
                    sys_disturbance = feedback(G,F_PI); % System Disturbance 

                elseif app.PID_controller_flag
                    app.Ki = app.KiEditFieldPIDController.Value;
                    app.Kp = app.KpEditFieldPIDController.Value;
                    app.Kd = app.KdEditFieldPIDController.Value;

                    F_PID = pid(app.Kp, app.Ki, app.Kd);
                    sys = feedback(G*F_PID,1);  % System General Transfer Function with G(s) and F_PID(s)
                    sys_disturbance = feedback(G,F_PID); % System Disturbance 

                end
                
                progressbar_controller.Value = .67;
                progressbar_controller.Message = 'Calculating Disturbance Response';

                %% set step function params
                LOW__Ref_degree = round(app.ServoAngleLimitsSlider.Value(1));
                HIGH__Ref_degree = round(app.ServoAngleLimitsSlider.Value(2));                
                Danger_degree = 130;
                

                if app.StepButton.Value 
                    
                    % Calculate Time vector from settings
                    app.repetitions = app.StepRepetitionsSpinner.Value;
                    app.period = app.StepPeriodSpinner.Value; 
                    
                    [input_signal, t] = step_input(app,LOW__Ref_degree, HIGH__Ref_degree, app.period, app.repetitions);

                    % get one Step Iteration response for system stability info
                    [one_period, one_period_time] = step_input(app, LOW__Ref_degree, HIGH__Ref_degree, app.period, 1);
                    
                    
                elseif app.RampButton.Value
                    app.repetitions = app.RampRepetitionsSpinner.Value;
                    app.period = app.RampPeriodSpinner.Value;

                    [input_signal, t] = symmetric_ramp_input(app, LOW__Ref_degree, HIGH__Ref_degree, app.period, app.repetitions);
                    % Simulate the system response to the symmetric two-phase ramp input
                    % get one Step Iteration response for system stability info
                    [one_period, one_period_time] = symmetric_ramp_input(app, LOW__Ref_degree, HIGH__Ref_degree, app.period, 1);
                    
                    
                elseif app.NaturalButton.Value
                    app.repetitions = app.NaturalRepetitionsSpinner.Value;
                    app.period = app.NaturalPeriodSpinner.Value; 

                    [input_signal, t] = natural_elbow_movement(app, LOW__Ref_degree, HIGH__Ref_degree, app.period, app.repetitions);
                    
                    % Simulate the system response to the elbow movement
                    [one_period, one_period_time] = natural_elbow_movement(app, LOW__Ref_degree, HIGH__Ref_degree, app.period, 1);
                    
                end

                
                [Plot_Value_old, time]  = lsim(sys,input_signal, t);
                
                disturbance_rejection = lsim(sys_disturbance,input_signal, t);

                % add low_ref degree offset 
                Plot_Value_old = Plot_Value_old + LOW__Ref_degree;
                input_signal = input_signal + LOW__Ref_degree;
                
                progressbar_controller.Value = .75;
                progressbar_controller.Message = 'Checking Stability...';

                %% check slope of step response
                % Calculate slope
                dt = diff(time);
                dy = diff(Plot_Value_old);
                slope = dy ./ dt;
                
                % Calculate 10-point average slope
                window_size = 10;
                avg_slope = movmean(slope, window_size);
                % Find steepest point based on average slope
                [max_avg_slope, k_avg] = max(abs(avg_slope));
                                
                % Find steepest point based on instantaneous slope (as before)
                [max_slope, k] = max(abs(slope));
                
             
                if (max_avg_slope || max_slope) > 500
                    report = ["Step response Slope is too step!"];
                    % "10-point average slope:";
                    %             "Steepest point occurs at t = " num2str(t(k_avg));
                    %             "Maximum average slope = " num2str(max_avg_slope)];
                    uialert(app.JUSTEXOUIFigure,report,"Warning", "Icon","warning");
                end

                %% check for stability and get graph info FOR STEP RESPONSE ONLY
                % get graph info for the first period of the input signal
                % i.e. the first step, ramp or first complete sine wave
                % cut the time, and input vector accordingly
                first_period_time = time(time>app.period/2 & time<(3*app.period/4));
                first_period_input = Plot_Value_old(time>app.period/2 & time<(3*app.period/4));
                
                STEP_respOpt = RespConfig('InputOffset',LOW__Ref_degree,'Amplitude',HIGH__Ref_degree);
                [one_period_sys, one_period_time] = step(sys, STEP_respOpt);
                Sys_info = stepinfo(one_period_sys, one_period_time, HIGH__Ref_degree, LOW__Ref_degree);
                Sys_info = stepinfo(sys, 'RiseTimeLimits',[0 0.63]);
                S = lsiminfo(first_period_input, first_period_time, HIGH__Ref_degree, LOW__Ref_degree);
                
                if app.PID_design_flag || app.PID_control_flag
                    % app.Stable_Factor = isstable(sys);  % check the system's stabiliy
                    % -> this is wrong! it checks the stability of a filter. Why was this used?
                    app.Stable_Factor = 1;
                elseif app.PI_design_flag || app.PI_control_flag
                    app.Stable_Factor = 1;
                end

                %Interpolate PlotValue to 200 points
                t = linspace(0, 1, numel(Plot_Value_old));
                t_ = linspace(0, 1, 200);
                % Plot_Value = interp1(t, Plot_Value_old, t_, 'linear'); 
                % input_signal = interp1(t, input_signal, t_, 'linear'); 
                Plot_Value = Plot_Value_old;
                t_ = time;

                Ref = Plot_Value(end);
                

                % Step Response Plot 
                % calculate and adjust upper and lower plot bounds
                upper_bounds = round(max(Plot_Value)+(mean(Plot_Value)/2),1);
                lower_bounds = round(min(Plot_Value)-(mean(Plot_Value)/2), 1);
                hold(app.StepReference,'on');
                app.StepReference.XLim = [0 length(t_)];
                % app.StepReference.YLim = [0 upper_bounds];
                app.StepReference.XTick = 0:10:length(t_);
                % app.StepReference.YTick = 0:(upper_bounds/10):upper_bounds;
                app.StepReference.XTickLabel = 0:0.02*10:max(t_);
                % app.StepReference.YTickLabel = 0:(upper_bounds/10):upper_bounds;
                app.StepReference.YGrid = 'on';
                time = linspace(1,length(t_), length(Plot_Value));
                line(app.StepReference, time', Plot_Value);
                if app.ShowInputSignal_flag
                    line(app.StepReference, time', input_signal, 'LineStyle','--');
                end

                [max_value, max_index] = max(Plot_Value);
                if app.Compare_changes_flag
                    if app.PI_control_flag 
                        text(app.StepReference, max_index, max_value,sprintf('Ti_{i} = %.1f \nK_{i} = %.1f', Ti, Ki), 'Interpreter','tex');
                    elseif app.PI_design_flag
                        text(app.StepReference, max_index, max_value,sprintf('\\omega_{c} = %.1f \\phi_{m} = %.1f', wc, phim), 'Interpreter','tex');
                    elseif app.PID_control_flag
                        text(app.StepReference, max_index, max_value,sprintf('K_{i} = %.1f \\tau = %f \n\\beta = %.1f \\zeta = %.1f', Ki, tau, beta, zeta), 'Interpreter','tex');
                    elseif app.PID_design_flag
                        text(app.StepReference, max_index, max_value,sprintf('\\omega_{c} = %.1f \\phi_{m} = %.1f \n\\beta = %.1f \\zeta = %.1f', wc, phim, beta, zeta), 'Interpreter','tex');
                    elseif app.P_controller_flag
                        text(app.StepReference, max_index, max_value,sprintf('\\K_{p} = %.1f',Kp), 'Interpreter','tex');
                    elseif app.PI_controller_flag
                        text(app.StepReference, max_index, max_value,sprintf('\\K_{p} = %.1f \\K_{i} = %.1f \n',Kp, Ki), 'Interpreter','tex');
                    elseif app.PID_controller_flag
                        text(app.StepReference, max_index, max_value,sprintf('\\K_{p} = %.1f \\K_{i} = %.1f \n \\K_{d} = %.1f',Kp, Ki, Kd), 'Interpreter','tex');
                    end
                end

                % Step Disturbance Plot
                % calculate and adjust upper and lower plot bounds
                upper_bounds = round(max(disturbance_rejection)+(mean(disturbance_rejection)/2),1);
                lower_bounds = round(min(disturbance_rejection)-(mean(disturbance_rejection)/2), 1);
                if upper_bounds <= 0.5
                    upper_bounds = 0.5;
                elseif lower_bounds > -0.1 && lower_bounds < 0.0
                    lower_bounds = -0.5;
                end
                hold(app.StepDisturbance,'on');
                app.StepDisturbance.XLim = [0 length(t_)];
                % app.StepDisturbance.YLim = [lower_bounds upper_bounds];
                app.StepDisturbance.XTick = 0:10:length(t_);
                % app.StepDisturbance.YTick = lower_bounds:(upper_bounds/10):upper_bounds;
                app.StepDisturbance.XTickLabel = 0:0.02*10:max(t_);
                % app.StepDisturbance.YTickLabel = lower_bounds:(upper_bounds/10):upper_bounds;
                app.StepDisturbance.YGrid = 'on';
                x_values = linspace(1,length(t_), length(disturbance_rejection));
                line(app.StepDisturbance,x_values', disturbance_rejection);
                
                progressbar_controller.Value = 1;
                progressbar_controller.Message = 'Finishing';

                %%Transfer function Plot
                %% -> moved to live plott!!
                % app.PLOT_degree = (HIGH__Ref_degree*Plot_Value)/Ref; % convert curve value to degrees
                app.PLOT_degree = Plot_Value; % conversion is obsolete!
                
                % line(app.ServoTrajectory, time', app.PLOT_degree); %% this line will be moved to live plotting!!!
                hold(app.ServoTrajectory,'on');
                app.ServoTrajectory.XLim = [0 max(t_)];
                app.ServoTrajectory.YLim = [0 Danger_degree+10];
                app.ServoTrajectory.XTick = 0:0.2:max(t_);
                app.ServoTrajectory.YTick = 0:10:(Danger_degree+10);
                app.ServoTrajectory.XTickLabel = 0:0.2:max(t_);
                app.ServoTrajectory.YTickLabel = 0:10:(Danger_degree+10);
                app.ServoTrajectory.YGrid = 'on';

                %%Ploting the Red zone
                X_End_point = app.ServoTrajectory.XTick(end);
                Y_End_point = app.ServoTrajectory.YTick(end);
                X_values = 1:X_End_point;
                Danger_degree_threshold = Danger_degree.* ones(1,X_End_point);
                Max_Value = Y_End_point.* ones(1,X_End_point);
                Ref_point_line = app.PLOT_degree(end).* ones(1,X_End_point);

                yline(app.ServoTrajectory, HIGH__Ref_degree, 'LineStyle', ':', 'Color', 'black');
                yline(app.ServoTrajectory, Danger_degree_threshold, 'LineWidth', 2, 'Color', 'red');
                % line(app.ServoTrajectory,X_values,Ref_point_line,":",'LineWidth',1,"Color","black"); %[0.8 0.8 0.8]
                % line(app.ServoTrajectory,X_values,Danger_degree_threshold,"red",'LineWidth',2);
                   
                % check if any element in the Plot vector exceeds the danger degree
                % Fill the Danger area red if true
                if any(app.PLOT_degree>= Danger_degree)
                    app.Danger = 1;    
                    %%Red zone fill
                    x = 0 : X_End_point;
                    curve1 = Danger_degree.* ones(1,length(x));
                    curve2 = Y_End_point.* ones(1,length(x));

                    x2 = [x, fliplr(x)];
                    inBetween = [curve1, fliplr(curve2)];
                    fill(app.ServoTrajectory,x2, inBetween, 'r','FaceAlpha',0.5, 'EdgeColor','none'); 
                end
                
                if max(app.PLOT_degree)>= Danger_degree && min(app.PLOT_degree)<= 0
                    report = "The used system (TF) is crosses the both Danger zones (130° and 0°). Please Try another system or change the Maximum and Minimum Servo Angle Limits";
                    uialert(app.JUSTEXOUIFigure, report,"Unstable System","Icon","warning");
                elseif max(app.PLOT_degree)>= Danger_degree 
                    report = "The used system (TF) is crosses the Danger zone (130°). Please Try another system or change the Maximum Servo Angle Limits";
                    uialert(app.JUSTEXOUIFigure, report,"Unstable System","Icon","warning");
                elseif min(app.PLOT_degree)<= 0
                    report = "The used system (TF) is crosses the Danger zone (0°). Please Try another system or change the Minimum Servo Angle Limits";
                    uialert(app.JUSTEXOUIFigure, report,"Unstable System","Icon","warning");
                end


                % show parameters only with two significant numbers!
                % (changed from 4 to 2)
                
                app.TransientTimeLabel.Text = 'Transient Time [s] :   ' + string(round(Sys_info.TransientTime,2));
                app.SettlingTimeLabel.Text = 'Settling Time [s]   :   ' + string(round(Sys_info.SettlingTime,2)');
                app.OverShootLabel.Text = 'Overshoot [%]      :   ' + string(round(Sys_info.Overshoot,2));
                app.PeakLabel.Text = 'Peak [deg]             :   ' + string(round(Sys_info.Peak*HIGH__Ref_degree,2));
                % Time Consnat is wrong -> it should be the time until 63%
                % of peak were reached?
                app.TimeConstantLabel.Text = 'Time Constant [s] :   ' + string(round((Sys_info.RiseTime),2));

                Poles = pole(sys);
                Zeros = zero(sys);
                app.PolesLabel.Text = 'Poles      :   ' + string(round(Poles,2));
                app.ZerosLabel.Text = 'Zeros      :   ' + string(round(Zeros,2));
                
                legend(app.ServoTrajectory,{'Servo Refrence Angle','Risk threshold'},'Location','southeast')
                close(progressbar_controller);
                %%%%%% RGB Switch Colours%%%%%%
                value = app.ServoPowerSwitch.Value;
                if app.connectFlag == true && value == "Off"
                    %% RGB (white)
                    CommunicationFunctions.RGB_Colour(app,app.color_red);
                end
                
                app.RefreshButton.Enable = "on";
                

                if app.charge_status || app.connectFlag
                    changeServoControlPanel(app, 'enable');
                    app.ExecuteButton.Enable = "off";
                    app.Servo_Plot_Reset.Enable = "off";                
                end

            catch ME
                report = getReport(ME);
                uialert(app.JUSTEXOUIFigure,report,"Error", "Icon","error");

                %%%%%% RGB Switch Colours%%%%%%
                value = app.ServoPowerSwitch.Value;
                if app.connectFlag == true && value == "Off"
                    %% RGB (red)
                    CommunicationFunctions.RGB_Colour(app,app.color_red);
                end
                
            end
            
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.RefreshButton.Enable = "off";

            clc
            if (~app.Compare_changes_flag)
                cla(app.ServoTrajectory ,'reset'); 
                cla(app.StepDisturbance ,'reset');
                cla(app.StepReference ,'reset');
                % UIXes_2
                app.ServoTrajectory.XLim = [0 200];
                app.ServoTrajectory.YLim = [0 140];
                title(app.ServoTrajectory, 'Step Plot: Input disturbance rejection');
                xlabel(app.ServoTrajectory, 'Timesteps [i]');
                ylabel(app.ServoTrajectory, 'Amplitude [°]');
                app.ServoTrajectory.XTick = [0 20 40 60 80 100 120 140 160 180 200];
                 app.ServoTrajectory.YTick = [0 20 40 60 80 100 120 140];
                app.ServoTrajectory.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
                app.ServoTrajectory.YGrid = 'on';

                % UIAxes
                app.StepDisturbance.XLim = [0 200];
                app.StepDisturbance.YLim = [0 140];
                title(app.StepDisturbance, 'Step Plot: Input disturbance rejection');
                xlabel(app.StepDisturbance, 'Timesteps [i]');
                ylabel(app.StepDisturbance, 'Amplitude');
                app.StepDisturbance.XLim = [0 200];
                app.StepDisturbance.YLim = [0 140];
                app.StepDisturbance.XTick = [0 20 40 60 80 100 120 140 160 180 200];
                app.StepDisturbance.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
                app.StepDisturbance.YTick = [0 20 40 60 80 100 120 140];
                app.StepDisturbance.YGrid = 'on';
            
                % UIAxes 3
                title(app.StepReference, 'Step Plot: Refercence Tracking');
                xlabel(app.StepReference, 'Timesteps [i]');
                ylabel(app.StepReference, 'Amplitude');
                app.StepReference.XLim = [0 200];
                app.StepReference.YLim = [0 140];
                app.StepReference.XTick = [0 20 40 60 80 100 120 140 160 180 200];
                app.StepReference.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
                app.StepReference.YTick = [0 20 40 60 80 100 120 140];
                app.StepReference.YGrid = 'on';
                
                app.numEditField.Value = "1";
                app.denEditField.Value= "(1 + s)";
            

                if app.PI_control_flag 
                    app.KiEditFieldPIControl.Value = 1;
                    app.TiEditFieldPIControl.Value = 1;
                elseif app.PI_design_flag
                    app.PhiMEditFieldPIDesign.Value = 45;
                    app.OmegaCEditFieldPIDesign.Value = 1.5;               
                elseif app.PID_control_flag
                    app.ZetaEditFieldPIDControl.Value = 1;
                    app.BetaEditFieldPIDControl.Value = 1;
                    app.KiEditFieldPIControl.Value = 1;
                    app.TauEditFieldPIDControl.Value = 1;
                elseif app.PID_design_flag
                    app.ZetaEditFieldPIDDesign.Value = 1;
                    app.OmegaCEditFieldPIDDesign.Value = 1;
                    app.BetaEditFieldPIDDesign.Value = 1;
                    app.PhiMEditFieldPIDDesign.Value = 1;
                end

            else
                if app.PI_control_flag 
                    app.KiEditFieldPIControl.Value = 1;
                    app.TiEditFieldPIControl.Value = 1;
                elseif app.PI_design_flag
                    app.PhiMEditFieldPIDesign.Value = 45;
                    app.OmegaCEditFieldPIDesign.Value = 1.5;               
                elseif app.PID_control_flag
                    app.ZetaEditFieldPIDControl.Value = 1;
                    app.BetaEditFieldPIDControl.Value = 1;
                    app.KiEditFieldPIControl.Value = 1;
                    app.TauEditFieldPIDControl.Value = 1;
                elseif app.PID_design_flag
                    app.ZetaEditFieldPIDDesign.Value = 1;
                    app.OmegaCEditFieldPIDDesign.Value = 1;
                    app.BetaEditFieldPIDDesign.Value = 1;
                    app.PhiMEditFieldPIDDesign.Value = 1;
                end

                app.TransientTimeLabel.Text = "Transient Time:";
                app.TimeConstantLabel.Text = "Time Constant  :";
                app.SettlingTimeLabel.Text = "Settling Time    :";
                app.OverShootLabel.Text = "OverShoot        :";
                app.PeakLabel.Text = "Peak                  :";
                
                app.ZerosLabel.Text = "Zeros          :";
                app.PolesLabel.Text = "Poles          :";
                app.ServoSpeedKnob.Value = '1';
            end

            changeTransferFunctionTab(app, 'enable');
            app.RefreshButton.Enable = "off";
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            try
                legend(app.ServoTrajectory,'off');
                CheckBattery(app,1);
                app.charge_status = 1; % only temp for debug
                app.StatusLamp.Color = [255,55,180]./255;
                app.Label.Text = 'PID mode';
                
                % disable all buttons
                changeTransferFunctionTab(app, 'disable');
                changeServoControlPanel(app, 'disable');

                if app.Stable_Factor == 1  && app.Danger == 0 && app.charge_status == 1
                    
                    progressbar_test = uiprogressdlg(app.JUSTEXOUIFigure, 'Title','Please Wait','Message','Zeroing Servo', 'Icon','info');
                    drawnow;
                    
                    %%%%%% RGB Error Colours %%%%%%
                    %% RGB (white)
                    %CommunicationFunctions.RGB_Colour(app,app.color_white);
                    app.Servo_Speed = str2num(app.ServoSpeedKnob.Value);
                    speed = 6 - app.Servo_Speed;
                    speed = 3;
                    stepsize = 10;
                    LOW_Ref_degree = 1800 - (app.ServoAngleLimitsSlider.Value(1)'*10);
                    HIGH_Ref_degree = 1800 - (app.ServoAngleLimitsSlider.Value(2)'*10);
                    uint16_LOW_Ref_degree = uint16(LOW_Ref_degree);
                    CommunicationFunctions.resetServoPosition(app, uint16_LOW_Ref_degree, speed, stepsize);
                    
% todo: get current servo motor position!!!!

                    deltaT = (speed * 0.05) * ((LOW_Ref_degree-HIGH_Ref_degree)/stepsize)

                    pause(deltaT);
                    progressbar_test.Message = "Sending Data to Exo";
                    progressbar_test.Value = 0.25;
                    % get plot values (angles)
                    % h = findobj(app.ServoTrajectory, 'type', 'line');
                    % Plot_Value = h(end).YData;

                    app.ServoTrajectory.NextPlot = 'add';  % move up to startfcn?
                    

                    % app.EMG_CH1.axh()
                    hold(app.ServoTrajectory, "on");
                    % app.dL_ServoAngle_reference_tracking = line(app.ServoTrajectory, nan, nan, 'LineStyle', '-');
                    % app.dL_ServoAgle_raw = line(app.ServoTrajectory, nan, nan, 'LineStyle', '--', 'Color', 'cyan');
                    h = animatedline(app.ServoTrajectory, nan, nan, 'LineStyle', '-');
                    h2 = animatedline(app.ServoTrajectory, nan, nan, 'LineStyle', '--', 'Color', 'cyan');
                    
                    
                    speed = 5 - app.Servo_Speed
                    CommunicationFunctions.setServoSpeed(app, speed);
                    

                    %% interpolate data to 200 data points
                    %Interpolate PlotValue to 200 points
                    t = linspace(0, 1, numel(app.PLOT_degree));
                    t_ = linspace(0, 1, 200);
                    % app.PLOT_degree = interp1(t, app.PLOT_degree, t_, 'linear'); 

                    T = app.period + (app.repetitions * app.period);% Total time duration of motion in seconds, add half period at start and end of the input signal
                    dt = 0.02; % Time step
                    time_vector = 0:dt:T; % Time vector
                    
                    
                    Max_Degree= 120;
                    %angle = typecast(round((180 - Plot_Value)), 'uint8');
                    % angle = uint16((1800 - (Plot_Value*10)));
                    angle = uint16((1800 - (app.PLOT_degree'*10)));
                    % angle = angle';

                    % split the angle vector into smaller parts (for sending)
                    % Desired chunk size
                    % bigger chuncks resulted in dataloss!! 
                    uint8_chunkSize = 26;
                    uint16_chunkSize = 13;
                    
                    
                    % Calculate the number of chunks
                    numChunks = ceil(length(angle) / uint16_chunkSize);
                    % set the pid package counter to the number of chunks
                    CommunicationFunctions.setPIDpackagecounter(app, uint8(numChunks));

                    error_flag = true;

                    % flush device before sending/receiving data
                    flush(app.connected_device, "input");
                    flush(app.connected_device, "output");
                    app.connected_device.NumBytesAvailable
                    

                    % send Data Chunk to the Exoskeleton
                    % also: Update progressbar after each chucnk was sent,
                    % to show sending process in app
                    for i = 1:numChunks
                        % Determine the start and end indices for the current chunk
                        startIndex = (i - 1) * uint16_chunkSize + 1;
                        endIndex = min(i * uint16_chunkSize, length(angle));
                        progressbar_test.Value = i/numChunks;
                        if error_flag
                            % Extract the current chunk of data
                            uint16_currentChunk = angle(startIndex:endIndex);
                            uint8_CurrentChunk = typecast(uint16_currentChunk, 'uint8');
                        
                            % Display or send the current chunk of data (replace this with your actual sending logic)
                            disp(['Sending Chunk uint16 ' , num2str(i), ': ', num2str(uint16_currentChunk)]);
                            disp(['Sending Chunk ', num2str(i), ': ', num2str(uint8_CurrentChunk)]);
                            
                            [error_flag]= CommunicationFunctions.moveServoPID(app, length(uint8_CurrentChunk), uint8_CurrentChunk);
                            pause(0.1);
                        

                        elseif (error_flag == false)
                            % Extract the current chunk of data
                            uint16_currentChunk = angle(startIndex:endIndex);
                            uint8_CurrentChunk = typecast(uint16_currentChunk, 'uint8');
                        
                            % Display or send the current chunk of data (replace this with your actual sending logic)
                            disp(['Sending Chunk uint16 ', num2str(i), ': ', num2str(uint16_currentChunk)]);
                            disp(['Sending Chunk ', num2str(i), ': ', num2str(uint8_CurrentChunk)]);
                            
                            [error_flag]= CommunicationFunctions.moveServoPID(app, length(uint8_CurrentChunk), uint8_CurrentChunk);
                            pause(0.1);
                        end
                    end

                    close(progressbar_test);
                
                    %servo_angles = string(microseconds);
                    Analog_voltage = [];
                    
                    a = tic; % start timer
                    for i=1:length(angle)-2
                        
                        %% get the servo Analog degree feedback from the 4th wire in the servo
                        
                        [~, temp] = CommunicationFunctions.getServoAngleStream(app);
                        temp_plot = ((1800-temp)/10);
                        i;
                        
                       
                        % hold(app.ServoTrajectory, 'on')
                        hold(app.ServoTrajectory, 'on');
                        addpoints(h, time_vector(i), app.PLOT_degree(i));
                        addpoints(h2, time_vector(i), temp_plot);
                        % app.dL_ServoAngle_reference_tracking.XData = [app.dL_ServoAngle_reference_tracking.XData i];
                        % app.dL_ServoAngle_reference_tracking.YData = [app.dL_ServoAngle_reference_tracking.YData app.PLOT_degree(i)];
                        % app.dL_ServoAgle_raw.XData = [app.dL_ServoAgle_raw.XData i];
                        % app.dL_ServoAgle_raw.YData = [app.dL_ServoAgle_raw.YData temp_plot];
                        hold(app.ServoTrajectory, 'off');
    
                        % drawnow;
                        
                        
                    end
                    % legend(app.ServoTrajectory,{'Servo Refrence Angle','Risk threshold', 'Servo Trajectory', 'Servo Feedback'},'Location','southeast')
                    legend(app.ServoTrajectory, [h h2], {'Servo Trajectory', 'Servo Feedback'},'Location','northeast')
                        
                    
                    % Analog_bit=Analog_voltage;
                     % y = (x - inMin) / (inMax - inMin) * (outMax - outMin) + outMin
                    %% moved to arduino!
                    % Analog_degree = (Analog_voltage - 850)/(2050-850).*(120-30) + 30;
                    % Analog_degree = (180/(266-738)).*(Analog_bit-738);
                    % servo_feedback = 180-Analog_degree; % 200 is an offset
                    
                    % enable reset Button
                    app.Servo_Plot_Reset.Enable = "on";
                    
                    app.angle_END = angle(end);
                    
                    % pause(0.5);
                    CheckBattery(app,2);
                    %clear connected_servo
                    %%writeread(connected_device, home_servo);
                    app.charge_status=0;
                    changeTransferFunctionTab(app, 'initial');
                    app.Servo_Plot_Reset.Visible = "on";

                else
                    report = "The used system (TF) is unstable or may corss the Danger zone . Please Try another system or change the Ref. Degree";
                    uialert(app.JUSTEXOUIFigure, report,"Unstable System","Icon","warning");
                    %%%%%% RGB Error Colours %%%%%%
                    %% RGB (red)
                    % CommunicationFunctions.RGB_Colour(app,app.color_red);
                    % app.charge_status=0;
                end

            catch ME
                report = getReport(ME);
                uialert(app.JUSTEXOUIFigure, report, "Error", "Icon", "error");
                app.Servo_Plot_Reset.Enable = "off";
                %%%%%% RGB Error Colours %%%%%%
                 %% RGB (red)
                CommunicationFunctions.RGB_Colour(app,app.color_red);
                app.charge_status=0;
            end
        end

        % Button pushed function: Servo_Plot_Reset
        function Servo_Plot_ResetPushed(app, event)
            
            try
                app.Servo_Plot_Reset.Enable = "off";
                % connected_servo = servo(connected_device,'D5', 'MaxPulseDuration', 0.00205, 'MinPulseDuration',0.00085); % Connect the servo values to Matlab through Arduino
                CheckBattery(app,2);
                
                app.Servo_Speed = str2double(app.ServoSpeedKnob.Value);
                speed = 5 - app.Servo_Speed;
                speed = 1;
                stepsize = 10;
                LOW_Ref_degree = 1800 - (app.ServoAngleLimitsSlider.Value(1)'*10);
                HIGH_Ref_degree = 1800 - (app.ServoAngleLimitsSlider.Value(2)'*10);
                uint16_LOW_Ref_degree = uint16(LOW_Ref_degree);
                CommunicationFunctions.resetServoPosition(app, uint16_LOW_Ref_degree, speed, stepsize);
                
                delete(findobj(app.ServoTrajectory, 'Color','c'))
                delete(findobj(app.ServoTrajectory, 'Color','k'));
                legend(app.ServoTrajectory,{'Risk threshold'},'Location','southeast')
                
                deltaT = (speed * 0.05) * ((LOW_Ref_degree - HIGH_Ref_degree)/stepsize);
                
                pause(deltaT);

                changeServoControlPanel(app, 'enable');
                app.Servo_Plot_Reset.Enable = "off";
                
            catch ME
                report = getReport(ME);
                uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end
        end

        % Value changed function: ServoPowerSwitch
        function ServoPowerSwitchValueChanged(app, event)
            value = app.ServoPowerSwitch.Value;
            CheckBattery(app,1);
            app.charge_status = 1; % only for debug!!!
            if ((value == "On") && (app.charge_status == 1))
                
                CommunicationFunctions.setServoState(app, 0x52);

                app.ExecuteButton.Enable = "on";
                app.ServoSpeedKnob.Enable = "on";
                app.Servo_Plot_Reset.Enable = "on";
                
                
                %%%%%% RGB Switch Colours%%%%%%
                %% RGB (green)
                    % CommunicationFunctions.RGB_Colour(app,app.color_green);
            else
                CommunicationFunctionsCommunicationFunctions.setServoState(app, 0x53);

                changeServoControlPanel(app, 'disable');
                changeTransferFunctionTab(app, 'initial');
                app.ServoPowerSwitch.Enable = "on";

                report = ["Servo Power turned off", "The Enable Pin of the Servo motor is turned off."];
                uialert(app.JUSTEXOUIFigure, report, "Information","Icon","info");
                %%%%%% RGB Switch Colours%%%%%%
                % if app.charge_status == 0
                %     CommunicationFunctions.RGB_Colour(app,app.color_red);           %% RGB (red)
                % else
                %     CommunicationFunctions.RGB_Colour(app,app.color_green);         %% RGB (white)
                % end
                
            end
            
        end

        % Callback function
        function CH1DropDownValueChanged(app, event)
            value = app.CH1DropDown.Value;
            if value == "EMG0"
                app.CH2DropDown.Value = app.CH2DropDown.Items{1,2};
            else
                app.CH2DropDown.Value = app.CH2DropDown.Items{1,1};
            end
        end

        % Callback function
        function CH2DropDownValueChanged(app, event)
            value = app.CH2DropDown.Value;
            if value == "EMG1"
                app.CH1DropDown.Value = app.CH1DropDown.Items{1,1};
            else
                app.CH1DropDown.Value = app.CH1DropDown.Items{1,2};
            end
        end

        % Value changed function: EmergnecySTOPButton
        function EmergnecySTOPButtonValueChanged(app, event)
            value = app.EmergnecySTOPButton.Value;

            try 
                
                % Set Arduino to emergency Stop Mode -> manually turn off
                % arduino
                CommunicationFunctions.emergencyStop(app);

                %h= findobj(app.JUSTEXOUIFigure,'Type','uibutton');
                h= findobj(app.JUSTEXOUIFigure,'-property','Enable');
                
                set(h,'Enable','off');

                report = ["Emergency Stop"; "System disconnected and diasbled. Please restart Program and manually turn off power to the Edu Exo Pro!"];
                uialert(app.JUSTEXOUIFigure, report, "Warning!", "Icon", "warning");

                clear all;


            catch ME
                report = getReport(ME);
                uialert(app.JUSTEXOUIFigure, report, "Error", "Icon", "error");
            end


        end

        % Value changed function: StartRecordingSwitch_DualThresh_Force
        function StartRecordingSwitch_DualThresh_ForceValueChanged(app, event)
            value = app.StartRecordingSwitch_DualThresh_Force.Value;
            try
                if strcmp(value, 'On')
                    % change UI components
                    changeForceControlTab(app, 'running');
                    
                    app.StatusLamp.Color = [50,255,255]./255;
                    app.Label.Text = 'Force control';
                    % clear up plots
                    cla(app.ForceReading);
                    app.ForceReading.XLim = [0 100];
                    app.upper_threshold = app.ForceUpperThresholdSlider.Value;
                    app.lower_threshold = app.ForceLowerThresholdSlider.Value;

                    % initialize plots
                    app.ForceReading.NextPlot = 'add';  % move upt to startfcn?
                    
                    time = 1:1:120;


                    %% initialize IMU plot
                    radius = 2.5;
                    upper_arm_ = stlread('stl/arm.stl');
                    lower_arm_ = stlread('stl/arm.stl');
                    torso_ = stlread('stl/torso.stl');
                    head_ = stlread('stl/head.stl');
                    Fs = 20;
                    % clear up plots
                    cla(app.IMUPlot_Force, "reset");
                   
                    
                    % init
                    %[30 0 0] 
                    % [x z y] eul2rotm([z y x])
                    app.torso = transformStl(app, torso_,[0 0 0],eul2quat([-pi/2 0 -pi/2]));
                    app.head = transformStl(app, head_,[0 0 0],eul2quat([0 0 0]));
                    app.upper_arm = transformStl(app,upper_arm_,[-0.25 0 0],eul2quat([0 0 pi]));
                    app.lower_arm = transformStl(app,lower_arm_,[0.25 0 0],eul2quat([pi 0 0]));
                    lims = radius*2;
                    
                   
                    app.fuse = imufilter('SampleRate',Fs,'DecimationFactor',1);
                    
                    % initialize plots
                    app.posePlot_Shoulder = poseplot("Parent",app.IMUPlot_Force);
                    hold(app.IMUPlot_Force);
                    app.posePlot_Elbow = poseplot("Parent", app.IMUPlot_Force);
                    app.IMUPlot_Force.Title.String = "3D Position Estimation";
                    % app.IMUPlot_EMG.XLabel.String = "North-x (m)";
                    % app.IMUPlot_EMG.YLabel.String = "East-y (m)"; 
                    % app.IMUPlot_EMG.ZLabel.String = "Down-z (m)"; 
                    app.IMUPlot_Force.YLim = [-lims lims];
                    app.IMUPlot_Force.XLim = [-lims lims];
                    app.IMUPlot_Force.ZLim = [-lims lims];
                    % app.IMUPlot_EMG.XTickLabel = []
                    app.IMUPlot_Force.XGrid = 'off';
                    app.IMUPlot_Force.YGrid = 'off';
                    app.IMUPlot_Force.ZGrid = 'off';
                    app.IMUPlot_Force.XMinorGrid = 'off';
                    app.IMUPlot_Force.YMinorGrid = 'off';
                    app.IMUPlot_Force.ZMinorGrid = 'off';
                    app.IMUPlot_Force.Color = 'none';

                    % place the torso to fixed position
                    trisurf(app.torso, "FaceColor", 'none', "Parent",app.IMUPlot_Force);
                    % trisurf(app.head, "FaceColor", 'none', "Parent", app.IMUPlot_Force);
                    % place the arm in a first fixed position
                    app.upper_arm_posepatch = trisurf(app.upper_arm,"Parent", app.IMUPlot_Force); 
                    app.lower_arm_posepatch = trisurf(app.lower_arm,"Parent", app.IMUPlot_Force); 
                    % view(app.IMUPlot,-27, 9);

                    
                    hold(app.ForceReading, "on");
                    % app.dL_Force_raw = line(app.ForceReading, nan, nan);
                    % app.dL_Force_filt = line(app.ForceReading, nan, nan, 'Color','red','LineStyle','--');
                    app.hForce = animatedline(app.ForceReading, nan, nan, 'Color', 'red', 'LineStyle','--', 'MaximumNumPoints',110);
                    app.h2Force = animatedline(app.ForceReading, nan, nan, 'MaximumNumPoints',110);
                    legend([app.hForce app.h2Force], {'raw', 'filtered'}, 'Location', 'northeast');

                    if (app.ShowThresholdCheckBox.Value)
                        app.upperHandle = yline(app.ForceReading, app.upper_threshold, 'Color','blue');
                        app.lowerHandle = yline(app.ForceReading, app.lower_threshold, 'Color','blue');
                        legend([app.hForce app.h2Force app.upperHandle app.lowerHandle], {'raw', 'filtered','Upper Thresh', 'Lower Thresh'}, 'Location', 'northeast');
                        
                    end
                    

                    hold(app.ForceReading, "off");


                    % check Thresholds
                    app.upper_threshold = uint16((app.ForceUpperThresholdSlider.Value/17));
                    app.lower_threshold = uint16(abs(app.ForceLowerThresholdSlider.Value/17));

                    speed = 5 - str2double(app.SpeedSetting_Force.Value);
                    stepsize = 10;
                    
                    CommunicationFunctions.setThresholds(app, 0x41, app.upper_threshold, app.lower_threshold, speed, stepsize);
                    pause(0.1);
                    [~, app.servoState] = CommunicationFunctions.setServoState(app, 0x52);
                    

                    CheckBattery(app,1);
                    app.ForceisPlotting = true;
                    
                    
                    StartRecordingForce(app);
                    
                else
                    app.ForceisPlotting = false;
                    StopRecording(app);
                    changeForceControlTab(app, 'initial');
                    pause(1);
                    % report = "The Battery Voltage is too low. Servo Motor cannot be operated. Please Charge Battery!";
                    % uialert(app.JUSTEXOUIFigure, report, "Battery Error", "Icon", "error");
                    CheckBattery(app,2)
                end

                catch ME
                    report = getReport(ME);
                    error_line = ME.stack.line;
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
                    
            end

        end

        % Callback function
        function CH2ThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue);
            app.CH2NumericThresholdField.Value = changingValue;
        end

        % Callback function
        function CH1ThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue);
            app.CH1NumericThresholdField.Value = changingValue;            
        end

        % Callback function
        function CH1ThresholdSliderValueChanged(app, event)
            value = round(app.CH1ThresholdSlider.Value);           
            app.CH1NumericThresholdField.Value = value;  
        end

        % Callback function
        function StartRecordingButtonPushed(app, event)
            
                try

                    app.MVCNormalizingButtonGroup.Enable = "off";
                    app.NumberofRecordingsSpinner.Enable = "off";
                    app.StatusLamp.Color = "c"; % only temporary
    
                    %%Clean the Axes and start over again
                    cla(app.MVC_Calibration);
                    app.MVC_Calibration.XLim = [0 15];

                    % initialize plots
                    app.MVC_Calibration.NextPlot = 'add';  % move upt to startfcn?

                    hold(app.MVC_Calibration, "on");
                    app.dl_EMG_Calibrate = line(app.MVC_Calibration, nan, nan);
                    
                    time = 1:1:120;
                    
                    CheckBattery(app,1);

                    app.servoState = true;
       
                    if ((app.charge_status == 1) && (app.servoState))

                        % Initialize Filter variables
                        windowSize = 5;
                        dataBuffer.CH1 = zeros(1, windowSize);
                        
                        currentIndex = 1;
                        i=1;
                        cent_counter = 0;


                        % execute code here
                        normalizing_option = app.MVCNormalizingButtonGroup.SelectedObject;
                        repetitions = app.NumberofRecordingsSpinner.Value;

                        %% read EMG Data
                        % Function to be executed when the Start button is pushed
                        app.MVCValues = []; % Clear previous data
                        

                        for rep = 1:repetitions
                            app.sEMGData = [];  % Clear previous data
                            for t = 1:(app.AcquisitionTime * app.SamplingRate)
                                new_data = app.acquireData();   % Start data acquisition
                                app.dl_EMG_Calibrate.YData(t) = new_data;
                                drawnow;
                                pause(0.5);
                            end
                            app.calculateMVC();  % Calculate MVC value
                        end

                        % Calculate average MVC value
                        averageMVC = mean(app.MVCValues);


                        %% plot filtered EMG data
                        % Add new data to animatedline
                        
                        % Channel 1
                        % app.dL_EMG_CH1_raw.YData(i) = app.sEMGData;
                        % app.dL_EMG_CH1_filt.YData(i) = newmovingAverage.CH1;
                        % % Channel 2
                        % app.dL_EMG_CH2_raw.YData(i) = newdata.CH2;
                        % app.dL_EMG_CH2_filt.YData(i) = newmovingAverage.CH2;
                        % 
                        % % adjust x-axis limits if necessary
                        % xlim(app.EMG_CH1, [max(0, i - 20), i + 1]);
                        % 
                        % drawnow;
     
                        % pause(0.015);
                        % 
                        % % Update the current index for the circular buffer
                        % currentIndex = mod(currentIndex, windowSize) + 1;
                        % time(i) = time(i)+1;
                        % %app.EMG_CH1.xticks(time(k))
                        % %app.EMG_CH1.xticklabels(string(time(k)))
                        % %app.EMG_CH1.XTick = cent_counter*100 + time(i);
                        % if(mod(i, 100) == 0) 
                        %     cent_counter =+ 1;
                        % 
                        % end
                        % i=mod(i, 100)+1;

                        app.ConfirmCalibrationButton_3.Enable = 'on';

                    else
                        report = "The Battery Voltage is too low. Servo Motor cannot be operated. Please Charge Battery!";
                        uialert(app.JUSTEXOUIFigure, report, "Battery Error", "Icon", "error");
                    end

                catch ME
                    report = getReport(ME);
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
                end


        end

        % Callback function
        function ConfirmCalibrationButton_3Pushed(app, event)
            try
                
                app.CalibrationTabs.SelectedTab = app.ZeroForceTab; %% open Zero Force Calibration Tab

            catch ME
                    report = getReport(ME);
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end

        end

        % Callback function
        function ConfirmCalibrationButton_2Pushed(app, event)
            try
                app.CalibrationTabs.SelectedTab = app.ServoAngleTab; %% open Servo Angle Calibration Tab
            catch ME
                    report = getReport(ME);
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end
        end

        % Callback function
        function ConfirmCalibrationButtonPushed(app, event)
            try
                % Create a table to store measurement data
                timestamp = '';
                timestamp = string(datetime, 'uuuu-MM-dd');
                %measurementTable = table(timestamp, app.MVCValues, app.ForceValues, app.AngleValues,'VariableNames', {'DateTime', 'MVCValues', 'ForceValues', 'Angles'});
                saveCSV(app, timestamp);
                report = ["Hurray";"Calibration Data has been saved!"];
                uialert(app.JUSTEXOUIFigure, report, "Success", "Icon", "success");
            catch ME
                report = getReport(ME);
                uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end
            
        end

        % Callback function
        function StartForceCalibartionButtonPushed(app, event)
            value = app.StartRecordingSwitch_DualThresh_Force.Value;
            try
                
                app.StatusLamp.Color = "m";
                app.ZeroForceOffsetLabel.Text = 'Zero Force Offset: ';

                % clear up plots
                cla(app.ZeroForce);
                app.ZeroForce.XLim = [0 100];
                app.Offset_Force_filt = zeros(1, 100);
                

                % initialize plots
                app.ZeroForce.NextPlot = 'add';  % move upt to startfcn?
                         
                hold(app.ZeroForce, "on");
                % app.dL_Force_raw = line(app.ForceReading, nan, nan);
                % app.dL_Force_filt = line(app.ForceReading, nan, nan, 'Color','red','LineStyle','--');
                h = animatedline(app.ZeroForce, nan, nan, 'Color', 'red', 'LineStyle','--', 'MaximumNumPoints',100);
                h2 = animatedline(app.ZeroForce, nan, nan);
                
                
                legend(app.ZeroForce, {'raw', 'filtered'}, 'Location', 'northeast');

                hold(app.ZeroForce, "off");

                % [~, app.servoState] = CommunicationFunctions.setServoState(app, 0x52);
                

                CheckBattery(app,1);
                

                if ((app.charge_status == 1))


                    % Initialize Filter variables
                        windowSize = 5;
                        dataBuffer = zeros(1, windowSize);
                        currentIndex = 1;
                        i=1;

                        % start Acquissition
                        CommunicationFunctions.startAcquisition(app, 0x32);
                    a = tic; % reset timer after updating
                    for counter=1:100
                        value = app.StartRecordingSwitch_DualThresh_Force.Value;

                        %% Read Force Data
                        
                        % read and convert new data to mN
                        temp_newdata = typecast(CommunicationFunctions.getForce_raw(app,1), 'int16');
                        newdata = (temp_newdata);%-3755;
                        
                        % Calculate Moving Average filter
                        
                         dataBuffer(currentIndex) = newdata;
                         newmovingAverage = mean(dataBuffer);

    
                        %% plot filtered Force data
                        % Add new data to animatedline
                        
                        % Force Sensor
                        hold(app.ForceReading, "on");
                        % app.Offset_Force_filt.XData = [app.Offset_Force_filt.XData counter];
                        app.Offset_Force_filt(counter) = newmovingAverage;
                        
                        addpoints(h, counter, newdata);
                        addpoints(h2, counter, newmovingAverage);
                        
                        hold(app.ForceReading, "off");
    
                        b = toc(a); % check timer
                        if b > (1/3)
                            drawnow % update screen every 1/30 seconds
                            a = tic; % reset timer after updating
                        end

                        % adjust x-axis limits if necessary
                        % if counter > 100
                        %     xlim(app.ForceReading, [max(0, counter - 50), counter + 1]);
                        % end

                        % force the plot to be drawn now
                        % drawnow;

                        % Update the current index for the circular buffer
                        currentIndex = mod(currentIndex, windowSize) + 1;
                        counter=counter+1;
                    end

                    pause(1);
                    app.zero_force_offset = app.Offset_Force_filt/100;
                    app.ZeroForceOffsetLabel.Text = 'Zero Force Offset: ' + string(round(app.zero_force_offset));

                    
                else
                    report = "The Battery Voltage is too low. Servo Motor cannot be operated. Please Charge Battery!";
                    uialert(app.JUSTEXOUIFigure, report, "Battery Error", "Icon", "error");
                end

                catch ME
                    report = getReport(ME);
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end

            
        end

        % Callback function
        function MeasureAngleButtonPushed(app, event)
            try
                %app.message2send.opcode = % servo
                app.message2send.data = [];
                app.message2send.datalength = 0;

                app.AnlgeValues(1) = send_receive_command(app);

                app.MeasuredAngleLabel.Text = 'Measured Angle:' + string(round(app.AngleValues(1)));

                app.MeasureAngleButton_2.Enable = 'on';
            catch ME
                report = getReport(ME);
                uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end
        end

        % Callback function
        function MeasureAngleButton_2Pushed(app, event)
            try
                %app.message2send.opcode = % servo
                app.message2send.data = [];
                app.message2send.datalength = 0;

                app.AnlgeValues(2) = send_receive_command(app);

                app.MeasuredAngleLabel_2.Text = 'Measured Angle:' + string(round(app.AngleValues(2)));

                app.ConfirmCalibrationButton.Enable = 'on';


            catch ME
                report = getReport(ME);
                uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end
        end

        % Clicked callback: BTDeviceDropDown
        function BTDeviceDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            app.selectFlag = true;
        end

        % Value changed function: ControlDropDown
        function ControlDropDownValueChanged(app, event)
            value = app.ControlDropDown.Value;
            mode = app.ControlDropDown.Value;

            switch mode
                case '1' % PI Control Parameters 
                    app.TuningPIPanel_Control_Params.Visible = "on";
                    app.TuningPIPanel_Control_Params.Enable = "on";
                    app.OptimizePIButton.Visible = "on";
                    app.OptimizePIButton.Enable = "on";
                    app.omega_G150Label.Visible = 'on';
                    app.TuningPIDPanel_Control_Params.Visible = "off";
                    app.TuningPIDPanel_Design_Params.Visible = "off";
                    app.TuningPIPanel_Design_Params.Visible = "off";
                    app.OptimizePIDButton.Visible = "off";
                    app.PControllerPanel.Visible = "off";
                    app.PIControllerPanel.Visible = "off";
                    app.PIDControllerPanel.Visible = "off";
                    app.PIDControllerPanel.Enable = "off";
                    app.PIControllerPanel.Enable = "off";
                    app.PControllerPanel.Enable = "off";
                    app.TuningPIDPanel_Control_Params.Enable = "off";
                    app.TuningPIDPanel_Design_Params.Enable = "off";
                    app.TuningPIPanel_Design_Params.Enable = "off";

                    app.PI_control_flag = true;
                    app.PID_control_flag = false;
                    app.PI_design_flag = false;
                    app.PID_design_flag = false;
                    app.P_controller_flag = false;
                    app.PI_controller_flag = false;
                    app.PID_controller_flag = false;
                    
               
                case '2' % PI Design Parameters 
                    app.TuningPIPanel_Design_Params.Visible = "on";
                    app.TuningPIPanel_Design_Params.Enable = "on";
                    app.OptimizePIButton.Visible = "on";
                    app.OptimizePIButton.Enable = "on";
                    app.omega_G150Label.Visible = 'on';
                    app.TuningPIDPanel_Design_Params.Visible = "off";
                    app.TuningPIDPanel_Control_Params.Visible = "off";
                    app.TuningPIPanel_Control_Params.Visible = "off";
                    app.OptimizePIDButton.Visible = "off";
                    app.PControllerPanel.Visible = "off";
                    app.PIControllerPanel.Visible = "off";
                    app.PIDControllerPanel.Visible = "off";
                    app.PIDControllerPanel.Enable = "off";
                    app.PIControllerPanel.Enable = "off";
                    app.PControllerPanel.Enable = "off";
                    app.TuningPIDPanel_Control_Params.Enable = "off";
                    app.TuningPIDPanel_Design_Params.Enable = "off";
                    app.TuningPIPanel_Control_Params.Enable = "off";

                    app.PI_design_flag = true;
                    app.PID_control_flag = false;
                    app.PI_control_flag = false;
                    app.PID_design_flag = false;
                    app.P_controller_flag = false;
                    app.PI_controller_flag = false;
                    app.PID_controller_flag = false;
                    

                case '3' % PID Control Parameters 
                    app.TuningPIDPanel_Control_Params.Visible = "on";
                    app.TuningPIDPanel_Control_Params.Enable = "on";
                    app.OptimizePIDButton.Visible = "on";
                    app.OptimizePIDButton.Enable = "on";
                    app.omega_G150Label.Visible = 'on';
                    app.TuningPIDPanel_Design_Params.Visible = "off";
                    app.TuningPIPanel_Design_Params.Visible = "off";
                    app.TuningPIPanel_Control_Params.Visible = "off";
                    app.OptimizePIButton.Visible = "off";
                    app.PControllerPanel.Visible = "off";
                    app.PIControllerPanel.Visible = "off";
                    app.PIDControllerPanel.Visible = "off";
                    app.PIDControllerPanel.Enable = "off";
                    app.PIControllerPanel.Enable = "off";
                    app.PControllerPanel.Enable = "off";
                    app.TuningPIDPanel_Design_Params.Enable = "off";
                    app.TuningPIPanel_Design_Params.Enable = "off";
                    app.TuningPIPanel_Control_Params.Enable = "off";

                    app.PID_control_flag = true;
                    app.PI_design_flag = false;
                    app.PI_control_flag = false;
                    app.PID_design_flag = false;
                    app.P_controller_flag = false;
                    app.PI_controller_flag = false;
                    app.PID_controller_flag = false;


                case '4' % PID Design Parameters 
                    app.TuningPIDPanel_Design_Params.Visible = "on";
                    app.TuningPIDPanel_Design_Params.Enable = "on";
                    app.OptimizePIDButton.Visible = "on";
                    app.OptimizePIDButton.Enable = "on";
                    app.omega_G150Label.Visible = 'on';
                    app.TuningPIDPanel_Control_Params.Visible = "off";
                    app.TuningPIPanel_Control_Params.Visible = "off";
                    app.TuningPIPanel_Design_Params.Visible = "off";
                    app.OptimizePIButton.Visible = "off";
                    app.PControllerPanel.Visible = "off";
                    app.PIControllerPanel.Visible = "off";
                    app.PIDControllerPanel.Visible = "off";
                    app.PIDControllerPanel.Enable = "off";
                    app.PIControllerPanel.Enable = "off";
                    app.PControllerPanel.Enable = "off";
                    app.TuningPIDPanel_Control_Params.Enable = "off";
                    app.TuningPIPanel_Design_Params.Enable = "off";
                    app.TuningPIPanel_Control_Params.Enable = "off";

                    app.PID_design_flag = true;
                    app.PID_control_flag = false;
                    app.PI_design_flag = false;
                    app.PI_control_flag = false;
                    app.P_controller_flag = false;
                    app.PI_controller_flag = false;
                    app.PID_controller_flag = false;

                case '5' % P Controller
                    app.PControllerPanel.Visible = "on";
                    app.PControllerPanel.Enable = "on";
                    app.PIControllerPanel.Visible = "off";
                    app.PIDControllerPanel.Visible = "off";
                    app.TuningPIDPanel_Control_Params.Visible = "off";
                    app.TuningPIPanel_Control_Params.Visible = "off";
                    app.TuningPIPanel_Design_Params.Visible = "off";
                    app.TuningPIDPanel_Design_Params.Visible = "off";
                    app.PIControllerPanel.Enable = "off";
                    app.PIDControllerPanel.Enable = "off";
                    app.TuningPIDPanel_Design_Params.Enable = "off";
                    app.TuningPIDPanel_Control_Params.Enable = "off";
                    app.TuningPIPanel_Design_Params.Enable = "off";
                    app.TuningPIPanel_Control_Params.Enable = "off";

                    app.OptimizePIButton.Visible = "off";
                    app.OptimizePIDButton.Visible = "off";
                    app.omega_G150Label.Visible = 'off';
                    app.P_controller_flag = true;
                    app.PID_design_flag = false;
                    app.PID_control_flag = false;
                    app.PI_design_flag = false;
                    app.PI_control_flag = false;
                    app.PI_controller_flag = false;
                    app.PID_controller_flag = false;

                case '6' % PI Controller
                    app.PIControllerPanel.Visible = "on";
                    app.PIControllerPanel.Enable = "on";
                    app.PIDControllerPanel.Visible = "off";
                    app.PControllerPanel.Visible = "off";
                    app.TuningPIDPanel_Control_Params.Visible = "off";
                    app.TuningPIPanel_Control_Params.Visible = "off";
                    app.TuningPIPanel_Design_Params.Visible = "off";
                    app.TuningPIDPanel_Design_Params.Visible = "off";
                    app.PIDControllerPanel.Enable = "off";
                    app.PControllerPanel.Enable = "off";
                    app.TuningPIDPanel_Design_Params.Enable = "off";
                    app.TuningPIDPanel_Control_Params.Enable = "off";
                    app.TuningPIPanel_Design_Params.Enable = "off";
                    app.TuningPIPanel_Control_Params.Enable = "off";

                    app.OptimizePIButton.Visible = "off";
                    app.OptimizePIDButton.Visible = "off";
                    app.omega_G150Label.Visible = 'off';
                    app.PI_controller_flag = true;
                    app.PID_design_flag = false;
                    app.PID_control_flag = false;
                    app.PI_design_flag = false;
                    app.PI_control_flag = false;
                    app.P_controller_flag = false;
                    app.PID_controller_flag = false;

                case '7' % PID Controller
                    app.PIDControllerPanel.Visible = "on";
                    app.PIDControllerPanel.Enable = "on";
                    app.PIControllerPanel.Visible = "off";
                    app.PControllerPanel.Visible = "off";
                    app.TuningPIDPanel_Control_Params.Visible = "off";
                    app.TuningPIPanel_Control_Params.Visible = "off";
                    app.TuningPIPanel_Design_Params.Visible = "off";
                    app.TuningPIDPanel_Design_Params.Visible = "off";
                    app.PIControllerPanel.Enable = "off";
                    app.PControllerPanel.Enable = "off";
                    app.TuningPIDPanel_Design_Params.Enable = "off";
                    app.TuningPIDPanel_Control_Params.Enable = "off";
                    app.TuningPIPanel_Design_Params.Enable = "off";
                    app.TuningPIPanel_Control_Params.Enable = "off";

                    app.OptimizePIButton.Visible = "off";
                    app.OptimizePIDButton.Visible = "off";
                    app.omega_G150Label.Visible = 'off';
                    app.PID_controller_flag = true;
                    app.PID_design_flag = false;
                    app.PID_control_flag = false;
                    app.PI_design_flag = false;
                    app.PI_control_flag = false;
                    app.P_controller_flag = false;
                    app.PI_controller_flag = false;
                    
                    
            end

            
        end

        % Button pushed function: OptimizePIButton
        function OptimizePIButtonPushed(app, event)
            % This function was implemented by Mohmand based on the
            % functions in the book!
            clc
            % cla(app.StepReference)
            % cla(app.StepDisturbance)
            % cla(app.ServoTrajectory)
            progressbar_PIopt = uiprogressdlg(app.JUSTEXOUIFigure,'Title','Please Wait','Message','Calculating optimized values', 'Icon','info');
           
            %%%%%%%%  G(s) Plant Transfer Funtion %%%%%%%%%%
            % G(S) Num Values
            G_num = convertCharsToStrings(app.numEditField.Value);
            G_den = convertCharsToStrings(app.denEditField.Value);
                            
            G = analyze_transfer_function(app, G_num, G_den) % create the G(s) plant
  
            progressbar_PIopt.Value = .33;
            [gain,phase,w]=bode(G);     % Bodediagram
            phase = squeeze(phase)
            w150=interp1(phase,w,-150);    %-150                                    % Beräknar ωG150
            [F,wc,phim,Ki,Ti,MS,MT]=pi_opt(G,0.3*w150,0.5*w150,45,60);
            progressbar_PIopt.Value = .67;
            phim = ceil(phim);
            phim_end = phim+1;
            phim_start = phim-2;
            
            wc = wc*10;
            wc = ceil(wc);
            wc = wc/10;
            wc_end = wc+0.02;
            wc_start = wc-0.03;

            [F,wc,phim,Ki,Ti,MS,MT]=pi_opt(G,wc_start,wc_end,phim_start,phim_end);                             % Optimal PI-reg.
            progressbar_PIopt.Value = 1;
            progressbar_PIopt.Message = 'Finishing';

            if app.PI_control_flag
                app.TiEditFieldPIControl.Value = Ti;
                app.KiEditFieldPIControl.Value = Ki;
            elseif app.PI_design_flag
                app.PhiMEditFieldPIDesign.Value = phim;
                app.OmegaCEditFieldPIDesign.Value = wc;
            end
            close(progressbar_PIopt);
            
            function [F,Ki,Ti]=pi_wc_phim(G,wc,phim)
                Nwc = length(wc); Nphim = length(phim);
                for k=1:Nphim
                    for i=1:Nwc
                        j=i+(k-1)*Nwc;
                        [gain_G,phase_G]=bode(G,wc(i));
                        phase_F=-180+phim(k)-phase_G;
                        Ti(j)=cotd(-phase_F)/wc(i);
                        Ki(j)=gain_G/sqrt(1+(Ti(j)*wc(i))^2);
                        F(:,:,j)=tf([Ki(j)*Ti(j) Ki(j)],[1 0]);
                    end
                end
            end

            function [F,wc,phim,Ki,Ti,MS,MT]=pi_opt(G,wc_min,wc_max,phim_min,phim_max)
                Nwc=20; Nphim=10;
                wc=[wc_min:(wc_max-wc_min)/(Nwc-1):wc_max];
                phim=[phim_min:(phim_max-phim_min)/(Nphim-1):phim_max];
                [F,Ki,Ti]=pi_wc_phim(G,wc,phim);                                              %The upper Function
                MS=norm(feedback(1,G*F),inf,1e-3);
                MT=norm(feedback(G*F,1),inf,1e-3);
                stab=norm(feedback(G*F,1));
                [Ki,iopt]=max(Ki'.*(MS<=1.7).*(MT<=1.3).*(stab<inf));
                F=F(:,:,iopt);
                MS=MS(iopt);
                MT=MT(iopt);
                Ti=Ti(iopt);
                [Am,phim,wpi,wc]=margin(G*F);
            end
        end

        % Value changed function: ShowThresholdCheckBox
        function ShowThresholdCheckBoxValueChanged(app, event)
            if (app.ShowThresholdCheckBox.Value)
                app.upperHandle = yline(app.ForceReading, app.ForceUpperThresholdSlider.Value, 'Color','blue');
                app.lowerHandle = yline(app.ForceReading, app.ForceLowerThresholdSlider.Value, 'Color','blue');
                legend([app.hForce app.h2Force app.upperHandle app.lowerHandle], {'raw', 'filtered','Upper Thresh', 'Lower Thresh'}, 'Location', 'northeast');
            else
               delete(app.upperHandle);
               delete(app.lowerHandle);
            end
        end

        % Callback function
        function ShowThresholdCheckBox_2ValueChanged(app, event)
            if (app.ShowThresholdCheckBox.Value)
                app.EMGHandleCH1 = yline(app.EMG_CH1, app.CH1ThresholdSlider.Value, 'Color','black');
                app.EMGHandleCH2 = yline(app.EMG_CH2, app.CH2ThresholdSlider.Value, 'Color','black');
                
            else
               delete(app.EMGHandleCH1);
               delete(app.EMGHandleCH2);
            end
            
        end

        % Value changed function: StartRecordingSwitch_DualThresh_EMG
        function StartRecordingSwitch_DualThresh_EMGValueChanged(app, event)
            value = app.StartRecordingSwitch_DualThresh_EMG.Value;

            try

                if strcmp(value, 'On')
                    % change UI components
                    changeEMGControlTab(app, 'running');
                    app.StatusLamp.Color = [175,0,255]./255;
                    app.Label.Text = 'EMG control';

                    %%Clean up plots
                    cla(app.EMG_OneCH);
                    app.EMG_OneCH.XLim = [0 100];
                    app.EMG_Upper_Threshold = app.EMGUpperThresholdSlider.Value;
                    app.EMG_Lower_Threshold = app.EMGLowerThresholdSlider.Value;
                    
            
                    % set filter type
                    filter_option = app.AppliedFilterButtonGroup_EMG.SelectedObject;
    

                    % initialize plots
                    app.EMG_OneCH.NextPlot = 'add';  % move upt to startfcn?
                    %initialize IMU plot
                    %% initialize IMU plot
                    radius = 2.5;
                    upper_arm_ = stlread('stl/arm.stl');
                    lower_arm_ = stlread('stl/arm.stl');
                    torso_ = stlread('stl/torso.stl');
                    head_ = stlread('stl/head.stl');
                    Fs = 20;
                    % clear up plots
                    cla(app.IMUPlot_EMG, "reset");
                    

                    % init
                    %[30 0 0] 
                    % [x z y] eul2rotm([z y x])
                    app.torso = transformStl(app, torso_,[0 0 0],eul2quat([-pi/2 0 -pi/2]));
                    app.head = transformStl(app, head_,[0 0 0],eul2quat([0 0 0]));
                    app.upper_arm = transformStl(app,upper_arm_,[-0.25 0 0],eul2quat([0 0 pi]));
                    app.lower_arm = transformStl(app,lower_arm_,[0.25 0 0],eul2quat([pi 0 0]));
                    lims = radius*2;
                    
                   
                    app.fuse = imufilter('SampleRate',Fs,'DecimationFactor',1)
                    
                    % initialize plots
                    app.posePlot_Shoulder = poseplot("Parent",app.IMUPlot_EMG);
                    hold(app.IMUPlot_EMG);
                    app.posePlot_Elbow = poseplot("Parent", app.IMUPlot_EMG);
                    app.IMUPlot_EMG.Title.String = "3D Position Estimation";
                    % app.IMUPlot_EMG.XLabel.String = "North-x (m)";
                    % app.IMUPlot_EMG.YLabel.String = "East-y (m)"; 
                    % app.IMUPlot_EMG.ZLabel.String = "Down-z (m)"; 
                    app.IMUPlot_EMG.YLim = [-lims lims];
                    app.IMUPlot_EMG.XLim = [-lims lims];
                    app.IMUPlot_EMG.ZLim = [-lims lims];
                    % app.IMUPlot_EMG.XTickLabel = []
                    app.IMUPlot_EMG.XGrid = 'off';
                    app.IMUPlot_EMG.YGrid = 'off';
                    app.IMUPlot_EMG.ZGrid = 'off';
                    app.IMUPlot_EMG.XMinorGrid = 'off';
                    app.IMUPlot_EMG.YMinorGrid = 'off';
                    app.IMUPlot_EMG.ZMinorGrid = 'off';
                    app.IMUPlot_EMG.Color = 'none';

                    % place the torso to fixed position
                    trisurf(app.torso, "FaceColor", 'none', "Parent",app.IMUPlot_EMG);
                    % trisurf(app.head, "EdgeColor", 'none', "Parent", app.IMUPlot_EMG);
                    % place the arm in a first fixed position
                    app.upper_arm_posepatch = trisurf(app.upper_arm,"Parent", app.IMUPlot_EMG); 
                    app.lower_arm_posepatch = trisurf(app.lower_arm,"Parent", app.IMUPlot_EMG); 
                    % view(app.IMUPlot,-27, 9);
                    
                    
                    % app.EMG_CH1.axh()
                    hold(app.EMG_OneCH, "on");
                    % app.dL_EMG_CH1_raw = line(app.EMG_CH1, nan, nan);
                    % app.dL_EMG_CH1_filt = line(app.EMG_CH1, nan, nan, 'Color','red','LineStyle','--');
                    app.emg1_h = animatedline(app.EMG_OneCH, nan, nan, 'Color', 'red', 'LineStyle','--', 'MaximumNumPoints',110);
                    app.emg1_h2 = animatedline(app.EMG_OneCH, nan, nan, 'MaximumNumPoints',110);
                    % app.EMGupperHandle = yline(app.EMG_OneCH, app.EMG_Upper_Threshold, 'Color','blue');
                    % app.EMGlowerHandle = yline(app.EMG_OneCH, app.EMG_Lower_Threshold, 'Color','blue');
                    legend([app.emg1_h app.emg1_h2], {'raw', 'filtered'}, 'Location', 'northeast');

                    if (app.EMGShowThresholdCheckBox.Value)
                        app.EMGupperHandle = yline(app.EMG_OneCH, app.EMGUpperThresholdSlider.Value/1000, 'Color','blue');
                        app.EMGlowerHandle = yline(app.EMG_OneCH, app.EMGLowerThresholdSlider.Value/1000, 'Color','blue');
                        legend([app.emg1_h app.emg1_h2 app.EMGupperHandle app.EMGlowerHandle], {'raw', 'filtered','Upper Thresh', 'Lower Thresh'}, 'Location', 'northeast');
                        
                    end


                    hold(app.EMG_OneCH, "off");

                    
                    % set EMG threshold values
                    %%TODO: check if bupper is bigger than lower!
                    % if not: make lower 100 point smaller than upper.
                    if app.EMGUpperThresholdSlider.Value < app.EMGLowerThresholdSlider.Value
                        if app.EMGLowerThresholdSlider.Value < 0.2
                            app.EMGLowerThresholdSlider.Value = 0.3;
                        app.EMGLowerThresholdSlider.Value = app.EMGUpperThresholdSlider.Value - 0.2;
                        end
                    end
                    app.EMG_Upper_Threshold = uint16((app.EMGUpperThresholdSlider.Value)*3.23);
                    app.EMG_Lower_Threshold = uint16((app.EMGLowerThresholdSlider.Value)*3.23);

                    speed = 5 - app.SpeedSetting_EMG.Value;
                    stepsize = 10;


                    CommunicationFunctions.setAnalogPin(app, 0x40, uint8(app.analogEMG0Pin));
                    pause(0.1);
                    CommunicationFunctions.setThresholds(app, 0x40, app.EMG_Upper_Threshold, app.EMG_Lower_Threshold, speed, stepsize);  
                    pause(0.1);
                    [~, app.servoState] = CommunicationFunctions.setServoState(app, 0x52);
                    
                    CheckBattery(app,1);
                    app.EMGisPlotting = true;


                    StartRecordingEMG(app);

                else
                    app.EMGisPlotting = false;
                    changeEMGControlTab(app, 'initial');
                    StopRecording(app);
                    pause(1);
                    CheckBattery(app,2);
    
                end
       

                catch ME
                    report = getReport(ME);
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
            end
            
            
        end

        % Value changed function: EMGShowThresholdCheckBox
        function EMGShowThresholdCheckBoxValueChanged(app, event)
            if (app.EMGShowThresholdCheckBox.Value)
                app.EMGupperHandle = yline(app.EMG_OneCH, app.EMGUpperThresholdSlider.Value/1000, 'Color','blue');
                app.EMGlowerHandle = yline(app.EMG_OneCH, app.EMGLowerThresholdSlider.Value/1000, 'Color','blue');
                legend([app.emg1_h app.emg1_h2 app.EMGupperHandle app.EMGlowerHandle], {'raw', 'filtered','Upper Thresh', 'Lower Thresh'}, 'Location', 'northeast');
            else
               delete(app.EMGupperHandle);
               delete(app.EMGlowerHandle);
            end            
        end

        % Value changed function: ApplyThresholdsButton_EMG
        function ApplyThresholdsButton_EMGValueChanged(app, event)
            value = app.ApplyThresholdsButton_EMG.Value;

            app.EMG_Upper_Threshold = uint16((app.EMGUpperThresholdSlider.Value)*3.23);
            app.EMG_Lower_Threshold = uint16((app.EMGLowerThresholdSlider.Value)*3.23);
            

            speed = 5 - str2double(app.SpeedSetting_EMG.Value);
            stepsize = 10;

            StopRecording(app);

            pause(0.1);
            CommunicationFunctions.setThresholds(app, 0x40, app.EMG_Upper_Threshold, app.EMG_Lower_Threshold, speed, stepsize);  
            pause(0.1);

            
            if (app.EMGShowThresholdCheckBox.Value)
                clearpoints(app.emg1_h);
                clearpoints(app.emg1_h2);
                delete(app.EMGupperHandle);
                delete(app.EMGlowerHandle);
                app.EMGupperHandle = yline(app.EMG_OneCH, app.EMGUpperThresholdSlider.Value/1000, 'Color','blue');
                app.EMGlowerHandle = yline(app.EMG_OneCH, app.EMGLowerThresholdSlider.Value/1000, 'Color','blue');
                legend([app.emg1_h app.emg1_h2 app.EMGupperHandle app.EMGlowerHandle], {'raw', 'filtered','Upper Thresh', 'Lower Thresh'}, 'Location', 'northeast');
            else
               clearpoints(app.emg1_h);
               clearpoints(app.emg1_h2);
               delete(app.EMGupperHandle);
               delete(app.EMGlowerHandle);
            end            

            StartRecordingEMG(app);
        end

        % Value changed function: ApplyThresholdsButton_Force
        function ApplyThresholdsButton_2ValueChanged(app, event)


            app.upper_threshold = uint16(app.ForceUpperThresholdSlider.Value/17);
            app.lower_threshold = uint16(abs(app.ForceLowerThresholdSlider.Value/17));
            
            speed = 5 - app.SpeedSetting_Force.Value;
            stepsize = 10;
            
            StopRecording(app);
            CommunicationFunctions.setThresholds(app, 0x41, app.upper_threshold, app.lower_threshold, speed, stepsize)
        
            if (app.ShowThresholdCheckBox.Value)
                clearpoints(app.hForce);
                clearpoints(app.h2Force);
                delete(app.upperHandle);
                delete(app.lowerHandle);
                app.upperHandle = yline(app.ForceReading, app.ForceUpperThresholdSlider.Value, 'Color','blue');
                app.lowerHandle = yline(app.ForceReading, app.ForceLowerThresholdSlider.Value, 'Color','blue');
                legend([app.hForce app.h2Force app.upperHandle app.lowerHandle], {'raw', 'filtered','Upper Thresh', 'Lower Thresh'}, 'Location', 'northeast');
            else
               clearpoints(app.hForce);
               clearpoints(app.h2Force);
               delete(app.upperHandle);
               delete(app.lowerHandle);
            end            

            StartRecordingForce(app);
            
        end

        % Callback function
        function ProcessDataButtonPushed(app, event)
            % Change button name to "Processing"
           
            app.ProcessDataButton.Text = 'Processing...';
            % Put text on top of icon
            app.ProcessDataButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.ProcessDataButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.ProcessDataButton.Icon = wbar;
            % Loop through something and update waitbar
            n = 50;
            for i = 1:n
                % Update image data (royalblue)
                if mod(i,10)==0 % update every 10 cycles; improves efficiency
                    
                    currentProg = min(round((size(wbar,2)-2)*(i/n)),size(wbar,2)-2);
                    RGB = app.ProcessDataButton.Icon;
                    RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
                    RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
                    RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            
                    app.ProcessDataButton.Icon = RGB;
                    % Pause to slow down animation 
                    pause(.3)
                end
                
             end
             % remove waitbar
             app.ProcessDataButton.Icon = '';
             % Change button name
             app.ProcessDataButton.Text = 'Process Data';
             
        end

        % Value changed function: TrackChangesCheckBox
        function TrackChangesCheckBoxValueChanged(app, event)
            app.Compare_changes_flag = app.TrackChangesCheckBox.Value;
            
        end

        % Value changed function: StartRecordingSwitch_IMU
        function StartRecordingSwitch_IMUValueChanged(app, event)
            value = app.StartRecordingSwitch_IMU.Value;

             try
                if strcmp(value, 'On')

                    %% initialize IMU plot
                    
                    radius = 2.5;
                    upper_arm_ = stlread('stl/arm.stl');
                    lower_arm_ = stlread('stl/arm.stl');
                    torso_ = stlread('stl/torso.stl');
                    head_ = stlread('stl/head.stl');
                    Fs = 20;
                    % clear up plots
                    cla(app.IMUPlot, "reset");


                    % init
                    %[30 0 0] 
                    % [x z y] eul2rotm([z y x])
                    app.torso = transformStl(app, torso_,[0 0 0],eul2quat([-pi/2 0 pi/2]));
                    app.head = transformStl(app, head_,[0 0 0],eul2quat([0 0 0]));
                    app.upper_arm = transformStl(app,upper_arm_,[-0.25 0 0],eul2quat([0 0 pi]));
                    app.lower_arm = transformStl(app,lower_arm_,[0.25 0 0],eul2quat([pi 0 0]));
                    lims = radius*2;


                    app.StatusLamp.Color = "m";
                    app.fuse = imufilter('SampleRate',Fs,'DecimationFactor',1);
                    % poseplot(app.IMUPlot,eye(3),[0 0 0],ScaleFactor=1);


                    % initialize plots
                    app.posePlot_Shoulder = poseplot("Parent",app.IMUPlot);
                    hold(app.IMUPlot);
                    app.posePlot_Elbow = poseplot("Parent", app.IMUPlot);
                    
                    % app.IMUPlot.XLabel.String = "North-x (m)";
                    % app.IMUPlot.YLabel.String = "East-y (m)"; 
                    % app.IMUPlot.ZLabel.String = "Down-z (m)"; 
                    app.IMUPlot.YLim = [-lims lims];
                    app.IMUPlot.XLim = [-lims lims];
                    app.IMUPlot.ZLim = [-lims lims];
                    % app.IMUPlot.XTickLabel = []
                    app.IMUPlot.XGrid = 'off';
                    app.IMUPlot.YGrid = 'off';
                    app.IMUPlot.ZGrid = 'off';
                    app.IMUPlot.XMinorGrid = 'off';
                    app.IMUPlot.YMinorGrid = 'off';
                    app.IMUPlot.ZMinorGrid = 'off';
                    app.IMUPlot.Color = 'none';


                    % place the torso to fixed position
                    % trisurf(app.torso, "FaceColor", 'r', "Parent",app.IMUPlot);
                    % trisurf(app.head, "EdgeColor", 'none', "Parent", app.IMUPlot);
                    % place the arm in a first fixed position
                    app.upper_arm_posepatch = trisurf(app.upper_arm,"Parent", app.IMUPlot); 
                    app.lower_arm_posepatch = trisurf(app.lower_arm,"Parent", app.IMUPlot); 
                    % view(app.IMUPlot,-27, 9);
                    app.IMUisPlotting = true;
                    pause(0.1);
                    StartRecordingIMU(app);
                    
                else
                    app.IMUisPlotting = false;
                    StopRecording(app);
                    % report = "The Battery Voltage is too low. Servo Motor cannot be operated. Please Charge Battery!";
                    % uialert(app.JUSTEXOUIFigure, report, "Battery Error", "Icon", "error");
                end

                catch ME
                    % report = [ME.message, getfield(ME.stack,'line')];
                    report = getReport(ME);
                    % error_line = ME.stack.line;
                    uialert(app.JUSTEXOUIFigure,report,"Error","Icon","error");
                    
            end
            
        end

        % Callback function
        function InputResponseButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputResponseButtonGroup.SelectedObject;
            switch selectedButton
                case 'Step'

                case 'Ramp'
            end


        end

        % Value changed function: ServoAngleLimitsSlider
        function ServoAngleLimitsSliderValueChanged(app, event)
            AdjustServoLimitsSliderGUI(app)
        end

        % Value changed function: HighRefDegreeEditField
        function HighRefDegreeEditFieldValueChanged(app, event)
            AdjustServoLimitsGUI(app)
        end

        % Value changed function: LowRefDegreeEditField
        function LowRefDegreeEditFieldValueChanged(app, event)
            AdjustServoLimitsGUI(app)
        end

        % Value changed function: EMGUpperThresholdSlider
        function EMGUpperThresholdSliderValueChanged(app, event)
            AdjustEMGThresholdsGUI(app)
        end

        % Value changed function: EMGLowerThresholdSlider
        function EMGLowerThresholdSliderValueChanged(app, event)
            AdjustEMGThresholdsGUI(app)
        end

        % Value changed function: EMGUpperThreshold
        function EMGUpperThresholdValueChanged(app, event)
            AdjustEMGThresholdsGUI(app)
        end

        % Value changed function: EMGLowerThreshold
        function EMGLowerThresholdValueChanged(app, event)
            AdjustEMGThresholdsGUI(app)
        end

        % Value changed function: ForceUpperThresholdSlider
        function ForceUpperThresholdSliderValueChanged(app, event)
            value = app.ForceUpperThresholdSlider.Value;
        
            
        end

        % Value changed function: ForceLowerThresholdSlider
        function ForceLowerThresholdSliderValueChanged(app, event)
            value = app.ForceLowerThresholdSlider.Value;
            
        end

        % Button pushed function: OptimizePIDButton
        function OptimizePIDButtonPushed(app, event)
            report = "Function not implemented!";
            uialert(app.JUSTEXOUIFigure,report,"Information","Icon","info");
        end

        % Value changed function: ShowInputSIgnalCheckBox
        function ShowInputSIgnalCheckBoxValueChanged(app, event)
             app.Compare_changes_flag = app.TrackChangesCheckBox.Value;
            app.ShowInputSignal_flag = app.ShowInputSIgnalCheckBox.Value;
            
        end

        % Value changed function: StepPeriodSpinner, StepRepetitionsSpinner
        function StepPeriodSpinnerValueChanged(app, event)
            cla(app.InputSignalGraph, 'reset')
            app.period = app.StepPeriodSpinner.Value;
            app.repetitions = app.StepRepetitionsSpinner.Value;
                       
            [input_signal, t] = step_input(app,app.LowRefDegreeEditField.Value, app.HighRefDegreeEditField.Value, app.period, app.repetitions);
            input_signal = input_signal+app.LowRefDegreeEditField.Value;
            hold(app.InputSignalGraph,'on');
            plot(app.InputSignalGraph, t, input_signal);
            % app.InputSignalGraph.XLim = [0 length(t)];
            app.InputSignalGraph.XLim = [0, max(t)];
            app.InputSignalGraph.XTick = 0:10:length(t);
            % app.InputSignalGraph.XTickLabel = 0:0.2*10:max(t);
            app.InputSignalGraph.XLabel.String = "Time [s]";
            app.InputSignalGraph.YLabel.String = "Amplitude [°]";
            app.InputSignalGraph.Title.String = "Input Signal";

        end

        % Value changed function: RampPeriodSpinner, RampRepetitionsSpinner
        function RampPeriodSpinnerValueChanged(app, event)
            cla(app.InputSignalGraph, 'reset')
            app.period = app.RampPeriodSpinner.Value;
            app.repetitions = app.RampRepetitionsSpinner.Value;
                       
            [input_signal, t] = symmetric_ramp_input(app,app.LowRefDegreeEditField.Value, app.HighRefDegreeEditField.Value, app.period, app.repetitions);
            input_signal = input_signal+app.LowRefDegreeEditField.Value;
            hold(app.InputSignalGraph,'on');
            plot(app.InputSignalGraph, t, input_signal);
            app.InputSignalGraph.XLim = [0, max(t)];
            app.InputSignalGraph.XTick = 0:10:length(t);
            app.InputSignalGraph.XLabel.String = "Time [s]";
            app.InputSignalGraph.YLabel.String = "Amplitude [°]";
            app.InputSignalGraph.Title.String = "Input Signal";
            
            
        end

        % Value changed function: NaturalPeriodSpinner, 
        % ...and 1 other component
        function NaturalPeriodSpinnerValueChanged(app, event)
            cla(app.InputSignalGraph, 'reset')
            app.period = app.NaturalPeriodSpinner.Value;
            app.repetitions = app.NaturalRepetitionsSpinner.Value;
                       
            [input_signal, t] = natural_elbow_movement(app,app.LowRefDegreeEditField.Value, app.HighRefDegreeEditField.Value, app.period, app.repetitions);
            input_signal = input_signal+app.LowRefDegreeEditField.Value;
            hold(app.InputSignalGraph,'on');
            plot(app.InputSignalGraph, t, input_signal);
            app.InputSignalGraph.XLim = [0, max(t)];
            app.InputSignalGraph.XTick = 0:10:length(t);
            app.InputSignalGraph.XLabel.String = "Time [s]";
            app.InputSignalGraph.YLabel.String = "Amplitude [°]";
            app.InputSignalGraph.Title.String = "Input Signal";
            
            
        end

        % Button pushed function: RefreshButton_Connection_List
        function RefreshButton_Connection_ListPushed(app, event)
            changeConnectionTab(app, 'initial');
            startupFcn(app);
        end

        % Callback function
        function ChannelsSwitchValueChanged(app, event)
            if strcmp(app.ChannelsSwitch.Value, 'Bi')
                app.BiChannel_flag = true;
            else 
                app.BiChannel_flag = false;
            end

            if app.BiChannel_flag
                app.CH2DropDown.Visible = "on";
                app.CH1DropDown.Visible = "on";
            else
                app.CH2DropDown.Visible = "off";
                app.CH1DropDown.Visible = "on";
            end
            
        end

        % Value changed function: CH1DropDown
        function CH1DropDownValueChanged2(app, event)
            Channel_Port = app.CH1DropDown.Value;
 

            if strcmp(Channel_Port, 'EMG0')
                app.analogEMG0Pin = 1;
                app.analogEMG1Pin = 2;
                app.CH2DropDown.Value = app.CH2DropDown.Items{1,2};
            else
                app.analogEMG0Pin = 2;
                app.analogEMG1Pin = 1;
                app.CH2DropDown.Value = app.CH2DropDown.Items{1,1};

            end
        end

        % Callback function: not associated with a component
        function realPIController_Button_pushed(app, event)
            initialValue = uint16(1800 - (app.ServoAngleLimitsSlider.Value(1)'*10));
            finalValue = uint16(1800 - (app.ServoAngleLimitsSlider.Value(2)'*10));
            repetitions = uint8(app.StepRepetitionsSpinner.Value);
            period = 15;
            T = (app.repetitions * period);% Total time duration of motion in seconds, add half period at start and end of the input signal
            dt = 0.02; % Time step
            time_vector = 0:dt:T; % Time vector
           
            
            setPIControlParams(app,initialValue, finalValue, repetitions, single(app.Ki), single(app.Kp));

            startPIController(app);


            % TODO ADD STREAM
            i = 1;

            try
                while true
                    % Read a line of data
                    a = tic; % start timer
                    
                        
                    %% get the servo Analog degree feedback from the 4th wire in the servo
                    
                    [~, temp] = CommunicationFunctions.getServoAngleStream(app);
                    temp_plot = ((1800-temp)/10);
                    i=+1;
                    
                   
                    % hold(app.ServoTrajectory, 'on')
                    hold(app.ServoTrajectory, 'on');
                    addpoints(h, time_vector(i), app.PLOT_degree(i));
                    addpoints(h2, time_vector(i), temp_plot);
                    % app.dL_ServoAngle_reference_tracking.XData = [app.dL_ServoAngle_reference_tracking.XData i];
                    % app.dL_ServoAngle_reference_tracking.YData = [app.dL_ServoAngle_reference_tracking.YData app.PLOT_degree(i)];
                    % app.dL_ServoAgle_raw.XData = [app.dL_ServoAgle_raw.XData i];
                    % app.dL_ServoAgle_raw.YData = [app.dL_ServoAgle_raw.YData temp_plot];
                    hold(app.ServoTrajectory, 'off');

                    % drawnow;


                    % legend(app.ServoTrajectory,{'Servo Refrence Angle','Risk threshold', 'Servo Trajectory', 'Servo Feedback'},'Location','southeast')
                    legend(app.ServoTrajectory, [h h2], {'Servo Trajectory', 'Servo Feedback'},'Location','northeast')
                     
                    
                    % Check for stop bit
                    if contains(data, 'STOP')
                        disp('Received stop bit. Ending data acquisition.');
                        break;
                    end
                    
                    % Parse the data

                end
            catch
                disp('Data acquisition stopped manually.');
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.JUSTEXOUIFigure = uifigure('Visible', 'off');
            app.JUSTEXOUIFigure.Name = 'JUST EXO';
            app.JUSTEXOUIFigure.Icon = fullfile(pathToMLAPP, 'photos', 'lastttt.png');
            app.TabGroup = uitabgroup(app.JUSTEXOUIFigure);
            app.SimulatedControllerTab = uitab(app.TabGroup);
            app.SimulatedControllerTab.Title = 'Simulated Controller';
            app.FunctionsResonseTab = uipanel(app.SimulatedControllerTab);
            app.StepDisturbance = uiaxes(app.FunctionsResonseTab);
            title(app.StepDisturbance, 'Step Plot: Input disturbance rejection')
            xlabel(app.StepDisturbance, 'Time [s]')
            ylabel(app.StepDisturbance, 'Ampiltude [°]')
            zlabel(app.StepDisturbance, 'Z')
            app.StepDisturbance.XLim = [0 200];
            app.StepDisturbance.YLim = [0 140];
            app.StepReference = uiaxes(app.FunctionsResonseTab);
            title(app.StepReference, 'Step Plot: Reference Tracking ')
            xlabel(app.StepReference, 'Time [s]')
            ylabel(app.StepReference, 'Amplitude [°]')
            zlabel(app.StepReference, 'Z')
            app.StepReference.XLim = [0 200];
            app.StepReference.YLim = [0 140];
            app.StepReference.XTick = [0 20 40 60 80 100 120 140 160 180 200];
            app.StepReference.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
            app.StepReference.YTick = [0 20 40 60 80 100 120 140];
            app.StepResponsePanel = uipanel(app.FunctionsResonseTab);
            app.StepResponsePanel.Title = 'Step Response';
            app.TransientTimeLabel = uilabel(app.StepResponsePanel);
            app.TransientTimeLabel.Text = 'Rise Time          :';
            app.SettlingTimeLabel = uilabel(app.StepResponsePanel);
            app.SettlingTimeLabel.Text = 'Settling Time    :';
            app.OverShootLabel = uilabel(app.StepResponsePanel);
            app.OverShootLabel.Text = 'OverShoot        :';
            app.PeakLabel = uilabel(app.StepResponsePanel);
            app.PeakLabel.Text = 'Peak                  :';
            app.TimeConstantLabel = uilabel(app.StepResponsePanel);
            app.TimeConstantLabel.Text = 'Time Constant  :';
            app.PolesLabel = uilabel(app.StepResponsePanel);
            app.PolesLabel.Text = 'Poles          :';
            app.ZerosLabel = uilabel(app.StepResponsePanel);
            app.ZerosLabel.Text = 'Zeros          :';
            app.Transfer = uipanel(app.SimulatedControllerTab);
            app.ServoTrajectory = uiaxes(app.Transfer);
            title(app.ServoTrajectory, 'Simulated Servo Angle')
            xlabel(app.ServoTrajectory, 'Time [s]')
            ylabel(app.ServoTrajectory, 'Amplitude [°]')
            zlabel(app.ServoTrajectory, 'Z')
            app.ServoTrajectory.XLim = [0 100];
            app.ServoTrajectory.YLim = [0 140];
            app.ServoTrajectory.XTick = [20 40 60 80 100];
            app.ServoTrajectory.XTickLabel = {'20'; '40'; '60'; '80'; '100'; '20'; '40'; '60'; '80'; '100'};
            app.TabGroup2 = uitabgroup(app.Transfer);
            app.TransferFunctionTab = uitab(app.TabGroup2);
            app.TransferFunctionTab.Title = 'Transfer Function';
            app.Image7_3 = uiimage(app.TransferFunctionTab);
            app.Image7_3.Enable = 'off';
            app.Image7_3.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_42.png');
            app.Image4_4 = uiimage(app.TransferFunctionTab);
            app.Image4_4.Enable = 'off';
            app.Image4_4.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_75px.png');
            app.Image4_3 = uiimage(app.TransferFunctionTab);
            app.Image4_3.Enable = 'off';
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_75px.png');
            app.Image4_2 = uiimage(app.TransferFunctionTab);
            app.Image4_2.Enable = 'off';
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_75px.png');
            app.Image4 = uiimage(app.TransferFunctionTab);
            app.Image4.Enable = 'off';
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_75px.png');
            app.ControlDropDown = uidropdown(app.TransferFunctionTab);
            app.ControlDropDown.Items = {'PI control parameters', 'PI design parameters', 'PID design parameters', 'PID control parameters', 'P - control', 'P - , I - control ', 'P - , I - , D - control'};
            app.ControlDropDown.ItemsData = {'1', '2', '4', '3', '5', '6', '7'};
            app.ControlDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlDropDownValueChanged, true);
            app.ControlDropDown.Enable = 'off';
            app.ControlDropDown.Placeholder = 'Control Options';
            app.ControlDropDown.Value = '1';
            app.ServoAngleLimitsSliderLabel = uilabel(app.TransferFunctionTab);
            app.ServoAngleLimitsSliderLabel.Text = 'Servo Angle Limits [°]';
            app.PLOTButton = uibutton(app.TransferFunctionTab, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Enable = 'off';
            app.PLOTButton.Text = 'PLOT';
            app.RefreshButton = uibutton(app.TransferFunctionTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'photos', 'reload.png');
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Text = '';
            app.ServoAngleLimitsSlider = uislider(app.TransferFunctionTab, 'range');
            app.ServoAngleLimitsSlider.Limits = [0 120];
            app.ServoAngleLimitsSlider.MajorTicks = [0 25 50 75 100 120];
            app.ServoAngleLimitsSlider.ValueChangedFcn = createCallbackFcn(app, @ServoAngleLimitsSliderValueChanged, true);
            app.ServoAngleLimitsSlider.Enable = 'off';
            app.ServoAngleLimitsSlider.Value = [10 62];
            app.TrackChangesCheckBox = uicheckbox(app.TransferFunctionTab);
            app.TrackChangesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackChangesCheckBoxValueChanged, true);
            app.TrackChangesCheckBox.Text = 'Track Changes';
            app.OptimizePIButton = uibutton(app.TransferFunctionTab, 'push');
            app.OptimizePIButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizePIButtonPushed, true);
            app.OptimizePIButton.Enable = 'off';
            app.OptimizePIButton.Text = 'Optimize PI';
            app.TuningPIDPanel_Design_Params = uipanel(app.TransferFunctionTab);
            app.TuningPIDPanel_Design_Params.Enable = 'off';
            app.TuningPIDPanel_Design_Params.Title = 'PID Design Parameters';
            app.TuningPIDPanel_Design_Params.Visible = 'off';
            app.KpLabel_4 = uilabel(app.TuningPIDPanel_Design_Params);
            app.KpLabel_4.Interpreter = 'latex';
            app.KpLabel_4.Text = '\phi$_{m}$';
            app.PhiMEditFieldPIDDesign = uieditfield(app.TuningPIDPanel_Design_Params, 'numeric');
            app.PhiMEditFieldPIDDesign.Value = 5;
            app.cLabel_8 = uilabel(app.TuningPIDPanel_Design_Params);
            app.cLabel_8.Interpreter = 'latex';
            app.cLabel_8.Text = '\beta';
            app.BetaEditFieldPIDDesign = uieditfield(app.TuningPIDPanel_Design_Params, 'numeric');
            app.BetaEditFieldPIDDesign.Value = 1;
            app.cEditFieldLabel = uilabel(app.TuningPIDPanel_Design_Params);
            app.cEditFieldLabel.Interpreter = 'latex';
            app.cEditFieldLabel.Text = 'ω$_{c}$';
            app.OmegaCEditFieldPIDDesign = uieditfield(app.TuningPIDPanel_Design_Params, 'numeric');
            app.OmegaCEditFieldPIDDesign.Value = 5;
            app.ZetaEditFieldPIDDesign = uieditfield(app.TuningPIDPanel_Design_Params, 'numeric');
            app.ZetaEditFieldPIDDesign.Editable = 'off';
            app.ZetaEditFieldPIDDesign.Visible = 'off';
            app.ZetaEditFieldPIDDesign.Tooltip = {'\zeta with constant value of 1'};
            app.ZetaEditFieldPIDDesign.Value = 1;
            app.zetaEditField_3Label_4 = uilabel(app.TuningPIDPanel_Design_Params);
            app.zetaEditField_3Label_4.Visible = 'off';
            app.zetaEditField_3Label_4.Interpreter = 'latex';
            app.zetaEditField_3Label_4.Text = '\zeta';
            app.TuningPIPanel_Design_Params = uipanel(app.TransferFunctionTab);
            app.TuningPIPanel_Design_Params.Enable = 'off';
            app.TuningPIPanel_Design_Params.Title = 'PI Design Parameters';
            app.TuningPIPanel_Design_Params.Visible = 'off';
            app.cLabel_9 = uilabel(app.TuningPIPanel_Design_Params);
            app.cLabel_9.Interpreter = 'latex';
            app.cLabel_9.Text = 'ω$_{c}$';
            app.OmegaCEditFieldPIDesign = uieditfield(app.TuningPIPanel_Design_Params, 'numeric');
            app.OmegaCEditFieldPIDesign.Value = 1.5;
            app.cLabel_10 = uilabel(app.TuningPIPanel_Design_Params);
            app.cLabel_10.Interpreter = 'latex';
            app.cLabel_10.Text = '\phi$_{m}$';
            app.PhiMEditFieldPIDesign = uieditfield(app.TuningPIPanel_Design_Params, 'numeric');
            app.PhiMEditFieldPIDesign.Value = 45;
            app.TuningPIDPanel_Control_Params = uipanel(app.TransferFunctionTab);
            app.TuningPIDPanel_Control_Params.Enable = 'off';
            app.TuningPIDPanel_Control_Params.Title = 'PID Control Parameters';
            app.TuningPIDPanel_Control_Params.Visible = 'off';
            app.KpLabel_5 = uilabel(app.TuningPIDPanel_Control_Params);
            app.KpLabel_5.Interpreter = 'latex';
            app.KpLabel_5.Text = '\tau';
            app.TauEditFieldPIDControl = uieditfield(app.TuningPIDPanel_Control_Params, 'numeric');
            app.TauEditFieldPIDControl.Value = 5;
            app.K_iLabel = uilabel(app.TuningPIDPanel_Control_Params);
            app.K_iLabel.Interpreter = 'latex';
            app.K_iLabel.Text = 'K$_{i}$';
            app.KiEditFieldPIDControl = uieditfield(app.TuningPIDPanel_Control_Params, 'numeric');
            app.KiEditFieldPIDControl.Value = 1;
            app.cLabel_11 = uilabel(app.TuningPIDPanel_Control_Params);
            app.cLabel_11.Interpreter = 'latex';
            app.cLabel_11.Text = '\beta';
            app.BetaEditFieldPIDControl = uieditfield(app.TuningPIDPanel_Control_Params, 'numeric');
            app.BetaEditFieldPIDControl.Value = 1;
            app.ZetaEditFieldPIDControl = uieditfield(app.TuningPIDPanel_Control_Params, 'numeric');
            app.ZetaEditFieldPIDControl.Value = 1;
            app.zetaEditField_3Label_5 = uilabel(app.TuningPIDPanel_Control_Params);
            app.zetaEditField_3Label_5.Interpreter = 'latex';
            app.zetaEditField_3Label_5.Text = '\zeta';
            app.InputResponseButtonGroup = uibuttongroup(app.TransferFunctionTab);
            app.InputResponseButtonGroup.Enable = 'off';
            app.InputResponseButtonGroup.Title = 'Input Response';
            app.StepButton = uiradiobutton(app.InputResponseButtonGroup);
            app.StepButton.Text = 'Step';
            app.StepButton.Value = true;
            app.RampButton = uiradiobutton(app.InputResponseButtonGroup);
            app.RampButton.Text = 'Ramp';
            app.NaturalButton = uiradiobutton(app.InputResponseButtonGroup);
            app.NaturalButton.Text = 'Natural';
            app.Image5 = uiimage(app.TransferFunctionTab);
            app.Image5.Enable = 'off';
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_490px.png');
            app.Image7 = uiimage(app.TransferFunctionTab);
            app.Image7.Enable = 'off';
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_84px.png');
            app.Image7_2 = uiimage(app.TransferFunctionTab);
            app.Image7_2.Enable = 'off';
            app.Image7_2.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_84px.png');
            app.Image6_4 = uiimage(app.TransferFunctionTab);
            app.Image6_4.Enable = 'off';
            app.Image6_4.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_25px.png');
            app.Image9 = uiimage(app.TransferFunctionTab);
            app.Image9.Enable = 'off';
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'photos', 'arrow_small.png');
            app.Gs_PlantPanel = uipanel(app.TransferFunctionTab);
            app.Gs_PlantPanel.Enable = 'off';
            app.Gs_PlantPanel.Title = 'Plant ';
            app.denEditField = uieditfield(app.Gs_PlantPanel, 'text');
            app.denEditField.Placeholder = '(s-0.05)^2(s+1 )';
            app.denEditField.Value = '(1 + s)';
            app.numEditField = uieditfield(app.Gs_PlantPanel, 'text');
            app.numEditField.Tooltip = {'Set input as factorial : \n or plynomial:'};
            app.numEditField.Placeholder = '(s + 2)';
            app.numEditField.Value = '1';
            app.GsLabel = uilabel(app.Gs_PlantPanel);
            app.GsLabel.Text = 'G(s) =';
            app.DivisionLine = uilabel(app.Gs_PlantPanel);
            app.DivisionLine.Text = '_________________';
            app.omega_G150Label = uilabel(app.Gs_PlantPanel);
            app.omega_G150Label.Interpreter = 'tex';
            app.omega_G150Label.Text = '\omega_{G150} = ';
            app.TuningPIPanel_Control_Params = uipanel(app.TransferFunctionTab);
            app.TuningPIPanel_Control_Params.Enable = 'off';
            app.TuningPIPanel_Control_Params.Title = 'PI Control Parameters';
            app.K_iEditField_2Label = uilabel(app.TuningPIPanel_Control_Params);
            app.K_iEditField_2Label.Interpreter = 'latex';
            app.K_iEditField_2Label.Text = 'K$_{i}$';
            app.KiEditFieldPIControl = uieditfield(app.TuningPIPanel_Control_Params, 'numeric');
            app.T_iEditFieldLabel = uilabel(app.TuningPIPanel_Control_Params);
            app.T_iEditFieldLabel.Interpreter = 'latex';
            app.T_iEditFieldLabel.Text = 'T$_{i}$';
            app.TiEditFieldPIControl = uieditfield(app.TuningPIPanel_Control_Params, 'numeric');
            app.LowRefDegreeEditField = uieditfield(app.TransferFunctionTab, 'numeric');
            app.LowRefDegreeEditField.Limits = [0 120];
            app.LowRefDegreeEditField.RoundFractionalValues = 'on';
            app.LowRefDegreeEditField.ValueDisplayFormat = '%11g';
            app.LowRefDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @LowRefDegreeEditFieldValueChanged, true);
            app.LowRefDegreeEditField.Enable = 'off';
            app.LowRefDegreeEditField.Value = 10;
            app.HighRefDegreeEditField = uieditfield(app.TransferFunctionTab, 'numeric');
            app.HighRefDegreeEditField.Limits = [0 120];
            app.HighRefDegreeEditField.RoundFractionalValues = 'on';
            app.HighRefDegreeEditField.ValueDisplayFormat = '%11g';
            app.HighRefDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @HighRefDegreeEditFieldValueChanged, true);
            app.HighRefDegreeEditField.Enable = 'off';
            app.HighRefDegreeEditField.Value = 62;
            app.PIControllerPanel = uipanel(app.TransferFunctionTab);
            app.PIControllerPanel.Enable = 'off';
            app.PIControllerPanel.Title = 'PI Controller';
            app.PIControllerPanel.Visible = 'off';
            app.K_iEditField_2Label_3 = uilabel(app.PIControllerPanel);
            app.K_iEditField_2Label_3.Interpreter = 'latex';
            app.K_iEditField_2Label_3.Text = 'K$_{p}$';
            app.KpEditFieldPIController = uieditfield(app.PIControllerPanel, 'numeric');
            app.K_iLabel_2 = uilabel(app.PIControllerPanel);
            app.K_iLabel_2.Interpreter = 'latex';
            app.K_iLabel_2.Text = 'K$_{i}$';
            app.KiEditFieldPIController = uieditfield(app.PIControllerPanel, 'numeric');
            app.PControllerPanel = uipanel(app.TransferFunctionTab);
            app.PControllerPanel.Enable = 'off';
            app.PControllerPanel.Title = 'P Controller';
            app.PControllerPanel.Visible = 'off';
            app.K_iEditField_2Label_2 = uilabel(app.PControllerPanel);
            app.K_iEditField_2Label_2.Interpreter = 'latex';
            app.K_iEditField_2Label_2.Text = 'K$_{p}$';
            app.KpEditFieldPController = uieditfield(app.PControllerPanel, 'numeric');
            app.PIDControllerPanel = uipanel(app.TransferFunctionTab);
            app.PIDControllerPanel.Enable = 'off';
            app.PIDControllerPanel.Title = 'PID Controller';
            app.PIDControllerPanel.Visible = 'off';
            app.K_iEditField_2Label_4 = uilabel(app.PIDControllerPanel);
            app.K_iEditField_2Label_4.Interpreter = 'latex';
            app.K_iEditField_2Label_4.Text = 'K$_{p}$';
            app.KpEditFieldPIDController = uieditfield(app.PIDControllerPanel, 'numeric');
            app.K_iLabel_3 = uilabel(app.PIDControllerPanel);
            app.K_iLabel_3.Interpreter = 'latex';
            app.K_iLabel_3.Text = 'K$_{i}$';
            app.KiEditFieldPIDController = uieditfield(app.PIDControllerPanel, 'numeric');
            app.K_iLabel_4 = uilabel(app.PIDControllerPanel);
            app.K_iLabel_4.Interpreter = 'latex';
            app.K_iLabel_4.Text = 'K$_{d}$';
            app.KdEditFieldPIDController = uieditfield(app.PIDControllerPanel, 'numeric');
            app.Image9_4 = uiimage(app.TransferFunctionTab);
            app.Image9_4.Enable = 'off';
            app.Image9_4.ImageSource = fullfile(pathToMLAPP, 'photos', 'circle.png');
            app.Image9_5 = uiimage(app.TransferFunctionTab);
            app.Image9_5.Enable = 'off';
            app.Image9_5.ImageSource = fullfile(pathToMLAPP, 'photos', 'arrow_small_down.png');
            app.Image9_6 = uiimage(app.TransferFunctionTab);
            app.Image9_6.Enable = 'off';
            app.Image9_6.ImageSource = fullfile(pathToMLAPP, 'photos', 'circle.png');
            app.Image9_7 = uiimage(app.TransferFunctionTab);
            app.Image9_7.Enable = 'off';
            app.Image9_7.ImageSource = fullfile(pathToMLAPP, 'photos', 'arrow_small.png');
            app.Image9_9 = uiimage(app.TransferFunctionTab);
            app.Image9_9.Enable = 'off';
            app.Image9_9.ImageSource = fullfile(pathToMLAPP, 'photos', 'circle.png');
            app.Image9_10 = uiimage(app.TransferFunctionTab);
            app.Image9_10.Enable = 'off';
            app.Image9_10.ImageSource = fullfile(pathToMLAPP, 'photos', 'arrow_small_up.png');
            app.Image9_11 = uiimage(app.TransferFunctionTab);
            app.Image9_11.Enable = 'off';
            app.Image9_11.ImageSource = fullfile(pathToMLAPP, 'photos', 'arrow_small.png');
            app.Image9_8 = uiimage(app.TransferFunctionTab);
            app.Image9_8.Enable = 'off';
            app.Image9_8.ImageSource = fullfile(pathToMLAPP, 'photos', 'arrow_small_down.png');
            app.rLabel = uilabel(app.TransferFunctionTab);
            app.rLabel.Enable = 'off';
            app.rLabel.Interpreter = 'tex';
            app.rLabel.Text = 'r';
            app.Label_2 = uilabel(app.TransferFunctionTab);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '_';
            app.Label_3 = uilabel(app.TransferFunctionTab);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '+';
            app.Label_4 = uilabel(app.TransferFunctionTab);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '+';
            app.Label_5 = uilabel(app.TransferFunctionTab);
            app.Label_5.Enable = 'off';
            app.Label_5.Text = '+';
            app.yLabel = uilabel(app.TransferFunctionTab);
            app.yLabel.Enable = 'off';
            app.yLabel.Interpreter = 'tex';
            app.yLabel.Text = 'y';
            app.y_mLabel = uilabel(app.TransferFunctionTab);
            app.y_mLabel.Enable = 'off';
            app.y_mLabel.Interpreter = 'tex';
            app.y_mLabel.Text = 'y_{m}';
            app.Label_6 = uilabel(app.TransferFunctionTab);
            app.Label_6.Enable = 'off';
            app.Label_6.Text = '+';
            app.vLabel = uilabel(app.TransferFunctionTab);
            app.vLabel.Enable = 'off';
            app.vLabel.Interpreter = 'tex';
            app.vLabel.Text = 'v';
            app.uLabel = uilabel(app.TransferFunctionTab);
            app.uLabel.Enable = 'off';
            app.uLabel.Interpreter = 'tex';
            app.uLabel.Text = 'u';
            app.OptimizePIDButton = uibutton(app.TransferFunctionTab, 'push');
            app.OptimizePIDButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizePIDButtonPushed, true);
            app.OptimizePIDButton.Visible = 'off';
            app.OptimizePIDButton.Text = 'Optimize PID';
            app.ShowInputSIgnalCheckBox = uicheckbox(app.TransferFunctionTab);
            app.ShowInputSIgnalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowInputSIgnalCheckBoxValueChanged, true);
            app.ShowInputSIgnalCheckBox.Text = 'Show Input SIgnal';
            app.ShowInputSIgnalCheckBox.Value = true;
            app.InputSignalTab = uitab(app.TabGroup2);
            app.InputSignalTab.Title = 'Input Signal';
            app.InputSignalGraph = uiaxes(app.InputSignalTab);
            title(app.InputSignalGraph, 'Input Signal')
            xlabel(app.InputSignalGraph, 'Time [s]')
            ylabel(app.InputSignalGraph, 'Amplitude [°]')
            zlabel(app.InputSignalGraph, 'Z')
            app.InputSignalGraph.XLim = [0 60];
            app.InputSignalGraph.YLim = [0 140];
            app.InputSignalGraph.ZTick = [];
            app.StepSignalParametersLabel = uilabel(app.InputSignalTab);
            app.StepSignalParametersLabel.Text = 'Step Signal Parameters';
            app.RampSignalParametersLabel = uilabel(app.InputSignalTab);
            app.RampSignalParametersLabel.Text = 'Ramp Signal Parameters';
            app.NaturalMovementSignalParametersLabel = uilabel(app.InputSignalTab);
            app.NaturalMovementSignalParametersLabel.Text = 'Natural Movement Signal Parameters';
            app.RepetitionsSpinnerLabel = uilabel(app.InputSignalTab);
            app.RepetitionsSpinnerLabel.Text = 'Repetitions';
            app.StepRepetitionsSpinner = uispinner(app.InputSignalTab);
            app.StepRepetitionsSpinner.Limits = [0 5];
            app.StepRepetitionsSpinner.ValueChangedFcn = createCallbackFcn(app, @StepPeriodSpinnerValueChanged, true);
            app.StepRepetitionsSpinner.Value = 3;
            app.PeriodSpinnerLabel = uilabel(app.InputSignalTab);
            app.PeriodSpinnerLabel.Text = 'Period';
            app.StepPeriodSpinner = uispinner(app.InputSignalTab);
            app.StepPeriodSpinner.Step = 0.2;
            app.StepPeriodSpinner.Limits = [0 2];
            app.StepPeriodSpinner.ValueChangedFcn = createCallbackFcn(app, @StepPeriodSpinnerValueChanged, true);
            app.StepPeriodSpinner.Value = 1;
            app.RepetitionsSpinner_2Label = uilabel(app.InputSignalTab);
            app.RepetitionsSpinner_2Label.Text = 'Repetitions';
            app.RampRepetitionsSpinner = uispinner(app.InputSignalTab);
            app.RampRepetitionsSpinner.Limits = [0 5];
            app.RampRepetitionsSpinner.ValueChangedFcn = createCallbackFcn(app, @RampPeriodSpinnerValueChanged, true);
            app.RampRepetitionsSpinner.Value = 3;
            app.PeriodSpinner_2Label = uilabel(app.InputSignalTab);
            app.PeriodSpinner_2Label.Text = 'Period';
            app.RampPeriodSpinner = uispinner(app.InputSignalTab);
            app.RampPeriodSpinner.Step = 0.2;
            app.RampPeriodSpinner.Limits = [0 2];
            app.RampPeriodSpinner.ValueChangedFcn = createCallbackFcn(app, @RampPeriodSpinnerValueChanged, true);
            app.RampPeriodSpinner.Value = 2;
            app.RepetitionsSpinner_2Label_2 = uilabel(app.InputSignalTab);
            app.RepetitionsSpinner_2Label_2.Text = 'Repetitions';
            app.NaturalRepetitionsSpinner = uispinner(app.InputSignalTab);
            app.NaturalRepetitionsSpinner.Limits = [0 5];
            app.NaturalRepetitionsSpinner.ValueChangedFcn = createCallbackFcn(app, @NaturalPeriodSpinnerValueChanged, true);
            app.NaturalRepetitionsSpinner.Value = 3;
            app.PeriodSpinner_2Label_2 = uilabel(app.InputSignalTab);
            app.PeriodSpinner_2Label_2.Text = 'Period';
            app.NaturalPeriodSpinner = uispinner(app.InputSignalTab);
            app.NaturalPeriodSpinner.Step = 0.2;
            app.NaturalPeriodSpinner.Limits = [0 5];
            app.NaturalPeriodSpinner.ValueChangedFcn = createCallbackFcn(app, @NaturalPeriodSpinnerValueChanged, true);
            app.NaturalPeriodSpinner.Value = 3;
            app.ServoControlPanel = uipanel(app.Transfer);
            app.ServoControlPanel.Title = 'Servo Control';
            app.ServoPowerSwitchLabel = uilabel(app.ServoControlPanel);
            app.ServoPowerSwitchLabel.Enable = 'off';
            app.ServoPowerSwitchLabel.Text = 'Servo Power';
            app.Servo_Plot_Reset = uibutton(app.ServoControlPanel, 'push');
            app.Servo_Plot_Reset.ButtonPushedFcn = createCallbackFcn(app, @Servo_Plot_ResetPushed, true);
            app.Servo_Plot_Reset.Icon = fullfile(pathToMLAPP, 'photos', 'reload.png');
            app.Servo_Plot_Reset.Enable = 'off';
            app.Servo_Plot_Reset.Text = '';
            app.ExecuteButton = uibutton(app.ServoControlPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Text = 'Execute ';
            app.ServoPowerSwitch = uiswitch(app.ServoControlPanel, 'slider');
            app.ServoPowerSwitch.ValueChangedFcn = createCallbackFcn(app, @ServoPowerSwitchValueChanged, true);
            app.ServoPowerSwitch.Enable = 'off';
            app.SpeedKnobLabel = uilabel(app.ServoControlPanel);
            app.SpeedKnobLabel.Enable = 'off';
            app.SpeedKnobLabel.Text = 'Speed';
            app.ServoSpeedKnob = uiknob(app.ServoControlPanel, 'discrete');
            app.ServoSpeedKnob.Items = {'Low', 'default', 'Medium', 'High'};
            app.ServoSpeedKnob.ItemsData = {'1', '2', '3', '4'};
            app.ServoSpeedKnob.Enable = 'off';
            app.ServoSpeedKnob.Value = '1';
            app.EMGControlTab = uitab(app.TabGroup);
            app.EMGControlTab.Title = 'EMG Control';
            app.TabGroup3 = uitabgroup(app.EMGControlTab);
            app.ThresholdControlTab = uitab(app.TabGroup3);
            app.ThresholdControlTab.Title = 'Threshold Control';
            app.ElectromyographyControlGraphs_2 = uipanel(app.ThresholdControlTab);
            app.ElectromyographyControlGraphs_2.Title = 'Electromyography Control';
            app.EMG_OneCH = uiaxes(app.ElectromyographyControlGraphs_2);
            xlabel(app.EMG_OneCH, 'Time [s]')
            ylabel(app.EMG_OneCH, 'ENV Amplitude [µV]')
            zlabel(app.EMG_OneCH, 'Z')
            app.EMG_OneCH.XLim = [0 100];
            app.EMG_OneCH.YLim = [0 0.35];
            app.EMG_OneCH.XTick = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.EMG_OneCH.XTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'; '3.5'; '4'; '4.5'; '5'; '5.5'; '6'; '6.5'; '7'; '7.5'; '8'; '8.5'; '9'; '9.5'; '10'};
            app.EMG_OneCH.YTick = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35];
            app.EMG_OneCH.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'; '350'};
            app.EMG_OneCH.ZTick = [];
            app.IMUPlot_EMG = uiaxes(app.ElectromyographyControlGraphs_2);
            title(app.IMUPlot_EMG, '3D Position Estimation')
            app.IMUPlot_EMG.XLim = [0 100];
            app.IMUPlot_EMG.YLim = [-1500 1500];
            app.IMUPlot_EMG.XTick = [];
            app.IMUPlot_EMG.YTick = [];
            app.IMUPlot_EMG.YTickLabel = '';
            app.EMGControlParameters_2 = uipanel(app.ThresholdControlTab);
            app.EMGControlParameters_2.Enable = 'off';
            app.EMGControlParameters_2.Title = 'Parameters';
            app.CH1DropDown = uidropdown(app.EMGControlParameters_2);
            app.CH1DropDown.Items = {'EMG0', 'EMG1'};
            app.CH1DropDown.ValueChangedFcn = createCallbackFcn(app, @CH1DropDownValueChanged2, true);
            app.CH1DropDown.Placeholder = 'choose Channel';
            app.CH1DropDown.Value = 'EMG0';
            app.StartSwitchLabel_4 = uilabel(app.EMGControlParameters_2);
            app.StartSwitchLabel_4.Text = 'Start';
            app.StartRecordingSwitch_DualThresh_EMG = uiswitch(app.EMGControlParameters_2, 'rocker');
            app.StartRecordingSwitch_DualThresh_EMG.ValueChangedFcn = createCallbackFcn(app, @StartRecordingSwitch_DualThresh_EMGValueChanged, true);
            app.ThresholdVLabel = uilabel(app.EMGControlParameters_2);
            app.ThresholdVLabel.Text = 'Threshold [µV]';
            app.EMGChannelLabel_2 = uilabel(app.EMGControlParameters_2);
            app.EMGChannelLabel_2.Text = 'EMG Channel';
            app.AppliedFilterButtonGroup_EMG = uibuttongroup(app.EMGControlParameters_2);
            app.AppliedFilterButtonGroup_EMG.Title = 'Applied Filter';
            app.EMG_LeftSidedMovingAverageButton = uiradiobutton(app.AppliedFilterButtonGroup_EMG);
            app.EMG_LeftSidedMovingAverageButton.Text = {'Left Sided '; 'Moving Average'};
            app.EMG_LeftSidedMovingAverageButton.Value = true;
            app.EMG_CenteredMovingAverageButton = uiradiobutton(app.AppliedFilterButtonGroup_EMG);
            app.EMG_CenteredMovingAverageButton.Text = {'Centered '; 'Moving Average'};
            app.EMG_EWMVA = uiradiobutton(app.AppliedFilterButtonGroup_EMG);
            app.EMG_EWMVA.Text = 'EWMVA';
            app.ModeSwitch_2Label = uilabel(app.EMGControlParameters_2);
            app.ModeSwitch_2Label.Enable = 'off';
            app.ModeSwitch_2Label.Visible = 'off';
            app.ModeSwitch_2Label.Text = 'Mode';
            app.ModeSwitch_2 = uiswitch(app.EMGControlParameters_2, 'rocker');
            app.ModeSwitch_2.Items = {'Min/Max', 'Increment'};
            app.ModeSwitch_2.Enable = 'off';
            app.ModeSwitch_2.Visible = 'off';
            app.ModeSwitch_2.Value = 'Min/Max';
            app.EMGShowThresholdCheckBox = uicheckbox(app.EMGControlParameters_2);
            app.EMGShowThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @EMGShowThresholdCheckBoxValueChanged, true);
            app.EMGShowThresholdCheckBox.Text = 'Show Threshold';
            app.UpperLabel = uilabel(app.EMGControlParameters_2);
            app.UpperLabel.Text = 'Upper';
            app.EMGUpperThresholdSlider = uislider(app.EMGControlParameters_2);
            app.EMGUpperThresholdSlider.Limits = [0 330];
            app.EMGUpperThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EMGUpperThresholdSliderValueChanged, true);
            app.EMGUpperThresholdSlider.Value = 200;
            app.LowerLabel = uilabel(app.EMGControlParameters_2);
            app.LowerLabel.Text = 'Lower';
            app.EMGLowerThresholdSlider = uislider(app.EMGControlParameters_2);
            app.EMGLowerThresholdSlider.Limits = [0 330];
            app.EMGLowerThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EMGLowerThresholdSliderValueChanged, true);
            app.EMGLowerThresholdSlider.Value = 100;
            app.ApplyThresholdsButton_EMG = uibutton(app.EMGControlParameters_2, 'state');
            app.ApplyThresholdsButton_EMG.ValueChangedFcn = createCallbackFcn(app, @ApplyThresholdsButton_EMGValueChanged, true);
            app.ApplyThresholdsButton_EMG.Text = 'Apply Thresholds';
            app.ServoMotorSpeedLabel = uilabel(app.EMGControlParameters_2);
            app.ServoMotorSpeedLabel.Text = 'Servo Motor Speed';
            app.SpeedSetting_EMG = uiknob(app.EMGControlParameters_2, 'discrete');
            app.SpeedSetting_EMG.Items = {'Low', 'default', 'Medium', 'High'};
            app.SpeedSetting_EMG.ItemsData = [1 2 3 4];
            app.SpeedSetting_EMG.Value = 1;
            app.EMGUpperThreshold = uieditfield(app.EMGControlParameters_2, 'numeric');
            app.EMGUpperThreshold.Limits = [0 330];
            app.EMGUpperThreshold.ValueChangedFcn = createCallbackFcn(app, @EMGUpperThresholdValueChanged, true);
            app.EMGUpperThreshold.Value = 200;
            app.EMGLowerThreshold = uieditfield(app.EMGControlParameters_2, 'numeric');
            app.EMGLowerThreshold.Limits = [0 330];
            app.EMGLowerThreshold.ValueChangedFcn = createCallbackFcn(app, @EMGLowerThresholdValueChanged, true);
            app.EMGLowerThreshold.Value = 100;
            app.CH2DropDown = uidropdown(app.EMGControlParameters_2);
            app.CH2DropDown.Items = {'EMG0', 'EMG1'};
            app.CH2DropDown.Visible = 'off';
            app.CH2DropDown.Placeholder = 'choose Channel';
            app.CH2DropDown.Value = 'EMG0';
            app.ForceControlTab = uitab(app.TabGroup);
            app.ForceControlTab.Title = 'Force Control';
            app.TabGroup3_2 = uitabgroup(app.ForceControlTab);
            app.ThresholdTab = uitab(app.TabGroup3_2);
            app.ThresholdTab.Title = 'Threshold ';
            app.ForceControlGraphs = uipanel(app.ThresholdTab);
            app.ForceControlGraphs.Title = 'Force Control';
            app.ForceReading = uiaxes(app.ForceControlGraphs);
            title(app.ForceReading, 'Force Sensor')
            xlabel(app.ForceReading, 'Time [s]')
            ylabel(app.ForceReading, 'Force Amplitude [mN]')
            zlabel(app.ForceReading, 'Z')
            app.ForceReading.XLim = [0 100];
            app.ForceReading.YLim = [-5000 5000];
            app.ForceReading.XTick = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.ForceReading.XTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'; '3.5'; '4'; '4.5'; '5'; '5.5'; '6'; '6.5'; '7'; '7.5'; '8'; '8.5'; '9'; '9.5'; '10'};
            app.ForceReading.YTick = [-5000 -4000 -3000 -2000 -1000 0 1000 2000 3000 4000 5000];
            app.ForceReading.YTickLabel = {'-5000'; '-4000'; '-3000'; '-2000'; '-1000'; '0'; '1000'; '2000'; '3000'; '4000'; '5000'};
            app.IMUPlot_Force = uiaxes(app.ForceControlGraphs);
            title(app.IMUPlot_Force, '3D Position Estimation')
            app.IMUPlot_Force.XLim = [0 100];
            app.IMUPlot_Force.YLim = [-1500 1500];
            app.IMUPlot_Force.XTick = [];
            app.IMUPlot_Force.YTick = [];
            app.IMUPlot_Force.YTickLabel = '';
            app.ForceParameters = uipanel(app.ForceControlGraphs);
            app.ForceParameters.Enable = 'off';
            app.ForceParameters.Title = 'Parameters';
            app.UpperThresholdSliderLabel = uilabel(app.ForceParameters);
            app.UpperThresholdSliderLabel.Text = 'Upper Threshold';
            app.ForceUpperThresholdSlider = uislider(app.ForceParameters);
            app.ForceUpperThresholdSlider.Limits = [0 1000];
            app.ForceUpperThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ForceUpperThresholdSliderValueChanged, true);
            app.ForceUpperThresholdSlider.Value = 500;
            app.LowerThresholdSliderLabel = uilabel(app.ForceParameters);
            app.LowerThresholdSliderLabel.Text = 'Lower Threshold';
            app.ForceLowerThresholdSlider = uislider(app.ForceParameters);
            app.ForceLowerThresholdSlider.Limits = [-1000 0];
            app.ForceLowerThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ForceLowerThresholdSliderValueChanged, true);
            app.ForceLowerThresholdSlider.Value = -500;
            app.StartSwitchLabel_7 = uilabel(app.ForceParameters);
            app.StartSwitchLabel_7.Text = 'Start';
            app.StartRecordingSwitch_DualThresh_Force = uiswitch(app.ForceParameters, 'rocker');
            app.StartRecordingSwitch_DualThresh_Force.ValueChangedFcn = createCallbackFcn(app, @StartRecordingSwitch_DualThresh_ForceValueChanged, true);
            app.AppliedFilterButtonGroup_Force = uibuttongroup(app.ForceParameters);
            app.AppliedFilterButtonGroup_Force.Title = 'Applied Filter';
            app.LeftSidedMovingAverageButton = uiradiobutton(app.AppliedFilterButtonGroup_Force);
            app.LeftSidedMovingAverageButton.Text = {'Left Sided '; 'Moving Average'};
            app.LeftSidedMovingAverageButton.Value = true;
            app.CenteredMovingAverageButton = uiradiobutton(app.AppliedFilterButtonGroup_Force);
            app.CenteredMovingAverageButton.Text = {'Centered '; 'Moving Average'};
            app.EWMVAButton = uiradiobutton(app.AppliedFilterButtonGroup_Force);
            app.EWMVAButton.Text = 'EWMVA';
            app.ApplyThresholdsButton_Force = uibutton(app.ForceParameters, 'state');
            app.ApplyThresholdsButton_Force.ValueChangedFcn = createCallbackFcn(app, @ApplyThresholdsButton_2ValueChanged, true);
            app.ApplyThresholdsButton_Force.Text = 'Apply Thresholds';
            app.ServoMotorSpeedLabel_4 = uilabel(app.ForceParameters);
            app.ServoMotorSpeedLabel_4.Text = 'Servo Motor Speed';
            app.SpeedSetting_Force = uiknob(app.ForceParameters, 'discrete');
            app.SpeedSetting_Force.Items = {'Low', 'default', 'Medium', 'High'};
            app.SpeedSetting_Force.ItemsData = {'1', '2', '3', '4'};
            app.SpeedSetting_Force.Value = '1';
            app.ShowThresholdCheckBox = uicheckbox(app.ForceParameters);
            app.ShowThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowThresholdCheckBoxValueChanged, true);
            app.ShowThresholdCheckBox.Text = 'Show Threshold';
            app.ShowThresholdCheckBox.Value = true;
            app.ThresholdmNLabel = uilabel(app.ForceParameters);
            app.ThresholdmNLabel.Text = 'Threshold [mN]';
            app.ArmWeightCompensationTab = uitab(app.TabGroup3_2);
            app.ArmWeightCompensationTab.Title = 'Arm Weight Compensation';
            app.ForceControlGraphs_2 = uipanel(app.ArmWeightCompensationTab);
            app.ForceControlGraphs_2.Title = 'Force Control';
            app.ForceReading_2 = uiaxes(app.ForceControlGraphs_2);
            title(app.ForceReading_2, 'EMG Sensor')
            xlabel(app.ForceReading_2, 'Time [s]')
            ylabel(app.ForceReading_2, 'ENV Amplitude [mV]')
            zlabel(app.ForceReading_2, 'Z')
            app.ForceReading_2.XLim = [0 100];
            app.ForceReading_2.YLim = [-1500 1500];
            app.ForceReading_2.YTick = [-5000 -4000 -3000 -2000 -1000 0 1000 2000 3000 4000 5000];
            app.ForceReading_2.YTickLabel = {'-5000'; '-4000'; '-3000'; '-2000'; '-1000'; '0'; '1000'; '2000'; '3000'; '4000'; '5000'};
            app.IMUPlot_Force_2 = uiaxes(app.ForceControlGraphs_2);
            title(app.IMUPlot_Force_2, '3D Position Estimation')
            app.IMUPlot_Force_2.XLim = [0 100];
            app.IMUPlot_Force_2.YLim = [-1500 1500];
            app.IMUPlot_Force_2.XTick = [];
            app.IMUPlot_Force_2.YTick = [];
            app.IMUPlot_Force_2.YTickLabel = '';
            app.ForceParameters_2 = uipanel(app.ForceControlGraphs_2);
            app.ForceParameters_2.Title = 'Parameters';
            app.StartSwitchLabel_8 = uilabel(app.ForceParameters_2);
            app.StartSwitchLabel_8.Text = 'Start';
            app.StartRecordingSwitch_Force_2 = uiswitch(app.ForceParameters_2, 'rocker');
            app.AppliedFilterButtonGroup_Force_2 = uibuttongroup(app.ForceParameters_2);
            app.AppliedFilterButtonGroup_Force_2.Title = 'Applied Filter';
            app.LeftSidedMovingAverageButton_2 = uiradiobutton(app.AppliedFilterButtonGroup_Force_2);
            app.LeftSidedMovingAverageButton_2.Text = {'Left Sided '; 'Moving Average'};
            app.LeftSidedMovingAverageButton_2.Value = true;
            app.CenteredMovingAverageButton_2 = uiradiobutton(app.AppliedFilterButtonGroup_Force_2);
            app.CenteredMovingAverageButton_2.Text = {'Centered '; 'Moving Average'};
            app.EWMVAButton_2 = uiradiobutton(app.AppliedFilterButtonGroup_Force_2);
            app.EWMVAButton_2.Text = 'EWMVA';
            app.ServoMotorSpeedLabel_5 = uilabel(app.ForceParameters_2);
            app.ServoMotorSpeedLabel_5.Text = 'Servo Motor Speed';
            app.SpeedSetting_Force_2 = uiknob(app.ForceParameters_2, 'discrete');
            app.SpeedSetting_Force_2.Items = {'Low', 'default', 'Medium', 'High'};
            app.SpeedSetting_Force_2.ItemsData = {'1', '2', '3', '4'};
            app.SpeedSetting_Force_2.Value = '1';
            app.UserHeightmSpinnerLabel = uilabel(app.ForceParameters_2);
            app.UserHeightmSpinnerLabel.Text = 'User Height  [m]';
            app.UserHeightmSpinner = uispinner(app.ForceParameters_2);
            app.UserHeightmSpinner.Step = 0.01;
            app.UserHeightmSpinner.Limits = [1 2.5];
            app.UserHeightmSpinner.Value = 1.58;
            app.UserWeightkgSpinnerLabel = uilabel(app.ForceParameters_2);
            app.UserWeightkgSpinnerLabel.Text = 'User Weight [kg]';
            app.UserWeightkgSpinner = uispinner(app.ForceParameters_2);
            app.UserWeightkgSpinner.Limits = [20 200];
            app.UserWeightkgSpinner.Value = 50;
            app.UserSpecificParametersLabel = uilabel(app.ForceParameters_2);
            app.UserSpecificParametersLabel.Text = 'User Specific Parameters';
            app.ServoPowerSwitch_2Label = uilabel(app.ForceParameters_2);
            app.ServoPowerSwitch_2Label.Text = 'Servo Power';
            app.ServoPowerSwitch_2 = uiswitch(app.ForceParameters_2, 'slider');
            app.IMUPosePlotTab = uitab(app.TabGroup);
            app.IMUPosePlotTab.Title = 'IMU Pose';
            app.IMUPosePlot = uipanel(app.IMUPosePlotTab);
            app.IMUPosePlot.Title = 'IMU Plot';
            app.IMUPlot = uiaxes(app.IMUPosePlot);
            title(app.IMUPlot, '3D Position Estimation')
            xlabel(app.IMUPlot, 'Time [s]')
            zlabel(app.IMUPlot, 'Z')
            app.IMUPlot.XLim = [0 100];
            app.IMUPlot.YLim = [-1500 1500];
            app.IMUPlot.XTick = [];
            app.IMUPlot.XTickLabel = '';
            app.IMUPlot.YTick = [];
            app.IMUPlot.ZTick = [];
            app.IMUPlot.Visible = 'off';
            app.IMU_Parameters = uipanel(app.IMUPosePlotTab);
            app.IMU_Parameters.Title = 'Parameters';
            app.StartSwitchLabel_5 = uilabel(app.IMU_Parameters);
            app.StartSwitchLabel_5.Text = 'Start';
            app.StartRecordingSwitch_IMU = uiswitch(app.IMU_Parameters, 'rocker');
            app.StartRecordingSwitch_IMU.ValueChangedFcn = createCallbackFcn(app, @StartRecordingSwitch_IMUValueChanged, true);
            app.BatteryManager = uipanel(app.JUSTEXOUIFigure);
            app.BatteryImage = uiimage(app.BatteryManager);
            app.BatteryImage.ImageSource = fullfile(pathToMLAPP, 'photos', 'fa-battery-4.png');
            app.BatteryPercentage = uilabel(app.BatteryManager);
            app.BatteryPercentage.Text = '';
            app.StatusManager = uipanel(app.JUSTEXOUIFigure);
            app.Label = uilabel(app.StatusManager);
            app.Label.Text = '';
            app.StatusLamp = uilamp(app.StatusManager);
            app.ConnectionManager = uipanel(app.JUSTEXOUIFigure);
            app.ConnectButton = uibutton(app.ConnectionManager, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.BTStatusImage = uiimage(app.ConnectionManager);
            app.BTDeviceDropDownLabel = uilabel(app.ConnectionManager);
            app.BTDeviceDropDownLabel.Text = 'BT Device';
            app.BTDeviceDropDown = uidropdown(app.ConnectionManager);
            app.BTDeviceDropDown.Items = {};
            app.BTDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @BTDeviceDropDownValueChanged, true);
            app.BTDeviceDropDown.Enable = 'off';
            app.BTDeviceDropDown.Placeholder = 'select device';
            app.BTDeviceDropDown.ClickedFcn = createCallbackFcn(app, @BTDeviceDropDownClicked, true);
            app.BTDeviceDropDown.Value = {};
            app.RefreshButton_Connection_List = uibutton(app.ConnectionManager, 'push');
            app.RefreshButton_Connection_List.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_Connection_ListPushed, true);
            app.RefreshButton_Connection_List.Icon = fullfile(pathToMLAPP, 'photos', 'reload.png');
            app.RefreshButton_Connection_List.Enable = 'off';
            app.RefreshButton_Connection_List.Text = '';
            app.EmergencyManager = uipanel(app.JUSTEXOUIFigure);
            app.EmergnecySTOPButton = uibutton(app.EmergencyManager, 'state');
            app.EmergnecySTOPButton.ValueChangedFcn = createCallbackFcn(app, @EmergnecySTOPButtonValueChanged, true);
            app.EmergnecySTOPButton.Icon = fullfile(pathToMLAPP, 'photos', 'stop-button.png');
            app.EmergnecySTOPButton.Text = '';
            app.Image = uiimage(app.JUSTEXOUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'photos', 'lastttt.png');
            app.Image_2 = uiimage(app.JUSTEXOUIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'photos', 'chalmers-university-of-technology-.png');
            % Show the figure after all components are created
            app.JUSTEXOUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BT_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.JUSTEXOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.JUSTEXOUIFigure)
        end
    end
end